classdef PhaseLABgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        LeftToolsGridLayout            matlab.ui.container.GridLayout
        holdClimLeftCheckBox           matlab.ui.control.CheckBox
        maxLeftEditField               matlab.ui.control.NumericEditField
        minLeftEditField               matlab.ui.control.NumericEditField
        invertCheckBoxLeft             matlab.ui.control.CheckBox
        colormapDropDownLeft           matlab.ui.control.DropDown
        colormapDropDown_3Label        matlab.ui.control.Label
        resetSlidersLeft               matlab.ui.control.Button
        Image5                         matlab.ui.control.Image
        GridLayout2                    matlab.ui.container.GridLayout
        clearButton                    matlab.ui.control.Image
        copyButton                     matlab.ui.control.Image
        messageArea                    matlab.ui.control.TextArea
        RenderingGridLayout            matlab.ui.container.GridLayout
        scaleButton                    matlab.ui.container.ButtonGroup
        scaleButtonpx                  matlab.ui.control.RadioButton
        scaleButtonum                  matlab.ui.control.RadioButton
        displayButton                  matlab.ui.container.ButtonGroup
        displayButtonStandard          matlab.ui.control.RadioButton
        displayButton3D                matlab.ui.control.RadioButton
        RightToolsGridLayout           matlab.ui.container.GridLayout
        holdClimRightCheckBox          matlab.ui.control.CheckBox
        maxRightEditField              matlab.ui.control.NumericEditField
        minRightEditField              matlab.ui.control.NumericEditField
        invertCheckBoxRight            matlab.ui.control.CheckBox
        resetSlidersRight              matlab.ui.control.Button
        colormapDropDownRight          matlab.ui.control.DropDown
        colormapDropDown_4Label        matlab.ui.control.Label
        ControlsGridLayout             matlab.ui.container.GridLayout
        NimText                        matlab.ui.control.NumericEditField
        jText                          matlab.ui.control.NumericEditField
        buttonNext                     matlab.ui.control.Image
        reload                         matlab.ui.control.Image
        buttonPrev                     matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        propertiesTab                  matlab.ui.container.Tab
        propertiesGridLayout           matlab.ui.container.GridLayout
        comment                        matlab.ui.control.Label
        panelCGM                       matlab.ui.container.Panel
        CGMsystemGridLayout            matlab.ui.container.GridLayout
        zetaEditField                  matlab.ui.control.NumericEditField
        zetaLabel                      matlab.ui.control.Label
        ItfFileName                    matlab.ui.control.Label
        CGMsystemLabel                 matlab.ui.control.Label
        CGMcameraName                  matlab.ui.control.EditField
        CGMcameraEditFieldLabel        matlab.ui.control.Label
        grexelsizeEditField            matlab.ui.control.NumericEditField
        grexelsizeEditFieldLabel       matlab.ui.control.Label
        zoomEditField                  matlab.ui.control.NumericEditField
        zoomEditFieldLabel             matlab.ui.control.Label
        dexelsizeEditField             matlab.ui.control.NumericEditField
        dexelsizeEditFieldLabel        matlab.ui.control.Label
        grexelsizeLabel                matlab.ui.control.Label
        zoomLabel                      matlab.ui.control.Label
        dexelsizeLabel                 matlab.ui.control.Label
        TLfocallengthEditField         matlab.ui.control.NumericEditField
        TLfocallengthEditFieldLabel    matlab.ui.control.Label
        numericalapertureEditField     matlab.ui.control.NumericEditField
        numericalapertureEditFieldLabel  matlab.ui.control.Label
        magnificationEditField         matlab.ui.control.NumericEditField
        magnificationEditField_3Label  matlab.ui.control.Label
        pixelsizeEditField             matlab.ui.control.NumericEditField
        pixelsizeEditFieldLabel        matlab.ui.control.Label
        wavelengthEditField            matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel       matlab.ui.control.Label
        TLfocallengthLabel             matlab.ui.control.Label
        MICROSCOPELabel                matlab.ui.control.Label
        magnificationLabel             matlab.ui.control.Label
        wavelengthLabel                matlab.ui.control.Label
        pixelsizeLabel                 matlab.ui.control.Label
        processingTab                  matlab.ui.container.Tab
        processingGridMLayout          matlab.ui.container.GridLayout
        offsetMethodDropDown           matlab.ui.control.DropDown
        offsetButton                   matlab.ui.control.Button
        filterButton                   matlab.ui.control.Button
        flattenButton                  matlab.ui.control.Button
        flattenOrderSpinner            matlab.ui.control.Spinner
        orderSpinnerLabel              matlab.ui.control.Label
        binningSpinner                 matlab.ui.control.Spinner
        binningButton                  matlab.ui.control.Button
        rot90Button                    matlab.ui.control.Button
        mirrorVButton                  matlab.ui.control.Button
        mirrorHButton                  matlab.ui.control.Button
        OFFSETLabel                    matlab.ui.control.Label
        BINNINGLabel                   matlab.ui.control.Label
        filterCutoffUnitLabel          matlab.ui.control.Label
        filterCutoffEditField          matlab.ui.control.NumericEditField
        cutoffLabel                    matlab.ui.control.Label
        filterMethodDropDown           matlab.ui.control.DropDown
        FILTERLabel                    matlab.ui.control.Label
        modifyWorkspaceCheckBox        matlab.ui.control.CheckBox
        RestoreAllImagesButton         matlab.ui.control.Button
        RestoreImageButton             matlab.ui.control.Button
        SaveAllImagesButton            matlab.ui.control.Button
        SaveImageButton                matlab.ui.control.Button
        flattenThresholdEditField      matlab.ui.control.NumericEditField
        thresholdEditFieldLabel        matlab.ui.control.Label
        flattenMethodDropDown          matlab.ui.control.DropDown
        heightCropUnitLabel            matlab.ui.control.Label
        widthCropUnitLabel             matlab.ui.control.Label
        heightEditField                matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        cropButtonCenterRect           matlab.ui.control.Image
        cropButtonCenterSquare         matlab.ui.control.Image
        cropButton                     matlab.ui.control.Image
        FLATTENLabel                   matlab.ui.control.Label
        CROPLabel                      matlab.ui.control.Label
        MOTIONLabel                    matlab.ui.control.Label
        ApplytoallimagesCheckBox       matlab.ui.control.CheckBox
        metrologyTab                   matlab.ui.container.Tab
        metrologyGridLayout            matlab.ui.container.GridLayout
        saveButton                     matlab.ui.control.Button
        saveFileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        saveFolderEditField            matlab.ui.control.EditField
        saveFolderImage                matlab.ui.control.Image
        AutoSaveCheckBox               matlab.ui.control.CheckBox
        SAVEDATALabel                  matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
        stepEditField                  matlab.ui.control.NumericEditField
        stepEditFieldLabel             matlab.ui.control.Label
        bkgRingLabel                   matlab.ui.control.Label
        bkgRingEditField               matlab.ui.control.NumericEditField
        NmaxEditField                  matlab.ui.control.NumericEditField
        NmaxEditFieldLabel             matlab.ui.control.Label
        DMmagicwandButton              matlab.ui.control.Button
        DMroiButton                    matlab.ui.control.Button
        DMradialButton                 matlab.ui.control.Button
        alphaOVDMLabel                 matlab.ui.control.Label
        crosscutPathButton             matlab.ui.control.Button
        CROSSCUTSLabel                 matlab.ui.control.Label
        METROLOGYLabel                 matlab.ui.control.Label
        crosscutRadial                 matlab.ui.control.Button
        crosscutsHVButton              matlab.ui.control.Button
        areaButton                     matlab.ui.control.Button
        distanceButton                 matlab.ui.control.Button
        pixelButton                    matlab.ui.control.Button
        exportTab                      matlab.ui.container.Tab
        exportGridLayout               matlab.ui.container.GridLayout
        perspCheckBox                  matlab.ui.control.CheckBox
        HzLabel                        matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        cblabelEditField               matlab.ui.control.EditField
        cblabelEditFieldLabel          matlab.ui.control.Label
        TCheckBox                      matlab.ui.control.CheckBox
        OPDCheckBox                    matlab.ui.control.CheckBox
        cbfactorEditField              matlab.ui.control.NumericEditField
        cbfactorEditFieldLabel         matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        phiEditField                   matlab.ui.control.NumericEditField
        phiEditFieldLabel              matlab.ui.control.Label
        rateVideoEditField             matlab.ui.control.NumericEditField
        rateEditFieldLabel             matlab.ui.control.Label
        exportMovieButton              matlab.ui.control.Button
        EXPORTVIDEOLabel               matlab.ui.control.Label
        exportSuffixLabel              matlab.ui.control.Label
        export_pngCheckBox             matlab.ui.control.CheckBox
        exportSelectionRadioButtonGroup  matlab.ui.container.ButtonGroup
        exportSelectionButton          matlab.ui.control.RadioButton
        exportAllButton                matlab.ui.control.RadioButton
        exportCurrentButton            matlab.ui.control.RadioButton
        export_epsCheckBox             matlab.ui.control.CheckBox
        export_txtCheckBox             matlab.ui.control.CheckBox
        exportFolderImage              matlab.ui.control.Image
        EXPORTIMAGELabel               matlab.ui.control.Label
        exportFileNameEditField        matlab.ui.control.EditField
        FileNameEditFieldLabel_2       matlab.ui.control.Label
        exportFolderEditField          matlab.ui.control.EditField
        exportImageButton              matlab.ui.control.Button
        AxesLeft                       matlab.ui.control.UIAxes
        AxesRight                      matlab.ui.control.UIAxes
    end

    
    properties (Access =  public)
        jim {mustBeInteger,mustBePositive} = 1 % # of the image that is currently displayed
        cbLeft  matlab.graphics.illustration.ColorBar % left panel colorbar
        cbRight matlab.graphics.illustration.ColorBar % right panel colorbar
        roiIN cell
        roiOUT cell
        savedData struct  % Table that collects the last measurments, saveData.table, saveData.sheetName
    end

    properties(Dependent)
        scale   % 'px' or 'µm'
        pxSize
        saveFile
        saveFolder
        saveFolderFile
        autoSave % true or false to write data in a file automatically
    end
    
    properties (Access = private)
        IM (1,:) ImageMethods % images to be displayed, possibly postprocessed
        IM0 (1,:) ImageMethods % original images, unmodified, unless save button is pressed.
        valLeftMin
        valLeftMax
        valRightMin
        valRightMax
        imageFun   %imagesc or image3D
        invColorMapLeft  logical = false % invert the left colomap
        invColorMapRight logical = false % invert theright colomap
        imageType
        Nim  % Number of images in IM
    end
    
    properties (Access = private, Dependent)
        imType % 'ImageQLSI' or 'ImageEM' or 'none'
    end
    
    properties (SetAccess = private, Dependent)
        ImageLeft % image object of the left panel
        ImageRight % image object of the right panel
        LightLeft % Light object of the left panel when in 3D rendering mode
        LightRight % light object of the right panel when in 3D rendering mode
    end
    
    methods
        
%         function set.IM(app,val)
%             app.IM=val;
%             app.updateImages();
%             drawnow
%         end

        function val = get.scale(app)
            val = app.scaleButton.SelectedObject.Text;
        end

        function val = get.saveFile(app)
            file = app.saveFileNameEditField.Value;
            if isempty(file)
                val = 'data.txt';
            else
                val = file;
            end
        end

        function val = get.pxSize(app)
            IMc = app.IMcurrent(app.jim,'WriteMode','r');
            val = IMc.pxSize;
        end

        function val = get.saveFolder(app)
            saveFold = app.saveFolderEditField.Value;
            if isempty(saveFold)
                val = pwd;
            else
                val = saveFold;
            end
        end

        function val = get.saveFolderFile(app)
            val = [app.saveFolder '/' app.saveFile];
        end

        function val = get.autoSave(app)
            val = app.AutoSaveCheckBox.Value;
        end

        function val = get.ImageLeft(app)
        % give a name to the image object contained in the left Axes object
            val = app.AxesLeft.Children(end);
        end

        function val = get.ImageRight(app)
        % give a name to the image object contained in the right Axes object
            val = app.AxesRight.Children(end);
        end

        function val = get.LightLeft(app)
            val = findall(app.AxesLeft.Children,'type','Light');
        end

        function val = get.LightRight(app)
            val = findall(app.AxesRight.Children,'type','Light');
        end

        function IMlist = IMcurrent(app,range,opt) % returns IM or IM0
            % IMcurrent()
            % IMcurrent(4)
            % IMcurrent('WriteMode','w')
            % IMcurrent(4,'WriteMode','w')
            arguments
                app
                range = app.jim
                opt.WriteMode (1,1) char {mustBeMember(opt.WriteMode,{'w','r'})} = 'r' 
            end
            if islogical(range)
                numList = 1:app.Nim;
                range = numList(range);
            end
            range = range(:)'; % makes sure it is an horizontal vector to be used in a loop: for ii = range
            No = numel(range);
            % initialize the output
            if strcmpi(app.imageType,'ImageQLSI')
                IMlist=ImageQLSI(No);
            elseif strcmpi(app.imageType,'ImageEM')
                IMlist=ImageEM(n=No);
            else
                app.imageType
                error('app.imageType not initialized')
            end

            % Determine whether original images app.IM0 are supposed to be
            % mofidied, or only the copy app.IM
            ws = app.modifyWorkspaceCheckBox.Value;

            n=0;
            for ii = range
                n=n+1;
                if ws % If the workspace is supposed to be modified,
                    IMlist(n)=app.IM0(ii); % ... one only works with app.IM0 anyway.
                elseif isempty(app.IM(ii).OPD) && strcmpi(opt.WriteMode,'r') % empty IM image, but in read mode,
                    IMlist(n)=app.IM0(ii); % ... one can safely return IM0.
                elseif isempty(app.IM(ii).OPD) && strcmpi(opt.WriteMode,'w') % empty IM image, but in read mode,
                    app.IM(ii)=copy(app.IM0(ii)); % ... a copy of IM0 is made in IM
                    IMlist(n)=app.IM(ii); % ... and one returns IM.
                elseif ~isempty(app.IM(ii).OPD) && strcmpi(opt.WriteMode,'w') % non-empty IM image, and in write mode,
                    IMlist(n)=app.IM(ii); % ... and one just returns IM.
                elseif ~isempty(app.IM(ii).OPD) && strcmpi(opt.WriteMode,'r') % non-empty IM image, and in read mode,
                    IMlist(n)=app.IM(ii); % ... and one just returns IM.
                end
            end
        end

        function switchQLSIparameters(app,val)
            arguments
                app
                val (1,:) char {mustBeMember(val,{'on','off'})}
            end
            app.panelCGM.Visible=val;
        end

        function val = get.imType(app)
            if isempty(app.IM0)
                val = 'none';
            else
                val = class(app.IM(1));
            end                
        end

        function updateImages(app)

            %% TODO : make this app also usable for interfero objects
            IMc = app.IMcurrent();

            if isempty(app.IM(app.jim))
                app.SaveImageButton.Enable = false;
                app.RestoreImageButton.Enable = false;
            else
                app.SaveImageButton.Enable = true;
                app.RestoreImageButton.Enable = true;
            end

            if isempty(app.IM)
                app.SaveAllImagesButton.Enable = false;
                app.RestoreAllImagesButton.Enable = false;
            else
                app.SaveAllImagesButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end            

            
            % saving the min max values of the images
            app.valLeftMin = min(IMc.OPD(:)*1e9);
            app.valLeftMax = max(IMc.OPD(:)*1e9);
            app.valRightMin = min(IMc.T(:));
            app.valRightMax = max(IMc.T(:));


            pxum = app.scaleButton.SelectedObject.Text; % 'px' or 'µm'
            if strcmpi(pxum,'px')
                fac = 1;
            elseif strcmpi(pxum,'µm')
                fac = IMc.pxSize*1e6;
            end
            %pxum = 'px';
            %fac = 1;
             

            % define the proper imaging function @imagesc or @image3D
            imSize = size(IMc.OPD);
            
            if strcmpi(app.displayButton.SelectedObject.Text,'standard')
                app.imageFun = @imagesc;
            elseif strcmpi(app.displayButton.SelectedObject.Text,'3D')
                app.imageFun = @image3D;
            end
            
            % display the image number in the text box
            app.jText.Value = app.jim; 
            app.NimText.Value = numel(app.IM); 

            
            % affiche les 2 images, et définit leur colormap
%            app.imageFun(app.AxesLeft, [1 imSize(2)]*fac,[1 imSize(1)]*fac,IMc.OPD*1e9)
            app.imageFun(app.AxesLeft, [1:imSize(2)]*fac,[1:imSize(1)]*fac,IMc.OPD*1e9)
            colormapDropDownLeftValueChanged(app)
            
            app.imageFun(app.AxesRight, [1:imSize(2)]*fac,[1:imSize(1)]*fac, IMc.T)
            colormapDropDownRightValueChanged(app)


            % update/reset the limits
            app.resetLimitsEditField()
            % redimension at the original size, in px coordinates
            xlim(app.AxesLeft,[1 imSize(2)]*fac); 
            ylim(app.AxesLeft,[1 imSize(1)]*fac); 
            xlim(app.AxesRight,[1 imSize(2)]*fac); 
            ylim(app.AxesRight,[1 imSize(1)]*fac); 
            xlabel(app.AxesRight,pxum)
            ylabel(app.AxesRight,' ')
            xlabel(app.AxesLeft,pxum)
            ylabel(app.AxesLeft,' ')
            drawnow

            % make Current Axes BoundAxes . TODO: understand why the function makeCurrentAxesBoundAxes does not work here (it freezes the home zoom button)
            zoom(app.AxesLeft,'reset')
            zoom(app.AxesRight,'reset')

            % Lie les axes seulement en xy
            linkaxes([app.AxesLeft,app.AxesRight],'xy')


            % Ajusts the DataAspactRatio to [1 1 x] where x is calculated properly
            adjustdAspect(app,'all')

            % update light position
            app.setLightPositions()


            % update properties tab
            app.wavelengthEditField.Value       = IMc.Illumination.lambda*1e9;
            app.pixelsizeEditField.Value        = IMc.Microscope.pxSize*1e9;
            app.magnificationEditField.Value    = abs(IMc.Microscope.M);
            app.numericalapertureEditField.Value= IMc.Microscope.Objective.NA;
            app.TLfocallengthEditField.Value    = IMc.Microscope.f_TL;
            app.comment.Text                    = IMc.comment;
            if strcmp(app.imType,'ImageQLSI')
                app.grexelsizeEditField.Value = abs(IMc.Microscope.CGcam.CG.Gamma*1e6);
                app.dexelsizeEditField.Value = abs(IMc.Microscope.CGcam.Camera.dxSize*1e6);
                app.zoomEditField.Value = abs(IMc.Microscope.CGcam.zoom);
                app.CGMcameraName.Value    = IMc.Microscope.CGcam.fileName;
                app.zetaEditField.Value             = IMc.Microscope.CGcam.zeta;
                app.ItfFileName.Text = IMc.ItfFileName;
            end
            
            % creates and initialize the 'matlab_graphics_resetplotview' structure
            zoom(app.AxesRight,1)
            zoom(app.AxesLeft,1)


        end

        function displayMessage(app,message,color)
            if nargin == 2
                color = [0 0 0];
            end
            app.messageArea.FontColor = color;
            app.messageArea.Value = sprintf(message); % the use of sprintf enable the execution of \n if it is contained in the input
        end

        function clearMessageArea(app,message)
            % message : message to be displayed before clearing. Optional
            if nargin == 2
                app.displayMessage(message)
                pause(1)
            end
            app.messageArea.Value = ' ';
            app.messageArea.FontColor = [0, 0, 0];
        end

        
    end
    
    methods (Access = private)
        
        function warn(app,message)
            % function that display
            warnColor = [1, 0.5, 0.2];
            app.displayMessage(message,warnColor)
            pause(0.5);
            app.clearMessageArea()
        end

        function resetLimitsEditField(app,which_one)
            arguments
                app
                which_one = 'all'
            end
            switch which_one

                case 'all'
                    resetLimitsEditField(app,'right')
                    resetLimitsEditField(app,'left')
                case 'left'
                    if ~app.holdClimLeftCheckBox.Value
                        app.minLeftEditField.Value = app.valLeftMin;
                        app.maxLeftEditField.Value = app.valLeftMax;
                        app.minLeftEditField.Value = round(app.minLeftEditField.Value,1);
                        app.maxLeftEditField.Value = round(app.maxLeftEditField.Value,1);
                        %clim(app.AxesLeft,'auto')
                        clim(app.AxesLeft,[app.minLeftEditField.Value app.maxLeftEditField.Value])
                    end
                case 'right'
                    if ~app.holdClimRightCheckBox.Value
                        app.minRightEditField.Value = app.valRightMin;
                        app.maxRightEditField.Value = app.valRightMax;
                        app.minRightEditField.Value = round(app.minRightEditField.Value,2);
                        app.maxRightEditField.Value = round(app.maxRightEditField.Value,2);
                        %clim(app.AxesRight,'auto')
                        clim(app.AxesRight,[app.minRightEditField.Value app.maxRightEditField.Value])
                    end
            end
            
        end

        function val = DX_imLeft(app)
            % width of the image
            val = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
        end
        function val = DY_imLeft(app)
            % height of the image
            val = max(app.ImageLeft.YData) - min(app.ImageLeft.YData);
        end
        function val = DZ_imLeft(app)
            % thickness of the image
            val = max(app.ImageLeft.CData(:)) - min(app.ImageLeft.CData(:));
        end
        function val = DX_imRight(app)
            % width of the image
            val = max(app.ImageRight.XData) - min(app.ImageRight.XData);
        end
        function val = DY_imRight(app)
            % height of the image
            val = max(app.ImageRight.YData) - min(app.ImageRight.YData);
        end
        function val = DZ_imRight(app)
            % thickness of the image
            val = max(app.ImageRight.CData(:)) - min(app.ImageRight.CData(:));
        end
        
        function setLightPositions(app)
            % updates the position of the light in case the image mode is
            % 3D. Useful when changing the scale of the image, for instance.
            if isequal(app.imageFun,@image3D)
                amplX = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
                %amplZ = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);


                % TODO : play with daspect([factor factor DZ/10])
                % to improve the 3Dd visualizatin of the T image
                    
                lightLeft = findall(app.AxesLeft.Children,'type','Light');
                lightRight = findall(app.AxesRight.Children,'type','Light');
                lightLeft.Position  = [370*amplX/100 -250*amplX/100 lightLeft.Position(3)];
                lightRight.Position = [370*amplX/100 -250*amplX/100 lightRight.Position(3)];
            end
        end

        function clearSavedData(app) % erase the stored data
            app.savedData = struct([]);
        end
        
        function hout = displayDualImage(app)
            h=figure;
            h.Position(3:4) = [850,410];
            h.Color = [1 1 1];
            h.ToolBar = "none";
            
            axLeft = copy(app.AxesLeft);
            axLeft.Parent=h;
            axLeft.Position = [10,10,400,400];
            colorbar(axLeft)
            
            axRight = copy(app.AxesRight);
            axRight.Parent=h;
            axRight.Position = [410,10,400,400];
            colorbar(axRight)

            if nargin
                hout = h;
            end
            
        end
        
        function moveToImage(app, num)
            if num > app.Nim
                app.warn("end of the image stack")
            elseif num < 1
                app.warn("end of the image stack")
            else
                app.jim=num;
            end
            app.updateImages()
            
        end
        
    end
    methods
        function adjustdAspect(app,side)
            arguments
                app
                side char {mustBeMember(side,{'Left','Right','left','right','OPD','T','all'})} = 'all'
            end

            if ismember(side,{'all','Left','left','OPD'})
                daspect(app.AxesLeft,[1 1 10*app.DZ_imLeft/app.DX_imLeft])
            end
            if ismember(side,{'all','Right','right','T'})
                daspect(app.AxesRight,[1 1 30*app.DZ_imRight/app.DX_imRight])
            end

        end

        function val = DZright(app)
            val = app.DZ_imRight;
        end

        function val = DZleft(app)
            val = app.DZ_imLeft;
        end

        function [xc, yc] = ginputUI(app,n,opt)
            arguments
                app
                n
                opt.outUnit char {mustBeMember(opt.outUnit,{'px','um','auto'})} = 'auto'
            end
            %fhv = app.UIFigure.HandleVisibility;        % Current status
            app.enableHandleVisibility()
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            [xc0, yc0] = ginput(n);
            if nargout==1 || nargout==0
                xc0 = [xc0, yc0];
            end

            switch opt.outUnit
                case 'auto'
                    xc=xc0;
                    yc=yc0;
                case 'px' % desired output in px
                    if strcmpi(app.scale,'px') % and app scale in px
                        xc=round(xc0);
                        yc=round(yc0);
                    else % and app scale in µm
                        xc=app.um2px(xc0);
                        yc=app.um2px(yc0);
                    end
                case 'µm' % desired output in µm
                    if strcmpi(app.scale,'px') % and app scale in px
                        xc=app.px2um(xc0);
                        yc=app.px2um(yc0);
                    else % output desired in µm while measured in px
                        xc=xc0;
                        yc=yc0;
                    end
            end

            app.disableHandleVisibility()
            %app.UIFigure.HandleVisibility = fhv;    % return original state
        end

        function enableHandleVisibility(app)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

        end

        function disableHandleVisibility(app)
            app.UIFigure.HandleVisibility = 'off';

        end


        function makeCurrentAxesBoundAxes(app)
            % Makes the current Axes range in x and y the limiting axes, to
            % be reach when pressing the home button in an Axes.
%            zoom(app.AxesLeft,'reset')
%            zoom(app.AxesRight,'reset')
            IMc=app.IMcurrent();
            axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
            if strcmp(app.scaleButton.SelectedObject.Text,'px')
                axesData.XLim=[1 , IMc.Nx];
                axesData.YLim=[1 , IMc.Ny];
            else
                axesData.XLim=[1 , IMc.Nx]*IMc.pxSize*1e6;
                axesData.YLim=[1 , IMc.Ny]*IMc.pxSize*1e6;
            end                
            setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
            setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);
        end

        function [vec1, vec2] = um2px(app,vec)
            % convert µm to px. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout <= 1 % only one output asked, or no output
                vec1 = round(vec/app.pxSize*1e-6);
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = round(vec(1)/app.pxSize*1e-6);
                vec2 = round(vec(2)/app.pxSize*1e-6);
            end
        end

        function [vec1, vec2] = um22px(app,vec)
            % convert µm^2 to px. 

            if length(vec)>=1 && nargout <= 1 % only one output asked, or no output
                vec1 = round(vec/(app.pxSize)^2*1e-12);
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = round(vec(1)/app.pxSize^2*1e-12);
                vec2 = round(vec(2)/app.pxSize^2*1e-12);
            end
        end

        function [vec1, vec2] = px2um(app,vec)
            % convert px to um. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = vec*app.pxSize*1e6;
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = vec(1)*app.pxSize*1e6;
                vec2 = vec(2)*app.pxSize*1e6;
            end
        end

        function [vec1, vec2] = px2um2(app,vec)
            % convert px to um. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = vec*app.pxSize^2*1e12;
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = vec(1)*app.pxSize^2*1e12;
                vec2 = vec(2)*app.pxSize^2*1e12;
            end
        end

        function saveData(app,opt)
            arguments
                app
                opt.tech = char.empty()
                opt.unit char {mustBeMember(opt.unit,{'px','um','undef'})} = 'undef'
                opt.position double = double.empty()
                opt.alpha double = double.empty()
                opt.DM double = double.empty()
                opt.OV double = double.empty()
                opt.values (1,2) double = [0 0]% =[OPD, T]
                opt.distance double = double.empty()
                opt.area double = double.empty()
                opt.crosscut double = []
                opt.format char {mustBeMember(opt.format,{'txt','dat','csv','xls'})} = 'xls'
                opt.writeData logical = false % tells whether the data as saved in a file, or just kept in hte variable app.saveTable
            end

            if strcmp(opt.unit,'undef') && any([~isempty(opt.position), ~isempty(opt.area), ~isempty(opt.distance), any(opt.values~=0)])
                error('a unit, px or um, must be specified with a position')
            end
        
            nRow = 1;
            nCol = 1;
            varType(nCol) = "datetime";
            varNames(nCol) = "date";
            data{nCol} = datetime("now");

            nCol = nCol+1;
            varType(nCol) = "double";
            varNames(nCol) = "image #";
            data{nCol} = app.jim;

            sheetName = opt.tech;
            if ~isempty(opt.position)
                sheetName = [sheetName, '_Position'];

                if numel(opt.position)~=2
                    error('A position must have 2 elements')
                end

                switch opt.unit
                    case 'px'
                        position_px = opt.position;
                        position_um = app.px2um(opt.position);
                    case 'um'
                        position_px = app.um2px(opt.position);
                        position_um = opt.position;
                end

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "X (px)";
                data{nCol} = position_px(1);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Y (px)";
                data{nCol} = position_px(2);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "X (µm)";
                data{nCol} = position_um(1);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Y (µm)";
                data{nCol} = position_um(2);
            end
            if ~isempty(opt.OV)
                sheetName = [sheetName, '_OV'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OV (x10^-18 m^3)";
                data{nCol} = opt.OV*1e18;
            end
            if ~isempty(opt.DM)
                sheetName = [sheetName, '_DM'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "DM (pg)";
                data{nCol} = opt.DM*1e12;
            end

            if ~isempty(opt.alpha)
                sheetName = [sheetName, '_alpha'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Re(alpha) (x10^-18)";
                data{nCol} = real(opt.alpha)*1e18;

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Im(alpha) (x10^-18)";
                data{nCol} = imag(opt.alpha)*1e18;

                nCol=nCol+1;
                varType(nCol) = "string";
                varNames(nCol) = "alpha (x10^-18)";
                data{nCol} = sprintf('=COMPLEXE(%f;%f)',real(opt.alpha)*1e18,imag(opt.alpha)*1e18);
            end
            if any(opt.values~=0)
                sheetName = [sheetName, '_values'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OPD value (nm)";
                data{nCol} = opt.values(1);
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "T value";
                data{nCol} = opt.values(2);
            end
            if ~isempty(opt.distance)
                sheetName = [sheetName, '_distance'];
                switch opt.unit
                    case 'px'
                        d_px = opt.distance;
                        d_um = app.px2um(opt.distance);
                    case 'um'
                        d_px = app.um2px(opt.distance);
                        d_um = opt.distance;
                end
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "distance (px)";
                data{nCol} = d_px;
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "distance (µm)";
                data{nCol} = d_um;
            end
            if ~isempty(opt.area)
                sheetName = [sheetName, '_area'];
                switch opt.unit
                    case 'px'
                        area_px = opt.area;
                        area_um = app.px2um2(opt.area);
                    case 'um'
                        area_px = app.um22px(opt.area);
                        area_um = opt.area;
                end
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "area (px)";
                data{nCol} = area_px;
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "area (µm^2)";
                data{nCol} = area_um;
            end
            T = table('Size',[nRow,nCol],'VariableTypes',varType,'VariableNames',varNames);
            T(1,:) = data;

            if ~isempty(opt.crosscut)
                sheetName = ['crosscut image #' num2str(app.jim)];
                nCol=1; % we cancel any previous data because one only wants to store columns
                varType = string.empty();
                varType(nCol) = "double";
                varNames = string.empty();
                varNames(nCol) = strcat("distance (",opt.unit,")");
                data = cell.empty();
                data{nCol} = opt.crosscut(:,1);
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OPD (nm)";
                data{nCol} = opt.crosscut(:,2)*1e9;
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "T";
                data{nCol} = opt.crosscut(:,3);
                nRow = length(data{nCol});
                T = table('Size',[nRow,nCol],'VariableTypes',varType,'VariableNames',varNames);
                T(:,:)=array2table([data{:,:}]);
            end


%            writetable(T, [removeExtension(app.saveFolderFile) '.' opt.format],'Delimiter','\t','WriteMode','Append','Sheet','MySheetName')
            
            app.savedData(1).table = T;
            app.savedData.sheetName = sheetName;
            app.savedData.format = opt.format;
            app.saveButton.Enable = true;
            if opt.writeData
                app.writeData(app.savedData)
            end

%             fName = [removeExtension(app.saveFolderFile) '.' opt.format];
%             if opt.writeData
%                 writetable(T, fName,'WriteMode','Append','Sheet',sheetName)
%                 fprintf(['Writing data in:\n' fName])
%             end
        end  

        function writeData(app,savedData)
            fName = [removeExtension(app.saveFolderFile) '.' savedData.format];
            if isempty(savedData) % TODO: griser le bouton quand il n'y a pas de données à sauver
                app.warn('No data to be saved')
            else
                writetable(savedData.table, fName,'WriteMode','Append','Sheet',savedData.sheetName)
                fprintf(['Writing data in:\n' fName])
                app.clearSavedData()
                app.saveButton.Enable = false;
            end
        end
    
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IM)
%            if nargin == 2
            app.imageType = class(IM(1));
            app.IM0=IM;
            app.Nim = numel(IM);
            if isa(IM,'ImageQLSI')
                app.IM = ImageQLSI(app.Nim);
            else
                app.IM = ImageEM(n=app.Nim);
            end
            app.NimText.Value = app.Nim;
            app.jText.Value = app.jim;
%            end
            set(app.AxesLeft,'DataAspectRatio',[1 1 1])
            set(app.AxesRight,'DataAspectRatio',[1 1 1])

            app.minLeftEditField.ValueDisplayFormat="%.1f";
            app.maxLeftEditField.ValueDisplayFormat="%.1f";

            app.minRightEditField.ValueDisplayFormat="%.2f";
            app.maxRightEditField.ValueDisplayFormat="%.2f";

            % hide some parameters if not a ImageQLSI object
            if ~strcmp(app.imType,'ImageQLSI')  % if not type ImageQLSI (ie type ImageEM)
                app.switchQLSIparameters('off') %  don't disply some buttons
            else
                app.switchQLSIparameters('on') %  don't disply some buttons
            end


            % create the colorbars
            app.cbLeft = colorbar(app.AxesLeft,"Visible","off");  % Visible off until they are properly placed
            app.cbLeft.Label.String = 'nm'; 
            app.cbRight = colorbar(app.AxesRight,"Visible","off");  % Visible off until they are properly placed

            app.updateImages();
            app.cbLeft.Visible="on"; 
            app.cbRight.Visible="on"; 
            app.saveButton.Enable = false;


        end

        % Button pushed function: binningButton
        function binningButtonPushed(app, event)
            binValue = app.binningSpinner.Value; % 2 or 3

            app.displayMessage('computation in progress, please wait...',[1, 0.5, 0.2])

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            IMc.binning(binValue);

            app.updateImages()
            app.clearMessageArea('Done')

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end
      
        end

        % Image clicked function: buttonPrev
        function buttonPrevImageClicked(app, event)
            app.moveToImage(app.jim-1)
        end

        % Image clicked function: buttonNext
        function buttonNextImageClicked(app, event)
            
            app.moveToImage(app.jim+1)
                        
        end

        % Image clicked function: clearButton
        function clearButtonImageClicked(app, event)
            app.clearMessageArea()
        end

        % Value changed function: colormapDropDownLeft
        function colormapDropDownLeftValueChanged(app, event)
            value = app.colormapDropDownLeft.Value;

            try
                cc = colormap(app.AxesLeft,value);
            catch % if the colormal is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesLeft,funColorMap(1024));
            end

            if app.invColorMapLeft
                colormap(app.AxesLeft,flipud(cc))
            end

        end

        % Value changed function: colormapDropDownRight
        function colormapDropDownRightValueChanged(app, event)
            value = app.colormapDropDownRight.Value;

            try
                cc = colormap(app.AxesRight,value);
            catch % if the colormal is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesRight,funColorMap(1024));
            end

            if app.invColorMapRight
                colormap(app.AxesRight,flipud(cc))
            end
            
        end

        % Image clicked function: copyButton
        function copyButtonImageClicked(app, event)
            clipboard(app.messageArea.Value)
        end

        % Image clicked function: cropButton, cropButtonCenterRect, 
        % ...and 1 other component
        function cropButtonImageClicked(app, event)
            shape = event.Source.Tag; % Square or Rectangle crop

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end

            IMc = app.IMcurrent(range,'WriteMode','w');

            if app.widthEditField.Value > 0
                cropSize = [app.widthEditField.Value , app.heightEditField.Value ];
            else
                cropSize = 'Manual';
            end
            
            if strcmpi(shape,'twoPoints')
                IMc.crop("twoPoints",true,"figure",app)
            else
                IMc.crop("Center",'manual',"figure",app,"Size",cropSize,"shape",shape)
            end

            app.updateImages() 
            app.makeCurrentAxesBoundAxes()

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end
        end

        % Button pushed function: crosscutPathButton
        function crosscutsPathButtonPushed(app, event)

            % TODO : transform this method into a method of the
            % ImageMethods class IM.crosscut([app]) and simply call it here.
            % This methods may have one argument, which is this app, or
            % none and in this case it creates an app.

            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            axes(app.AxesLeft)
            [cx,cy,cOPD,cx0,cy0]=improfile(100);
            axes(app.AxesRight)
            [~,~,cT]=improfile(app.IMcurrent.T,cx0,cy0,100);
            
            if strcmpi(app.scale,'px')
                cx_px=cx;
                cy_px=cy;
            else
                cx_px=app.um2px(cx);
                cy_px=app.um2px(cy);
            end

            
            dist=0;
            for ni=1:length(cx)-1
                dist=dist+sqrt((cx(ni)-cx(ni+1))^2+(cy(ni)-cy(ni+1))^2);
            end

            figure;
            ax1=subplot(1,2,1);
            hp1=plot(cOPD);
            hp1.XData=hp1.XData/max(hp1.XData)*dist;
            hp1.LineWidth=2;
            hp1.Color=[0.8 0.5 0.2];
            xlabel(app.scale)
            ylabel('nm')
            title('wavefront','Color',[0.8 0.5 0.2],'FontSize',14)
            grid on

            ax2=subplot(1,2,2);
            hp2=plot(cT);
            hp2.XData=hp2.XData/max(hp2.XData)*dist;
            hp2.LineWidth=2;
            hp2.Color=[0.2 0.5 0.8];
            xlabel(app.scale)
            title('transmission','Color',[0.2 0.5 0.8],'FontSize',14)
            grid on
            
            linkaxes([ax1, ax2],'x')

            app.UIFigure.HandleVisibility = fhv;    % return original state


            app.saveData('tech','crosscutPath','crosscut',[hp1.XData(:),cOPD(:),cT(:)],'unit',app.scale,'writeData',app.autoSave)


        end

        % Button pushed function: crosscutsHVButton
        function crosscutsHVButtonPushed(app, event)

            % TODO : transform this method into a method of the
            % ImageMethods class IM.crosscut([app]) and simply call it here.
            % This methods may have one argument, which is this app, or
            % none and in this case it creates an app.

            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            axes(app.AxesLeft)
            [cx,cy]=ginput(1);
            
            if strcmpi(app.scale,'px')
                cx_px=round(cx);
                cy_px=round(cy);
                fac=1;
            else
                cx_px=app.um2px(cx);
                cy_px=app.um2px(cy);
                fac=app.IMcurrent.pxSize*1e6;
            end
            xx = 1:app.IMcurrent.Nx;
            yy = 1:app.IMcurrent.Ny;

            figure;
            ax1=subplot(1,2,1);
            hold on
            hp1H=plot(xx*fac,app.IMcurrent.OPD(cy_px,:));
            hp1V=plot(yy*fac,app.IMcurrent.OPD(:,cx_px));
            hp1H.LineWidth=2;
            hp1V.LineWidth=2;
            hp1H.Color=[0.8 0.5 0.2];
            hp1V.Color=[0.8 0.2 0.5];
            xlabel(app.scale)
            ylabel('nm')
            legend({'horizontal','vertical'})
            title('wavefront','Color',[0.8 0.35 0.35],'FontSize',14)
            grid on

            ax2=subplot(1,2,2);
            hold on
            hp2H=plot(xx*fac,app.IMcurrent.T(cy_px,:));
            hp2V=plot(yy*fac,app.IMcurrent.T(:,cx_px));
            hp2H.LineWidth=2;
            hp2V.LineWidth=2;
            hp2H.Color=[0.2 0.5 0.8];
            hp2V.Color=[0.5 0.2 0.8];
            xlabel(app.scale)
            ylabel('nm')
            legend({'horizontal','vertical'})
            title('transmission','Color',[0.35 0.35 0.8],'FontSize',14)
            grid on

            linkaxes([ax1, ax2],'x')

            app.UIFigure.HandleVisibility = fhv;    % return original state

        end

        % Button pushed function: crosscutRadial
        function crosscutRadialButtonPushed(app, event)
            [profile, coords] = radialAverage(app);

             app.saveData('tech','crosscutPath','crosscut',[coords(:),profile.OPD(:),profile.T(:)],'unit',app.scale,'writeData',app.autoSave)

            %figure,plot(profile)
        end

        % Selection changed function: displayButton
        function displayButtonSelectionChanged(app, event)
            updateImages(app)
        end

        % Button pushed function: DMradialButton
        function DMradialButtonPushed(app, event)
            params = alpha_ImageProfile(app.IMcurrent,'nmax',app.NmaxEditField.Value, ...
                'figure',app,...
                'nBkg',app.bkgRingEditField.Value,  ...
                'NNP', 1,...
                'zoom', 1,...
                'step', app.stepEditField.Value,...
                'keepPoint',false, ...
                'display',false); % displays many measurements at once

            message = sprintf('alpha = %.3g µm^3\nOV    = %.3g µm^3\nDM    = %.3g pg',params.alpha*1e18, params.OV*1e18, 5.55e-3*params.OV*1e18);
            app.displayMessage(message)
        end

        % Button pushed function: DMroiButton
        function DMroiButtonPushed(app, event)
            [alpha, OV] = roiAlphaOV(app.IMcurrent,app);
            to update : app.saveData('tech','roi','alpha',alphaRealMean+1i*abs(alphaImagMean),'OV',OV(iNP,io),'writeData',app.autoSave)
            disp('toto')

        end

        % Button pushed function: DMmagicwandButton
        function DMmagicwandButtonPushed(app, event)
            magicWandAlphaOV(app)
        end

        % Button pushed function: flattenButton
        function flattenButtonPushed(app, event)
            method = app.flattenMethodDropDown.Value;
            nmax = app.flattenOrderSpinner.Value;
            thresh = app.flattenThresholdEditField.Value;
            app.displayMessage('computation in progress, please wait...',[1, 0.5, 0.2])

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            IMc.flatten(method,'nmax',nmax,'threshold',thresh)
            app.updateImages()
            app.clearMessageArea('Done')

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)

            method = app.filterMethodDropDown.Value;
            nmax = app.filterCutoffEditField.Value;

            app.displayMessage('computation in progress, please wait...',[1, 0.5, 0.2])

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            switch method
                case 'HighPass'
                    IMc.highPassFilter(nmax);
                case 'LowPass'
                    IMc.lowPassFilter(nmax);
            end                    
            app.updateImages()
            app.clearMessageArea('Done')

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end


        end

        % Value changed function: invertCheckBoxLeft
        function invertCheckBoxLeftValueChanged(app, event)
            value = app.invertCheckBoxLeft.Value;
            app.invColorMapLeft = value ;
            colormapDropDownLeftValueChanged(app)
        end

        % Value changed function: invertCheckBoxRight
        function invertCheckBoxRightValueChanged(app, event)
            value = app.invertCheckBoxRight.Value;
            app.invColorMapRight = value ;
            colormapDropDownRightValueChanged(app)
        end

        % Value changed function: maxLeftEditField, minLeftEditField
        function limitsLeftValueChanging(app, event)
            clim(app.AxesLeft, [app.minLeftEditField.Value,  app.maxLeftEditField.Value])
            drawnow
        end

        % Value changed function: maxRightEditField, minRightEditField
        function limitsRightValueChanged(app, event)
            clim(app.AxesRight, [app.minRightEditField.Value,  app.maxRightEditField.Value])
            drawnow
        end

        % Button pushed function: mirrorHButton
        function mirrorHbuttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            IMc.mirrorH();
            app.updateImages()

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end

                %            app.ImageRight.CData = app.IMcurrent.T;
%            app.ImageLeft.CData = app.IMcurrent.OPD*1e9;            
        end

        % Button pushed function: mirrorVButton
        function mirrorVbuttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            IMc.mirrorV();
            app.updateImages()

            app.SaveImageButton.Enable = true;
            app.SaveAllImagesButton.Enable = true;
            app.RestoreImageButton.Enable = true;
            app.RestoreAllImagesButton.Enable = true;
%            app.ImageRight.CData = app.IMcurrent.T;
%            app.ImageLeft.CData = app.IMcurrent.OPD*1e9;
        end

        % Button pushed function: offsetButton
        function offsetButtonPushed(app, event)
            method = app.offsetMethodDropDown.Value;

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');


            IMc.level0('method',method,'twoPoints',true,'figure',app)
            app.updateImages()
            app.clearMessageArea('Done')

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end
        end

        % Button pushed function: pixelButton
        function pixelButtonPushed(app, event)
            [xc, yc] = app.ginputUI(1);
            if strcmpi(app.scale,'µm')
                xc=app.um2px(xc);
                yc=app.um2px(yc);
            else
                xc=round(xc);
                yc=round(yc);
            end
            OPDval = app.IMcurrent.OPD(yc,xc)*1e9;
            Tval = app.IMcurrent.T(yc,xc);
            str = sprintf('OPD:\t%6.2f\tnm\nT  :\t%4.3f\n',OPDval, Tval);
            app.displayMessage(str)
            clipboard('copy',sprintf('%.4f\t %.4f',OPDval, Tval))

            app.saveData('position',[xc,yc],'values',[OPDval, Tval],'unit','px','writeData',app.autoSave)


        end

        % Button pushed function: distanceButton
        function distanceButtonPushed(app, event)
            [xc, yc] = app.ginputUI(2);
            
            dist = sqrt((xc(1)-xc(2))^2 +(yc(1)-yc(2))^2);

            if strcmpi(app.scale,'µm')
                dist=app.um2px(dist);
            else
                dist=round(dist);
            end
            
            str = sprintf('distance:\t%5.2f\t px\ndistance:\t%5.1f\t',dist,app.px2um(dist));

            app.displayMessage(str)
            clipboard('copy',num2str(dist))

            app.saveData('distance',dist,'unit','px','writeData',app.autoSave)
            
        end

        % Button pushed function: areaButton
        function areaButtonPushed(app, event)
            [xc, yc] = app.ginputUI(2);

            area = (xc(1)-xc(2))*(yc(1)-yc(2));
            
            if strcmpi(app.scale,'µm')
                area=app.um22px(area);
            else
                area=round(area);
            end
            
            str = sprintf('distance:\t%5.2f\t px\ndistance:\t%5.1f\t',area,app.px2um2(area));

            app.displayMessage(str)
            clipboard('copy',num2str(area))

            app.saveData('area',area,'unit','px','writeData',app.autoSave)
 
        end

        % Image clicked function: reload
        function reloadImageClicked(app, event)
            app.moveToImage(app.jText.Value)
        end

        % Button pushed function: resetSlidersLeft
        function resetLeftLimitsButtonPushed(app, event)
            app.resetLimitsEditField('Left')
        end

        % Button pushed function: resetSlidersRight
        function resetRightLimitsButtonPushed(app, event)
            app.resetLimitsEditField('Right')            
        end

        % Button pushed function: rot90Button
        function rot90buttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w');

            IMc.rot90(-1);
            % app.IMcurrent = app.IMcurrent.rot90(-1); % avoids the use of app.SaveImageButton.Enable = true;
            app.updateImages

            if ~app.modifyWorkspaceCheckBox.Value
                app.SaveImageButton.Enable = true;
                app.SaveAllImagesButton.Enable = true;
                app.RestoreImageButton.Enable = true;
                app.RestoreAllImagesButton.Enable = true;
            end

%            app.ImageRight.CData = app.IMcurrent.T;
%            app.ImageLeft.CData = app.IMcurrent.OPD*1e9;
            % to make the axes follow, in the case of non-square images
%            app.makeCurrentAxesBoundAxes
%            app.reloadImageClicked
        end

        % Selection changed function: scaleButton
        function scaleButtonSelectionChanged(app, event)

            if isequal(app.imageFun,@imagesc)
                app.ImageRight.XData = [1 , app.IMcurrent.Nx];
                app.ImageRight.YData = [1 , app.IMcurrent.Ny];
                app.ImageLeft.XData = [1 , app.IMcurrent.Nx];
                app.ImageLeft.YData = [1 , app.IMcurrent.Ny];
            elseif isequal(app.imageFun,@image3D)
                app.ImageRight.XData = 1 : app.IMcurrent.Nx;
                app.ImageRight.YData = 1 : app.IMcurrent.Ny;
                app.ImageLeft.XData = 1 : app.IMcurrent.Nx;
                app.ImageLeft.YData = 1 : app.IMcurrent.Ny;
            end                
                
            if strcmpi(event.NewValue.Text,'px')
                app.makeCurrentAxesBoundAxes

                xlabel(app.AxesRight,'px')
                xlabel(app.AxesLeft,'px')

                app.widthCropUnitLabel.Text = 'px';
                app.heightCropUnitLabel.Text = 'px';
                
            else % µm  

                axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
                axesData.XLim=[1 , app.IMcurrent.Nx]*app.IMcurrent.pxSize*1e6;
                axesData.YLim=[1 , app.IMcurrent.Ny]*app.IMcurrent.pxSize*1e6;
                setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
                setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);

                app.ImageRight.XData = app.ImageRight.XData *app.IMcurrent.pxSize*1e6;
                app.ImageRight.YData = app.ImageRight.YData *app.IMcurrent.pxSize*1e6;
                app.ImageLeft.XData  = app.ImageLeft.XData  *app.IMcurrent.pxSize*1e6;
                app.ImageLeft.YData  = app.ImageLeft.YData  *app.IMcurrent.pxSize*1e6;

                xlabel(app.AxesRight,'µm')
                xlabel(app.AxesLeft,'µm')

                app.widthCropUnitLabel.Text = 'µm';
                app.heightCropUnitLabel.Text = 'µm';
            end
            zoom(app.AxesLeft,'out')
            zoom(app.AxesRight,'out')
            app.adjustdAspect('all')
            app.setLightPositions()

            
            drawnow
        end

        % Image clicked function: saveFolderImage
        function saveFolderImageClicked(app, event)
            app.UIFigure.Visible = 'off';
            try
                app.saveFolderEditField.Value = uigetdir(pwd);
                app.UIFigure.Visible = 'on';  
            catch
                app.UIFigure.Visible = 'on';  
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if isempty(fields(app.savedData))
                app.warn('no data recorded')
            else
                app.writeData(app.savedData)
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % transfert of IM to IM0
            app.IM0(app.jim).write(app.IMcurrent()); % IM0 = IMcurrent, without modidying the original IM0 handle, in order to actually modify the workspace
            % erase IM
            if isa(app.IMcurrent,'ImageQLSI')
                app.IM(app.jim) = ImageQLSI(1);
            else
                app.IM(app.jim) = ImageEM(1);
            end
            % disable save button and allsave button if all the IM are empty
            app.SaveImageButton.Enable = false;
            app.RestoreImageButton.Enable = false;
            if isempty(app.IM)
                app.SaveAllImagesButton.Enable = false;
                app.RestoreAllImagesButton.Enable = false;
            end            

        end

        % Button pushed function: RestoreImageButton
        function RestoreImageButtonPushed(app, event)
            
            if strcmpi(app.imageType,'ImageQLSI')
                app.IM(app.jim) = ImageQLSI(1);
            else
                app.IM(app.jim) = ImageEM(n=1);
            end
            app.RestoreImageButton.Enable = false;
            app.SaveImageButton.Enable = false;

            if isempty(app.IM)
                app.RestoreAllImagesButton.Enable = false;
                app.SaveAllImagesButton.Enable = false;
            end    
            
            app.updateImages()
        end

        % Button pushed function: RestoreAllImagesButton
        function RestoreAllImagesButtonPushed(app, event)
            if strcmpi(app.imageType,'ImageQLSI')
                for io = 1:app.Nim
                    app.IM(io) = ImageQLSI(1);
                end
            else
                for io = 1:app.Nim
                    app.IM(io) = ImageEM(n=1);
                end
            end
            
            app.updateImages()
            
        end

        % Button pushed function: SaveAllImagesButton
        function SaveAllImagesButtonPushed(app, event)
            range = ~isempty(app.IM); % subarray of all the image numbers that are not empty
            % transfert of IM to IM0
            app.IM0(range).write(app.IMcurrent(range));
            % erase IM
            if isa(app.IMcurrent,'ImageQLSI')
                app.IM = ImageQLSI(app.Nim);
            else
                app.IM = ImageEM(n=app.Nim);
            end
            % disable save button and allsave button if all the IM are empty
            app.SaveImageButton.Enable = false;
            app.RestoreImageButton.Enable = false;
            app.SaveAllImagesButton.Enable = false;
            app.RestoreAllImagesButton.Enable = false;
            
        end

        % Button pushed function: exportImageButton
        function exportImageButtonPushed(app, event)
            selectType = app.exportSelectionRadioButtonGroup.SelectedObject.Text;
            Spng = app.export_pngCheckBox.Value;
            Seps = app.export_epsCheckBox.Value;
            Stxt = app.export_txtCheckBox.Value;
            fileName = [app.exportFileNameEditField.Value];
            folderName = [app.exportFolderEditField.Value];
            if isempty(folderName) % if no folder name is specified
                folderName = pwd;
            end

            switch selectType
                case 'current'
                    range = app.jim;
                case 'all'
                    range = 1:app.Nim;
                case 'selection'
                    app_IS = imageSelector();
                    while isempty(app_IS.range)
                        pause(0.4)
                    end
                    range = app_IS.range;
                    app_IS.delete
            end
            IMexport = app.IMcurrent(range,'WriteMode','r');

            for io = 1:numel(IMexport)
                close all
                if Spng || Seps
                    app.moveToImage(range(io))
                    hfig = app.displayDualImage();
                end
                if Spng
                    saveas(hfig,[folderName '/' fileName '_' textkkk(range(io))], 'png')
                end
                if Seps
                    saveas(hfig,[folderName '/' fileName '_' textkkk(range(io))],'epsc')
                end
                if Stxt
                    writematrix(app.IMcurrent(range(io)).OPD,[folderName '/' fileName '_OPD_' textkkk(range(io)) '.txt'],'Delimiter',' ')
                    writematrix(app.IMcurrent(range(io)).T,[folderName '/' fileName '_T_' textkkk(range(io)) '.txt'],'Delimiter',' ')
                end
            end
            
            app.exportFolderEditField.Value = folderName;
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.AxesRight = uiaxes(app.MainGridLayout);
            title(app.AxesRight, 'Transmittance')
            xlabel(app.AxesRight, 'X')
            ylabel(app.AxesRight, 'Y')
            zlabel(app.AxesRight, 'Z')
            app.AxesLeft = uiaxes(app.MainGridLayout);
            title(app.AxesLeft, 'Wavefront')
            xlabel(app.AxesLeft, 'X')
            ylabel(app.AxesLeft, 'Y')
            zlabel(app.AxesLeft, 'Z')
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.propertiesTab = uitab(app.TabGroup);
            app.propertiesTab.Title = 'properties';
            app.propertiesGridLayout = uigridlayout(app.propertiesTab);
            app.pixelsizeLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeLabel.Text = 'nm';
            app.wavelengthLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthLabel.Text = 'nm';
            app.magnificationLabel = uilabel(app.propertiesGridLayout);
            app.magnificationLabel.Text = 'X';
            app.MICROSCOPELabel = uilabel(app.propertiesGridLayout);
            app.MICROSCOPELabel.Text = 'MICROSCOPE';
            app.TLfocallengthLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthLabel.Text = 'mm';
            app.wavelengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.wavelengthEditField.Editable = 'off';
            app.pixelsizeEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeEditFieldLabel.Text = 'pixel size';
            app.pixelsizeEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.pixelsizeEditField.Editable = 'off';
            app.magnificationEditField_3Label = uilabel(app.propertiesGridLayout);
            app.magnificationEditField_3Label.Text = 'magnification';
            app.magnificationEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.magnificationEditField.Editable = 'off';
            app.numericalapertureEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.numericalapertureEditFieldLabel.Text = 'numerical aperture';
            app.numericalapertureEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.numericalapertureEditField.Editable = 'off';
            app.TLfocallengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthEditFieldLabel.Text = 'TL focal length';
            app.TLfocallengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.TLfocallengthEditField.Editable = 'off';
            app.panelCGM = uipanel(app.propertiesGridLayout);
            app.CGMsystemGridLayout = uigridlayout(app.panelCGM);
            app.dexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeLabel.Text = 'nm';
            app.zoomLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomLabel.Text = 'X';
            app.grexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeLabel.Text = 'µm';
            app.dexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeEditFieldLabel.Text = 'dexel size';
            app.dexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.dexelsizeEditField.Editable = 'off';
            app.zoomEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomEditFieldLabel.Text = 'zoom';
            app.zoomEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zoomEditField.Editable = 'off';
            app.grexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeEditFieldLabel.Text = 'grexel size';
            app.grexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.grexelsizeEditField.Editable = 'off';
            app.CGMcameraEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMcameraEditFieldLabel.Text = 'CGM camera';
            app.CGMcameraName = uieditfield(app.CGMsystemGridLayout, 'text');
            app.CGMcameraName.Editable = 'off';
            app.CGMsystemLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMsystemLabel.Text = 'CGM system';
            app.ItfFileName = uilabel(app.CGMsystemGridLayout);
            app.ItfFileName.Text = '';
            app.zetaLabel = uilabel(app.CGMsystemGridLayout);
            app.zetaLabel.Text = 'zeta';
            app.zetaEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zetaEditField.Editable = 'off';
            app.comment = uilabel(app.propertiesGridLayout);
            app.comment.Text = '';
            app.processingTab = uitab(app.TabGroup);
            app.processingTab.Title = 'processing';
            app.processingGridMLayout = uigridlayout(app.processingTab);
            app.ApplytoallimagesCheckBox = uicheckbox(app.processingGridMLayout);
            app.ApplytoallimagesCheckBox.Text = 'Apply to all images';
            app.MOTIONLabel = uilabel(app.processingGridMLayout);
            app.MOTIONLabel.Text = 'MOTION';
            app.CROPLabel = uilabel(app.processingGridMLayout);
            app.CROPLabel.Text = 'CROP';
            app.FLATTENLabel = uilabel(app.processingGridMLayout);
            app.FLATTENLabel.Text = 'FLATTEN';
            app.cropButton = uiimage(app.processingGridMLayout);
            app.cropButton.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButton.Tag = 'twoPoints';
            app.cropButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_select.svg');
            app.cropButtonCenterSquare = uiimage(app.processingGridMLayout);
            app.cropButtonCenterSquare.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterSquare.Tag = 'Square';
            app.cropButtonCenterSquare.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerSquare.svg');
            app.cropButtonCenterRect = uiimage(app.processingGridMLayout);
            app.cropButtonCenterRect.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterRect.Tag = 'Rectangle';
            app.cropButtonCenterRect.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerRectangle.svg');
            app.widthEditFieldLabel = uilabel(app.processingGridMLayout);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.widthEditField.Limits = [0 Inf];
            app.heightEditFieldLabel = uilabel(app.processingGridMLayout);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.heightEditField.Limits = [0 Inf];
            app.widthCropUnitLabel = uilabel(app.processingGridMLayout);
            app.widthCropUnitLabel.Text = 'px';
            app.heightCropUnitLabel = uilabel(app.processingGridMLayout);
            app.heightCropUnitLabel.Text = 'px';
            app.flattenMethodDropDown = uidropdown(app.processingGridMLayout);
            app.flattenMethodDropDown.Items = {'Legendre', 'Sine', 'Chebychev', 'Zernike', 'Hermite'};
            app.flattenMethodDropDown.Value = 'Legendre';
            app.thresholdEditFieldLabel = uilabel(app.processingGridMLayout);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.flattenThresholdEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.SaveImageButton = uibutton(app.processingGridMLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveData.svg');
            app.SaveImageButton.Text = 'Save';
            app.SaveAllImagesButton = uibutton(app.processingGridMLayout, 'push');
            app.SaveAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllImagesButtonPushed, true);
            app.SaveAllImagesButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveAllData.svg');
            app.SaveAllImagesButton.Text = 'Save all';
            app.RestoreImageButton = uibutton(app.processingGridMLayout, 'push');
            app.RestoreImageButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreImageButtonPushed, true);
            app.RestoreImageButton.Text = 'Restore';
            app.RestoreAllImagesButton = uibutton(app.processingGridMLayout, 'push');
            app.RestoreAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreAllImagesButtonPushed, true);
            app.RestoreAllImagesButton.Text = 'Restore all';
            app.modifyWorkspaceCheckBox = uicheckbox(app.processingGridMLayout);
            app.modifyWorkspaceCheckBox.Text = 'Workspace';
            app.FILTERLabel = uilabel(app.processingGridMLayout);
            app.FILTERLabel.Text = 'FILTER';
            app.filterMethodDropDown = uidropdown(app.processingGridMLayout);
            app.filterMethodDropDown.Items = {'HighPass', 'LowPass'};
            app.filterMethodDropDown.Value = 'HighPass';
            app.cutoffLabel = uilabel(app.processingGridMLayout);
            app.cutoffLabel.Text = 'cutoff';
            app.filterCutoffEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.filterCutoffEditField.Value = 10;
            app.filterCutoffUnitLabel = uilabel(app.processingGridMLayout);
            app.filterCutoffUnitLabel.Text = 'px';
            app.BINNINGLabel = uilabel(app.processingGridMLayout);
            app.BINNINGLabel.Text = 'BINNING';
            app.OFFSETLabel = uilabel(app.processingGridMLayout);
            app.OFFSETLabel.Text = 'OFFSET';
            app.mirrorHButton = uibutton(app.processingGridMLayout, 'push');
            app.mirrorHButton.ButtonPushedFcn = createCallbackFcn(app, @mirrorHbuttonImageClicked, true);
            app.mirrorHButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorH.svg');
            app.mirrorHButton.Text = '';
            app.mirrorVButton = uibutton(app.processingGridMLayout, 'push');
            app.mirrorVButton.ButtonPushedFcn = createCallbackFcn(app, @mirrorVbuttonImageClicked, true);
            app.mirrorVButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorV.svg');
            app.mirrorVButton.Text = '';
            app.rot90Button = uibutton(app.processingGridMLayout, 'push');
            app.rot90Button.ButtonPushedFcn = createCallbackFcn(app, @rot90buttonImageClicked, true);
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'newGUI', 'rot90.svg');
            app.rot90Button.Text = '';
            app.binningButton = uibutton(app.processingGridMLayout, 'push');
            app.binningButton.ButtonPushedFcn = createCallbackFcn(app, @binningButtonPushed, true);
            app.binningButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'binning.svg');
            app.binningButton.Text = '';
            app.binningSpinner = uispinner(app.processingGridMLayout);
            app.binningSpinner.Limits = [2 3];
            app.binningSpinner.Value = 2;
            app.orderSpinnerLabel = uilabel(app.processingGridMLayout);
            app.orderSpinnerLabel.Text = 'order';
            app.flattenOrderSpinner = uispinner(app.processingGridMLayout);
            app.flattenOrderSpinner.Limits = [1 10];
            app.flattenOrderSpinner.Value = 1;
            app.flattenButton = uibutton(app.processingGridMLayout, 'push');
            app.flattenButton.ButtonPushedFcn = createCallbackFcn(app, @flattenButtonPushed, true);
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'BC.svg');
            app.flattenButton.Text = '';
            app.filterButton = uibutton(app.processingGridMLayout, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'filter.svg');
            app.filterButton.Text = '';
            app.offsetButton = uibutton(app.processingGridMLayout, 'push');
            app.offsetButton.ButtonPushedFcn = createCallbackFcn(app, @offsetButtonPushed, true);
            app.offsetButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'offset.svg');
            app.offsetButton.Text = '';
            app.offsetMethodDropDown = uidropdown(app.processingGridMLayout);
            app.offsetMethodDropDown.Items = {'mean', 'median', 'boundary'};
            app.offsetMethodDropDown.Value = 'mean';
            app.metrologyTab = uitab(app.TabGroup);
            app.metrologyTab.Title = 'metrology';
            app.metrologyGridLayout = uigridlayout(app.metrologyTab);
            app.pixelButton = uibutton(app.metrologyGridLayout, 'push');
            app.pixelButton.ButtonPushedFcn = createCallbackFcn(app, @pixelButtonPushed, true);
            app.pixelButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'pixel.svg');
            app.pixelButton.Text = 'pixel';
            app.distanceButton = uibutton(app.metrologyGridLayout, 'push');
            app.distanceButton.ButtonPushedFcn = createCallbackFcn(app, @distanceButtonPushed, true);
            app.distanceButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'distanceButton.svg');
            app.distanceButton.Text = 'distance';
            app.areaButton = uibutton(app.metrologyGridLayout, 'push');
            app.areaButton.ButtonPushedFcn = createCallbackFcn(app, @areaButtonPushed, true);
            app.areaButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'areaButton.svg');
            app.areaButton.Text = 'area';
            app.crosscutsHVButton = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutsHVButton.ButtonPushedFcn = createCallbackFcn(app, @crosscutsHVButtonPushed, true);
            app.crosscutsHVButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'HVcrosscut.svg');
            app.crosscutsHVButton.Text = 'H / V';
            app.crosscutRadial = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutRadial.ButtonPushedFcn = createCallbackFcn(app, @crosscutRadialButtonPushed, true);
            app.crosscutRadial.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.crosscutRadial.Text = 'radial';
            app.METROLOGYLabel = uilabel(app.metrologyGridLayout);
            app.METROLOGYLabel.Text = 'METROLOGY';
            app.CROSSCUTSLabel = uilabel(app.metrologyGridLayout);
            app.CROSSCUTSLabel.Text = 'CROSS CUTS';
            app.crosscutPathButton = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutPathButton.ButtonPushedFcn = createCallbackFcn(app, @crosscutsPathButtonPushed, true);
            app.crosscutPathButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Pathcrosscut.svg');
            app.crosscutPathButton.Text = 'path';
            app.alphaOVDMLabel = uilabel(app.metrologyGridLayout);
            app.alphaOVDMLabel.Text = 'alpha / OV / DM';
            app.DMradialButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMradialButton.ButtonPushedFcn = createCallbackFcn(app, @DMradialButtonPushed, true);
            app.DMradialButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.DMradialButton.Text = 'radial';
            app.DMroiButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMroiButton.ButtonPushedFcn = createCallbackFcn(app, @DMroiButtonPushed, true);
            app.DMroiButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'roiButton.svg');
            app.DMroiButton.Text = 'roi';
            app.DMmagicwandButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMmagicwandButton.ButtonPushedFcn = createCallbackFcn(app, @DMmagicwandButtonPushed, true);
            app.DMmagicwandButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'MWButton.svg');
            app.DMmagicwandButton.Text = {'magic'; 'wand'};
            app.GridLayout = uigridlayout(app.metrologyGridLayout);
            app.NmaxEditFieldLabel = uilabel(app.GridLayout);
            app.NmaxEditFieldLabel.Text = 'Nmax';
            app.NmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.NmaxEditField.Limits = [0 Inf];
            app.NmaxEditField.Value = 100;
            app.bkgRingEditField = uieditfield(app.GridLayout, 'numeric');
            app.bkgRingEditField.Limits = [0 Inf];
            app.bkgRingEditField.Value = 4;
            app.bkgRingLabel = uilabel(app.GridLayout);
            app.bkgRingLabel.Text = 'bkg ring';
            app.stepEditFieldLabel = uilabel(app.GridLayout);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.GridLayout, 'numeric');
            app.stepEditField.Limits = [0 Inf];
            app.stepEditField.Value = 1;
            app.SAVEDATALabel = uilabel(app.metrologyGridLayout);
            app.SAVEDATALabel.Text = 'SAVE DATA';
            app.AutoSaveCheckBox = uicheckbox(app.metrologyGridLayout);
            app.AutoSaveCheckBox.Text = 'auto save';
            app.saveFolderImage = uiimage(app.metrologyGridLayout);
            app.saveFolderImage.ImageClickedFcn = createCallbackFcn(app, @saveFolderImageClicked, true);
            app.saveFolderImage.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'folder.svg');
            app.saveFolderEditField = uieditfield(app.metrologyGridLayout, 'text');
            app.FileNameEditFieldLabel = uilabel(app.metrologyGridLayout);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.saveFileNameEditField = uieditfield(app.metrologyGridLayout, 'text');
            app.saveButton = uibutton(app.metrologyGridLayout, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportData.svg');
            app.saveButton.Text = 'save';
            app.exportTab = uitab(app.TabGroup);
            app.exportTab.Title = 'export';
            app.exportGridLayout = uigridlayout(app.exportTab);
            app.exportImageButton = uibutton(app.exportGridLayout, 'push');
            app.exportImageButton.ButtonPushedFcn = createCallbackFcn(app, @exportImageButtonPushed, true);
            app.exportImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'save.svg');
            app.exportImageButton.Text = 'Export';
            app.exportFolderEditField = uieditfield(app.exportGridLayout, 'text');
            app.FileNameEditFieldLabel_2 = uilabel(app.exportGridLayout);
            app.FileNameEditFieldLabel_2.Text = 'File Name';
            app.exportFileNameEditField = uieditfield(app.exportGridLayout, 'text');
            app.exportFileNameEditField.Value = 'im';
            app.EXPORTIMAGELabel = uilabel(app.exportGridLayout);
            app.EXPORTIMAGELabel.Text = 'EXPORT IMAGE';
            app.exportFolderImage = uiimage(app.exportGridLayout);
            app.exportFolderImage.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'folder.svg');
            app.export_txtCheckBox = uicheckbox(app.exportGridLayout);
            app.export_txtCheckBox.Text = 'txt';
            app.export_txtCheckBox.Value = true;
            app.export_epsCheckBox = uicheckbox(app.exportGridLayout);
            app.export_epsCheckBox.Text = 'eps';
            app.exportSelectionRadioButtonGroup = uibuttongroup(app.exportGridLayout);
            app.exportCurrentButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportCurrentButton.Text = 'current';
            app.exportCurrentButton.Value = true;
            app.exportAllButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportAllButton.Text = 'all';
            app.exportSelectionButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportSelectionButton.Text = 'selection';
            app.export_pngCheckBox = uicheckbox(app.exportGridLayout);
            app.export_pngCheckBox.Text = 'png';
            app.exportSuffixLabel = uilabel(app.exportGridLayout);
            app.exportSuffixLabel.Text = '_000N';
            app.EXPORTVIDEOLabel = uilabel(app.exportGridLayout);
            app.EXPORTVIDEOLabel.Text = 'EXPORT VIDEO';
            app.exportMovieButton = uibutton(app.exportGridLayout, 'push');
            app.exportMovieButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportMovie.svg');
            app.exportMovieButton.Text = 'Export';
            app.rateEditFieldLabel = uilabel(app.exportGridLayout);
            app.rateEditFieldLabel.Text = 'rate';
            app.rateVideoEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.rateVideoEditField.Value = 5;
            app.phiEditFieldLabel = uilabel(app.exportGridLayout);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.phiEditField.Value = 45;
            app.thetaEditFieldLabel = uilabel(app.exportGridLayout);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.thetaEditField.Value = 45;
            app.cbfactorEditFieldLabel = uilabel(app.exportGridLayout);
            app.cbfactorEditFieldLabel.Text = 'cb factor';
            app.cbfactorEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.cbfactorEditField.Value = 1;
            app.OPDCheckBox = uicheckbox(app.exportGridLayout);
            app.OPDCheckBox.Text = 'OPD';
            app.OPDCheckBox.Value = true;
            app.TCheckBox = uicheckbox(app.exportGridLayout);
            app.TCheckBox.Text = 'T';
            app.TCheckBox.Value = true;
            app.cblabelEditFieldLabel = uilabel(app.exportGridLayout);
            app.cblabelEditFieldLabel.Text = 'cb label';
            app.cblabelEditField = uieditfield(app.exportGridLayout, 'text');
            app.cblabelEditField.Value = 'Optical Path difference (nm)';
            app.TitleEditFieldLabel = uilabel(app.exportGridLayout);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.exportGridLayout, 'text');
            app.HzLabel = uilabel(app.exportGridLayout);
            app.HzLabel.Text = 'Hz';
            app.perspCheckBox = uicheckbox(app.exportGridLayout);
            app.perspCheckBox.Text = 'persp';
            app.perspCheckBox.Value = true;
            app.ControlsGridLayout = uigridlayout(app.MainGridLayout);
            app.buttonPrev = uiimage(app.ControlsGridLayout);
            app.buttonPrev.ImageClickedFcn = createCallbackFcn(app, @buttonPrevImageClicked, true);
            app.buttonPrev.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'leftArrow.svg');
            app.reload = uiimage(app.ControlsGridLayout);
            app.reload.ImageClickedFcn = createCallbackFcn(app, @reloadImageClicked, true);
            app.reload.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'reload.svg');
            app.buttonNext = uiimage(app.ControlsGridLayout);
            app.buttonNext.ImageClickedFcn = createCallbackFcn(app, @buttonNextImageClicked, true);
            app.buttonNext.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'rightArrow.svg');
            app.jText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.NimText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.RightToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.colormapDropDown_4Label = uilabel(app.RightToolsGridLayout);
            app.colormapDropDown_4Label.Text = 'colormap';
            app.colormapDropDownRight = uidropdown(app.RightToolsGridLayout);
            app.colormapDropDownRight.Items = {'gray', 'parula', 'hot'};
            app.colormapDropDownRight.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownRightValueChanged, true);
            app.colormapDropDownRight.Value = 'gray';
            app.resetSlidersRight = uibutton(app.RightToolsGridLayout, 'push');
            app.resetSlidersRight.ButtonPushedFcn = createCallbackFcn(app, @resetRightLimitsButtonPushed, true);
            app.resetSlidersRight.Text = 'reset';
            app.invertCheckBoxRight = uicheckbox(app.RightToolsGridLayout);
            app.invertCheckBoxRight.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxRightValueChanged, true);
            app.invertCheckBoxRight.Text = 'invert';
            app.minRightEditField = uieditfield(app.RightToolsGridLayout, 'numeric');
            app.minRightEditField.ValueChangedFcn = createCallbackFcn(app, @limitsRightValueChanged, true);
            app.maxRightEditField = uieditfield(app.RightToolsGridLayout, 'numeric');
            app.maxRightEditField.ValueChangedFcn = createCallbackFcn(app, @limitsRightValueChanged, true);
            app.holdClimRightCheckBox = uicheckbox(app.RightToolsGridLayout);
            app.holdClimRightCheckBox.Text = 'hold';
            app.RenderingGridLayout = uigridlayout(app.MainGridLayout);
            app.displayButton = uibuttongroup(app.RenderingGridLayout);
            app.displayButton.SelectionChangedFcn = createCallbackFcn(app, @displayButtonSelectionChanged, true);
            app.displayButton3D = uiradiobutton(app.displayButton);
            app.displayButton3D.Text = '3D';
            app.displayButtonStandard = uiradiobutton(app.displayButton);
            app.displayButtonStandard.Text = 'standard';
            app.displayButtonStandard.Value = true;
            app.scaleButton = uibuttongroup(app.RenderingGridLayout);
            app.scaleButton.SelectionChangedFcn = createCallbackFcn(app, @scaleButtonSelectionChanged, true);
            app.scaleButtonum = uiradiobutton(app.scaleButton);
            app.scaleButtonum.Text = 'µm';
            app.scaleButtonpx = uiradiobutton(app.scaleButton);
            app.scaleButtonpx.Text = 'px';
            app.scaleButtonpx.Value = true;
            app.GridLayout2 = uigridlayout(app.MainGridLayout);
            app.messageArea = uitextarea(app.GridLayout2);
            app.messageArea.Editable = 'off';
            app.copyButton = uiimage(app.GridLayout2);
            app.copyButton.ImageClickedFcn = createCallbackFcn(app, @copyButtonImageClicked, true);
            app.copyButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'clipboard.svg');
            app.clearButton = uiimage(app.GridLayout2);
            app.clearButton.ImageClickedFcn = createCallbackFcn(app, @clearButtonImageClicked, true);
            app.clearButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'erase.svg');
            app.Image5 = uiimage(app.MainGridLayout);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'logoPhaseLAB2.png');
            app.LeftToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.resetSlidersLeft = uibutton(app.LeftToolsGridLayout, 'push');
            app.resetSlidersLeft.ButtonPushedFcn = createCallbackFcn(app, @resetLeftLimitsButtonPushed, true);
            app.resetSlidersLeft.Text = 'reset';
            app.colormapDropDown_3Label = uilabel(app.LeftToolsGridLayout);
            app.colormapDropDown_3Label.Text = 'colormap';
            app.colormapDropDownLeft = uidropdown(app.LeftToolsGridLayout);
            app.colormapDropDownLeft.Items = {'phase1024', 'Pradeep', 'gray', 'parula', 'jet'};
            app.colormapDropDownLeft.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownLeftValueChanged, true);
            app.colormapDropDownLeft.Value = 'phase1024';
            app.invertCheckBoxLeft = uicheckbox(app.LeftToolsGridLayout);
            app.invertCheckBoxLeft.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxLeftValueChanged, true);
            app.invertCheckBoxLeft.Text = 'invert';
            app.minLeftEditField = uieditfield(app.LeftToolsGridLayout, 'numeric');
            app.minLeftEditField.ValueChangedFcn = createCallbackFcn(app, @limitsLeftValueChanging, true);
            app.maxLeftEditField = uieditfield(app.LeftToolsGridLayout, 'numeric');
            app.maxLeftEditField.ValueChangedFcn = createCallbackFcn(app, @limitsLeftValueChanging, true);
            app.holdClimLeftCheckBox = uicheckbox(app.LeftToolsGridLayout);
            app.holdClimLeftCheckBox.Text = 'hold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseLABgui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end