classdef PhaseLABgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        LeftToolsGridLayout            matlab.ui.container.GridLayout
        invertCheckBoxLeft             matlab.ui.control.CheckBox
        SliderMaxLeft                  matlab.ui.control.Slider
        SliderMinLeft                  matlab.ui.control.Slider
        colormapDropDownLeft           matlab.ui.control.DropDown
        colormapDropDown_3Label        matlab.ui.control.Label
        resetSlidersLeft               matlab.ui.control.Button
        Image5                         matlab.ui.control.Image
        GridLayout2                    matlab.ui.container.GridLayout
        clearButton                    matlab.ui.control.Image
        copyButton                     matlab.ui.control.Image
        messageArea                    matlab.ui.control.TextArea
        RenderingGridLayout            matlab.ui.container.GridLayout
        scaleButton                    matlab.ui.container.ButtonGroup
        scaleButtonpx                  matlab.ui.control.RadioButton
        scaleButtonum                  matlab.ui.control.RadioButton
        displayButton                  matlab.ui.container.ButtonGroup
        displayButtonStandard          matlab.ui.control.RadioButton
        displayButton3D                matlab.ui.control.RadioButton
        RightToolsGridLayout           matlab.ui.container.GridLayout
        invertCheckBoxRight            matlab.ui.control.CheckBox
        SliderMaxRight                 matlab.ui.control.Slider
        SliderMinRight                 matlab.ui.control.Slider
        resetSlidersRight              matlab.ui.control.Button
        colormapDropDownRight          matlab.ui.control.DropDown
        colormapDropDown_4Label        matlab.ui.control.Label
        ControlsGridLayout             matlab.ui.container.GridLayout
        NimText                        matlab.ui.control.NumericEditField
        jText                          matlab.ui.control.NumericEditField
        buttonNext                     matlab.ui.control.Image
        reload                         matlab.ui.control.Image
        buttonPrev                     matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        propertiesTab                  matlab.ui.container.Tab
        propertiesGridLayout           matlab.ui.container.GridLayout
        comment                        matlab.ui.control.Label
        panelCGM                       matlab.ui.container.Panel
        CGMsystemGridLayout            matlab.ui.container.GridLayout
        zetaEditField                  matlab.ui.control.NumericEditField
        zetaLabel                      matlab.ui.control.Label
        ItfFileName                    matlab.ui.control.Label
        CGMsystemLabel                 matlab.ui.control.Label
        CGMcameraName                  matlab.ui.control.EditField
        CGMcameraEditFieldLabel        matlab.ui.control.Label
        grexelsizeEditField            matlab.ui.control.NumericEditField
        grexelsizeEditFieldLabel       matlab.ui.control.Label
        zoomEditField                  matlab.ui.control.NumericEditField
        zoomEditFieldLabel             matlab.ui.control.Label
        dexelsizeEditField             matlab.ui.control.NumericEditField
        dexelsizeEditFieldLabel        matlab.ui.control.Label
        grexelsizeLabel                matlab.ui.control.Label
        zoomLabel                      matlab.ui.control.Label
        dexelsizeLabel                 matlab.ui.control.Label
        TLfocallengthEditField         matlab.ui.control.NumericEditField
        TLfocallengthEditFieldLabel    matlab.ui.control.Label
        numericalapertureEditField     matlab.ui.control.NumericEditField
        numericalapertureEditFieldLabel  matlab.ui.control.Label
        magnificationEditField         matlab.ui.control.NumericEditField
        magnificationEditField_3Label  matlab.ui.control.Label
        pixelsizeEditField             matlab.ui.control.NumericEditField
        pixelsizeEditFieldLabel        matlab.ui.control.Label
        wavelengthEditField            matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel       matlab.ui.control.Label
        TLfocallengthLabel             matlab.ui.control.Label
        MICROSCOPELabel                matlab.ui.control.Label
        magnificationLabel             matlab.ui.control.Label
        wavelengthLabel                matlab.ui.control.Label
        pixelsizeLabel                 matlab.ui.control.Label
        metrologyTab                   matlab.ui.container.Tab
        processingGridLayout           matlab.ui.container.GridLayout
        GridLayout                     matlab.ui.container.GridLayout
        stepEditField                  matlab.ui.control.NumericEditField
        stepEditFieldLabel             matlab.ui.control.Label
        bkgRingLabel                   matlab.ui.control.Label
        EditField_bkgRing              matlab.ui.control.NumericEditField
        NmaxEditField                  matlab.ui.control.NumericEditField
        NmaxEditFieldLabel             matlab.ui.control.Label
        magicwandButton                matlab.ui.control.Button
        roiButton                      matlab.ui.control.Button
        radialButton                   matlab.ui.control.Button
        alphaOVDMLabel                 matlab.ui.control.Label
        pathButton                     matlab.ui.control.Button
        CROSSCUTSLabel                 matlab.ui.control.Label
        METROLOGYLabel                 matlab.ui.control.Label
        crossCutRadial                 matlab.ui.control.Button
        HVButton                       matlab.ui.control.Button
        areaButton                     matlab.ui.control.Button
        distanceButton                 matlab.ui.control.Button
        pixelButton                    matlab.ui.control.Button
        PostprocessingTab              matlab.ui.container.Tab
        GridPostProcessing             matlab.ui.container.GridLayout
        heightCropUnitLabel            matlab.ui.control.Label
        widthCropUnitLabel             matlab.ui.control.Label
        heightEditField                matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        cropButtonCenterRect           matlab.ui.control.Image
        cropButtonCenterSquare         matlab.ui.control.Image
        cropButton                     matlab.ui.control.Image
        coma                           matlab.ui.control.Image
        BACKGROUNDCORRECTIONLabel_2    matlab.ui.control.Label
        CROPLabel                      matlab.ui.control.Label
        rot90button                    matlab.ui.control.Image
        mirrorHbutton                  matlab.ui.control.Image
        mirrorVbutton                  matlab.ui.control.Image
        FLIPROTATIONLabel              matlab.ui.control.Label
        ApplytoallimagesCheckBox       matlab.ui.control.CheckBox
        AxesLeft                       matlab.ui.control.UIAxes
        AxesRight                      matlab.ui.control.UIAxes
    end

    
    properties (Access =  public)
        IM (1,:) ImageMethods % QLSI image list
        cbLeft  matlab.graphics.illustration.ColorBar % left panel colorbar
        cbRight matlab.graphics.illustration.ColorBar % right panel colorbar
    end

    properties(Dependent)
        scale   % 'px' or 'µm'
    end
    
    properties (Access = private)
        jim = 1 % # of the image that is currently displayed
        valLeftMin
        valLeftMax
        valRightMin
        valRightMax
        imageFun   %imagesc or image3D
        invColorMapLeft  logical = false % invert the left colomap
        invColorMapRight logical = false % invert theright colomap
    end
    
    properties (Access = private, Dependent)
        Nim  % Number of images in IM
        imType % 'ImageQLSI' or 'ImageEM' or 'none'
    end
    
    properties (SetAccess = private, Dependent)
        ImageLeft % image object of the left panel
        ImageRight % image object of the right panel
    end
    
    methods
        
        function set.IM(app,val)
            app.IM=val;
            app.updateImages();
            drawnow
        end

        function val = get.scale(app)
            val = app.scaleButton.SelectedObject.Text;
        end


        function val = get.ImageLeft(app)
        % give a name to the image object contained in the left Axes object
            val = app.AxesLeft.Children(end);
        end

        function val = get.ImageRight(app)
        % give a name to the image object contained in the right Axes object
            val = app.AxesRight.Children(end);
        end

        function switchQLSIparameters(app,val)
            arguments
                app
                val (1,:) char {mustBeMember(val,{'on','off'})}
            end
            app.panelCGM.Visible=val;
        end

        function val = get.Nim(app)
            if isempty(app.IM)
                val = 1;
            else
                val = numel(app.IM);
            end                
        end

        function val = get.imType(app)
            if isempty(app.IM)
                val = 'none';
            else
                val = class(app.IM(1));
            end                
        end

        function updateImages(app)
            pxum = app.scaleButton.SelectedObject.Text; % 'px' or 'µm'
            if strcmpi(pxum,'px')
                fac = 1;
            elseif strcmpi(pxum,'µm')
                fac = app.IM(app.jim).pxSize*1e6;
            end
            %pxum = 'px';
            %fac = 1;
             
            % hide some parameters if not a ImageQLSI object
            if ~strcmp(app.imType,'ImageQLSI')  % if not type ImageQLSI (ie type ImageEM)
                app.switchQLSIparameters('off') %  don't disply some buttons
            end

            % define the proper imaging function @imagesc or @image3D
            imSize = size(app.IM(app.jim).OPD);
            
            if strcmpi(app.displayButton.SelectedObject.Text,'standard')
                app.imageFun = @imagesc;
            elseif strcmpi(app.displayButton.SelectedObject.Text,'3D')
                app.imageFun = @image3D;
            end
            
            % display the image number in the text box
            app.jText.Value = app.jim; 
            app.NimText.Value = numel(app.IM); 

            
            % affiche les 2 images, et définit leur colormap
            app.imageFun(app.AxesLeft, [1 imSize(2)]*fac,[1 imSize(1)]*fac,app.IM(app.jim).OPD*1e9)
            colormap(app.AxesLeft,phase1024); 
            
            app.imageFun(app.AxesRight, [1 imSize(2)]*fac,[1 imSize(1)]*fac, app.IM(app.jim).T)
            colormap(app.AxesRight, "gray"); 

            % redimension at the original size, in px coordinates
            xlim(app.AxesLeft,[1 imSize(2)]*fac); 
            ylim(app.AxesLeft,[1 imSize(1)]*fac); 
            xlim(app.AxesRight,[1 imSize(2)]*fac); 
            ylim(app.AxesRight,[1 imSize(1)]*fac); 
            xlabel(app.AxesRight,pxum)
            ylabel(app.AxesRight,' ')
            xlabel(app.AxesLeft,pxum)
            ylabel(app.AxesLeft,' ')
            drawnow

            % Lie les axes seulement en xy
            linkaxes([app.AxesLeft,app.AxesRight],'xy')

            
            % create the colorbars
            app.cbLeft = colorbar(app.AxesLeft); 
            app.cbLeft.Label.String = 'nm'; 
            app.cbRight = colorbar(app.AxesRight); 
            clim(app.AxesRight,'auto')
            clim(app.AxesLeft,'auto')

            % saving the min max values of the images
            app.valLeftMin = min(app.IM(app.jim).OPD(:)*1e9);
            app.valLeftMax = max(app.IM(app.jim).OPD(:)*1e9);
            app.valRightMin = min(app.IM(app.jim).T(:));
            app.valRightMax = max(app.IM(app.jim).T(:));

            % update properties tab
            app.wavelengthEditField.Value       = app.IM(app.jim).Illumination.lambda*1e9;
            app.pixelsizeEditField.Value        = app.IM(app.jim).Microscope.pxSize*1e9;
            app.magnificationEditField.Value    = abs(app.IM(app.jim).Microscope.M);
            app.numericalapertureEditField.Value= app.IM(app.jim).Microscope.Objective.NA;
            app.TLfocallengthEditField.Value    = app.IM(app.jim).Microscope.f_TL;
            app.comment.Text                    = app.IM(app.jim).comment;
            if strcmp(app.imType,'ImageQLSI')
                app.grexelsizeEditField.Value = abs(app.IM(app.jim).Microscope.CGcam.CG.Gamma*1e6);
                app.dexelsizeEditField.Value = abs(app.IM(app.jim).Microscope.CGcam.Camera.dxSize*1e6);
                app.zoomEditField.Value = abs(app.IM(app.jim).Microscope.CGcam.zoom);
                app.CGMcameraName.Value    = app.IM(1).Microscope.CGcam.fileName;
                app.zetaEditField.Value             = app.IM(app.jim).Microscope.CGcam.zeta;
                app.ItfFileName.Text = app.IM(app.jim).ItfFileName;
            end
            
            % creates and initialize the 'matlab_graphics_resetplotview' structure
            zoom(app.AxesRight,1)
            zoom(app.AxesLeft,1)

            % Ajusts the DataAspactRatio to [1 1 x] where x is calculated properly
            adjustdAspect(app,'all')
            drawnow

            % update light position
            app.setLightPositions()

            % update/reset the sliders
            app.resetSlider('all')

        end
    end
    
    methods (Access = private)
        
        function warn(app,message)
            % function that display
            app.messageArea.FontColor = [1, 0.5, 0.2];
            app.messageArea.Value = message;
            pause(0.5);
            app.messageArea.Value = ' ';
        end

        function resetSlider(app,which_one)
            arguments
                app
                which_one = 'all'
            end
            % left image (OPD)
            if any([which_one == 1 , strcmpi(which_one,'OPD') , strcmpi(which_one,'Left') ])
                slider = {app.SliderMinLeft, app.SliderMaxLeft};
                lims   = {app.valLeftMin, app.valLeftMax}; % min and max pixel value of the image (upper limit of the colorbar)
            elseif any ([which_one == 2 , strcmpi(which_one,'T') , strcmpi(which_one,'Right')])
                slider = {app.SliderMinRight, app.SliderMaxRight};
                lims   = {app.valRightMin, app.valRightMax}; % max pixel value of the image (upper limit of the colorbar)
            elseif strcmpi(which_one,'all')
                slider = {app.SliderMinLeft, app.SliderMaxLeft;
                          app.SliderMinRight, app.SliderMaxRight};
                lims = {app.valLeftMin , app.valLeftMax;
                        app.valRightMin, app.valRightMax}; % max pixel value of the image (upper limit of the colorbar)
            end

            for ii = 1 : numel(slider)/2 % loop over the number of sliders to update
                for jj = 1 : 2 % 1: min   2: max
                    center = lims{ii,jj}; % center value of the slider
                    ampl = lims{ii,2} - lims{ii,1};
                    delta = ampl/5; % ±  delta is the range of value accessible with the slider
                    slider{ii,jj}.Limits =  [center-delta, center+delta];
                    slider{ii,jj}.MajorTicks = [center-delta, center, center+delta];
                    slider{ii,jj}.MajorTickLabels = {num2str(round(center-delta)), num2str(round(center)), num2str(round(center+delta))};
                    slider{ii,jj}.Value = center;
                end
            end

        end

        function val = DX_imLeft(app)
            % width of the image
            val = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
        end
        function val = DY_imLeft(app)
            % height of the image
            val = max(app.ImageLeft.YData) - min(app.ImageLeft.YData);
        end
        function val = DZ_imLeft(app)
            % thickness of the image
            val = max(app.ImageLeft.CData(:)) - min(app.ImageLeft.CData(:));
        end
        function val = DX_imRight(app)
            % width of the image
            val = max(app.ImageRight.XData) - min(app.ImageRight.XData);
        end
        function val = DY_imRight(app)
            % height of the image
            val = max(app.ImageRight.YData) - min(app.ImageRight.YData);
        end
        function val = DZ_imRight(app)
            % thickness of the image
            val = max(app.ImageRight.CData(:)) - min(app.ImageRight.CData(:));
        end
        
        function setLightPositions(app)
            % updates the position of the light in case the image mode is
            % 3D. Useful when changing the scale of the image, for instance.
            if isequal(app.imageFun,@image3D)
                amplX = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
                %amplZ = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
                
                app.AxesLeft.Children(1).Position = [370*amplX/100 -250*amplX/100 app.AxesLeft.Children(1).Position(3)];
                app.AxesRight.Children(1).Position = [370*amplX/100 -250*amplX/100 app.AxesRight.Children(1).Position(3)];
                disp('tto')
            end
        end
    end
    methods
        function adjustdAspect(app,side)
            arguments
                app
                side char {mustBeMember(side,{'Left','Right','left','right','OPD','T','all'})} = 'all'
            end

            if ismember(side,{'all','Left','left','OPD'})
                daspect(app.AxesLeft,[1 1 10*app.DZ_imLeft/app.DX_imLeft])
            elseif ismember(side,{'all','Right','right','T'})
                daspect(app.AxesRight,[1 1 10*app.DZ_imRight/app.DX_imRight])
            else
                error('error in the input')
            end

        end

        function makeCurrentAxesBoundAxes(app)
            % Makes the current Axes range in x and y the limiting axes, to
            % be reach when pressing the home button in an Axes.
            axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
            if strcmp(app.scaleButton.SelectedObject.Text,'px')
                axesData.XLim=[1 , app.IM(app.jim).Nx];
                axesData.YLim=[1 , app.IM(app.jim).Ny];
            else
                axesData.XLim=[1 , app.IM(app.jim).Nx]*app.IM(app.jim).pxSize*1e6;
                axesData.YLim=[1 , app.IM(app.jim).Ny]*app.IM(app.jim).pxSize*1e6;
            end                
            setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
            setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);
        end

        function [vec1, vec2] = um2px(app,vec)
            % convert µm to px. Useful when using the crop function, to
            % stay in µm units

            
            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = round(vec/app.IM(app.jim).pxSize*1e-6);
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = round(vec(1)/app.IM(app.jim).pxSize*1e-6);
                vec2 = round(vec(2)/app.IM(app.jim).pxSize*1e-6);
            end
        end

        function [vec1, vec2] = px2um(app,vec)
            % convert px to um. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = vec*app.IM(app.jim).pxSize*1e6;
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = vec(1)*app.IM(app.jim).pxSize*1e6;
                vec2 = vec(2)*app.IM(app.jim).pxSize*1e6;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IM)
            if nargin == 2
                app.IM=IM;
                app.NimText.Value = app.Nim;
                app.jText.Value = app.jim;
            end
            set(app.AxesLeft,'DataAspectRatio',[1 1 1])
            set(app.AxesRight,'DataAspectRatio',[1 1 1])


        end

        % Image clicked function: buttonPrev
        function buttonPrevImageClicked(app, event)
            nextj = app.jim-1;
            if nextj < 1
                app.warn("end of the image stack")
            else
                app.jim=nextj;
            end
            app.updateImages()
        end

        % Image clicked function: buttonNext
        function buttonNextImageClicked(app, event)
            nextj = app.jim+1;
            if nextj > app.Nim
                app.warn("end of the image stack")
            else
                app.jim=nextj;
            end
            app.updateImages()
            
        end

        % Image clicked function: reload
        function reloadImageClicked(app, event)
            app.updateImages()
        end

        % Value changing function: SliderMinLeft
        function SliderMinLeftValueChanging(app, event)
            clim(app.AxesLeft, [event.Value, app.cbLeft.Limits(2)])
            drawnow
            
        end

        % Value changing function: SliderMaxLeft
        function SliderMaxLeftValueChanging(app, event)
            clim(app.AxesLeft, [app.cbLeft.Limits(1),  event.Value])
            drawnow
        end

        % Value changing function: SliderMinRight
        function SliderMinRightValueChanging(app, event)
            clim(app.AxesRight, [event.Value, app.cbRight.Limits(2)])
            drawnow
            
        end

        % Value changing function: SliderMaxRight
        function SliderMaxRightValueChanging(app, event)
            clim(app.AxesRight, [app.cbRight.Limits(1),  event.Value])
            drawnow
            
        end

        % Button pushed function: resetSlidersLeft
        function resetSlidersLeftButtonPushed(app, event)
            clim(app.AxesLeft, [app.valLeftMin, app.valLeftMax])
            app.resetSlider('Left')
        end

        % Button pushed function: resetSlidersRight
        function resetSlidersRightButtonPushed(app, event)
            clim(app.AxesRight, [app.valRightMin, app.valRightMax])
            app.resetSlider('Right')            
        end

        % Selection changed function: scaleButton
        function scaleButtonSelectionChanged(app, event)

            if isequal(app.imageFun,@imagesc)
                app.ImageRight.XData = [1 , app.IM(app.jim).Nx];
                app.ImageRight.YData = [1 , app.IM(app.jim).Ny];
                app.ImageLeft.XData = [1 , app.IM(app.jim).Nx];
                app.ImageLeft.YData = [1 , app.IM(app.jim).Ny];
            elseif isequal(app.imageFun,@image3D)
                app.ImageRight.XData = 1 : app.IM(app.jim).Nx;
                app.ImageRight.YData = 1 : app.IM(app.jim).Ny;
                app.ImageLeft.XData = 1 : app.IM(app.jim).Nx;
                app.ImageLeft.YData = 1 : app.IM(app.jim).Ny;
            end                
                
            if strcmpi(event.NewValue.Text,'px')
                app.makeCurrentAxesBoundAxes

                xlabel(app.AxesRight,'px')
                xlabel(app.AxesLeft,'px')

                app.widthCropUnitLabel.Text = 'px';
                app.heightCropUnitLabel.Text = 'px';
                
            else % µm  
                axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
                axesData.XLim=[1 , app.IM(app.jim).Nx]*app.IM(app.jim).pxSize*1e6;
                axesData.YLim=[1 , app.IM(app.jim).Ny]*app.IM(app.jim).pxSize*1e6;
                setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
                setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);

                app.ImageRight.XData = app.ImageRight.XData *app.IM(app.jim).pxSize*1e6;
                app.ImageRight.YData = app.ImageRight.YData *app.IM(app.jim).pxSize*1e6;
                app.ImageLeft.XData  = app.ImageLeft.XData  *app.IM(app.jim).pxSize*1e6;
                app.ImageLeft.YData  = app.ImageLeft.YData  *app.IM(app.jim).pxSize*1e6;

                xlabel(app.AxesRight,'µm')
                xlabel(app.AxesLeft,'µm')

                app.widthCropUnitLabel.Text = 'µm';
                app.heightCropUnitLabel.Text = 'µm';
            end
            zoom(app.AxesLeft,'out')
            zoom(app.AxesRight,'out')
            app.adjustdAspect('all')
            app.setLightPositions()

            
            drawnow
        end

        % Selection changed function: displayButton
        function displayButtonSelectionChanged(app, event)
            updateImages(app)
        end

        % Image clicked function: rot90button
        function rot90buttonImageClicked(app, event)
            app.IM.rot90(-1);
            app.ImageRight.CData = app.IM(app.jim).T;
            app.ImageLeft.CData = app.IM(app.jim).OPD*1e9;

            % to make the axes follow, in the case of non-square images
            app.makeCurrentAxesBoundAxes
            app.reloadImageClicked
        end

        % Image clicked function: mirrorVbutton
        function mirrorVbuttonImageClicked(app, event)
            app.IM.mirrorV();
            app.ImageRight.CData = app.IM(app.jim).T;
            app.ImageLeft.CData = app.IM(app.jim).OPD*1e9;
        end

        % Image clicked function: mirrorHbutton
        function mirrorHbuttonImageClicked(app, event)
            app.IM.mirrorH();
            app.ImageRight.CData = app.IM(app.jim).T;
            app.ImageLeft.CData = app.IM(app.jim).OPD*1e9;            
        end

        % Value changed function: colormapDropDownLeft
        function colormapDropDownLeftValueChanged(app, event)
            value = app.colormapDropDownLeft.Value;

            try
                cc = colormap(app.AxesLeft,value);
            catch % if the colormal is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesLeft,funColorMap(1024));
            end

            if app.invColorMapLeft
                colormap(app.AxesLeft,flipud(cc))
            end

        end

        % Value changed function: invertCheckBoxLeft
        function invertCheckBoxLeftValueChanged(app, event)
            value = app.invertCheckBoxLeft.Value;
            app.invColorMapLeft = value ;
            colormapDropDownLeftValueChanged(app)
        end

        % Value changed function: colormapDropDownRight
        function colormapDropDownRightValueChanged(app, event)
            value = app.colormapDropDownRight.Value;

            try
                cc = colormap(app.AxesRight,value);
            catch % if the colormal is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesRight,funColorMap(1024));
            end

            if app.invColorMapRight
                colormap(app.AxesRight,flipud(cc))
            end
            
        end

        % Value changed function: invertCheckBoxRight
        function invertCheckBoxRightValueChanged(app, event)
            value = app.invertCheckBoxRight.Value;
            app.invColorMapRight = value ;
            colormapDropDownRightValueChanged(app)
        end

        % Image clicked function: cropButton, cropButtonCenterRect, 
        % ...and 1 other component
        function cropButtonImageClicked(app, event)
            shape = event.Source.Tag; % Square or Rectangle crop

            if app.invertCheckBoxLeft.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end

            if app.widthEditField.Value > 0
                cropSize = [app.widthEditField.Value , app.heightEditField.Value ];
            else
                cropSize = 'Manual';
            end
            
            if strcmpi(shape,'twoPoints')
                app.IM(range).crop("twoPoints",true,"figure",app)
            else
                app.IM(range).crop("Center",'manual',"figure",app,"Size",cropSize,"shape",shape)
            end

            app.updateImages() 
            app.makeCurrentAxesBoundAxes()

        end

        % Callback function
        function cropButtonCenterSquareImageClicked(app, event)
            
            shape = event.Source.Tag; % Square or Rectangle crop

            if app.invertCheckBoxLeft.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end

            if app.widthEditField > 0
                cropSize = [app.widthEditField, app.heightEditField];
            else
                cropSize = 'Manual';
            end
            
            if strcmpi(shape,'twoPoints')
                app.IM(range).crop("twoPoints",true,"figure",app)
            else
                app.IM(range).crop("Center",'manual',"figure",app,"Size",cropSize,"shape",shape)
            end

            app.updateImages() 
            app.makeCurrentAxesBoundAxes()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.AxesRight = uiaxes(app.MainGridLayout);
            title(app.AxesRight, 'Transmittance')
            xlabel(app.AxesRight, 'X')
            ylabel(app.AxesRight, 'Y')
            zlabel(app.AxesRight, 'Z')
            app.AxesLeft = uiaxes(app.MainGridLayout);
            title(app.AxesLeft, 'Wavefront')
            xlabel(app.AxesLeft, 'X')
            ylabel(app.AxesLeft, 'Y')
            zlabel(app.AxesLeft, 'Z')
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.propertiesTab = uitab(app.TabGroup);
            app.propertiesTab.Title = 'properties';
            app.propertiesGridLayout = uigridlayout(app.propertiesTab);
            app.pixelsizeLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeLabel.Text = 'nm';
            app.wavelengthLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthLabel.Text = 'nm';
            app.magnificationLabel = uilabel(app.propertiesGridLayout);
            app.magnificationLabel.Text = 'X';
            app.MICROSCOPELabel = uilabel(app.propertiesGridLayout);
            app.MICROSCOPELabel.Text = 'MICROSCOPE';
            app.TLfocallengthLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthLabel.Text = 'mm';
            app.wavelengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.wavelengthEditField.Editable = 'off';
            app.pixelsizeEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeEditFieldLabel.Text = 'pixel size';
            app.pixelsizeEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.pixelsizeEditField.Editable = 'off';
            app.magnificationEditField_3Label = uilabel(app.propertiesGridLayout);
            app.magnificationEditField_3Label.Text = 'magnification';
            app.magnificationEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.magnificationEditField.Editable = 'off';
            app.numericalapertureEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.numericalapertureEditFieldLabel.Text = 'numerical aperture';
            app.numericalapertureEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.numericalapertureEditField.Editable = 'off';
            app.TLfocallengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthEditFieldLabel.Text = 'TL focal length';
            app.TLfocallengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.TLfocallengthEditField.Editable = 'off';
            app.panelCGM = uipanel(app.propertiesGridLayout);
            app.CGMsystemGridLayout = uigridlayout(app.panelCGM);
            app.dexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeLabel.Text = 'nm';
            app.zoomLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomLabel.Text = 'X';
            app.grexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeLabel.Text = 'µm';
            app.dexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeEditFieldLabel.Text = 'dexel size';
            app.dexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.dexelsizeEditField.Editable = 'off';
            app.zoomEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomEditFieldLabel.Text = 'zoom';
            app.zoomEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zoomEditField.Editable = 'off';
            app.grexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeEditFieldLabel.Text = 'grexel size';
            app.grexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.grexelsizeEditField.Editable = 'off';
            app.CGMcameraEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMcameraEditFieldLabel.Text = 'CGM camera';
            app.CGMcameraName = uieditfield(app.CGMsystemGridLayout, 'text');
            app.CGMcameraName.Editable = 'off';
            app.CGMsystemLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMsystemLabel.Text = 'CGM system';
            app.ItfFileName = uilabel(app.CGMsystemGridLayout);
            app.ItfFileName.Text = '';
            app.zetaLabel = uilabel(app.CGMsystemGridLayout);
            app.zetaLabel.Text = 'zeta';
            app.zetaEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zetaEditField.Editable = 'off';
            app.comment = uilabel(app.propertiesGridLayout);
            app.comment.Text = '';
            app.metrologyTab = uitab(app.TabGroup);
            app.metrologyTab.Title = 'metrology';
            app.processingGridLayout = uigridlayout(app.metrologyTab);
            app.pixelButton = uibutton(app.processingGridLayout, 'push');
            app.pixelButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'pixel.svg');
            app.pixelButton.Text = 'pixel';
            app.distanceButton = uibutton(app.processingGridLayout, 'push');
            app.distanceButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'distanceButton.svg');
            app.distanceButton.Text = 'distance';
            app.areaButton = uibutton(app.processingGridLayout, 'push');
            app.areaButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'areaButton.svg');
            app.areaButton.Text = 'area';
            app.HVButton = uibutton(app.processingGridLayout, 'push');
            app.HVButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'HVcrosscut.svg');
            app.HVButton.Text = 'H / V';
            app.crossCutRadial = uibutton(app.processingGridLayout, 'push');
            app.crossCutRadial.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.crossCutRadial.Text = 'radial';
            app.METROLOGYLabel = uilabel(app.processingGridLayout);
            app.METROLOGYLabel.Text = 'METROLOGY';
            app.CROSSCUTSLabel = uilabel(app.processingGridLayout);
            app.CROSSCUTSLabel.Text = 'CROSS CUTS';
            app.pathButton = uibutton(app.processingGridLayout, 'push');
            app.pathButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Pathcrosscut.svg');
            app.pathButton.Text = 'path';
            app.alphaOVDMLabel = uilabel(app.processingGridLayout);
            app.alphaOVDMLabel.Text = 'alpha / OV / DM';
            app.radialButton = uibutton(app.processingGridLayout, 'push');
            app.radialButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.radialButton.Text = 'radial';
            app.roiButton = uibutton(app.processingGridLayout, 'push');
            app.roiButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'roiButton.svg');
            app.roiButton.Text = 'roi';
            app.magicwandButton = uibutton(app.processingGridLayout, 'push');
            app.magicwandButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'MWButton.svg');
            app.magicwandButton.Text = {'magic'; 'wand'};
            app.GridLayout = uigridlayout(app.processingGridLayout);
            app.NmaxEditFieldLabel = uilabel(app.GridLayout);
            app.NmaxEditFieldLabel.Text = 'Nmax';
            app.NmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField_bkgRing = uieditfield(app.GridLayout, 'numeric');
            app.bkgRingLabel = uilabel(app.GridLayout);
            app.bkgRingLabel.Text = 'bkg ring';
            app.stepEditFieldLabel = uilabel(app.GridLayout);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.GridLayout, 'numeric');
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = 'Postprocessing';
            app.GridPostProcessing = uigridlayout(app.PostprocessingTab);
            app.ApplytoallimagesCheckBox = uicheckbox(app.GridPostProcessing);
            app.ApplytoallimagesCheckBox.Text = 'Apply to all images';
            app.FLIPROTATIONLabel = uilabel(app.GridPostProcessing);
            app.FLIPROTATIONLabel.Text = 'FLIP / ROTATION';
            app.mirrorVbutton = uiimage(app.GridPostProcessing);
            app.mirrorVbutton.ImageClickedFcn = createCallbackFcn(app, @mirrorVbuttonImageClicked, true);
            app.mirrorVbutton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'mirrorV.svg');
            app.mirrorHbutton = uiimage(app.GridPostProcessing);
            app.mirrorHbutton.ImageClickedFcn = createCallbackFcn(app, @mirrorHbuttonImageClicked, true);
            app.mirrorHbutton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'mirrorH.svg');
            app.rot90button = uiimage(app.GridPostProcessing);
            app.rot90button.ImageClickedFcn = createCallbackFcn(app, @rot90buttonImageClicked, true);
            app.rot90button.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'rot90.svg');
            app.CROPLabel = uilabel(app.GridPostProcessing);
            app.CROPLabel.Text = 'CROP';
            app.BACKGROUNDCORRECTIONLabel_2 = uilabel(app.GridPostProcessing);
            app.BACKGROUNDCORRECTIONLabel_2.Text = 'BACKGROUND CORRECTION';
            app.coma = uiimage(app.GridPostProcessing);
            app.coma.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'coma.png');
            app.cropButton = uiimage(app.GridPostProcessing);
            app.cropButton.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButton.Tag = 'twoPoints';
            app.cropButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_select.svg');
            app.cropButtonCenterSquare = uiimage(app.GridPostProcessing);
            app.cropButtonCenterSquare.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterSquare.Tag = 'Square';
            app.cropButtonCenterSquare.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerSquare.svg');
            app.cropButtonCenterRect = uiimage(app.GridPostProcessing);
            app.cropButtonCenterRect.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterRect.Tag = 'Rectangle';
            app.cropButtonCenterRect.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerRectangle.svg');
            app.widthEditFieldLabel = uilabel(app.GridPostProcessing);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.GridPostProcessing, 'numeric');
            app.heightEditFieldLabel = uilabel(app.GridPostProcessing);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.GridPostProcessing, 'numeric');
            app.widthCropUnitLabel = uilabel(app.GridPostProcessing);
            app.widthCropUnitLabel.Text = 'px';
            app.heightCropUnitLabel = uilabel(app.GridPostProcessing);
            app.heightCropUnitLabel.Text = 'px';
            app.ControlsGridLayout = uigridlayout(app.MainGridLayout);
            app.buttonPrev = uiimage(app.ControlsGridLayout);
            app.buttonPrev.ImageClickedFcn = createCallbackFcn(app, @buttonPrevImageClicked, true);
            app.buttonPrev.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'leftArrow.svg');
            app.reload = uiimage(app.ControlsGridLayout);
            app.reload.ImageClickedFcn = createCallbackFcn(app, @reloadImageClicked, true);
            app.reload.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'reload.svg');
            app.buttonNext = uiimage(app.ControlsGridLayout);
            app.buttonNext.ImageClickedFcn = createCallbackFcn(app, @buttonNextImageClicked, true);
            app.buttonNext.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'rightArrow.svg');
            app.jText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.NimText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.RightToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.colormapDropDown_4Label = uilabel(app.RightToolsGridLayout);
            app.colormapDropDown_4Label.Text = 'colormap';
            app.colormapDropDownRight = uidropdown(app.RightToolsGridLayout);
            app.colormapDropDownRight.Items = {'gray', 'parula', 'hot'};
            app.colormapDropDownRight.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownRightValueChanged, true);
            app.colormapDropDownRight.Value = 'gray';
            app.resetSlidersRight = uibutton(app.RightToolsGridLayout, 'push');
            app.resetSlidersRight.ButtonPushedFcn = createCallbackFcn(app, @resetSlidersRightButtonPushed, true);
            app.resetSlidersRight.Text = 'reset';
            app.SliderMinRight = uislider(app.RightToolsGridLayout);
            app.SliderMinRight.Limits = [-100 100];
            app.SliderMinRight.MajorTicks = [-100 -50 0 50 100];
            app.SliderMinRight.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SliderMinRight.ValueChangingFcn = createCallbackFcn(app, @SliderMinRightValueChanging, true);
            app.SliderMinRight.MinorTicks = [];
            app.SliderMaxRight = uislider(app.RightToolsGridLayout);
            app.SliderMaxRight.Limits = [-100 100];
            app.SliderMaxRight.MajorTicks = [-100 -50 0 50 100];
            app.SliderMaxRight.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SliderMaxRight.ValueChangingFcn = createCallbackFcn(app, @SliderMaxRightValueChanging, true);
            app.SliderMaxRight.MinorTicks = [];
            app.invertCheckBoxRight = uicheckbox(app.RightToolsGridLayout);
            app.invertCheckBoxRight.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxRightValueChanged, true);
            app.invertCheckBoxRight.Text = 'invert';
            app.RenderingGridLayout = uigridlayout(app.MainGridLayout);
            app.displayButton = uibuttongroup(app.RenderingGridLayout);
            app.displayButton.SelectionChangedFcn = createCallbackFcn(app, @displayButtonSelectionChanged, true);
            app.displayButton3D = uiradiobutton(app.displayButton);
            app.displayButton3D.Text = '3D';
            app.displayButtonStandard = uiradiobutton(app.displayButton);
            app.displayButtonStandard.Text = 'standard';
            app.displayButtonStandard.Value = true;
            app.scaleButton = uibuttongroup(app.RenderingGridLayout);
            app.scaleButton.SelectionChangedFcn = createCallbackFcn(app, @scaleButtonSelectionChanged, true);
            app.scaleButtonum = uiradiobutton(app.scaleButton);
            app.scaleButtonum.Text = 'µm';
            app.scaleButtonpx = uiradiobutton(app.scaleButton);
            app.scaleButtonpx.Text = 'px';
            app.scaleButtonpx.Value = true;
            app.GridLayout2 = uigridlayout(app.MainGridLayout);
            app.messageArea = uitextarea(app.GridLayout2);
            app.messageArea.Editable = 'off';
            app.copyButton = uiimage(app.GridLayout2);
            app.copyButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'clipboard.svg');
            app.clearButton = uiimage(app.GridLayout2);
            app.clearButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'erase.svg');
            app.Image5 = uiimage(app.MainGridLayout);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'logoPhaseLAB2.png');
            app.LeftToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.resetSlidersLeft = uibutton(app.LeftToolsGridLayout, 'push');
            app.resetSlidersLeft.ButtonPushedFcn = createCallbackFcn(app, @resetSlidersLeftButtonPushed, true);
            app.resetSlidersLeft.Text = 'reset';
            app.colormapDropDown_3Label = uilabel(app.LeftToolsGridLayout);
            app.colormapDropDown_3Label.Text = 'colormap';
            app.colormapDropDownLeft = uidropdown(app.LeftToolsGridLayout);
            app.colormapDropDownLeft.Items = {'phase1024', 'Pradeep', 'gray', 'parula', 'jet'};
            app.colormapDropDownLeft.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownLeftValueChanged, true);
            app.colormapDropDownLeft.Value = 'phase1024';
            app.SliderMinLeft = uislider(app.LeftToolsGridLayout);
            app.SliderMinLeft.Limits = [-100 100];
            app.SliderMinLeft.MajorTicks = [-100 -50 0 50 100];
            app.SliderMinLeft.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SliderMinLeft.ValueChangingFcn = createCallbackFcn(app, @SliderMinLeftValueChanging, true);
            app.SliderMinLeft.MinorTicks = [];
            app.SliderMaxLeft = uislider(app.LeftToolsGridLayout);
            app.SliderMaxLeft.Limits = [-100 100];
            app.SliderMaxLeft.MajorTicks = [-100 -50 0 50 100];
            app.SliderMaxLeft.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SliderMaxLeft.ValueChangingFcn = createCallbackFcn(app, @SliderMaxLeftValueChanging, true);
            app.SliderMaxLeft.MinorTicks = [];
            app.invertCheckBoxLeft = uicheckbox(app.LeftToolsGridLayout);
            app.invertCheckBoxLeft.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxLeftValueChanged, true);
            app.invertCheckBoxLeft.Text = 'invert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseLABgui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end