classdef InterferoInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        jimEditField      matlab.ui.control.NumericEditField
        NimEditField      matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        moveRightButton   matlab.ui.control.Button
        moveLeftButton    matlab.ui.control.Button
        GridLayout3       matlab.ui.container.GridLayout
        cropLabel         matlab.ui.control.Label
        cropEditField     matlab.ui.control.NumericEditField
        UIAxes_FFT        matlab.ui.control.UIAxes
        UIAxes_wavefront  matlab.ui.control.UIAxes
        UIAxes_ITF        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IM
        itf % Interfero image
        wf  % Wavefront image
        hWF   % Handle of the wavefront image
        hITF % Handle of the ITF image
        hFFT % Handle of the FFT image

        dispFunction {mustBeMember(dispFunction,{'itf','fft'})} = 'itf'
        
        % ROI / affichage
        roiHalfSize (1,1) double  = 64;   % demi-taille => ROI = (2*roiHalfSize+1)^2
        useHann     (1,1) logical = true;
        logFFT      (1,1) logical = true;
        
        % Suivi curseur
        lastXY (1,2) double = [NaN NaN]; % [x y] en coord image
        
        % Timer
        fftTimer timer    
    end

    properties(Dependent)
        jim % # of current image in IM
        Nim % total number of images in IM
    end

    methods (Access = private)
        
        % function updateFFTFromCursor(app)
        %     % Vérifie que la figure est valide
        %     if ~isvalid(app.UIFigure) || ~strcmp(app.UIFigure.Visible,'on')
        %         return
        %     end
        % 
        %     % Lire la position curseur en coordonnées UIAxes_wavefront
        %     cp = app.UIAxes_wavefront.CurrentPoint;   % 2x3
        %     x = cp(1,1);
        %     y = cp(1,2);
        % 
        %     % Si le curseur est hors des limites image -> ne rien faire
        %     if x < 0.5 || x > size(app.itf,2)+0.5 || y < 0.5 || y > size(app.itf,1)+0.5
        %         return
        %     end
        % 
        %     % Évite de recalculer si pas bougé "assez"
        %     if all(abs([x y] - app.lastXY) < 0.2)   % tolérance en pixel
        %         return
        %     end
        %     app.lastXY = [x y];
        % 
        %     % ROI centré sur (x,y) en indices image
        %     cx = round(x);
        %     cy = round(y);
        % 
        %     hs = app.roiHalfSize;
        %     x1 = max(1, cx-hs); x2 = min(size(app.itf,2), cx+hs);
        %     y1 = max(1, cy-hs); y2 = min(size(app.itf,1), cy+hs);
        % 
        %     roi = app.itf(y1:y2, x1:x2);
        % 
        %     % Pour garder une FFT de taille constante, on pad à la taille nominale
        %     N = 2*hs+1;
        %     roiPad = zeros(N, N, 'like', roi);
        %     roiPad(1:size(roi,1), 1:size(roi,2)) = roi;
        % 
        %     % Option : fenêtrage (réduit leakage)
        %     if app.useHann
        %         w = hann(N) * hann(N).';
        %         roiPad = roiPad .* w;
        %     end
        % 
        %     % FFT
        %     if strcmp(app.dispFunction,'fft')
        %         F = fftshift(fft2(roiPad));
        %         A = abs(F);
        % 
        %         if app.logFFT
        %             A = log1p(A);
        %         end
        %     else
        %         A = roiPad;
        %     end
        % 
        %     % Update image FFT (très rapide si on garde le même handle)
        %     app.hFFT.CData = A;
        % 
        %     % % Optionnel : autoscale doux
        %     % clim = prctile(A(:), [1 99.5]);
        %     % if all(isfinite(clim)) && clim(2) > clim(1)
        %     %     app.UIAxes_ITF.CLim = clim;
        %     % end
        % 
        %     drawnow limitrate nocallbacks
        % end

        function UIFigureCloseRequest(app, event)
            app.UIFigure.WindowButtonMotionFcn = [];
            delete(app);
        end

        function onMouseMove(app)
            % Position curseur en coordonnées UIAxes_wavefront
            cp = app.UIAxes_wavefront.CurrentPoint;   % 2x3
            x = cp(1,1);
            y = cp(1,2);
        
            % Ne rafraîchir que si la souris est dans les limites de l’image
            if x < 0.5 || x > size(app.itf,2)+0.5 || y < 0.5 || y > size(app.itf,1)+0.5
                return
            end
        
            % Anti-bruit (évite recalcul si déplacement trop faible)
            if all(abs([x y] - app.lastXY) < 0.2)
                return
            end
            app.lastXY = [x y];
        
            % Calcul / update FFT (reprend ton code existant)
            app.updateFFTAt(x, y);
        end


        function updateFFTAt(app, x, y)
            cx = round(x);
            cy = round(y);
        
            hs = app.roiHalfSize;
            x1 = max(1, cx-hs); x2 = min(size(app.itf,2), cx+hs);
            y1 = max(1, cy-hs); y2 = min(size(app.itf,1), cy+hs);
        
            roi = app.itf(y1:y2, x1:x2);
            N = 2*hs+1;
            roiPad = zeros(N,N,'like',roi);
            roiPad(1:size(roi,1), 1:size(roi,2)) = roi;
        
        
            A = roiPad;

            if app.useHann
                w = hann(N) * hann(N).';
                roiPad = roiPad .* w;
            end
    
            F = fftshift(fft2(roiPad));
            F = abs(F);
            if app.logFFT
                F = log1p(F);
            end


            app.hFFT.CData = F;
            app.hITF.CData = A;
        
            drawnow limitrate nocallbacks
        end

        function onWFClick(app, evt)
            app.saveImages(evt)
        end

        function saveImages(app, evt)
            % Coordonnées du clic dans le repère des données (image)
            % (Avec imagesc, ça correspond aux coordonnées pixel ~ index)
            ip = evt.IntersectionPoint;  % [x y z]
            x = ip(1);
            y = ip(2);
        
            % Vérif dans l'image
            if x < 0.5 || x > size(app.itf,2)+0.5 || y < 0.5 || y > size(app.itf,1)+0.5
                return
            end
        
            A = app.hITF.CData;
            F = app.hFFT.CData;
            
            % --- Sauvegardes ---
            outDir = fullfile(pwd, "FFT_clicks");
            if ~exist(outDir, 'dir'); mkdir(outDir); end
        
            ts = datestr(now,'yyyymmdd_HHMMSS_FFF');
            base = fullfile(outDir, "click_" + ts);
        
            % 1) FFT (raw) + infos (MAT recommandé)
            click = struct();
            click.x = x; click.y = y;                 % coords cliquées (axes)
            click.cx = round(x); click.cy = round(y); % indices (pixels)
%            click.roi = roiPad;
%            click.roi_bounds = [x1 x2 y1 y2];         % bornes dans l'image originale
            click.F = F;                              % FFT complexe du crop (le plus utile)
            click.A = A;                              % amplitude (celle affichée)
            click.roiHalfSize = app.roiHalfSize;
            click.useHann = app.useHann;
            click.logFFT = app.logFFT;
        
            save(base + ".mat", "-struct", "click");
        
            % 2) Image FFT (PNG) — optionnel mais pratique
            % Normalisation en 16-bit pour garder de la dynamique
            A2 = A - min(A(:));
            A2 = A2 ./ max(A2(:));
            imwrite(uint16(65535*A2), base + "_ITF.png");
        
            F2 = F ./ max(F(:));
            imwrite(uint16(65535*F2), base + "_FFT.png");


            % 3) Image FFT (TIFF) — optionnel mais pratique
            % Normalisation en 16-bit pour garder de la dynamique
            A16 = A - min(A(:));
            A16 = A16 ./ max(A16(:));
            A16 = uint16(65535 * A16);            
            imwrite(A16, base + "_ITF.tif");
        
            F16 = F ./ max(F(:));
            F16 = uint16(65535 * F16);            
            imwrite(F16, base + "_ITF.tif");
        
            % Petit retour visuel (option)
            % disp("Saved: " + base);
        end
        
        function setIM(app,ii)
            app.itf = app.IM(ii).Interfero.Itf;
            app.wf = app.IM(ii).OPDnm;
            app.hWF.CData = app.wf;
        end
        
    end

    methods
        function val = get.jim(app)
            val = app.jimEditField.Value;
        end
        function set.jim(app,val)
            app.jimEditField.Value = val;
        end
        function val = get.Nim(app)
            val = app.NimEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IM)
            arguments
                app
                IM (:,1) ImageQLSI
            end

            app.IM = IM;
            app.setIM(1)

            app.NimEditField.Value = numel(app.IM);
            
            app.UIFigure.Position = [5 100 1508 584];
            app.roiHalfSize = app.cropEditField.Value;

            app.hWF = imagesc(app.UIAxes_wavefront,[0.5 size(app.itf,2)+0.5],[0.5 size(app.itf,1)+0.5],app.wf);
            axis(app.UIAxes_wavefront, 'image');
            colormap(app.UIAxes_wavefront, Sepia);
            app.UIAxes_wavefront.XLim = [0.5 size(app.itf,2)+0.5];
            app.UIAxes_wavefront.YLim = [0.5 size(app.itf,1)+0.5];


            % Affichage FFT (placeholder)
            cla(app.UIAxes_ITF);
            cla(app.UIAxes_FFT);
            app.hFFT = imagesc(app.UIAxes_FFT, zeros(2*app.roiHalfSize+1));
            app.hITF = imagesc(app.UIAxes_ITF, zeros(2*app.roiHalfSize+1));
            axis(app.UIAxes_ITF, 'image');
            colormap(app.UIAxes_ITF, 'gray');
            axis(app.UIAxes_FFT, 'image');
            colormap(app.UIAxes_FFT, 'gray');
            %colorbar(app.UIAxes_ITF)


            % Timer de rafraîchissement
            % app.fftTimer = timer( ...
            %     'ExecutionMode','fixedSpacing', ...
            %     'Period', 0.05, ...          % 20 Hz (ajuste)
            %     'BusyMode','drop', ...
            %     'TimerFcn', @(~,~)app.updateFFTFromCursor() );
            % 
            % start(app.fftTimer);

            % Mise à jour FFT en bougeant la souris
            app.UIFigure.WindowButtonMotionFcn = @(src,evt)app.onMouseMove();


            % Autoriser le clic sur l'image
            app.hWF.PickableParts = 'all';
            app.hWF.HitTest = 'on';
            
            % Callback clic
            app.hWF.ButtonDownFcn = @(src,evt)app.onWFClick(evt);
        end

        % Value changed function: cropEditField
        function cropEditFieldValueChanged(app, event)

            app.roiHalfSize = app.cropEditField.Value;

        end

        % Button pushed function: moveLeftButton, moveRightButton
        function moveButtonPushed(app, event)
            switch event.Source.Tag
                case 'Left'
                    app.jim = mod(app.jim-2,app.Nim)+1;
                case 'Right'
                    app.jim = mod(app.jim,app.Nim)+1;
            end
            app.setIM(app.jim)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_ITF = uiaxes(app.GridLayout);
            title(app.UIAxes_ITF, 'ITF')
            zlabel(app.UIAxes_ITF, 'Z')
            app.UIAxes_wavefront = uiaxes(app.GridLayout);
            title(app.UIAxes_wavefront, 'Wavefront')
            zlabel(app.UIAxes_wavefront, 'Z')
            app.UIAxes_FFT = uiaxes(app.GridLayout);
            title(app.UIAxes_FFT, 'FFT')
            zlabel(app.UIAxes_FFT, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.cropEditField = uieditfield(app.GridLayout3, 'numeric');
            app.cropEditField.Limits = [1 Inf];
            app.cropEditField.ValueDisplayFormat = '%1d';
            app.cropEditField.ValueChangedFcn = createCallbackFcn(app, @cropEditFieldValueChanged, true);
            app.cropEditField.Value = 150;
            app.cropLabel = uilabel(app.GridLayout3);
            app.cropLabel.Text = 'crop:';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.moveLeftButton = uibutton(app.GridLayout4, 'push');
            app.moveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.moveLeftButton.Tag = 'Left';
            app.moveLeftButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.moveLeftButton.Text = '';
            app.moveRightButton = uibutton(app.GridLayout4, 'push');
            app.moveRightButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.moveRightButton.Tag = 'Right';
            app.moveRightButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.moveRightButton.Text = '';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '/';
            app.NimEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NimEditField.Limits = [1 Inf];
            app.NimEditField.RoundFractionalValues = 'on';
            app.NimEditField.Editable = 'off';
            app.NimEditField.Value = 1;
            app.jimEditField = uieditfield(app.GridLayout4, 'numeric');
            app.jimEditField.Limits = [1 Inf];
            app.jimEditField.RoundFractionalValues = 'on';
            app.jimEditField.ValueDisplayFormat = '%d';
            app.jimEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterferoInspector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end