classdef JonesMatrixMultiplier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout_Analyzer_2  matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        Label_slash            matlab.ui.control.Label
        Nim_EditField          matlab.ui.control.NumericEditField
        jim_EditField          matlab.ui.control.NumericEditField
        PreviousButton         matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        GridLayout2            matlab.ui.container.GridLayout
        GridLayout4            matlab.ui.container.GridLayout
        holdCheckBox           matlab.ui.control.CheckBox
        maxEditField           matlab.ui.control.NumericEditField
        maxEditFieldLabel      matlab.ui.control.Label
        fftCheckBox            matlab.ui.control.CheckBox
        J_UIAxes               matlab.ui.control.UIAxes
        GridLayout_Analyzer    matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        equationTextArea       matlab.ui.control.TextArea
        generateButton         matlab.ui.control.Button
        thetaQWP_outSlider     matlab.ui.control.Slider
        Label_4                matlab.ui.control.Label
        AnalyzerLabel          matlab.ui.control.Label
        D_outSlider            matlab.ui.control.Slider
        Label_2                matlab.ui.control.Label
        thetaP_outSlider       matlab.ui.control.Slider
        Label_3                matlab.ui.control.Label
        AnalyzerUIAxes         matlab.ui.control.UIAxes
        GridLayout_Ein         matlab.ui.container.GridLayout
        GridLayout_hin         matlab.ui.container.GridLayout
        hSwitchLabel           matlab.ui.control.Label
        h_inSwitch             matlab.ui.control.Switch
        EinLabel               matlab.ui.control.Label
        ecc_inSlider           matlab.ui.control.Slider
        epsilonLabel           matlab.ui.control.Label
        theta_inSlider         matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        EinUIAxes              matlab.ui.control.UIAxes
    end

    
    %   Ein  ->  J  ->   Eout   ->   QWP(theta_QWP)  ->  P(theta_P)   ->    Eana 
    
    properties (Access = private)
        AllowedAngles = [0 30 22.5 45 60 90  120 135 150 174 180] % Description
    end

    properties
        J JonesMatrix  % Jones Martrix
    end

    properties(Dependent)
        jim (1,1) double % current displayed image number
        Ein   (2,1) double  % Calculated Ein  field
        Eout  ImageEM       % Calculated J*Ein, the E field after the J object
        Eana  ImageEM       % Calculated Efield after the analyzer
        Nim   (1,1) double  % Number of J elements
    end

    methods (Access = private)
        
        function updateEllipse(app,ax)
            arguments
                app
                ax {mustBeMember(ax,{'Ein','Analyzer'})}
            end
            if strcmp(ax, 'Ein')
                e = app.ecc_inSlider.Value;
                thetaP = app.theta_inSlider.Value;
                h = app.h_inSwitch.Value;
                if string(h) == "Left"
                    col = [0.2, 0.4, 0.9];
                else
                    col = [0.9, 0.2, 0.4];
                end                
            else % Eout
                e = sqrt(1-(1-app.D_outSlider.Value)^2);
                thetaP = app.thetaP_outSlider.Value;
                thetaQWP = app.thetaQWP_outSlider.Value;
                % plot of the QWP
                col = [0.2, 0.9, 0.4];
                app.plotEllipse(app.(ax+"UIAxes"), 1, thetaQWP, [1 1 1])
                hold(app.(ax+"UIAxes"),"on")
            end
            app.plotEllipse(app.(ax+"UIAxes"), e, thetaP, col)
            hold(app.(ax+"UIAxes"),"off")
            app.updateEanaImage()
        end

        
        function updateEanaImage(app)
            if ~app.fftCheckBox.Value % no fft
                app.J_UIAxes.Children.CData = app.Eana.E2;
                app.J_UIAxes.CLim = [0 norm(app.Ein)^2];
            else % fft
                app.J_UIAxes.Children.CData = log(abs(app.Eana.E2));
                app.J_UIAxes.CLimMode = "auto";
            end
            app.updateCLim()
            drawnow
        end
        
        function f = Jfunc(app)
            if app.fftCheckBox.Value
                f = @(x) FFT(x);   % FFT method of the ImageEM class
            else
                f = @(x) x;
            end
            
        end
        
        function updateCLim(app)
            if ~app.holdCheckBox.Value
                app.J_UIAxes.CLimMode = 'Auto';
                app.maxEditField.Value = app.J_UIAxes.CLim(2);
            else
                app.J_UIAxes.CLim(2) = app.maxEditField.Value;
            end
        end
        
        function updateEquation(app)
            D = app.D_outSlider.Value;
            theta_QWP = app.thetaQWP_outSlider.Value;
            theta_P = app.thetaP_outSlider.Value;
            if app.fftCheckBox.Value
                fstr = "FFT";
            else
                fstr = "";
            end
            str = ["Eout = "+fstr+"(J("+app.jim+")*["+app.Ein(1)+"; "+app.Ein(2)+"]);";
                  "Eana = Eout.Jones('QWP',"+ ...
                                     app.thetaQWP_outSlider.Value+ ...
                                     ",'J',{[1 0; 0 "+ ...
                                     1-app.D_outSlider.Value+"], "+ ...
                                     app.thetaP_outSlider.Value+"});";
                  "figure, imagegb(log(abs(Eana.E2)))"];
            app.equationTextArea.Value = str;
        end
    end

    methods % Get methods

        function val = get.Ein(app)
            theta = app.theta_inSlider.Value;
            h = 2*(string(app.h_inSwitch.Value) == "Right")-1;
            e = app.ecc_inSlider.Value;
            Einx = cosd(theta) - 1i*h*sind(theta)*sqrt(1-e^2);
            Einy = sind(theta) + 1i*h*cosd(theta)*sqrt(1-e^2);
            val = [Einx; Einy];
        end

        function val = get.Eout(app)
            f = app.Jfunc;
            val = f( app.J(app.jim)*app.Ein );
        end

        function val = get.Eana(app)
%            val = app.Eout.Jones('QWP',app.thetaQWP_outSlider.Value,'P',app.thetaP_outSlider.Value);
            val = app.Eout.Jones('QWP',app.thetaQWP_outSlider.Value,'J',{[1 0; 0 1-app.D_outSlider.Value], app.thetaP_outSlider.Value});
        end

        function val = get.Nim(app)
            val = numel(app.J);
        end

        function val = get.jim(app)
            val = app.jim_EditField.Value;
        end
    end

    methods(Static)
        function plotEllipse(ax, e , theta, col)
            a = 1;             % demi-grand axe (échelle)
            b = a*sqrt(1 - e^2);
            
            t = linspace(0, 2*pi, 200);
            
            % ellipse non tournée
            x0 = a*cos(t);
            y0 = b*sin(t);
            
            % rotation
            R = [cosd(theta) -sind(theta);
                 sind(theta)  cosd(theta)];
            
            xy = R * [x0; y0];
            plot(ax, xy(1,:), xy(2,:), 'LineWidth', 3, 'Color', col) 

            drawnow
        end

        function val = R(theta)
            val = [cosd(theta) -sind(theta) ; sind(theta) cosd(theta)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, J)
            arguments
                app
                J JonesMatrix  = JonesMatrix([1 1i; -1i 1])
            end
            app.theta_inSlider.MajorTicks = app.AllowedAngles;
            app.thetaP_outSlider.MajorTicks = app.AllowedAngles;
            app.theta_inSlider.MajorTicks = app.AllowedAngles;
            app.thetaP_outSlider.MajorTicks = app.AllowedAngles;
            app.thetaQWP_outSlider.MajorTicks = app.AllowedAngles;

            app.J = J;
            app.Nim_EditField.Value = app.Nim;
            imagesc(app.J_UIAxes,zeros(J(1).Ny, J(1).Nx));
            xlim(app.J_UIAxes,[1,J(1).Nx])
            ylim(app.J_UIAxes,[1,J(1).Ny])
            colorbar(app.J_UIAxes,'Color',"w")
            app.J_UIAxes.CLim(1) = 0;

            app.updateEllipse('Ein')
            app.updateEllipse('Analyzer')

        end

        % Value changed function: D_outSlider, ecc_inSlider, h_inSwitch
        function eccSlidersValueChanging(app, event)
            v = event.Source.Value;
            v = round(v*10)/10;
            event.Source.Value = v;
            
            app.updateEllipse(event.Source.Tag)
        end

        % Value changed function: thetaP_outSlider, thetaQWP_outSlider, 
        % ...and 1 other component
        function thetaSliderValueChanged(app, event)
            v0 = event.Source.Value;
            [~, idx] = min(abs(app.AllowedAngles - v0));
            v = app.AllowedAngles(idx);
            
            if v ~= v0
                event.Source.Value = v;
            end
                    
            app.updateEllipse(event.Source.Tag)
        end

        % Value changed function: jim_EditField
        function jim_EditFieldValueChanged(app, event)
            value = app.jim_EditField.Value;
            app.jim_EditField.Value = min([value,app.Nim]);
            app.updateEanaImage()
        end

        % Callback function: NextButton, PreviousButton, fftCheckBox
        function moveButtonPushed(app, event)
            switch event.Source.Tag
                case 'Next'
                    app.jim_EditField.Value = min(app.jim_EditField.Value + 1,app.Nim);
                case 'Previous'
                    app.jim_EditField.Value = max(app.jim_EditField.Value - 1,1);
            end   
            app.updateEanaImage()
        end

        % Value changed function: maxEditField
        function climValueChanged(app, event)
            app.holdCheckBox.Value = 1;
            app.updateCLim()
            app.updateEanaImage();
            
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            app.updateEquation()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_Ein = uigridlayout(app.GridLayout);
            app.EinUIAxes = uiaxes(app.GridLayout_Ein);
            app.EinUIAxes.XLim = [-1 1];
            app.EinUIAxes.YLim = [-1 1];
            app.EinUIAxes.XAxisLocation = 'origin';
            app.EinUIAxes.XTick = [-1 -0.5 0 0.5 1];
            app.EinUIAxes.XTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.EinUIAxes.YAxisLocation = 'origin';
            app.EinUIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.EinUIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.Label = uilabel(app.GridLayout_Ein);
            app.Label.Text = 'θ';
            app.theta_inSlider = uislider(app.GridLayout_Ein);
            app.theta_inSlider.Limits = [0 180];
            app.theta_inSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180];
            app.theta_inSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.theta_inSlider.MinorTicks = [];
            app.theta_inSlider.Tag = 'Ein';
            app.theta_inSlider.Tooltip = {'Rotation angle'};
            app.epsilonLabel = uilabel(app.GridLayout_Ein);
            app.epsilonLabel.Text = 'ε';
            app.ecc_inSlider = uislider(app.GridLayout_Ein);
            app.ecc_inSlider.Limits = [0 1];
            app.ecc_inSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ecc_inSlider.ValueChangedFcn = createCallbackFcn(app, @eccSlidersValueChanging, true);
            app.ecc_inSlider.MinorTicks = [];
            app.ecc_inSlider.Tag = 'Ein';
            app.ecc_inSlider.Tooltip = {'Eccentricity'};
            app.EinLabel = uilabel(app.GridLayout_Ein);
            app.EinLabel.Text = 'Incoming E field';
            app.GridLayout_hin = uigridlayout(app.GridLayout_Ein);
            app.h_inSwitch = uiswitch(app.GridLayout_hin, 'slider');
            app.h_inSwitch.Items = {'Left', 'Right'};
            app.h_inSwitch.ValueChangedFcn = createCallbackFcn(app, @eccSlidersValueChanging, true);
            app.h_inSwitch.Tag = 'Ein';
            app.h_inSwitch.Tooltip = {'Handedness'};
            app.h_inSwitch.Value = 'Left';
            app.hSwitchLabel = uilabel(app.GridLayout_hin);
            app.hSwitchLabel.Text = 'h';
            app.GridLayout_Analyzer = uigridlayout(app.GridLayout);
            app.AnalyzerUIAxes = uiaxes(app.GridLayout_Analyzer);
            app.AnalyzerUIAxes.XLim = [-1 1];
            app.AnalyzerUIAxes.YLim = [-1 1];
            app.AnalyzerUIAxes.XAxisLocation = 'origin';
            app.AnalyzerUIAxes.XTick = [-1 -0.5 0 0.5 1];
            app.AnalyzerUIAxes.XTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.AnalyzerUIAxes.YAxisLocation = 'origin';
            app.AnalyzerUIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.AnalyzerUIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.Label_3 = uilabel(app.GridLayout_Analyzer);
            app.Label_3.Text = 'θ_P';
            app.thetaP_outSlider = uislider(app.GridLayout_Analyzer);
            app.thetaP_outSlider.Limits = [0 180];
            app.thetaP_outSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180];
            app.thetaP_outSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.thetaP_outSlider.MinorTicks = [];
            app.thetaP_outSlider.Tag = 'Analyzer';
            app.thetaP_outSlider.Tooltip = {'Rotation angle'};
            app.Label_2 = uilabel(app.GridLayout_Analyzer);
            app.Label_2.Text = 'D';
            app.D_outSlider = uislider(app.GridLayout_Analyzer);
            app.D_outSlider.Limits = [0 1];
            app.D_outSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.D_outSlider.ValueChangedFcn = createCallbackFcn(app, @eccSlidersValueChanging, true);
            app.D_outSlider.MinorTicks = [];
            app.D_outSlider.Tag = 'Analyzer';
            app.D_outSlider.Tooltip = {'Diattenuation'};
            app.AnalyzerLabel = uilabel(app.GridLayout_Analyzer);
            app.AnalyzerLabel.Text = 'Analyzer';
            app.Label_4 = uilabel(app.GridLayout_Analyzer);
            app.Label_4.Text = 'θ_QWP';
            app.thetaQWP_outSlider = uislider(app.GridLayout_Analyzer);
            app.thetaQWP_outSlider.Limits = [0 180];
            app.thetaQWP_outSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180];
            app.thetaQWP_outSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.thetaQWP_outSlider.MinorTicks = [];
            app.thetaQWP_outSlider.Tag = 'Analyzer';
            app.thetaQWP_outSlider.Tooltip = {'Birefringence'};
            app.GridLayout5 = uigridlayout(app.GridLayout_Analyzer);
            app.generateButton = uibutton(app.GridLayout5, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.equationTextArea = uitextarea(app.GridLayout5);
            app.GridLayout_Analyzer_2 = uigridlayout(app.GridLayout);
            app.J_UIAxes = uiaxes(app.GridLayout_Analyzer_2);
            app.J_UIAxes.XTick = [];
            app.J_UIAxes.YTick = [];
            app.J_UIAxes.ZTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout_Analyzer_2);
            app.fftCheckBox = uicheckbox(app.GridLayout2);
            app.fftCheckBox.ValueChangedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.fftCheckBox.Text = 'fft';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.maxEditFieldLabel = uilabel(app.GridLayout4);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout4, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @climValueChanged, true);
            app.holdCheckBox = uicheckbox(app.GridLayout4);
            app.holdCheckBox.Text = 'hold';
            app.GridLayout3 = uigridlayout(app.GridLayout_Analyzer_2);
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.NextButton.Tag = 'Next';
            app.NextButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'rightArrow.svg');
            app.NextButton.Text = '';
            app.PreviousButton = uibutton(app.GridLayout3, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.PreviousButton.Tag = 'Previous';
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'leftArrow.svg');
            app.PreviousButton.Text = '';
            app.jim_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.jim_EditField.Limits = [0 Inf];
            app.jim_EditField.RoundFractionalValues = 'on';
            app.jim_EditField.ValueDisplayFormat = '%d';
            app.jim_EditField.ValueChangedFcn = createCallbackFcn(app, @jim_EditFieldValueChanged, true);
            app.jim_EditField.Value = 1;
            app.Nim_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Nim_EditField.Limits = [0 Inf];
            app.Nim_EditField.RoundFractionalValues = 'on';
            app.Nim_EditField.ValueDisplayFormat = '%d';
            app.Nim_EditField.Editable = 'off';
            app.Nim_EditField.Value = 1;
            app.Label_slash = uilabel(app.GridLayout3);
            app.Label_slash.Text = '/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JonesMatrixMultiplier(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end