classdef PhaseLABgui_multiCanal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        restorePushTool                matlab.ui.container.toolbar.PushTool
        saveImagePushTool              matlab.ui.container.toolbar.PushTool
        saveAllImagesPushTool          matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        spacerPushTool                 matlab.ui.container.toolbar.PushTool
        mirrorVPushTool                matlab.ui.container.toolbar.PushTool
        mirrorHPushTool                matlab.ui.container.toolbar.PushTool
        rot90PushTool                  matlab.ui.container.toolbar.PushTool
        offsetPushTool                 matlab.ui.container.toolbar.PushTool
        cropPushTool                   matlab.ui.container.toolbar.PushTool
        flattenPushTool                matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        filterPushTool                 matlab.ui.container.toolbar.PushTool
        binningPushTool                matlab.ui.container.toolbar.PushTool
        spacerPushTool2                matlab.ui.container.toolbar.PushTool
        pixelPushTool                  matlab.ui.container.toolbar.PushTool
        distancePushTool               matlab.ui.container.toolbar.PushTool
        areaPushTool                   matlab.ui.container.toolbar.PushTool
        crosscutPushTool               matlab.ui.container.toolbar.PushTool
        HVcrosscutPushTool             matlab.ui.container.toolbar.PushTool
        radialCrosscutPushTool         matlab.ui.container.toolbar.PushTool
        roiPushTool                    matlab.ui.container.toolbar.PushTool
        MWPushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        saveDataPushTool               matlab.ui.container.toolbar.PushTool
        exportImagePushTool            matlab.ui.container.toolbar.PushTool
        exportMoviePushTool            matlab.ui.container.toolbar.PushTool
        MainGridLayout                 matlab.ui.container.GridLayout
        LeftToolsGridLayout            matlab.ui.container.GridLayout
        DrymassdensityCheckBox         matlab.ui.control.CheckBox
        holdClimLeftCheckBox           matlab.ui.control.CheckBox
        maxLeftEditField               matlab.ui.control.NumericEditField
        minLeftEditField               matlab.ui.control.NumericEditField
        invertCheckBoxLeft             matlab.ui.control.CheckBox
        colormapDropDownLeft           matlab.ui.control.DropDown
        colormapDropDown_3Label        matlab.ui.control.Label
        resetSlidersLeft               matlab.ui.control.Button
        Image5                         matlab.ui.control.Image
        GridLayout2                    matlab.ui.container.GridLayout
        clearButton                    matlab.ui.control.Image
        copyButton                     matlab.ui.control.Image
        messageArea                    matlab.ui.control.TextArea
        RenderingGridLayout            matlab.ui.container.GridLayout
        ChannelButtonGroup             matlab.ui.container.ButtonGroup
        Channel8Button                 matlab.ui.control.RadioButton
        Channel7Button                 matlab.ui.control.RadioButton
        Channel6Button                 matlab.ui.control.RadioButton
        Channel5Button                 matlab.ui.control.RadioButton
        Channel1Button                 matlab.ui.control.RadioButton
        Channel2Button                 matlab.ui.control.RadioButton
        Channel3Button                 matlab.ui.control.RadioButton
        Channel4Button                 matlab.ui.control.RadioButton
        scaleButton                    matlab.ui.container.ButtonGroup
        scaleButtonpx                  matlab.ui.control.RadioButton
        scaleButtonum                  matlab.ui.control.RadioButton
        displayButton                  matlab.ui.container.ButtonGroup
        displayButtonStandard          matlab.ui.control.RadioButton
        displayButton3D                matlab.ui.control.RadioButton
        RightToolsGridLayout           matlab.ui.container.GridLayout
        holdClimRightCheckBox          matlab.ui.control.CheckBox
        maxRightEditField              matlab.ui.control.NumericEditField
        minRightEditField              matlab.ui.control.NumericEditField
        invertCheckBoxRight            matlab.ui.control.CheckBox
        resetSlidersRight              matlab.ui.control.Button
        colormapDropDownRight          matlab.ui.control.DropDown
        colormapDropDown_4Label        matlab.ui.control.Label
        ControlsGridLayout             matlab.ui.container.GridLayout
        commentEditField               matlab.ui.control.EditField
        commentEditFieldLabel          matlab.ui.control.Label
        NimText                        matlab.ui.control.NumericEditField
        jText                          matlab.ui.control.NumericEditField
        nextImageButton                matlab.ui.control.Image
        reload                         matlab.ui.control.Image
        prevImageButton                matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        propertiesTab                  matlab.ui.container.Tab
        propertiesGridLayout           matlab.ui.container.GridLayout
        Warning                        matlab.ui.control.Label
        scalebarOPDCheckBox            matlab.ui.control.CheckBox
        scalebarTCheckBox              matlab.ui.control.CheckBox
        ItfFileName                    matlab.ui.control.Label
        comment                        matlab.ui.control.Label
        panelCGM                       matlab.ui.container.Panel
        CGMsystemGridLayout            matlab.ui.container.GridLayout
        gratingDistanceLabel           matlab.ui.control.Label
        gratingdistanceEditField       matlab.ui.control.NumericEditField
        gratingdistanceEditFieldLabel  matlab.ui.control.Label
        zetafactorEditField            matlab.ui.control.NumericEditField
        zetaLabel                      matlab.ui.control.Label
        CGMsystemLabel                 matlab.ui.control.Label
        CGMcameraName                  matlab.ui.control.EditField
        CGMcameraEditFieldLabel        matlab.ui.control.Label
        grexelsizeEditField            matlab.ui.control.NumericEditField
        grexelsizeEditFieldLabel       matlab.ui.control.Label
        zoomEditField                  matlab.ui.control.NumericEditField
        zoomEditFieldLabel             matlab.ui.control.Label
        dexelsizeEditField             matlab.ui.control.NumericEditField
        dexelsizeEditFieldLabel        matlab.ui.control.Label
        grexelsizeLabel                matlab.ui.control.Label
        zoomLabel                      matlab.ui.control.Label
        dexelsizeLabel                 matlab.ui.control.Label
        TLfocallengthEditField         matlab.ui.control.NumericEditField
        TLfocallengthEditFieldLabel    matlab.ui.control.Label
        numericalapertureEditField     matlab.ui.control.NumericEditField
        numericalapertureEditFieldLabel  matlab.ui.control.Label
        magnificationEditField         matlab.ui.control.NumericEditField
        magnificationEditField_3Label  matlab.ui.control.Label
        pixelsizeEditField             matlab.ui.control.NumericEditField
        pixelsizeEditFieldLabel        matlab.ui.control.Label
        wavelengthEditField            matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel       matlab.ui.control.Label
        TLfocallengthLabel             matlab.ui.control.Label
        MICROSCOPELabel                matlab.ui.control.Label
        magnificationLabel             matlab.ui.control.Label
        wavelengthLabel                matlab.ui.control.Label
        pixelsizeLabel                 matlab.ui.control.Label
        processingTab                  matlab.ui.container.Tab
        processingGridMLayout          matlab.ui.container.GridLayout
        normTbutton                    matlab.ui.control.Button
        widthCropUnitLabel_2           matlab.ui.control.Label
        marginEditField                matlab.ui.control.NumericEditField
        marginEditFieldLabel           matlab.ui.control.Label
        GridLayout_paramsFlatten       matlab.ui.container.GridLayout
        ManualCheckBox                 matlab.ui.control.CheckBox
        GridLayout3                    matlab.ui.container.GridLayout
        maskTypeDropDown               matlab.ui.control.DropDown
        flattenInvertCheckBox          matlab.ui.control.CheckBox
        flattenCutEditField            matlab.ui.control.NumericEditField
        GradThreshLabel_3              matlab.ui.control.Label
        n_FlattenOrderEditField        matlab.ui.control.NumericEditField
        namxLabel                      matlab.ui.control.Label
        m_FlattenOrderEditField        matlab.ui.control.NumericEditField
        flattenThresholdEditField      matlab.ui.control.NumericEditField
        GradThreshLabel                matlab.ui.control.Label
        flattenSmoothingEditField      matlab.ui.control.NumericEditField
        GradThreshLabel_2              matlab.ui.control.Label
        SetparametersButton            matlab.ui.control.Button
        savewsEditField                matlab.ui.control.EditField
        savewsButton                   matlab.ui.control.Button
        untiltButton                   matlab.ui.control.Button
        duplicateImageButton           matlab.ui.control.Button
        refocusingBackwardButton       matlab.ui.control.Image
        refocusingForwardButton        matlab.ui.control.Image
        refocusingEditField            matlab.ui.control.NumericEditField
        REFOCUSINGLabel                matlab.ui.control.Label
        offsetMethodDropDown           matlab.ui.control.DropDown
        offsetButton                   matlab.ui.control.Button
        filterButton                   matlab.ui.control.Button
        flattenButton                  matlab.ui.control.Button
        binningSpinner                 matlab.ui.control.Spinner
        binningButton                  matlab.ui.control.Button
        rot90Button                    matlab.ui.control.Button
        mirrorVButton                  matlab.ui.control.Button
        mirrorHButton                  matlab.ui.control.Button
        OFFSETLabel                    matlab.ui.control.Label
        BINNINGLabel                   matlab.ui.control.Label
        filterCutoffUnitLabel          matlab.ui.control.Label
        filterCutoffEditField          matlab.ui.control.NumericEditField
        cutoffLabel                    matlab.ui.control.Label
        filterMethodDropDown           matlab.ui.control.DropDown
        FILTERLabel                    matlab.ui.control.Label
        modifyWorkspaceCheckBox        matlab.ui.control.CheckBox
        restoreAllImagesButton         matlab.ui.control.Button
        restoreImageButton             matlab.ui.control.Button
        saveAllImagesButton            matlab.ui.control.Button
        saveImageButton                matlab.ui.control.Button
        flattenMethodDropDown          matlab.ui.control.DropDown
        heightCropUnitLabel            matlab.ui.control.Label
        widthCropUnitLabel             matlab.ui.control.Label
        heightEditField                matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        cropButtonCenterRect           matlab.ui.control.Image
        cropButtonCenterSquare         matlab.ui.control.Image
        cropButton                     matlab.ui.control.Image
        FLATTENLabel                   matlab.ui.control.Label
        CROPLabel                      matlab.ui.control.Label
        MOTIONLabel                    matlab.ui.control.Label
        ApplytoallimagesCheckBox       matlab.ui.control.CheckBox
        metrologyTab                   matlab.ui.container.Tab
        metrologyGridLayout            matlab.ui.container.GridLayout
        saveDataButton                 matlab.ui.control.Button
        saveFileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        saveFolderEditField            matlab.ui.control.EditField
        saveFolderImage                matlab.ui.control.Image
        AutoSaveCheckBox               matlab.ui.control.CheckBox
        SAVEDATALabel                  matlab.ui.control.Label
        DMGridLayout                   matlab.ui.container.GridLayout
        DznmEditField                  matlab.ui.control.NumericEditField
        DznmEditFieldLabel             matlab.ui.control.Label
        gammaLabel                     matlab.ui.control.Label
        gammaEditField                 matlab.ui.control.NumericEditField
        stepEditField                  matlab.ui.control.NumericEditField
        stepEditFieldLabel             matlab.ui.control.Label
        bkgRingLabel                   matlab.ui.control.Label
        bkgRingEditField               matlab.ui.control.NumericEditField
        NmaxEditField                  matlab.ui.control.NumericEditField
        NmaxEditFieldLabel             matlab.ui.control.Label
        DMmagicwandButton              matlab.ui.control.Button
        DMroiButton                    matlab.ui.control.Button
        DMradialButton                 matlab.ui.control.Button
        alphaOVDMLabel                 matlab.ui.control.Label
        crosscutPathButton             matlab.ui.control.Button
        CROSSCUTSLabel                 matlab.ui.control.Label
        METROLOGYLabel                 matlab.ui.control.Label
        crosscutRadial                 matlab.ui.control.Button
        crosscutsHVButton              matlab.ui.control.Button
        areaButton                     matlab.ui.control.Button
        distanceButton                 matlab.ui.control.Button
        pixelButton                    matlab.ui.control.Button
        exportTab                      matlab.ui.container.Tab
        exportGridLayout               matlab.ui.container.GridLayout
        timeUnitEditField              matlab.ui.control.EditField
        timeSize                       matlab.ui.control.NumericEditField
        imtimeLabel_2                  matlab.ui.control.Label
        navigEditField                 matlab.ui.control.EditField
        navigButton                    matlab.ui.control.Button
        colorButton                    matlab.ui.control.Button
        polarButton                    matlab.ui.control.Button
        channelsButton                 matlab.ui.control.Button
        DISPLAYIMAGELabel              matlab.ui.control.Label
        timeEditField                  matlab.ui.control.NumericEditField
        imtimeLabel                    matlab.ui.control.Label
        export_figureCheckBox          matlab.ui.control.CheckBox
        export_matCheckBox             matlab.ui.control.CheckBox
        overviewButton                 matlab.ui.control.Button
        perspCheckBox                  matlab.ui.control.CheckBox
        HzLabel                        matlab.ui.control.Label
        titleVideoEditField            matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        cblabelEditField               matlab.ui.control.EditField
        cblabelEditFieldLabel          matlab.ui.control.Label
        TCheckBox                      matlab.ui.control.CheckBox
        OPDCheckBox                    matlab.ui.control.CheckBox
        cbfactorEditField              matlab.ui.control.NumericEditField
        cbfactorEditFieldLabel         matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        phiEditField                   matlab.ui.control.NumericEditField
        phiEditFieldLabel              matlab.ui.control.Label
        rateVideoEditField             matlab.ui.control.NumericEditField
        rateEditFieldLabel             matlab.ui.control.Label
        exportMovieButton              matlab.ui.control.Button
        EXPORTVIDEOLabel               matlab.ui.control.Label
        exportSuffixLabel              matlab.ui.control.Label
        export_pngCheckBox             matlab.ui.control.CheckBox
        exportSelectionRadioButtonGroup  matlab.ui.container.ButtonGroup
        exportSelectionButton          matlab.ui.control.RadioButton
        exportAllButton                matlab.ui.control.RadioButton
        exportCurrentButton            matlab.ui.control.RadioButton
        export_epsCheckBox             matlab.ui.control.CheckBox
        export_txtCheckBox             matlab.ui.control.CheckBox
        exportFolderImage              matlab.ui.control.Image
        EXPORTIMAGELabel               matlab.ui.control.Label
        exportFileNameEditField        matlab.ui.control.EditField
        FileNameEditFieldLabel_2       matlab.ui.control.Label
        exportFolderEditField          matlab.ui.control.EditField
        exportImageButton              matlab.ui.control.Button
        AxesLeft                       matlab.ui.control.UIAxes
        AxesRight                      matlab.ui.control.UIAxes
    end


    properties (Access =  public)
        jim {mustBeInteger,mustBePositive} = 1 % # of the image that is currently displayed
        cbLeft  matlab.graphics.illustration.ColorBar % left panel colorbar
        cbRight matlab.graphics.illustration.ColorBar % right panel colorbar
        roiIN cell % roi object that are saved when using the MW tool
        roiOUT cell % roi object that are saved when using the MW tool
        savedData struct  % Table that collects the last measurments, saveData.table, saveData.sheetName
    end
    
    properties(Dependent)
        scale   % 'px' or 'µm'
        pxSize  % pixel size of the current displayed image
        saveFile % File name to save data
        saveFolder % Folder name to save data
        saveFolderFile % folder and file names concantenated
        autoSave % true or false to write data in a file automatically
        Nim  % Number of images in IM
        Nch  % Maximum number of channels
    end

    properties %(Access = private)
        IM  (:,:) {mustBeA(IM, ["ImageMethods", "Interfero"])}  = ImageMethods() % images to be displayed, possibly postprocessed
        IM0 (:,:) {mustBeA(IM0, {'ImageMethods', 'Interfero'})}  = ImageMethods() % original images, unmodified, unless save button is pressed.
        iCh = 1    % numero of the active Channel
        imageFun   %imagesc or image3D
        invColorMapLeft  logical = false % invert the left colomap
        invColorMapRight logical = false % invert theright colomap
        timeUnitFactor = 1   % multiplication factor for the conversion of the time of the movie
    end

    properties(Access = private)
        scalebar
        manualFlattenMask
    end
    properties (Access = private, Dependent)
        imType % 'ImageQLSI' or 'ImageEM' or 'none'
    end

    properties (Dependent)
        ImageLeft % image object of the left panel
        ImageRight % image object of the right panel
        LightLeft % Light object of the left panel when in 3D rendering mode
        LightRight % light object of the right panel when in 3D rendering mode
    end
    properties(SetAccess = private, GetAccess = public)
        im1type char {mustBeMember(im1type,{'','OPDnm','T','DWx','DWy','Itf','RefIm','dmd'})}
        im2type char {mustBeMember(im2type,{'','OPDnm','T','DWx','DWy','Itf','RefIm','dmd'})}
    end

    properties(Constant)
        warnColor = [1, 0.5, 0.2];
    end

    methods

        %         function set.IM(app,val)
        %             app.IM=val;
        %             app.updateImages();
        %             drawnow
        %         end

        function updateImages(app)

            %% TODO : make this app also usable for interfero objects
            app.disableImageControls()
            IMc = app.IMcurrent();

            app.toggleSaveButtons()

            pxum = app.scaleButton.SelectedObject.Text; % 'px' or 'µm'
            if strcmpi(pxum,'px')
                fac = 1;
            elseif strcmpi(pxum,'µm')
                fac = IMc.pxSize*1e6;
            end

            % define the proper imaging function @imagesc or @image3D
            imSize = [IMc.Ny, IMc.Nx];

            if strcmpi(app.displayButton.SelectedObject.Text,'standard')
                app.imageFun = @imagesc;
            elseif strcmpi(app.displayButton.SelectedObject.Text,'3D')
                app.imageFun = @image3D;
            end

            % display the image number in the text box
            app.jText.Value = app.jim;
            app.NimText.Value = size(app.IM,1);

            % affiche les 2 images, et définit leur colormap
            %            app.imageFun(app.AxesLeft, [1 imSize(2)]*fac,[1 imSize(1)]*fac,IMc.OPDnm)
            app.imageFun(app.AxesLeft, (1:imSize(2))*fac,(1:imSize(1))*fac,IMc.(app.im1type))
            app.AxesLeft.YDir = 'normal';
            colormapDropDownLeftValueChanged(app)

            app.imageFun(app.AxesRight, (1:imSize(2))*fac,(1:imSize(1))*fac, IMc.(app.im2type))
            app.AxesRight.YDir = 'normal';
            colormapDropDownRightValueChanged(app)

            app.commentEditField.Value = IMc.comment;

            % update/reset the limits
            app.resetLimitsEditField()
            % redimension at the original size, in px coordinates
            xlim(app.AxesLeft,[1 imSize(2)]*fac);
            ylim(app.AxesLeft,[1 imSize(1)]*fac);
            xlim(app.AxesRight,[1 imSize(2)]*fac);
            ylim(app.AxesRight,[1 imSize(1)]*fac);
            xlabel(app.AxesRight,pxum)
            ylabel(app.AxesRight,' ')
            xlabel(app.AxesLeft,pxum)
            ylabel(app.AxesLeft,' ')
            drawnow

            % make Current Axes BoundAxes . TODO: understand why the function makeCurrentAxesBoundAxes does not work here (it freezes the home zoom button)
            zoom(app.AxesLeft,'reset')
            zoom(app.AxesRight,'reset')
            drawnow
            % Lie les axes seulement en xy
            linkaxes([app.AxesLeft,app.AxesRight],'xy')


            % Ajusts the DataAspactRatio to [1 1 x] where x is calculated properly
            adjustdAspect(app,'all')

            % update light position
            app.setLightPositions()


            % update properties tab
            if app.imType == "ImageMethods"
                app.wavelengthEditField.Value   = IMc.Illumination.lambda*1e9;
            end
            if ~isempty(IMc.Microscope)
                app.pixelsizeEditField.Value        = IMc.pxSize*1e9;
                app.magnificationEditField.Value    = abs(IMc.Microscope.M);
                app.numericalapertureEditField.Value= IMc.Microscope.Objective.NA;
                app.TLfocallengthEditField.Value    = IMc.Microscope.f_TL;
                app.commentEditField.Value = IMc.comment;
            end            
            app.comment.Text                    = IMc.comment;
            if strcmp(app.imType,'ImageQLSI')
                app.grexelsizeEditField.Value = abs(IMc.Microscope.CGcam.CG.Gamma*1e6);
                app.dexelsizeEditField.Value = abs(IMc.Microscope.CGcam.Camera.dxSize*1e6);
                app.zoomEditField.Value = abs(IMc.Microscope.CGcam.zoom);
                app.CGMcameraName.Value    = IMc.Microscope.CGcam.fileName;
                app.zetafactorEditField.Value             = IMc.Microscope.CGcam.zeta;
                app.gratingdistanceEditField.Value = IMc.Microscope.CGcam.CGpos*1e3;
                app.ItfFileName.Text = IMc.ItfFileName;
            end

            % creates and initialize the 'matlab_graphics_resetplotview' structure
            zoom(app.AxesRight,'out')
            zoom(app.AxesLeft,'out')

            set (app.UIFigure, 'WindowButtonMotionFcn','') % In case a function was interrupted, such as a crop function
            updateScalebars(app)
            
            drawnow
            app.enableImageControls();

            app.enableImageControls()


        end


        function val = get.scale(app)
            val = app.scaleButton.SelectedObject.Text;
        end

        function val = get.saveFile(app)
            file = app.saveFileNameEditField.Value;
            if isempty(file)
                val = 'data.txt';
            else
                val = file;
            end
        end

        function val = get.pxSize(app)
            IMc = app.IMcurrent(app.jim,'WriteMode','r');
            val = IMc.pxSize;
        end

        function val = get.saveFolder(app)
            saveFold = app.saveFolderEditField.Value;
            if isempty(saveFold)
                val = pwd;
            else
                val = saveFold;
            end
        end

        function val = get.saveFolderFile(app)
            val = [app.saveFolder '/' app.saveFile];
        end

        function val = get.autoSave(app)
            val = app.AutoSaveCheckBox.Value;
        end

        function val = get.Nim(app)
            val = numel(app.IM0(:,1));
        end

        function val = get.ImageLeft(app)
            % give a name to the image object contained in the left Axes object
            val = app.AxesLeft.Children(end);
        end

        function val = get.ImageRight(app)
            % give a name to the image object contained in the right Axes object
            val = app.AxesRight.Children(end);
        end

        function val = get.LightLeft(app)
            val = findall(app.AxesLeft.Children,'type','Light');
        end

        function val = get.LightRight(app)
            val = findall(app.AxesRight.Children,'type','Light');
        end

        function IMlist = IMcurrent(app,range,opt) % returns IM or IM0
            % IMcurrent()
            % IMcurrent(4)
            % IMcurrent('WriteMode','w')
            % IMcurrent(4,'WriteMode','w')
            arguments
                app
                range = app.jim
                opt.WriteMode (1,1) char {mustBeMember(opt.WriteMode,{'w','r'})} = 'r'
                opt.allChannels logical  = false % returns the Nch channels or not
            end
            if ischar(range)
                if strcmpi(range,'all')
                    range = 1:app.Nim;
                else
                    error('wrong input for range')
                end
            elseif islogical(range)
                numList = 1:app.Nim;
                range = numList(range);
            end
            range = range(:)'; % makes sure it is an horizontal vector to be used in a loop: for ii = range
            No = numel(range);

            if opt.allChannels
                rangeCh = 1:app.Nch;
            else
                rangeCh = app.iCh;
            end

            % initialize the output
            if strcmpi(app.imType,'ImageQLSI')
                IMlist=repmat(ImageQLSI(1), No, length(rangeCh));
            elseif strcmpi(app.imType,'ImageEM')
                IMlist=repmat(ImageEM(n=1), No, length(rangeCh));
            elseif strcmpi(app.imType,'Interfero')
                IMlist=repmat(Interfero(1), No, length(rangeCh));
            else
                app.imType
                error('app.imType not initialized')
            end

            % Determine whether original images app.IM0 are supposed to be
            % mofidied, or only the copy app.IM
            ws = app.modifyWorkspaceCheckBox.Value;

            % Conditions to decide if the method returns IM or IM0.
            % If it returns IM, the modification will be provisional,
            % waiting for the save button to be pushed.
            % We could always return IM, just in case, but when it is only
            % to read a property of the image, it is a pity to duplicate
            % the image all the time:
            m=0;
            for ic = rangeCh
                m=m+1;
                n=0;
                for ii = range
                    n=n+1;
                    if ws % If the workspace is supposed to be modified,
                        IMlist(n,m) = app.IM0(ii,ic); % ... one only works with app.IM0 anyway.
                    elseif is_empty(app.IM(ii,ic)) && strcmpi(opt.WriteMode,'r') % empty IM image, but in read mode,
                        IMlist(n,m) = app.IM0(ii,ic); % ... one can safely return IM0.
                    elseif is_empty(app.IM(ii,ic)) && strcmpi(opt.WriteMode,'w') % empty IM image, but in write mode,
                        app.IM(ii,ic) = Methods.duplicate(app.IM0(ii,ic)); % ... a copy of IM0 is made in IM (and of all its object properties, hence duplicate instead of just copy)
                        IMlist(n,m) = app.IM(ii,ic); % ... and one returns IM.
                    elseif ~is_empty(app.IM(ii,ic)) && strcmpi(opt.WriteMode,'w') % non-empty IM image, and in write mode,
                        IMlist(n,m) = app.IM(ii,ic); % ... and one just returns IM.
                    elseif ~is_empty(app.IM(ii,ic)) && strcmpi(opt.WriteMode,'r') % non-empty IM image, and in read mode,
                        IMlist(n,m) = app.IM(ii,ic); % ... and one just returns IM.
                    end
                end
            end
        end

        function switchQLSIparameters(app,val)
            arguments
                app
                val (1,:) char {mustBeMember(val,{'on','off'})}
            end
            app.panelCGM.Visible = val;
        end

        function val = get.imType(app)
            if is_empty(app.IM0(1))
                val = 'none';
            else
                val = class(app.IM0(1));
            end
        end


        function displayMessage(app,message,opt)
            arguments
                app
                message {mustBeText(message)}
                opt.time = []
                opt.color = [0 0 0]
            end

            app.messageArea.FontColor = opt.color;
            app.messageArea.Value = sprintf(message); % the use of sprintf enable the execution of \n if it is contained in the input
            drawnow
            if ~isempty(opt.time)
                pause(opt.time)
                app.clearMessageArea()
            end
            drawnow
        end

        function clearMessageArea(app)
            app.messageArea.Value = ' ';
            app.messageArea.FontColor = [0, 0, 0];
            drawnow
        end


    end

    methods (Access = private)

        function warn(app,message)
            % function that display
            app.displayMessage(message,'color',app.warnColor,'time',0.5)
        end

        function resetLimitsEditField(app,which_one)
            arguments
                app
                which_one = 'all'
            end
            IMc = app.IMcurrent('WriteMode','r');
            % saving the min max values of the images
            valLeftMin = min(IMc.(app.im1type)(:));
            valLeftMax = max(IMc.(app.im1type)(:));
            valRightMin = min(IMc.(app.im2type)(:));
            valRightMax = max(IMc.(app.im2type)(:));

            switch which_one

                case 'all'
                    resetLimitsEditField(app,'right')
                    resetLimitsEditField(app,'left')
                case 'left'
                    if ~app.holdClimLeftCheckBox.Value
                        app.minLeftEditField.Value = round(double(valLeftMin),1);
                        app.maxLeftEditField.Value = round(double(valLeftMax),1);
                        %clim(app.AxesLeft,'auto')
                        try
                            clim(app.AxesLeft,[app.minLeftEditField.Value app.maxLeftEditField.Value])
                        catch
                            app.displayMessage("Not conventional values of the OPD image. The axis limits are not adjusted.",'color',[1 0.5 0.2])
                        end
                    end
                case 'right'
                    if ~app.holdClimRightCheckBox.Value
                        app.minRightEditField.Value = round(double(valRightMin),3); % 3 digits in the case of graphene
                        app.maxRightEditField.Value = round(double(valRightMax),3);
                        %clim(app.AxesRight,'auto')
                        clims = [app.minRightEditField.Value app.maxRightEditField.Value];
                        if clims(1) == clims(2) % if the T image is flat, like with a theoretical OPD Gaussian profile
                            clims = clims(1)+[-0.01 0.01];
                        end
                        clim(app.AxesRight,clims)
                    end
            end

        end

        function val = DX_imLeft(app)
            % width of the image
            val = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
        end
        function val = DY_imLeft(app)
            % height of the image
            val = max(app.ImageLeft.YData) - min(app.ImageLeft.YData);
        end
        function val = DZ_imLeft(app)
            % thickness of the image
            val = max(app.ImageLeft.CData(:)) - min(app.ImageLeft.CData(:));
        end
        function val = DX_imRight(app)
            % width of the image
            val = max(app.ImageRight.XData) - min(app.ImageRight.XData);
        end
        function val = DY_imRight(app)
            % height of the image
            val = max(app.ImageRight.YData) - min(app.ImageRight.YData);
        end
        function val = DZ_imRight(app)
            % thickness of the image
            val = max(app.ImageRight.CData(:)) - min(app.ImageRight.CData(:));
        end

        function setLightPositions(app)
            % updates the position of the light in case the image mode is
            % 3D. Useful when changing the scale of the image, for instance.
            if isequal(app.imageFun,@image3D)
                amplX = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);
                %amplZ = max(app.ImageLeft.XData) - min(app.ImageLeft.XData);


                % TODO : play with daspect([factor factor DZ/10])
                % to improve the 3Dd visualizatin of the T image

                lightLeft = findall(app.AxesLeft.Children,'type','Light');
                lightRight = findall(app.AxesRight.Children,'type','Light');
                lightLeft.Position  = [370*amplX/100 -250*amplX/100 lightLeft.Position(3)];
                lightRight.Position = [370*amplX/100 -250*amplX/100 lightRight.Position(3)];
            end
        end

        function clearSavedData(app) % erase the stored data
            app.savedData = struct([]);
        end

        function hout = displayDualImage(app)
            h = figure;
            if app.TCheckBox.Value
                h.Position(3:4) = [850,410];
            else
                h.Position(3:4) = [440,410];
            end
            h.Color = [1 1 1];
            h.ToolBar = "none";

            axLeft = copy(app.AxesLeft);
            axLeft.Parent=h;
            axLeft.Position = [10,10,400,400];
            cb=colorbar();
            cb.Label.String = app.cbLeft.Label.String;


            if app.TCheckBox.Value

                axRight = copy(app.AxesRight);
                axRight.Parent = h;
                axRight.Position = [410,10,400,400];
                colorbar(axRight)
            end
            if nargin
                hout = h;
            end

        end

        function moveToImage(app, num)
            if num > app.Nim
                app.warn("end of the image stack")
            elseif num < 1
                app.warn("end of the image stack")
            else
                app.jim=num;
            end
            app.updateImages()

        end


        function range = saveImageRange(app)
            selectType = app.exportSelectionRadioButtonGroup.SelectedObject.Text;
            switch selectType
                case 'current'
                    range = app.jim;
                case 'all'
                    range = 1:app.Nim;
                case 'selection'
                    app_IS = imageSelector();
                    while isempty(app_IS.range)
                        pause(0.4)
                    end
                    range = app_IS.range;
                    app_IS.delete
            end

        end

        function toggleSaveButtons(app)
            if is_empty(app.IM(app.jim,app.iCh))
                app.saveImageButton.Enable = false;
                app.saveImagePushTool.Enable = false;
                app.restoreImageButton.Enable = false;
                app.restorePushTool.Enable = false;
            else
                app.saveImageButton.Enable = true;
                app.saveImagePushTool.Enable = true;
                app.restoreImageButton.Enable = true;
                app.restorePushTool.Enable = true;
            end
            if is_empty(app.IM)
                app.saveAllImagesButton.Enable = false;
                app.saveAllImagesPushTool.Enable = false;
                app.restoreAllImagesButton.Enable = false;
            else
                app.saveAllImagesButton.Enable = true;
                app.saveAllImagesPushTool.Enable = true;
                app.restoreAllImagesButton.Enable = true;
            end

        end


        function disablePanZoom(app)
            pan(app.AxesLeft,'off')
            pan(app.AxesRight,'off')
            zoom(app.AxesLeft,'off')
            zoom(app.AxesRight,'off')
        end

        function bring2front(app)
            drawnow
            figure(app.UIFigure);
        end


        function enableImageControls(app)
            app.prevImageButton.Enable = "on";
            app.nextImageButton.Enable = "on";
        end
        function disableImageControls(app)
            app.prevImageButton.Enable = "off";
            app.nextImageButton.Enable = "off";
        end
        
        function updateScalebars(app)
            IMc = app.IMcurrent();
            if ~isempty(IMc.Microscope)
                app.scalebar = Scalebar(20e-6,IMc.Microscope.pxSize);
                Scalebar.remove(app.AxesLeft);
                Scalebar.remove(app.AxesRight);
    
                % px or µm
                app.scalebar.unit = app.scaleButton.SelectedObject.Text;
    
                if app.scalebarOPDCheckBox.Value
                    app.scalebar.insert(app.AxesLeft)
                else
                    Scalebar.remove(app.AxesLeft);
                end
    
                if app.scalebarTCheckBox.Value
                    app.scalebar.insert(app.AxesRight)
                else
                    Scalebar.remove(app.AxesRight);
                end
            end
             
        end
        
        function enableFlattenParams(app, val)
            arguments
                app
                val logical = true
            end

            app.flattenThresholdEditField.Enable = val;
            app.flattenSmoothingEditField.Enable = val;
            app.flattenCutEditField.Enable = val;
        end
    end
    methods
        function adjustdAspect(app,side)
            arguments
                app
                side char {mustBeMember(side,{'Left','Right','left','right','OPD','T','all'})} = 'all'
            end

            if ismember(side,{'all','Left','left','OPD'})
                try
                    daspect(app.AxesLeft,[1 1 10*app.DZ_imLeft/app.DX_imLeft])
                catch
                    app.displayMessage("Warning: the OPD image values are not conventional. The data aspect ratio was not adjusted.",[1, 0.5, 0.2])
                end

            end
            if ismember(side,{'all','Right','right','T'})
                daspect(app.AxesRight,[1 1 30*app.DZ_imRight/app.DX_imRight])
            end

        end

        function val = DZright(app)
            val = app.DZ_imRight;
        end

        function val = DZleft(app)
            val = app.DZ_imLeft;
        end

        function [xc, yc] = ginputUI(app,n,opt)
            arguments
                app
                n
                opt.outUnit char {mustBeMember(opt.outUnit,{'px','um','auto'})} = 'auto'
            end
            %fhv = app.UIFigure.HandleVisibility;        % Current status
            app.enableHandleVisibility()
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            [xc0, yc0] = ginput(n);
            if nargout==1 || nargout==0
                xc0 = [xc0, yc0];
            end

            switch opt.outUnit
                case 'auto'
                    xc=xc0;
                    yc=yc0;
                case 'px' % desired output in px
                    if strcmpi(app.scale,'px') % and app scale in px
                        xc=round(xc0);
                        yc=round(yc0);
                    else % and app scale in µm
                        xc=app.um2px(xc0);
                        yc=app.um2px(yc0);
                    end
                case {'µm','um'} % desired output in µm
                    if strcmpi(app.scale,'px') % and app scale in px
                        xc=app.px2um(xc0);
                        yc=app.px2um(yc0);
                    else % output desired in µm while measured in px
                        xc=xc0;
                        yc=yc0;
                    end
            end

            app.disableHandleVisibility()
            %app.UIFigure.HandleVisibility = fhv;    % return original state
        end

        function enableHandleVisibility(app)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

        end

        function disableHandleVisibility(app)
            app.UIFigure.HandleVisibility = 'off';

        end


        function makeCurrentAxesBoundAxes(app)
            % Makes the current Axes range in x and y the limiting axes, to
            % be reach when pressing the home button in an Axes.
            %            zoom(app.AxesLeft,'reset')
            %            zoom(app.AxesRight,'reset')
            IMc=app.IMcurrent();
            axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
            if strcmp(app.scaleButton.SelectedObject.Text,'px')
                axesData.XLim=[1 , IMc.Nx];
                axesData.YLim=[1 , IMc.Ny];
            else
                axesData.XLim=[1 , IMc.Nx]*IMc.pxSize*1e6;
                axesData.YLim=[1 , IMc.Ny]*IMc.pxSize*1e6;
            end
            setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
            setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);
        end

        function [vec1, vec2] = um2px(app,vec)
            % convert µm to px. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout <= 1 % only one output asked, or no output
                vec1 = round(vec/app.pxSize*1e-6);
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = round(vec(1)/app.pxSize*1e-6);
                vec2 = round(vec(2)/app.pxSize*1e-6);
            end
        end

        function [vec1, vec2] = um22px(app,vec)
            % convert µm^2 to px.

            if length(vec)>=1 && nargout <= 1 % only one output asked, or no output
                vec1 = round(vec/(app.pxSize)^2*1e-12);
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = round(vec(1)/app.pxSize^2*1e-12);
                vec2 = round(vec(2)/app.pxSize^2*1e-12);
            end
        end

        function [vec1, vec2] = px2um(app,vec)
            % convert px to um. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = vec*app.pxSize*1e6;
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = vec(1)*app.pxSize*1e6;
                vec2 = vec(2)*app.pxSize*1e6;
            end
        end

        function [vec1, vec2] = px2um2(app,vec)
            % convert px to um. Useful when using the crop function, to
            % stay in µm units

            if length(vec)>=1 && nargout == 1 % only one output asked
                vec1 = vec*app.pxSize^2*1e12;
            elseif length(vec) == nargout % ask as many output as the length of the vector
                vec1 = vec(1)*app.pxSize^2*1e12;
                vec2 = vec(2)*app.pxSize^2*1e12;
            end
        end

        function saveData(app,opt)
            arguments
                app
                opt.tech = char.empty()
                opt.unit char {mustBeMember(opt.unit,{'px','um','µm','undef'})} = 'undef'
                opt.position double = double.empty()
                opt.alpha double = double.empty()
                opt.DM double = double.empty()
                opt.OV double = double.empty()
                opt.values (1,2) double = [0 0]% =[OPD, T]
                opt.distance double = double.empty()
                opt.area double = double.empty()
                opt.crosscut double = []
                opt.format char {mustBeMember(opt.format,{'txt','dat','csv','xls'})} = 'xls'
                opt.writeData logical = false % tells whether the data as saved in a file, or just kept in hte variable app.saveTable
            end

            if strcmp(opt.unit,'undef') && any([~isempty(opt.position), ~isempty(opt.area), ~isempty(opt.distance), any(opt.values~=0)])
                error('a unit, px or um, must be specified with a position')
            end

            nRow = 1;
            nCol = 1;
            varType(nCol) = "datetime";
            varNames(nCol) = "date";
            data{nCol} = datetime("now");

            nCol = nCol+1;
            varType(nCol) = "double";
            varNames(nCol) = "image #";
            data{nCol} = app.jim;

            sheetName = opt.tech;
            if ~isempty(opt.position)
                sheetName = [sheetName, '_Position'];

                if numel(opt.position)~=2
                    error('A position must have 2 elements')
                end

                switch opt.unit
                    case 'px'
                        position_px = opt.position;
                        position_um = app.px2um(opt.position);
                    case {'um','µm'}
                        position_px = app.um2px(opt.position);
                        position_um = opt.position;
                end

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "X (px)";
                data{nCol} = position_px(1);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Y (px)";
                data{nCol} = position_px(2);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "X (µm)";
                data{nCol} = position_um(1);

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Y (µm)";
                data{nCol} = position_um(2);
            end
            if ~isempty(opt.OV)
                sheetName = [sheetName, '_OV'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OV (x10^-18 m^3)";
                data{nCol} = opt.OV*1e18;
            end
            if ~isempty(opt.DM)
                sheetName = [sheetName, '_DM'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "DM (pg)";
                data{nCol} = opt.DM*1e12;
            end

            if ~isempty(opt.alpha)
                sheetName = [sheetName, '_alpha'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Re(alpha) (x10^-18)";
                data{nCol} = real(opt.alpha)*1e18;

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "Im(alpha) (x10^-18)";
                data{nCol} = imag(opt.alpha)*1e18;

                nCol=nCol+1;
                varType(nCol) = "string";
                varNames(nCol) = "alpha (x10^-18)";
                data{nCol} = sprintf('=COMPLEXE(%f;%f)',real(opt.alpha)*1e18,imag(opt.alpha)*1e18);
            end
            if any(opt.values~=0)
                sheetName = [sheetName, '_values'];

                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OPD value (nm)";
                data{nCol} = opt.values(1);
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "T value";
                data{nCol} = opt.values(2);
            end
            if ~isempty(opt.distance)
                sheetName = [sheetName, '_distance'];
                switch opt.unit
                    case 'px'
                        d_px = opt.distance;
                        d_um = app.px2um(opt.distance);
                    case {'um','µm'}
                        d_px = app.um2px(opt.distance);
                        d_um = opt.distance;
                end
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "distance (px)";
                data{nCol} = d_px;
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "distance (µm)";
                data{nCol} = d_um;
            end
            if ~isempty(opt.area)
                sheetName = [sheetName, '_area'];
                switch opt.unit
                    case 'px'
                        area_px = opt.area;
                        area_um = app.px2um2(opt.area);
                    case {'um','µm'}
                        area_px = app.um22px(opt.area);
                        area_um = opt.area;
                end
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "area (px)";
                data{nCol} = area_px;
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "area (µm^2)";
                data{nCol} = area_um;
            end
            T = table('Size',[nRow,nCol],'VariableTypes',varType,'VariableNames',varNames);
            T(1,:) = data;

            if ~isempty(opt.crosscut)
                sheetName = ['crosscut image #' num2str(app.jim)];
                nCol=1; % we cancel any previous data because one only wants to store columns
                varType = string.empty();
                varType(nCol) = "double";
                varNames = string.empty();
                varNames(nCol) = strcat("distance (",opt.unit,")");
                data = cell.empty();
                data{nCol} = opt.crosscut(:,1);
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "OPD (nm)";
                data{nCol} = opt.crosscut(:,2);
                nCol=nCol+1;
                varType(nCol) = "double";
                varNames(nCol) = "T";
                data{nCol} = opt.crosscut(:,3);
                nRow = length(data{nCol});
                T = table('Size',[nRow,nCol],'VariableTypes',varType,'VariableNames',varNames);
                T(:,:)=array2table([data{:,:}]);
            end


            %            writetable(T, [removeExtension(app.saveFolderFile) '.' opt.format],'Delimiter','\t','WriteMode','Append','Sheet','MySheetName')

            app.savedData(1).table = T;
            app.savedData.sheetName = sheetName;
            app.savedData.format = opt.format;
            app.saveDataButton.Enable = true;
            app.saveDataPushTool.Enable = true;
            if opt.writeData
                app.writeData(app.savedData)
            end

            %             fName = [removeExtension(app.saveFolderFile) '.' opt.format];
            %             if opt.writeData
            %                 writetable(T, fName,'WriteMode','Append','Sheet',sheetName)
            %                 fprintf(['Writing data in:\n' fName])
            %             end
        end

        function writeData(app,savedData)
            fName = [removeExtension(app.saveFolderFile) '.' savedData.format];
            if isempty(savedData) % TODO: griser le bouton quand il n'y a pas de données à sauver
                app.warn('No data to be saved')
            else
                writetable(savedData.table, fName,'WriteMode','Append','Sheet',savedData.sheetName)
                fprintf(['Writing data in:\n' fName '\n'])
                app.clearSavedData()
                app.saveDataButton.Enable = false;
                app.saveDataPushTool.Enable = false;
            end
        end

        function val = get.Nch(app)
            % number of channels
            val = size(app.IM0,2);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IM)
            %            if nargin == 2
            %app.imType = class(IM(1));
            app.IM0=IM;

            switch app.imType
                case {'ImageQLSI','ImageEM'}
                    app.im1type= 'OPDnm';
                    app.im2type = 'T';
                case {'Interfero'}
                    app.im1type = 'Itf';
                    app.im2type= 'RefIm';
                    app.AxesLeft.Title.String = "Object interfero";
                    app.AxesRight.Title.String = "Reference interfero";
                    app.colormapDropDownLeft.Value = "gray";
                otherwise
                    error([app.imType ' is not a proper input class. Must be ImageQLSI, ImageEM or Interfero'])
            end

            if app.Nch == 1
                app.Channel1Button.delete();
                app.Channel2Button.delete();
                app.Channel3Button.delete();
                app.Channel4Button.delete();
                app.Channel5Button.delete();
                app.Channel6Button.delete();
                app.Channel7Button.delete();
                app.Channel8Button.delete();
                app.ChannelButtonGroup.Visible = false;
            else % hide channels that are not used
                for ii = app.Nch+1 : 8
                    app.("Channel"+ii+"Button").delete();
                end
            end


            if isa(IM,'ImageQLSI')
                app.IM = repmat(ImageQLSI(app.Nim), 1, app.Nch);
            elseif isa(IM,'ImageEM')
                app.IM = repmat(ImageEM(n=app.Nim), 1, app.Nch);
            elseif isa(IM,'Interfero')
                app.IM = repmat(Interfero(app.Nim), 1, app.Nch);
            else
                error('wrong input')
            end
            app.NimText.Value = app.Nim;
            app.jText.Value = app.jim;
            %            end
            set(app.AxesLeft,'DataAspectRatio',[1 1 1])
            set(app.AxesRight,'DataAspectRatio',[1 1 1])

            app.minLeftEditField.ValueDisplayFormat="%.1f";
            app.maxLeftEditField.ValueDisplayFormat="%.1f";

            app.minRightEditField.ValueDisplayFormat="%.2f";
            app.maxRightEditField.ValueDisplayFormat="%.2f";

            % hide some parameters if not a ImageQLSI object
            if ~strcmp(app.imType,'ImageQLSI')  % if not type ImageQLSI (ie type ImageEM)
                app.switchQLSIparameters('off') %  don't disply some buttons
            else
                app.switchQLSIparameters('on') %  don't disply some buttons
            end

            if isempty(app.IM0(1).Microscope)
                app.Warning.Visible = "on";
                app.Warning.Text = app.Warning.Text+ " No Microscope";
            end
            if isprop(app.IM0(1),'Illumination')
            if isempty(app.IM0(1).Illumination)
                app.Warning.Visible = "on";
                app.Warning.Text = app.Warning.Text+ " No Illumination.";
            end
            end

            % create the colorbars
            app.cbLeft = colorbar(app.AxesLeft,"Visible","off");  % Visible off until they are properly placed
            app.cbLeft.Label.String = 'nm';
            app.cbRight = colorbar(app.AxesRight,"Visible","off");  % Visible off until they are properly placed

            app.updateImages();
            app.cbLeft.Visible="on";
            app.cbRight.Visible="on";
            app.saveDataButton.Enable = false;
            app.saveDataPushTool.Enable = false;

            app.timeSize.AllowEmpty = 'on';
            app.timeSize.Value = [];

            app.m_FlattenOrderEditField.AllowEmpty = true;
            app.m_FlattenOrderEditField.Value = [];


        end

        % Callback function: areaButton, areaPushTool
        function areaButtonPushed(app, event)
            [xc0, yc0] = app.ginputUI(2);
            xc(1) = min(xc0);
            xc(2) = max(xc0);
            yc(1) = min(yc0);
            yc(2) = max(yc0);
            

            area = (xc(1)-xc(2))*(yc(1)-yc(2));

            if strcmpi(app.scale,'µm')
                area=app.um22px(area);
            else
                area=round(area);
            end
            meanOPDval = mean(mean(app.IMcurrent(app.jim).(app.im1type)(yc(1):yc(2),xc(1):xc(2))));
            meanTval = mean(mean(app.IMcurrent(app.jim).(app.im2type)(yc(1):yc(2),xc(1):xc(2))));

            str = sprintf('area:\t%5.0f\t px\ndistance:\t%5.1f\t µm^2\navg OPD value:\t%.4f\t nm\navg T value:\t%4.4f', ...
                area,app.px2um2(area),meanOPDval,meanTval);

            app.displayMessage(str)
            clipboard('copy',num2str(area))

            app.saveData('area',area,'unit','px','writeData',app.autoSave)

        end

        % Callback function: binningButton, binningPushTool
        function binningButtonPushed(app, event)
            binValue = app.binningSpinner.Value; % 2 or 3

            app.displayMessage('computation in progress, please wait...','color',[1, 0.5, 0.2])

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            IMc(:).binning(binValue);

            app.updateImages()
            app.displayMessage('Done','time',1)

            if ~app.modifyWorkspaceCheckBox.Value
                app.toggleSaveButtons
            end

        end

        % Image clicked function: prevImageButton
        function prevImageButtonImageClicked(app, event)
            app.moveToImage(app.jim-1)
        end

        % Image clicked function: nextImageButton
        function nextImageButtonImageClicked(app, event)

            app.moveToImage(app.jim+1)

        end

        % Image clicked function: clearButton
        function clearButtonImageClicked(app, event)
            app.clearMessageArea()
        end

        % Value changed function: colormapDropDownLeft
        function colormapDropDownLeftValueChanged(app, event)
            value = app.colormapDropDownLeft.Value;

            try
                cc = colormap(app.AxesLeft,value);
            catch % if the colormap is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesLeft,funColorMap(1024));
            end

            if app.invColorMapLeft
                colormap(app.AxesLeft,flipud(cc))
            end

        end

        % Value changed function: colormapDropDownRight
        function colormapDropDownRightValueChanged(app, event)
            value = app.colormapDropDownRight.Value;

            try
                cc = colormap(app.AxesRight,value);
            catch % if the colormal is defined as a function, like Pradeep(Nval)
                funColorMap = str2func(value);
                cc = colormap(app.AxesRight,funColorMap(1024));
            end

            if app.invColorMapRight
                colormap(app.AxesRight,flipud(cc))
            end

        end

        % Value changed function: commentEditField
        function commentEditFieldValueChanged(app, event)
            IMc = app.IMcurrent(app.jim,'WriteMode','w');
            IMc.comment = app.commentEditField.Value;
            toggleSaveButtons(app)

        end

        % Image clicked function: copyButton
        function copyButtonImageClicked(app, event)
            clipboard('copy',app.messageArea.Value{1})
        end

        % Callback function: cropButton, cropButtonCenterRect, 
        % ...and 2 other components
        function cropButtonImageClicked(app, event)
            set (app.UIFigure, 'WindowButtonMotionFcn','') % In case a function was interrupted, such as a crop function
            app.disablePanZoom()
            shape = event.Source.Tag; % Square or Rectangle crop
            if isempty(shape)
                shape = 'twoPoints';
            end

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end

            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            if app.widthEditField.Value > 0
                cropSize = [app.widthEditField.Value , app.heightEditField.Value ];
            else
                cropSize = 'Manual';
            end

            if app.marginEditField.Value ~= 0
                IMc.crop("margin",app.marginEditField.Value)
            elseif strcmpi(shape,'twoPoints')
                IMc.crop("twoPoints",true,"app",app)
            else
                IMc.crop("Center",'manual',"app",app,"Size",cropSize,"shape",shape)
            end

            app.updateImages()
            app.makeCurrentAxesBoundAxes()

            if ~app.modifyWorkspaceCheckBox.Value
                app.saveImageButton.Enable = true;
                app.saveImagePushTool.Enable = true;
                app.saveAllImagesButton.Enable = true;
                app.saveAllImagesPushTool.Enable = true;
                app.restoreImageButton.Enable = true;
                app.restorePushTool.Enable = true;
                app.restoreAllImagesButton.Enable = true;
            end
        end

        % Callback function: crosscutPathButton, crosscutPushTool
        function crosscutsPathButtonPushed(app, event)

            % TODO : transform this method into a method of the
            % ImageMethods class IM.crosscut([app]) and simply call it here.
            % This methods may have one argument, which is this app, or
            % none and in this case it creates an app.

            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            axes(app.AxesLeft)
            [cx,cy,cOPD,cx0,cy0]=improfile(100);
            axes(app.AxesRight)
            [~,~,cT]=improfile(app.ImageRight.CData,cx0,cy0,100);

            dist=0;
            for ni=1:length(cx)-1
                dist=dist+sqrt((cx(ni)-cx(ni+1))^2+(cy(ni)-cy(ni+1))^2);
            end

            figure;
            ax1=subplot(1,2,1);
            hp1=plot(cOPD);
            hp1.XData=hp1.XData/max(hp1.XData)*dist;
            hp1.LineWidth=2;
            hp1.Color=[0.8 0.5 0.2];
            xlabel(app.scale)
            ylabel('nm')
            title('wavefront','Color',[0.8 0.5 0.2],'FontSize',14)
            grid on

            ax2=subplot(1,2,2);
            hp2=plot(cT);
            hp2.XData=hp2.XData/max(hp2.XData)*dist;
            hp2.LineWidth=2;
            hp2.Color=[0.2 0.5 0.8];
            xlabel(app.scale)
            title('transmission','Color',[0.2 0.5 0.8],'FontSize',14)
            grid on

            linkaxes([ax1, ax2],'x')

            app.UIFigure.HandleVisibility = fhv;    % return original state


            app.saveData('tech','crosscutPath','crosscut',[hp1.XData(:),cOPD(:),cT(:)],'unit',app.scale,'writeData',app.autoSave)


        end

        % Callback function: HVcrosscutPushTool, crosscutsHVButton
        function crosscutsHVButtonPushed(app, event)

            % TODO : transform this method into a method of the
            % ImageMethods class IM.crosscut([app]) and simply call it here.
            % This methods may have one argument, which is this app, or
            % none and in this case it creates an app.

            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            axes(app.AxesLeft)
            [cx,cy]=ginput(1);

            if strcmpi(app.scale,'px')
                cx_px=round(cx);
                cy_px=round(cy);
                fac=1;
            else
                cx_px=app.um2px(cx);
                cy_px=app.um2px(cy);
                fac=app.IMcurrent.pxSize*1e6;
            end
            xx = 1:app.IMcurrent.Nx;
            yy = 1:app.IMcurrent.Ny;

            figure;
            ax1=subplot(1,2,1);
            hold on
            hp1H=plot(xx*fac,app.IMcurrent.(app.im1type)(cy_px,:));
            hp1V=plot(yy*fac,app.IMcurrent.(app.im1type)(:,cx_px));
            hp1H.LineWidth=2;
            hp1V.LineWidth=2;
            hp1H.Color=[0.8 0.5 0.2];
            hp1V.Color=[0.8 0.2 0.5];
            xlabel(app.scale)
            ylabel('nm')
            legend({'horizontal','vertical'})
            title('wavefront','Color',[0.8 0.35 0.35],'FontSize',14)
            grid on

            ax2=subplot(1,2,2);
            hold on
            hp2H=plot(xx*fac,app.IMcurrent.T(cy_px,:));
            hp2V=plot(yy*fac,app.IMcurrent.T(:,cx_px));
            hp2H.LineWidth=2;
            hp2V.LineWidth=2;
            hp2H.Color=[0.2 0.5 0.8];
            hp2V.Color=[0.5 0.2 0.8];
            xlabel(app.scale)
            ylabel('nm')
            legend({'horizontal','vertical'})
            title('transmission','Color',[0.35 0.35 0.8],'FontSize',14)
            grid on

            linkaxes([ax1, ax2],'x')

            app.UIFigure.HandleVisibility = fhv;    % return original state

        end

        % Callback function: crosscutRadial, radialCrosscutPushTool
        function crosscutRadialButtonPushed(app, event)
            [profile, coords] = radialAverage(app);
            switch app.imType
                case {'ImageQLSI','ImageEM'}
                    app.saveData('tech','crosscutPath','crosscut',[coords(:),1e9*profile.OPD(:),profile.T(:)],'unit',app.scale,'writeData',app.autoSave)
                case {'Interfero'}
                    app.saveData('tech','crosscutPath','crosscut',[coords(:),profile.OPD(:),profile.T(:)],'unit',app.scale,'writeData',app.autoSave)
            end

            % figure,plot(profile)
        end

        % Selection changed function: displayButton
        function displayButtonSelectionChanged(app, event)
            updateImages(app)
        end

        % Callback function: distanceButton, distancePushTool
        function distanceButtonPushed(app, event)
            [xc, yc] = app.ginputUI(2);

            dist = sqrt((xc(1)-xc(2))^2 +(yc(1)-yc(2))^2);

            if strcmpi(app.scale,'µm')
                dist=app.um2px(dist);
            else
                dist=round(dist);
            end

            str = sprintf('distance:\t%5.2f\t px\ndistance:\t%5.1f\t µm',dist,app.px2um(dist));

            app.displayMessage(str)
            clipboard('copy',num2str(dist))

            app.saveData('distance',dist,'unit','px','writeData',app.autoSave)

        end

        % Button pushed function: DMradialButton
        function DMradialButtonPushed(app, event)
            params = alpha_ImageProfile(app.IMcurrent,'nmax',app.NmaxEditField.Value, ...
                'figure',app,...
                'nBkg',app.bkgRingEditField.Value,  ...
                'NNP', 1,...
                'Dz',app.DznmEditField.Value*1e-9,...
                'zoom', 1,...
                'step', app.stepEditField.Value,...
                'keepPoint',false, ...
                'display',false); % displays many measurements at once

            message = sprintf('alpha = %.4g + i* %.4g (*1e-18 m^3)\nOV    = %.4g µm^3\nDM    = %.4g pg',real(params.alpha)*1e18,imag(params.alpha)*1e18, params.OV*1e18, params.OV*1e18/app.gammaEditField.Value);
            app.bring2front()
            app.displayMessage(message)


        end

        % Callback function: DMmagicwandButton, MWPushTool
        function DMmagicwandButtonPushed(app, event)
            [alpha,OV,~,app.roiIN,app.roiOUT] = ...
                magicWandAlphaOV(app.IMcurrent(), ...
                'roiIN',app.roiIN, ...
                'roiOUT',app.roiOUT, ...
                'bkTh', app.bkgRingEditField.Value, ...
                'step', app.stepEditField.Value,...
                'NNP', 1,...
                'nmax', app.NmaxEditField.Value);

            alphaRealMean = real(alpha);
            alphaImagMean = imag(alpha);
            DM = 1e6*OV/app.gammaEditField.Value; % dry mass in [g].

            if alphaRealMean>1e-19 || alphaImagMean>1e-19
                if alphaImagMean<0
                    alpha2print = sprintf('(%.4g-i*%.4g) x10^-18 [m^3]',1e18*alphaRealMean,-1e18*alphaImagMean);
                else
                    alpha2print = sprintf('(%.4g+i*%.4g) x10^-18 [m^3]',1e18*alphaRealMean,1e18*alphaImagMean);
                end
                OV2print = sprintf('%.4ge-18 m^3',1e18*OV);
            else
                if alphaImagMean<0
                    alpha2print = sprintf('(%.4g-i*%.4g) x10^-21 [m^3]',1e21*alphaRealMean,-1e21*alphaImagMean);
                else
                    alpha2print = sprintf('(%.4g+i*%.4g) x10^-21 [m^3]',1e21*alphaRealMean,1e21*alphaImagMean);
                end
                OV2print = sprintf('%.4ge-21 [m^3]',1e21*OV);
            end
            DM2print = sprintf('%.4g pg',DM*1e12);
            clipboard('copy',sprintf('%.4g\t%.4g\t%.4g\t%.4g',alphaRealMean,alphaImagMean,OV,DM))
            fprintf(['alpha: ' alpha2print '\nOV: ' OV2print '\nDM: ' DM2print '\n'])
            app.displayMessage(sprintf(['alpha: ' alpha2print '\nOV: ' OV2print '\nDM: ' DM2print]))
            app.bring2front
            app.saveData('tech','MW','alpha',alphaRealMean+1i*abs(alphaImagMean),'OV',OV,'DM',DM,'writeData',app.autoSave)

        end

        % Callback function: DMroiButton, roiPushTool
        function DMroiButtonPushed(app, event)
            [alpha, OV] = roiAlphaOV(app.IMcurrent,app);
            app.bring2front
            app.saveData('tech','roi','alpha',alpha,'OV',OV,'writeData',app.autoSave)

        end

        % Callback function: PushTool3, duplicateImageButton
        function duplicateImageButtonPushed(app, event)
            % add an elements to the image array:
            app.IM = [app.IM; app.IM(end,:)];
            app.IM0 = [app.IM0; app.IM0(end,:)];
            % shift the elements
            app.IM(app.jim+2:end,:)  = app.IM(app.jim+1:end-1,:);
            app.IM0(app.jim+2:end,:) = app.IM0(app.jim+1:end-1,:);

            % duplicate the image of interest
            app.IM0(app.jim+1,:) = copy(app.IMcurrent(app.jim, "allChannels", true)); % Copy and not duplicate to keep the same Microscope and Illumination handles. No reason to be different

            % reinitialisation des IM, pour que IMcurrent pointe maintenant
            % vers IM0, et pas sur IM. Reviens à sauvegarder les
            % changements.
            if isa(app.IMcurrent,'ImageQLSI')
                app.IM(app.jim,:) = repmat(ImageQLSI(1),1,app.Nch);
                app.IM(app.jim+1,:) = repmat(ImageQLSI(1),1,app.Nch);
            else
                app.IM(app.jim,:) = repmat(ImageEM(n=1),1,app.Nch);
                app.IM(app.jim+1,:) = repmat(ImageEM(n=1),1,app.Nch);
            end

            app.moveToImage(app.jim+1)


        end

        % Image clicked function: exportFolderImage
        function exportFolderImageClicked(app, event)
            %app.UIFigure.Visible='off' ;
            app.exportFolderEditField.Value = uigetdir();
            %app.UIFigure.Visible='on';
            figure(app.UIFigure);

        end

        % Callback function: exportImageButton, exportImagePushTool
        function exportImageButtonPushed(app, event)
            app.displayMessage('wait...')        
            Spng = app.export_pngCheckBox.Value;
            Seps = app.export_epsCheckBox.Value;
            Stxt = app.export_txtCheckBox.Value;
            Smat = app.export_matCheckBox.Value;
            Sfig = app.export_figureCheckBox.Value;
            fileName = [app.exportFileNameEditField.Value];
            folderName = [app.exportFolderEditField.Value];
            if isempty(folderName) % if no folder name is specified
                folderName = pwd;
            end

            range = app.saveImageRange();

            IMexport = app.IMcurrent(range,'WriteMode','r','allChannels',true);

            for io = 1:size(IMexport,1)
                for ip = 1:size(IMexport,2)
                    try
    
                        if Spng || Seps || Sfig
                            app.moveToImage(range(io))
                            hfig = app.displayDualImage();
                            drawnow
                        end
                        if Spng
                            saveas(hfig,[folderName '/' fileName '_' textkkk(range(io))], 'png')
                        end
                        if Seps
                            saveas(hfig,[folderName '/' fileName '_' textkkk(range(io))],'epsc')
                        end
                        if Sfig
                            saveas(hfig,[folderName '/' fileName '_' textkkk(range(io))],'fig')
                        end
                        if Stxt
                            writematrix(IMexport(range(io),ip).(app.im1type),[folderName '/' fileName '_OPD(nm)_' textkkk(range(io)) '_p' num2str(ip) '.txt'],'Delimiter',' ')
                            writematrix(IMexport(range(io),ip).(app.im2type),[folderName '/' fileName '_T_' textkkk(range(io)) '_p' num2str(ip) '.txt'],'Delimiter',' ')
                        end
                        app.displayMessage("wait...   "+io+"/"+numel(IMexport),'color',[1, 0.5, 0.2])
                    catch
                        pause(1)
                    end
                end
            end
            if Smat
                IMlist = app.IMcurrent(range);
                assignin('base','IM_PhaseLAB',IMlist)
                fprintf('Variable IM_PhaseLAB saved in the workspace\n')
            end
            close all
            app.bring2front()
            app.exportFolderEditField.Value = folderName;
            app.displayMessage('Exportation complete')        

        end

        % Callback function: exportMovieButton, exportMoviePushTool
        function exportMovieButtonPushed(app, event)
            range = app.saveImageRange();
            folderName = [app.exportFolderEditField.Value];
            if isempty(folderName) % if no folder name is specified
                folderName = pwd;
            end

            types = cell.empty();
            zrange = cell.empty();
            if app.OPDCheckBox.Value
                types(end+1) = {'OPD'};

                if app.holdClimLeftCheckBox.Value % if hold z limits
                    valLeftMin = app.minLeftEditField.Value;
                    valLeftMax = app.maxLeftEditField.Value;
                    zrange(end+1) = {[valLeftMin valLeftMax]};
                else
                    zrange(end+1) = {[]};
                end

            end
            if app.TCheckBox.Value
                types(end+1) = {'T'};

                if app.holdClimRightCheckBox.Value % if hold z limits
                    valRightMin = app.minRightEditField.Value;
                    valRightMax = app.maxRightEditField.Value;
                    zrange(end+1) = {[valRightMin valRightMax]};
                else
                    zrange(end+1) = {[]};
                end
            end


            videoName = [folderName '/' app.exportFileNameEditField.Value '.avi'];
            makeMoviedx(app.IMcurrent(range),videoName,...
                rate = app.rateVideoEditField.Value,...
                persp = app.perspCheckBox.Value,...
                phi =app.phiEditField.Value,...
                theta = app.thetaEditField.Value,...
                title = app.titleVideoEditField.Value,...
                clabel = app.cblabelEditField.Value,...
                imType  = types,...
                timeUnit = app.timeUnitEditField.Value, ...
                zrange = zrange, ...
                frameTime=app.timeEditField.Value * app.timeUnitFactor, ...
                timeFontSize=app.timeSize.Value, ...
                colorMap = {app.colormapDropDownLeft.Value,app.colormapDropDownRight.Value});
            %            opt.ampl = 0;
            %            opt.zrange =
            %            opt.colorMap =  parulaNeuron
            %            opt.factor = 1 % correction factor, for instance 5.55e-3 to convert the OPD color scale into dry mass


        end

        % Callback function: flattenButton, flattenPushTool
        function flattenButtonPushed(app, event)
            if app.imType == "ImageQLSI"
    
                method = app.flattenMethodDropDown.Value;
                nmax = [app.n_FlattenOrderEditField.Value, app.m_FlattenOrderEditField.Value]; % m can be empty. In this case, only equals n.
                
                if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                    range = app.jim;
                else % crop all the images
                    range = 1:app.Nim;
                end
                
                paramsIn.threshold = app.flattenThresholdEditField.Value;
                paramsIn.smoothing = app.flattenSmoothingEditField.Value;
                paramsIn.cut       = app.flattenCutEditField.Value;
                paramsIn.invert    = app.flattenInvertCheckBox.Value;
                paramsIn.type      = app.maskTypeDropDown.Value;
                
                if app.ManualCheckBox.Value % Manual mask
                    if isempty(app.manualFlattenMask)
                        app.displayMessage("You asked for a Manual selection of the crop, first define one by pressing 'Set parameters'","time",1,"color",app.warnColor)
                    else
                        app.displayMessage('computation in progress, please wait...','color',[1, 0.5, 0.2])
                        IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);
                        if paramsIn.invert
                            IMc.flatten(method,'nmax',nmax,'mask',~app.manualFlattenMask);
                        else
                            IMc.flatten(method,'nmax',nmax,'mask',app.manualFlattenMask);
                        end
                        app.updateImages()
                        app.displayMessage('Done','time',1)
                    end
                else % automatic calculated mask
                    app.displayMessage('computation in progress, please wait...','color',[1, 0.5, 0.2])
                    IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);
                    IMc.flatten(method,'nmax',nmax,'params',paramsIn);         
                    app.updateImages()
                    app.displayMessage('Done','time',1)
                    if length(nmax) == 1
                        app.displayMessage("IMc.flatten('"+method+"','nmax',["+nmax+"],'threshold',"+paramsIn.threshold+",'smoothing',"+paramsIn.smoothing+",'cut',"+paramsIn.cut+",'invert',"+paramsIn.invert+",'type','"+paramsIn.type+"','display',false);  ")
                    else
                        app.displayMessage("IMc.flatten('"+method+"','nmax',["+nmax(1)+" "+nmax(2)+"],'threshold',"+paramsIn.threshold+",'smoothing',"+paramsIn.smoothing+",'cut',"+paramsIn.cut+",'invert',"+paramsIn.invert+",'type','"+paramsIn.type+"','display',false);  ")
                    end  
                end
    
                if ~app.modifyWorkspaceCheckBox.Value
                    app.toggleSaveButtons
                end

            else
                app.displayMessage("This function is not active with " + app.imType + "objects",'color',app.warnColor,'time',1)
            end
            
        end

        % Callback function: filterButton, filterPushTool
        function filterButtonPushed(app, event)
            if app.imType == "ImageQLSI"

                method = app.filterMethodDropDown.Value;
                nmax = app.filterCutoffEditField.Value;
    
                app.displayMessage('computation in progress, please wait...','color',[1, 0.5, 0.2])
    
                if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                    range = app.jim;
                else % crop all the images
                    range = 1:app.Nim;
                end
                IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);
    
                switch method
                    case 'HighPass'
                        IMc(:).highPassFilter(nmax);
                    case 'LowPass'
                        IMc(:).lowPassFilter(nmax);
                end
                app.updateImages()
                app.displayMessage('Done','time',1)
    
                if ~app.modifyWorkspaceCheckBox.Value
                    app.toggleSaveButtons
                end

            else
                app.displayMessage("This function is not active with " + app.imType + "objects",'color',app.warnColor,'time',1)
            end


        end

        % Value changed function: invertCheckBoxLeft
        function invertCheckBoxLeftValueChanged(app, event)
            value = app.invertCheckBoxLeft.Value;
            app.invColorMapLeft = value ;
            colormapDropDownLeftValueChanged(app)
        end

        % Value changed function: invertCheckBoxRight
        function invertCheckBoxRightValueChanged(app, event)
            value = app.invertCheckBoxRight.Value;
            app.invColorMapRight = value ;
            colormapDropDownRightValueChanged(app)
        end

        % Value changed function: jText
        function jTextValueChanged(app, event)
            app.reloadImageClicked()
        end

        % Value changed function: maxLeftEditField, minLeftEditField
        function limitsLeftValueChanging(app, event)
            clim(app.AxesLeft, [app.minLeftEditField.Value,  app.maxLeftEditField.Value])
            drawnow
        end

        % Value changed function: maxRightEditField, minRightEditField
        function limitsRightValueChanged(app, event)
            clim(app.AxesRight, [app.minRightEditField.Value,  app.maxRightEditField.Value])
            drawnow
        end

        % Callback function: mirrorHButton, mirrorHPushTool
        function mirrorHbuttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            IMc(:).flipud();
            app.updateImages()

            if ~app.modifyWorkspaceCheckBox.Value
                app.toggleSaveButtons
            end

            %            app.ImageRight.CData = app.IMcurrent.T;
            %            app.ImageLeft.CData = app.IMcurrent.OPD;
        end

        % Callback function: mirrorVButton, mirrorVPushTool
        function mirrorVbuttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            IMc(:).fliplr();
            app.updateImages()

            app.toggleSaveButtons
            %            app.ImageRight.CData = app.IMcurrent.T;
            %            app.ImageLeft.CData = app.IMcurrent.OPD;
        end

        % Callback function: normTbutton, offsetButton, offsetPushTool
        function offsetButtonPushed(app, event)
            if app.imType == "ImageQLSI"

                app.displayMessage('Wait...',"color",app.warnColor)

                method = app.offsetMethodDropDown.Value;

                if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                    range = app.jim;
                else % crop all the images
                    range = 1:app.Nim;
                end
                IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);
    
                switch event.Source.Tag
                    case 'W'
                        IMc.level0('method',method,'twoPoints',true,'app',app)
                    case 'T'
                        IMc.normT('method',method,'twoPoints',true,'app',app)
                end
                app.updateImages()
                app.displayMessage('Done','time',1)
    
                if ~app.modifyWorkspaceCheckBox.Value
                    app.toggleSaveButtons
                end

            else
                app.displayMessage("This function is not active with " + app.imType + " objects",'color',app.warnColor,'time',1)
            end

        end

        % Button pushed function: overviewButton
        function overviewButtonPushed(app, event)

            IMc = app.IMcurrent('all',"WriteMode",'r');
            types = cell.empty();
            if app.OPDCheckBox.Value
                types(end+1) = {'OPD'};
            end
            if app.TCheckBox.Value
                types(end+1) = {'T'};
            end
            hfig = IMc.overview("types",types);
            hfig.UserData=app;
            set(hfig,'CloseRequestFcn',@(source, event)closeFig(hfig))
            function closeFig(hfig)
                hfig.Visible = false;
                hfig.UserData.bring2front()
                hfig.delete
            end

        end

        % Callback function: pixelButton, pixelPushTool
        function pixelButtonPushed(app, event)
            [xc, yc] = app.ginputUI(1);
            if strcmpi(app.scale,'µm')
                xc=app.um2px(xc);
                yc=app.um2px(yc);
            else
                xc=round(xc);
                yc=round(yc);
            end
            OPDval = app.IMcurrent.(app.im1type)(yc,xc);
            Tval = app.IMcurrent.(app.im2type)(yc,xc);
            str = sprintf('OPD:\t%6.2f\tnm\nT  :\t%4.3f\n',OPDval, Tval);
            app.displayMessage(str)
            clipboard('copy',sprintf('%.4f\t %.4f',OPDval, Tval))

            app.saveData('position',[xc,yc],'values',[OPDval, Tval],'unit','px','writeData',app.autoSave)


        end

        % Image clicked function: refocusingBackwardButton, 
        % ...and 1 other component
        function refocusingButtonImageClicked(app, event)
            if app.imType == "ImageQLSI"

            % direction of the propagation
            sign = str2double(event.Source.Tag); % +1 or -1 depending of which of the two buttons are pressed.

            z_step = 0.2; % [µm]
            app.refocusingEditField.Value = round(app.refocusingEditField.Value + sign*z_step,1); % rounds the number at 100 nm
            app.refocusingEditFieldValueChanged() % Oddly enough, this function is not launched by the previous code line.

            else
                app.displayMessage("This function is not active with " + app.imType + " objects",'color',app.warnColor,'time',1)
            end

        end

        % Value changed function: refocusingEditField
        function refocusingEditFieldValueChanged(app, event)
            % refocuses an image, or a series of images, or a set of
            % channels.

            z_focus = app.refocusingEditField.Value;

            if app.ApplytoallimagesCheckBox.Value == 0 % refocuses a single image, or the set of Nch channels
                range = app.jim;
            else % refocus all the images
                if app.Nch > 1                         % refocuses a set of Nch channels
                    range = app.jim;
                    warning(['cannot apply refocusing to all images when working' ...
                        'with multiples channels. Will only apply to the N channels' ...
                        'of the currently displayed image'])
                else                                   % refocuses a set of images
                    range = 1:app.Nim;
                end
            end

            N0 = length(range)*app.Nch; % either of these values is 1
            if isa(app.IM0(1), 'ImageQLSI')
                IMout = ImageQLSI(N0);
            else
                IMout = ImageEM(n=N0);
            end

            ii = 0;
            for ic = 1:app.Nch
                for ir = 1:length(range)
                    ii=ii+1;
                    IMout(ii) = app.IM0(range(ir),ic).propagation(z_focus*1e-6); % propagates from the original IM0 image
                end
            end

            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            % at that point, IMc is either a vector of Nch images, or a
            % vector of length(range) images.
            switch app.imType
                case {'ImageQLSI','ImageEM'}
                    for ii = 1:N0
                        IMc(ii).T0 = IMout(ii).T;
                        IMc(ii).OPD0 = IMout(ii).OPD;
                    end
                case 'Interfero'
                    for ii = 1:N0
                        IMc(ii).Itf0 = IMout(ii).Itf;
                        IMc(ii).Ref.Itf0 = IMout(ii).RefIm;
                    end
            end
            
            %pourquoi le refocusin prend autant de temps ??!!

            app.updateImages()

            if ~app.modifyWorkspaceCheckBox.Value
                app.toggleSaveButtons
            end

        end

        % Image clicked function: reload
        function reloadImageClicked(app, event)
            app.moveToImage(app.jText.Value)
        end

        % Button pushed function: resetSlidersLeft
        function resetLeftLimitsButtonPushed(app, event)
            app.resetLimitsEditField('Left')
        end

        % Button pushed function: resetSlidersRight
        function resetRightLimitsButtonPushed(app, event)
            app.resetLimitsEditField('Right')
        end

        % Callback function: restoreImageButton, restorePushTool
        function restoreImageButtonPushed(app, event)

            if strcmpi(app.imType,'ImageQLSI')
                app.IM(app.jim,:) = repmat(ImageQLSI(1), 1, app.Nch);
            elseif strcmpi(app.imType,'ImageEM')
                app.IM(app.jim,:) = repmat(ImageEM(n=1), 1, app.Nch);
            elseif strcmpi(app.imType,'Interfero')
                app.IM(app.jim,:) = repmat(Interfero(1), 1, app.Nch);
            end
            app.toggleSaveButtons

            app.updateImages()
        end

        % Button pushed function: restoreAllImagesButton
        function restoreAllImagesButtonPushed(app, event)
            if strcmpi(app.imType,'ImageQLSI')
                for io = 1:app.Nim
                    app.IM(io,:) = repmat(ImageQLSI(1), 1, app.Nch);
                end
            elseif strcmpi(app.imType,'ImageEM')
                for io = 1:app.Nim
                    app.IM(io,:) = repmat(ImageEM(n=1), 1, app.Nch);
                end
            elseif strcmpi(app.imType,'Interfero')
                for io = 1:app.Nim
                    app.IM(io,:) = repmat(Interfero(1), 1, app.Nch);
                end
            end

            app.updateImages()

        end

        % Callback function: rot90Button, rot90PushTool
        function rot90buttonImageClicked(app, event)
            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);

            IMc(:).rot90(-1);
            % app.IMcurrent = app.IMcurrent.rot90(-1); % avoids the use of app.saveImageButton.Enable = true;
            app.updateImages

            if ~app.modifyWorkspaceCheckBox.Value
                app.toggleSaveButtons
            end

            %            app.ImageRight.CData = app.IMcurrent.T;
            %            app.ImageLeft.CData = app.IMcurrent.OPD;
            % to make the axes follow, in the case of non-square images
            %            app.makeCurrentAxesBoundAxes
            %            app.reloadImageClicked
        end

        % Selection changed function: scaleButton
        function scaleButtonSelectionChanged(app, event)

            if isequal(app.imageFun,@imagesc)
                app.ImageRight.XData = [1 , app.IMcurrent.Nx];
                app.ImageRight.YData = [1 , app.IMcurrent.Ny];
                app.ImageLeft.XData = [1 , app.IMcurrent.Nx];
                app.ImageLeft.YData = [1 , app.IMcurrent.Ny];
            elseif isequal(app.imageFun,@image3D)
                app.ImageRight.XData = 1 : app.IMcurrent.Nx;
                app.ImageRight.YData = 1 : app.IMcurrent.Ny;
                app.ImageLeft.XData = 1 : app.IMcurrent.Nx;
                app.ImageLeft.YData = 1 : app.IMcurrent.Ny;
            end

            if strcmpi(event.NewValue.Text,'px')
                app.makeCurrentAxesBoundAxes

                xlabel(app.AxesRight,'px')
                xlabel(app.AxesLeft,'px')

                app.widthCropUnitLabel.Text = 'px';
                app.heightCropUnitLabel.Text = 'px';

            else % µm

                axesData = getappdata(app.AxesLeft,'matlab_graphics_resetplotview');
                axesData.XLim=[1 , app.IMcurrent.Nx]*app.IMcurrent.pxSize*1e6;
                axesData.YLim=[1 , app.IMcurrent.Ny]*app.IMcurrent.pxSize*1e6;
                setappdata(app.AxesLeft,'matlab_graphics_resetplotview',axesData);
                setappdata(app.AxesRight,'matlab_graphics_resetplotview',axesData);

                app.ImageRight.XData = app.ImageRight.XData *app.IMcurrent.pxSize*1e6;
                app.ImageRight.YData = app.ImageRight.YData *app.IMcurrent.pxSize*1e6;
                app.ImageLeft.XData  = app.ImageLeft.XData  *app.IMcurrent.pxSize*1e6;
                app.ImageLeft.YData  = app.ImageLeft.YData  *app.IMcurrent.pxSize*1e6;

                xlabel(app.AxesRight,'µm')
                xlabel(app.AxesLeft,'µm')

                app.widthCropUnitLabel.Text = 'µm';
                app.heightCropUnitLabel.Text = 'µm';
            end
            zoom(app.AxesLeft,'out')
            zoom(app.AxesRight,'out')
            app.adjustdAspect('all')
            app.setLightPositions()

            app.updateScalebars()


            drawnow
        end

        % Image clicked function: saveFolderImage
        function saveFolderImageClicked(app, event)
            app.UIFigure.Visible = 'off';
            try
                app.saveFolderEditField.Value = uigetdir(pwd);
                app.UIFigure.Visible = 'on';
            catch
                app.UIFigure.Visible = 'on';
            end
        end

        % Callback function: saveDataButton, saveDataPushTool
        function saveDataButtonPushed(app, event)
            if isempty(fields(app.savedData))
                app.warn('no data recorded')
            else
                app.writeData(app.savedData)
            end
        end

        % Callback function: saveImageButton, saveImagePushTool
        function saveImageButtonPushed(app, event)
            % transfert of IM to IM0
            app.IM0(app.jim,:).write(app.IMcurrent("allChannels", true)); % IM0 = IMcurrent, without modidying the original IM0 handle, in order to actually modify the workspace
            % TODO : I think it is
            % what copy exactly
            % does. To be checked.
            % erase IM
            
            app.displayMessage('Wait...')
            if isa(app.IMcurrent,'ImageQLSI')
                app.IM(app.jim,:) = repmat(ImageQLSI(1), 1, app.Nch);
            else
                app.IM(app.jim,:) = repmat(ImageEM(n=1), 1, app.Nch);
            end
            % disable save button and allsave button if all the IM are empty
            app.toggleSaveButtons
            app.displayMessage('Done','time',1)


        end

        % Callback function: saveAllImagesButton, saveAllImagesPushTool
        function saveAllImagesButtonPushed(app, event)
            range = ~is_empty(app.IM); % subarray of all the image numbers that are not empty
            % transfert of IM to IM0
            app.displayMessage('Wait...')
            IMc = app.IMcurrent(1:app.Nim,"allChannels",true);
            app.IM0(range).write(IMc(range));
            % erase IM
            if isa(app.IMcurrent,'ImageQLSI')
                app.IM = repmat(ImageQLSI(app.Nim), 1, app.Nch);
            else
                app.IM = repmat(ImageEM(n=app.Nim), 1, app.Nch);
            end
            % disable save button and allsave button if all the IM are empty
            app.toggleSaveButtons

            app.displayMessage('Done','time',1)


        end

        % Callback function: PushTool4, untiltButton
        function untiltButtonPushed(app, event)

            if app.ApplytoallimagesCheckBox.Value == 0 % crop a single image
                range = app.jim;
            else % crop all the images
                range = 1:app.Nim;
            end
            IMc = app.IMcurrent(range,'WriteMode','w','allChannels',true);


            IMc(:).untilt('twoPoints',true,'app',app,'all',true)
            app.updateImages()
            app.displayMessage('Done','time',1)

            if ~app.modifyWorkspaceCheckBox.Value
                app.toggleSaveButtons
            end

        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelButtonGroup.SelectedObject;
            app.iCh = str2double(selectedButton.Text(end)); % numero of the button
            app.updateImages()

        end

        % Button pushed function: savewsButton
        function savewsButtonPushed(app, event)
            varName = app.savewsEditField.Value;
            assignin('base', varName, app.IMcurrent(1:app.Nim, "allChannels", true));
        end

        % Button pushed function: navigButton
        function navigButtonPushed(app, event)
            % display a navigation panel, from one set of images to another
            % by pressing the right and left arrows. Based on the
            % dynamicFigure function

            chaine = app.navigEditField.Value;
            app.clearMessageArea()
            if isempty(chaine)
                app.displayMessage("Fill in the field below the navig button to indicate the images to be displayed. For instance: \n OPD, T, DWx, DWy",'color',[0.8, 0.5, 0.2])
                return
            end


            % Chaîne de caractères initiale
            
            % Utilisation de la fonction strsplit pour diviser la chaîne
            imList = strsplit(chaine, {' ', '  ', ', ', ',',' , ', ' ,', '; ', ';',' ; ', ' ;'});
            Ni = numel(imList);
            imDisp = cell(Ni, 1);
            for ii = 1:Ni
                switch imList{ii}
                    case {'OPD', 'DWx', 'DWy', 'Ph'}
                        imDisp{ii} = 'ph';
                    case 'T'
                        imDisp{ii} = 'bw';
                    otherwise
                        imDisp{ii} = 'gb';
                end
            end


            IMc = app.IMcurrent(1:app.Nim);
            if Ni == 1
                dynamicFigure(imDisp{1}, {IMc.(imList{1})})
            elseif Ni == 2
                dynamicFigure(imDisp{1}, {IMc.(imList{1})}, ...
                    imDisp{2}, {IMc.(imList{2})})
            elseif Ni == 3
                dynamicFigure(imDisp{1}, {IMc.(imList{1})}, ...
                    imDisp{2}, {IMc.(imList{2})}, ...
                    imDisp{3}, {IMc.(imList{3})})
            elseif Ni == 4
                dynamicFigure(imDisp{1}, {IMc.(imList{1})}, ...
                    imDisp{2}, {IMc.(imList{2})}, ...
                    imDisp{3}, {IMc.(imList{3})}, ...
                    imDisp{4}, {IMc.(imList{4})})
            elseif Ni == 5
                dynamicFigure(imDisp{1}, {IMc.(imList{1})}, ...
                    imDisp{2}, {IMc.(imList{2})}, ...
                    imDisp{3}, {IMc.(imList{3})}, ...
                    imDisp{4}, {IMc.(imList{4})}, ...
                    imDisp{5}, {IMc.(imList{5})})
            elseif Ni == 6
                dynamicFigure(imDisp{1}, {IMc.(imList{1})}, ...
                    imDisp{2}, {IMc.(imList{2})}, ...
                    imDisp{3}, {IMc.(imList{3})}, ...
                    imDisp{4}, {IMc.(imList{4})}, ...
                    imDisp{5}, {IMc.(imList{5})}, ...
                    imDisp{6}, {IMc.(imList{6})})
            end


        end

        % Value changed function: timeUnitEditField
        function timeUnitEditFieldValueChanged(app, event)
            value = app.timeUnitEditField.Value;
            if ~ismember(value,{'s','min','h'})
                app.timeUnitEditField.Value = 's';
            end

            switch app.timeUnitEditField.Value
                case 's'
                    app.timeUnitFactor = 1;
                case 'min'
                    app.timeUnitFactor = 60;
                case 'h'
                    app.timeUnitFactor = 3600;
            end
        end

        % Button pushed function: SetparametersButton
        function SetFlattenParametersButtonPushed(app, event)

            if app.imType == "ImageQLSI"

                paramsIn.threshold = app.flattenThresholdEditField.Value;
                paramsIn.smoothing  = app.flattenSmoothingEditField.Value;
                paramsIn.cut        = app.flattenCutEditField.Value;
                paramsIn.invert     = app.flattenInvertCheckBox.Value;
                paramsIn.type     = app.maskTypeDropDown.Value;
                
                [~, paramsOut] = segmentationMaskGen(mean(app.IMcurrent('allChannels',true)), paramsIn);
                app.flattenThresholdEditField.Value = paramsOut.threshold;
                app.flattenSmoothingEditField.Value = paramsOut.smoothing;
                app.flattenCutEditField.Value = paramsOut.cut;
                app.flattenInvertCheckBox.Value = paramsOut.invert;
                app.maskTypeDropDown.Value = paramsOut.type;
                app.manualFlattenMask = paramsOut.manualMask;
                if ~isempty(app.manualFlattenMask)
                    app.ManualCheckBox.Value = true;
                    app.enableFlattenParams(false)
                else
                    app.ManualCheckBox.Value = false;
                    app.enableFlattenParams(true)
                end                    

            else
                app.displayMessage("This function is not active with " + app.imType + " objects",'color',app.warnColor,'time',1)
            end
        end

        % Value changed function: scalebarOPDCheckBox, scalebarTCheckBox
        function scalebarCheckBoxesValueChanged(app, event)
            app.updateScalebars()
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            if app.ManualCheckBox.Value
                app.enableFlattenParams(false);
            else
                app.enableFlattenParams(true);
            end                
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'rightarrow'
                    app.moveToImage(app.jim+1)
                case 'leftarrow'
                    app.moveToImage(app.jim-1)
            end
        
        end

        % Value changed function: DrymassdensityCheckBox
        function DrymassdensityCheckBoxValueChanged(app, event)
            value = app.DrymassdensityCheckBox.Value;
            if value
                app.im1type = 'dmd';
                app.cbLeft.Label.String = 'pg/µm^2';
            else
                app.im1type = 'OPDnm';
                app.cbLeft.Label.String = 'nm';
            end
            app.updateImages()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PhaseLAB GUI';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'newGUI', 'favicon.png');
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'import.svg');
            app.restorePushTool = uipushtool(app.Toolbar);
            app.restorePushTool.ClickedCallback = createCallbackFcn(app, @restoreImageButtonPushed, true);
            app.restorePushTool.Enable = 'off';
            app.restorePushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'undo.svg');
            app.saveImagePushTool = uipushtool(app.Toolbar);
            app.saveImagePushTool.ClickedCallback = createCallbackFcn(app, @saveImageButtonPushed, true);
            app.saveImagePushTool.Enable = 'off';
            app.saveImagePushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveData.svg');
            app.saveAllImagesPushTool = uipushtool(app.Toolbar);
            app.saveAllImagesPushTool.ClickedCallback = createCallbackFcn(app, @saveAllImagesButtonPushed, true);
            app.saveAllImagesPushTool.Enable = 'off';
            app.saveAllImagesPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveAllData.svg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @duplicateImageButtonPushed, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'newGUI', 'clipboard.svg');
            app.spacerPushTool = uipushtool(app.Toolbar);
            app.spacerPushTool.Enable = 'off';
            app.spacerPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'blank.svg');
            app.mirrorVPushTool = uipushtool(app.Toolbar);
            app.mirrorVPushTool.ClickedCallback = createCallbackFcn(app, @mirrorVbuttonImageClicked, true);
            app.mirrorVPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorV.svg');
            app.mirrorHPushTool = uipushtool(app.Toolbar);
            app.mirrorHPushTool.ClickedCallback = createCallbackFcn(app, @mirrorHbuttonImageClicked, true);
            app.mirrorHPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorH.svg');
            app.rot90PushTool = uipushtool(app.Toolbar);
            app.rot90PushTool.ClickedCallback = createCallbackFcn(app, @rot90buttonImageClicked, true);
            app.rot90PushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'rot90.svg');
            app.offsetPushTool = uipushtool(app.Toolbar);
            app.offsetPushTool.ClickedCallback = createCallbackFcn(app, @offsetButtonPushed, true);
            app.offsetPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'offset.svg');
            app.cropPushTool = uipushtool(app.Toolbar);
            app.cropPushTool.ClickedCallback = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'crop_select.svg');
            app.flattenPushTool = uipushtool(app.Toolbar);
            app.flattenPushTool.ClickedCallback = createCallbackFcn(app, @flattenButtonPushed, true);
            app.flattenPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'BC.svg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @untiltButtonPushed, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'newGUI', 'untilt.svg');
            app.filterPushTool = uipushtool(app.Toolbar);
            app.filterPushTool.ClickedCallback = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'filter.svg');
            app.binningPushTool = uipushtool(app.Toolbar);
            app.binningPushTool.ClickedCallback = createCallbackFcn(app, @binningButtonPushed, true);
            app.binningPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'binning.svg');
            app.spacerPushTool2 = uipushtool(app.Toolbar);
            app.spacerPushTool2.Enable = 'off';
            app.spacerPushTool2.Icon = fullfile(pathToMLAPP, 'newGUI', 'blank.svg');
            app.pixelPushTool = uipushtool(app.Toolbar);
            app.pixelPushTool.ClickedCallback = createCallbackFcn(app, @pixelButtonPushed, true);
            app.pixelPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'pixel.svg');
            app.distancePushTool = uipushtool(app.Toolbar);
            app.distancePushTool.ClickedCallback = createCallbackFcn(app, @distanceButtonPushed, true);
            app.distancePushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'distanceButton.svg');
            app.areaPushTool = uipushtool(app.Toolbar);
            app.areaPushTool.ClickedCallback = createCallbackFcn(app, @areaButtonPushed, true);
            app.areaPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'areaButton.svg');
            app.crosscutPushTool = uipushtool(app.Toolbar);
            app.crosscutPushTool.ClickedCallback = createCallbackFcn(app, @crosscutsPathButtonPushed, true);
            app.crosscutPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'Pathcrosscut.svg');
            app.HVcrosscutPushTool = uipushtool(app.Toolbar);
            app.HVcrosscutPushTool.ClickedCallback = createCallbackFcn(app, @crosscutsHVButtonPushed, true);
            app.HVcrosscutPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'HVcrosscut.svg');
            app.radialCrosscutPushTool = uipushtool(app.Toolbar);
            app.radialCrosscutPushTool.ClickedCallback = createCallbackFcn(app, @crosscutRadialButtonPushed, true);
            app.radialCrosscutPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.roiPushTool = uipushtool(app.Toolbar);
            app.roiPushTool.ClickedCallback = createCallbackFcn(app, @DMroiButtonPushed, true);
            app.roiPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'roiButton.svg');
            app.MWPushTool = uipushtool(app.Toolbar);
            app.MWPushTool.ClickedCallback = createCallbackFcn(app, @DMmagicwandButtonPushed, true);
            app.MWPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'MWButton.svg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Visible = 'off';
            app.PushTool2.Enable = 'off';
            app.saveDataPushTool = uipushtool(app.Toolbar);
            app.saveDataPushTool.ClickedCallback = createCallbackFcn(app, @saveDataButtonPushed, true);
            app.saveDataPushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportData.svg');
            app.exportImagePushTool = uipushtool(app.Toolbar);
            app.exportImagePushTool.ClickedCallback = createCallbackFcn(app, @exportImageButtonPushed, true);
            app.exportImagePushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'save.svg');
            app.exportMoviePushTool = uipushtool(app.Toolbar);
            app.exportMoviePushTool.ClickedCallback = createCallbackFcn(app, @exportMovieButtonPushed, true);
            app.exportMoviePushTool.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportMovie.svg');
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.AxesRight = uiaxes(app.MainGridLayout);
            title(app.AxesRight, 'Transmittance')
            xlabel(app.AxesRight, 'X')
            ylabel(app.AxesRight, 'Y')
            zlabel(app.AxesRight, 'Z')
            app.AxesLeft = uiaxes(app.MainGridLayout);
            title(app.AxesLeft, 'Wavefront')
            xlabel(app.AxesLeft, 'X')
            ylabel(app.AxesLeft, 'Y')
            zlabel(app.AxesLeft, 'Z')
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.propertiesTab = uitab(app.TabGroup);
            app.propertiesTab.Title = 'properties';
            app.propertiesGridLayout = uigridlayout(app.propertiesTab);
            app.pixelsizeLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeLabel.Text = 'nm';
            app.wavelengthLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthLabel.Text = 'nm';
            app.magnificationLabel = uilabel(app.propertiesGridLayout);
            app.magnificationLabel.Text = 'X';
            app.MICROSCOPELabel = uilabel(app.propertiesGridLayout);
            app.MICROSCOPELabel.Text = 'MICROSCOPE';
            app.TLfocallengthLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthLabel.Text = 'mm';
            app.wavelengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.wavelengthEditField.Editable = 'off';
            app.pixelsizeEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.pixelsizeEditFieldLabel.Text = 'pixel size';
            app.pixelsizeEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.pixelsizeEditField.Editable = 'off';
            app.magnificationEditField_3Label = uilabel(app.propertiesGridLayout);
            app.magnificationEditField_3Label.Text = 'magnification';
            app.magnificationEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.magnificationEditField.Editable = 'off';
            app.numericalapertureEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.numericalapertureEditFieldLabel.Text = 'numerical aperture';
            app.numericalapertureEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.numericalapertureEditField.Editable = 'off';
            app.TLfocallengthEditFieldLabel = uilabel(app.propertiesGridLayout);
            app.TLfocallengthEditFieldLabel.Text = 'TL focal length';
            app.TLfocallengthEditField = uieditfield(app.propertiesGridLayout, 'numeric');
            app.TLfocallengthEditField.Editable = 'off';
            app.panelCGM = uipanel(app.propertiesGridLayout);
            app.CGMsystemGridLayout = uigridlayout(app.panelCGM);
            app.dexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeLabel.Text = 'nm';
            app.zoomLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomLabel.Text = 'X';
            app.grexelsizeLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeLabel.Text = 'µm';
            app.dexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.dexelsizeEditFieldLabel.Text = 'dexel size';
            app.dexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.dexelsizeEditField.Editable = 'off';
            app.zoomEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.zoomEditFieldLabel.Text = 'zoom';
            app.zoomEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zoomEditField.Editable = 'off';
            app.grexelsizeEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.grexelsizeEditFieldLabel.Text = 'grexel size';
            app.grexelsizeEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.grexelsizeEditField.Editable = 'off';
            app.CGMcameraEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMcameraEditFieldLabel.Text = 'CGM camera';
            app.CGMcameraName = uieditfield(app.CGMsystemGridLayout, 'text');
            app.CGMcameraName.Editable = 'off';
            app.CGMsystemLabel = uilabel(app.CGMsystemGridLayout);
            app.CGMsystemLabel.Text = 'CGM system';
            app.zetaLabel = uilabel(app.CGMsystemGridLayout);
            app.zetaLabel.Text = '$\zeta$ factor';
            app.zetafactorEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.zetafactorEditField.Editable = 'off';
            app.gratingdistanceEditFieldLabel = uilabel(app.CGMsystemGridLayout);
            app.gratingdistanceEditFieldLabel.Text = 'grating distance';
            app.gratingdistanceEditField = uieditfield(app.CGMsystemGridLayout, 'numeric');
            app.gratingdistanceEditField.Editable = 'off';
            app.gratingDistanceLabel = uilabel(app.CGMsystemGridLayout);
            app.gratingDistanceLabel.Text = 'mm';
            app.comment = uilabel(app.propertiesGridLayout);
            app.comment.Text = '';
            app.ItfFileName = uilabel(app.propertiesGridLayout);
            app.ItfFileName.Text = '';
            app.scalebarTCheckBox = uicheckbox(app.propertiesGridLayout);
            app.scalebarTCheckBox.ValueChangedFcn = createCallbackFcn(app, @scalebarCheckBoxesValueChanged, true);
            app.scalebarTCheckBox.Text = 'scalebar T';
            app.scalebarOPDCheckBox = uicheckbox(app.propertiesGridLayout);
            app.scalebarOPDCheckBox.ValueChangedFcn = createCallbackFcn(app, @scalebarCheckBoxesValueChanged, true);
            app.scalebarOPDCheckBox.Text = 'scalebar OPD';
            app.Warning = uilabel(app.propertiesGridLayout);
            app.Warning.Visible = 'off';
            app.Warning.Text = 'Warning';
            app.processingTab = uitab(app.TabGroup);
            app.processingTab.Title = 'processing';
            app.processingGridMLayout = uigridlayout(app.processingTab);
            app.ApplytoallimagesCheckBox = uicheckbox(app.processingGridMLayout);
            app.ApplytoallimagesCheckBox.Text = 'Apply to all images';
            app.MOTIONLabel = uilabel(app.processingGridMLayout);
            app.MOTIONLabel.Text = 'MOTION';
            app.CROPLabel = uilabel(app.processingGridMLayout);
            app.CROPLabel.Text = 'CROP';
            app.FLATTENLabel = uilabel(app.processingGridMLayout);
            app.FLATTENLabel.Text = 'FLATTEN';
            app.cropButton = uiimage(app.processingGridMLayout);
            app.cropButton.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButton.Tag = 'twoPoints';
            app.cropButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_select.svg');
            app.cropButtonCenterSquare = uiimage(app.processingGridMLayout);
            app.cropButtonCenterSquare.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterSquare.Tag = 'Square';
            app.cropButtonCenterSquare.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerSquare.svg');
            app.cropButtonCenterRect = uiimage(app.processingGridMLayout);
            app.cropButtonCenterRect.ImageClickedFcn = createCallbackFcn(app, @cropButtonImageClicked, true);
            app.cropButtonCenterRect.Tag = 'Rectangle';
            app.cropButtonCenterRect.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'crop_centerRectangle.svg');
            app.widthEditFieldLabel = uilabel(app.processingGridMLayout);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.widthEditField.Limits = [0 Inf];
            app.heightEditFieldLabel = uilabel(app.processingGridMLayout);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.heightEditField.Limits = [0 Inf];
            app.widthCropUnitLabel = uilabel(app.processingGridMLayout);
            app.widthCropUnitLabel.Text = 'px';
            app.heightCropUnitLabel = uilabel(app.processingGridMLayout);
            app.heightCropUnitLabel.Text = 'px';
            app.flattenMethodDropDown = uidropdown(app.processingGridMLayout);
            app.flattenMethodDropDown.Items = {'Legendre', 'Waves', 'Chebyshev', 'Zernike', 'Hermite'};
            app.flattenMethodDropDown.Value = 'Legendre';
            app.saveImageButton = uibutton(app.processingGridMLayout, 'push');
            app.saveImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImageButtonPushed, true);
            app.saveImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveData.svg');
            app.saveImageButton.Text = 'Save';
            app.saveAllImagesButton = uibutton(app.processingGridMLayout, 'push');
            app.saveAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @saveAllImagesButtonPushed, true);
            app.saveAllImagesButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveAllData.svg');
            app.saveAllImagesButton.Text = 'Save all';
            app.restoreImageButton = uibutton(app.processingGridMLayout, 'push');
            app.restoreImageButton.ButtonPushedFcn = createCallbackFcn(app, @restoreImageButtonPushed, true);
            app.restoreImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'undo.svg');
            app.restoreImageButton.Text = 'Restore';
            app.restoreAllImagesButton = uibutton(app.processingGridMLayout, 'push');
            app.restoreAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @restoreAllImagesButtonPushed, true);
            app.restoreAllImagesButton.Text = 'Restore all';
            app.modifyWorkspaceCheckBox = uicheckbox(app.processingGridMLayout);
            app.modifyWorkspaceCheckBox.Text = 'Workspace';
            app.FILTERLabel = uilabel(app.processingGridMLayout);
            app.FILTERLabel.Text = 'FILTER';
            app.filterMethodDropDown = uidropdown(app.processingGridMLayout);
            app.filterMethodDropDown.Items = {'HighPass', 'LowPass'};
            app.filterMethodDropDown.Value = 'HighPass';
            app.cutoffLabel = uilabel(app.processingGridMLayout);
            app.cutoffLabel.Text = 'cutoff';
            app.filterCutoffEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.filterCutoffEditField.Value = 10;
            app.filterCutoffUnitLabel = uilabel(app.processingGridMLayout);
            app.filterCutoffUnitLabel.Text = 'px';
            app.BINNINGLabel = uilabel(app.processingGridMLayout);
            app.BINNINGLabel.Text = 'BINNING';
            app.OFFSETLabel = uilabel(app.processingGridMLayout);
            app.OFFSETLabel.Text = 'OFFSET';
            app.mirrorHButton = uibutton(app.processingGridMLayout, 'push');
            app.mirrorHButton.ButtonPushedFcn = createCallbackFcn(app, @mirrorHbuttonImageClicked, true);
            app.mirrorHButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorH.svg');
            app.mirrorHButton.Text = '';
            app.mirrorVButton = uibutton(app.processingGridMLayout, 'push');
            app.mirrorVButton.ButtonPushedFcn = createCallbackFcn(app, @mirrorVbuttonImageClicked, true);
            app.mirrorVButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'mirrorV.svg');
            app.mirrorVButton.Text = '';
            app.rot90Button = uibutton(app.processingGridMLayout, 'push');
            app.rot90Button.ButtonPushedFcn = createCallbackFcn(app, @rot90buttonImageClicked, true);
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'newGUI', 'rot90.svg');
            app.rot90Button.Text = '';
            app.binningButton = uibutton(app.processingGridMLayout, 'push');
            app.binningButton.ButtonPushedFcn = createCallbackFcn(app, @binningButtonPushed, true);
            app.binningButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'binning.svg');
            app.binningButton.Text = '';
            app.binningSpinner = uispinner(app.processingGridMLayout);
            app.binningSpinner.Limits = [2 3];
            app.binningSpinner.Value = 2;
            app.flattenButton = uibutton(app.processingGridMLayout, 'push');
            app.flattenButton.ButtonPushedFcn = createCallbackFcn(app, @flattenButtonPushed, true);
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'BC.svg');
            app.flattenButton.Text = '';
            app.filterButton = uibutton(app.processingGridMLayout, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'filter.svg');
            app.filterButton.Text = '';
            app.offsetButton = uibutton(app.processingGridMLayout, 'push');
            app.offsetButton.ButtonPushedFcn = createCallbackFcn(app, @offsetButtonPushed, true);
            app.offsetButton.Tag = 'W';
            app.offsetButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'offset.svg');
            app.offsetButton.Text = '';
            app.offsetMethodDropDown = uidropdown(app.processingGridMLayout);
            app.offsetMethodDropDown.Items = {'mean', 'median', 'boundaryMedian', 'histogram'};
            app.offsetMethodDropDown.Value = 'mean';
            app.REFOCUSINGLabel = uilabel(app.processingGridMLayout);
            app.REFOCUSINGLabel.Text = 'REFOCUSING';
            app.refocusingEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.refocusingEditField.ValueChangedFcn = createCallbackFcn(app, @refocusingEditFieldValueChanged, true);
            app.refocusingForwardButton = uiimage(app.processingGridMLayout);
            app.refocusingForwardButton.ImageClickedFcn = createCallbackFcn(app, @refocusingButtonImageClicked, true);
            app.refocusingForwardButton.Tag = '1';
            app.refocusingForwardButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'rightArrow.svg');
            app.refocusingBackwardButton = uiimage(app.processingGridMLayout);
            app.refocusingBackwardButton.ImageClickedFcn = createCallbackFcn(app, @refocusingButtonImageClicked, true);
            app.refocusingBackwardButton.Tag = '-1';
            app.refocusingBackwardButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'leftArrow.svg');
            app.duplicateImageButton = uibutton(app.processingGridMLayout, 'push');
            app.duplicateImageButton.ButtonPushedFcn = createCallbackFcn(app, @duplicateImageButtonPushed, true);
            app.duplicateImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'clipboard.svg');
            app.duplicateImageButton.Text = 'Duplic.';
            app.untiltButton = uibutton(app.processingGridMLayout, 'push');
            app.untiltButton.ButtonPushedFcn = createCallbackFcn(app, @untiltButtonPushed, true);
            app.untiltButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'untilt.svg');
            app.untiltButton.Text = 'Untilt';
            app.savewsButton = uibutton(app.processingGridMLayout, 'push');
            app.savewsButton.ButtonPushedFcn = createCallbackFcn(app, @savewsButtonPushed, true);
            app.savewsButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'saveData.svg');
            app.savewsButton.Text = '';
            app.savewsEditField = uieditfield(app.processingGridMLayout, 'text');
            app.savewsEditField.Value = 'IMnew';
            app.SetparametersButton = uibutton(app.processingGridMLayout, 'push');
            app.SetparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetFlattenParametersButtonPushed, true);
            app.SetparametersButton.Text = 'Set parameters';
            app.GridLayout_paramsFlatten = uigridlayout(app.processingGridMLayout);
            app.GradThreshLabel_2 = uilabel(app.GridLayout_paramsFlatten);
            app.GradThreshLabel_2.Text = 'smoothing';
            app.flattenSmoothingEditField = uieditfield(app.GridLayout_paramsFlatten, 'numeric');
            app.flattenSmoothingEditField.Limits = [0 Inf];
            app.GradThreshLabel = uilabel(app.GridLayout_paramsFlatten);
            app.GradThreshLabel.Text = 'threshold';
            app.flattenThresholdEditField = uieditfield(app.GridLayout_paramsFlatten, 'numeric');
            app.flattenThresholdEditField.Limits = [0 Inf];
            app.m_FlattenOrderEditField = uieditfield(app.GridLayout_paramsFlatten, 'numeric');
            app.m_FlattenOrderEditField.Limits = [0 Inf];
            app.m_FlattenOrderEditField.RoundFractionalValues = 'on';
            app.m_FlattenOrderEditField.Value = 2;
            app.namxLabel = uilabel(app.GridLayout_paramsFlatten);
            app.namxLabel.Text = 'n,m';
            app.n_FlattenOrderEditField = uieditfield(app.GridLayout_paramsFlatten, 'numeric');
            app.n_FlattenOrderEditField.Limits = [0 Inf];
            app.n_FlattenOrderEditField.RoundFractionalValues = 'on';
            app.n_FlattenOrderEditField.Value = 2;
            app.GradThreshLabel_3 = uilabel(app.GridLayout_paramsFlatten);
            app.GradThreshLabel_3.Text = 'cut';
            app.flattenCutEditField = uieditfield(app.GridLayout_paramsFlatten, 'numeric');
            app.flattenCutEditField.Limits = [0 Inf];
            app.flattenCutEditField.Value = 0.5;
            app.GridLayout3 = uigridlayout(app.GridLayout_paramsFlatten);
            app.flattenInvertCheckBox = uicheckbox(app.GridLayout3);
            app.flattenInvertCheckBox.Text = 'invert';
            app.maskTypeDropDown = uidropdown(app.GridLayout3);
            app.maskTypeDropDown.Items = {'gradients', 'T', 'OPD', 'Rytov', 'OPDnm'};
            app.maskTypeDropDown.Value = 'gradients';
            app.ManualCheckBox = uicheckbox(app.GridLayout_paramsFlatten);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.marginEditFieldLabel = uilabel(app.processingGridMLayout);
            app.marginEditFieldLabel.Text = 'margin';
            app.marginEditField = uieditfield(app.processingGridMLayout, 'numeric');
            app.marginEditField.Limits = [0 Inf];
            app.widthCropUnitLabel_2 = uilabel(app.processingGridMLayout);
            app.widthCropUnitLabel_2.Text = 'px';
            app.normTbutton = uibutton(app.processingGridMLayout, 'push');
            app.normTbutton.ButtonPushedFcn = createCallbackFcn(app, @offsetButtonPushed, true);
            app.normTbutton.Tag = 'T';
            app.normTbutton.Icon = fullfile(pathToMLAPP, 'newGUI', 'normT.svg');
            app.normTbutton.Text = '';
            app.metrologyTab = uitab(app.TabGroup);
            app.metrologyTab.Title = 'metrology';
            app.metrologyGridLayout = uigridlayout(app.metrologyTab);
            app.pixelButton = uibutton(app.metrologyGridLayout, 'push');
            app.pixelButton.ButtonPushedFcn = createCallbackFcn(app, @pixelButtonPushed, true);
            app.pixelButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'pixel.svg');
            app.pixelButton.Text = 'pixel';
            app.distanceButton = uibutton(app.metrologyGridLayout, 'push');
            app.distanceButton.ButtonPushedFcn = createCallbackFcn(app, @distanceButtonPushed, true);
            app.distanceButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'distanceButton.svg');
            app.distanceButton.Text = 'distance';
            app.areaButton = uibutton(app.metrologyGridLayout, 'push');
            app.areaButton.ButtonPushedFcn = createCallbackFcn(app, @areaButtonPushed, true);
            app.areaButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'areaButton.svg');
            app.areaButton.Text = 'area';
            app.crosscutsHVButton = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutsHVButton.ButtonPushedFcn = createCallbackFcn(app, @crosscutsHVButtonPushed, true);
            app.crosscutsHVButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'HVcrosscut.svg');
            app.crosscutsHVButton.Text = 'H / V';
            app.crosscutRadial = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutRadial.ButtonPushedFcn = createCallbackFcn(app, @crosscutRadialButtonPushed, true);
            app.crosscutRadial.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.crosscutRadial.Text = 'radial';
            app.METROLOGYLabel = uilabel(app.metrologyGridLayout);
            app.METROLOGYLabel.Text = 'METROLOGY';
            app.CROSSCUTSLabel = uilabel(app.metrologyGridLayout);
            app.CROSSCUTSLabel.Text = 'CROSS CUTS';
            app.crosscutPathButton = uibutton(app.metrologyGridLayout, 'push');
            app.crosscutPathButton.ButtonPushedFcn = createCallbackFcn(app, @crosscutsPathButtonPushed, true);
            app.crosscutPathButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Pathcrosscut.svg');
            app.crosscutPathButton.Text = 'path';
            app.alphaOVDMLabel = uilabel(app.metrologyGridLayout);
            app.alphaOVDMLabel.Text = 'alpha / OV / DM';
            app.DMradialButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMradialButton.ButtonPushedFcn = createCallbackFcn(app, @DMradialButtonPushed, true);
            app.DMradialButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'Rcrosscut.svg');
            app.DMradialButton.Text = 'radial';
            app.DMroiButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMroiButton.ButtonPushedFcn = createCallbackFcn(app, @DMroiButtonPushed, true);
            app.DMroiButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'roiButton.svg');
            app.DMroiButton.Text = 'roi';
            app.DMmagicwandButton = uibutton(app.metrologyGridLayout, 'push');
            app.DMmagicwandButton.ButtonPushedFcn = createCallbackFcn(app, @DMmagicwandButtonPushed, true);
            app.DMmagicwandButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'MWButton.svg');
            app.DMmagicwandButton.Text = {'magic'; 'wand'};
            app.DMGridLayout = uigridlayout(app.metrologyGridLayout);
            app.NmaxEditFieldLabel = uilabel(app.DMGridLayout);
            app.NmaxEditFieldLabel.Text = 'Nmax';
            app.NmaxEditField = uieditfield(app.DMGridLayout, 'numeric');
            app.NmaxEditField.Limits = [0 Inf];
            app.NmaxEditField.Value = 100;
            app.bkgRingEditField = uieditfield(app.DMGridLayout, 'numeric');
            app.bkgRingEditField.Limits = [0 Inf];
            app.bkgRingEditField.Value = 4;
            app.bkgRingLabel = uilabel(app.DMGridLayout);
            app.bkgRingLabel.Text = 'bkg ring';
            app.stepEditFieldLabel = uilabel(app.DMGridLayout);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.DMGridLayout, 'numeric');
            app.stepEditField.Limits = [0 Inf];
            app.stepEditField.Value = 1;
            app.gammaEditField = uieditfield(app.DMGridLayout, 'numeric');
            app.gammaEditField.Value = 0.18;
            app.gammaLabel = uilabel(app.DMGridLayout);
            app.gammaLabel.Interpreter = 'latex';
            app.gammaLabel.Text = '$\gamma$';
            app.DznmEditFieldLabel = uilabel(app.DMGridLayout);
            app.DznmEditFieldLabel.Text = 'Dz [nm]';
            app.DznmEditField = uieditfield(app.DMGridLayout, 'numeric');
            app.DznmEditField.Limits = [0 Inf];
            app.SAVEDATALabel = uilabel(app.metrologyGridLayout);
            app.SAVEDATALabel.Text = 'SAVE DATA';
            app.AutoSaveCheckBox = uicheckbox(app.metrologyGridLayout);
            app.AutoSaveCheckBox.Text = 'auto save';
            app.saveFolderImage = uiimage(app.metrologyGridLayout);
            app.saveFolderImage.ImageClickedFcn = createCallbackFcn(app, @saveFolderImageClicked, true);
            app.saveFolderImage.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'folder.svg');
            app.saveFolderEditField = uieditfield(app.metrologyGridLayout, 'text');
            app.FileNameEditFieldLabel = uilabel(app.metrologyGridLayout);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.saveFileNameEditField = uieditfield(app.metrologyGridLayout, 'text');
            app.saveDataButton = uibutton(app.metrologyGridLayout, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButtonPushed, true);
            app.saveDataButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportData.svg');
            app.saveDataButton.Text = 'save';
            app.exportTab = uitab(app.TabGroup);
            app.exportTab.Title = 'export';
            app.exportGridLayout = uigridlayout(app.exportTab);
            app.exportImageButton = uibutton(app.exportGridLayout, 'push');
            app.exportImageButton.ButtonPushedFcn = createCallbackFcn(app, @exportImageButtonPushed, true);
            app.exportImageButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'save.svg');
            app.exportImageButton.Text = 'Export';
            app.exportFolderEditField = uieditfield(app.exportGridLayout, 'text');
            app.FileNameEditFieldLabel_2 = uilabel(app.exportGridLayout);
            app.FileNameEditFieldLabel_2.Text = 'File Name';
            app.exportFileNameEditField = uieditfield(app.exportGridLayout, 'text');
            app.exportFileNameEditField.Value = 'im';
            app.EXPORTIMAGELabel = uilabel(app.exportGridLayout);
            app.EXPORTIMAGELabel.Text = 'EXPORT IMAGE';
            app.exportFolderImage = uiimage(app.exportGridLayout);
            app.exportFolderImage.ImageClickedFcn = createCallbackFcn(app, @exportFolderImageClicked, true);
            app.exportFolderImage.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'folder.svg');
            app.export_txtCheckBox = uicheckbox(app.exportGridLayout);
            app.export_txtCheckBox.Text = 'txt';
            app.export_txtCheckBox.Value = true;
            app.export_epsCheckBox = uicheckbox(app.exportGridLayout);
            app.export_epsCheckBox.Text = 'eps';
            app.exportSelectionRadioButtonGroup = uibuttongroup(app.exportGridLayout);
            app.exportCurrentButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportCurrentButton.Text = 'current';
            app.exportCurrentButton.Value = true;
            app.exportAllButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportAllButton.Text = 'all';
            app.exportSelectionButton = uiradiobutton(app.exportSelectionRadioButtonGroup);
            app.exportSelectionButton.Text = 'selection';
            app.export_pngCheckBox = uicheckbox(app.exportGridLayout);
            app.export_pngCheckBox.Text = 'png';
            app.exportSuffixLabel = uilabel(app.exportGridLayout);
            app.exportSuffixLabel.Text = '_000N';
            app.EXPORTVIDEOLabel = uilabel(app.exportGridLayout);
            app.EXPORTVIDEOLabel.Text = 'EXPORT VIDEO';
            app.exportMovieButton = uibutton(app.exportGridLayout, 'push');
            app.exportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @exportMovieButtonPushed, true);
            app.exportMovieButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'exportMovie.svg');
            app.exportMovieButton.Text = 'Export';
            app.rateEditFieldLabel = uilabel(app.exportGridLayout);
            app.rateEditFieldLabel.Text = 'rate';
            app.rateVideoEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.rateVideoEditField.Value = 5;
            app.phiEditFieldLabel = uilabel(app.exportGridLayout);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.phiEditField.Value = 45;
            app.thetaEditFieldLabel = uilabel(app.exportGridLayout);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.thetaEditField.Value = 45;
            app.cbfactorEditFieldLabel = uilabel(app.exportGridLayout);
            app.cbfactorEditFieldLabel.Text = 'cb factor';
            app.cbfactorEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.cbfactorEditField.Value = 1;
            app.OPDCheckBox = uicheckbox(app.exportGridLayout);
            app.OPDCheckBox.Text = 'OPD';
            app.OPDCheckBox.Value = true;
            app.TCheckBox = uicheckbox(app.exportGridLayout);
            app.TCheckBox.Text = 'T';
            app.TCheckBox.Value = true;
            app.cblabelEditFieldLabel = uilabel(app.exportGridLayout);
            app.cblabelEditFieldLabel.Text = 'cb label';
            app.cblabelEditField = uieditfield(app.exportGridLayout, 'text');
            app.cblabelEditField.Value = 'Optical Path difference (nm)';
            app.TitleEditFieldLabel = uilabel(app.exportGridLayout);
            app.TitleEditFieldLabel.Text = 'Title';
            app.titleVideoEditField = uieditfield(app.exportGridLayout, 'text');
            app.HzLabel = uilabel(app.exportGridLayout);
            app.HzLabel.Text = 'Hz';
            app.perspCheckBox = uicheckbox(app.exportGridLayout);
            app.perspCheckBox.Text = 'persp';
            app.perspCheckBox.Value = true;
            app.overviewButton = uibutton(app.exportGridLayout, 'push');
            app.overviewButton.ButtonPushedFcn = createCallbackFcn(app, @overviewButtonPushed, true);
            app.overviewButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'overview.svg');
            app.overviewButton.Text = 'overview';
            app.export_matCheckBox = uicheckbox(app.exportGridLayout);
            app.export_matCheckBox.Text = 'mat';
            app.export_figureCheckBox = uicheckbox(app.exportGridLayout);
            app.export_figureCheckBox.Text = 'fig';
            app.imtimeLabel = uilabel(app.exportGridLayout);
            app.imtimeLabel.Text = 'im. time';
            app.timeEditField = uieditfield(app.exportGridLayout, 'numeric');
            app.DISPLAYIMAGELabel = uilabel(app.exportGridLayout);
            app.DISPLAYIMAGELabel.Text = 'DISPLAY IMAGE';
            app.channelsButton = uibutton(app.exportGridLayout, 'push');
            app.channelsButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'channels.svg');
            app.channelsButton.Text = 'channels';
            app.polarButton = uibutton(app.exportGridLayout, 'push');
            app.polarButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'polarView.svg');
            app.polarButton.Text = 'polar';
            app.colorButton = uibutton(app.exportGridLayout, 'push');
            app.colorButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'colorView.svg');
            app.colorButton.Text = 'color';
            app.navigButton = uibutton(app.exportGridLayout, 'push');
            app.navigButton.ButtonPushedFcn = createCallbackFcn(app, @navigButtonPushed, true);
            app.navigButton.Icon = fullfile(pathToMLAPP, 'newGUI', 'dynamicView.svg');
            app.navigButton.Text = 'navig.';
            app.navigEditField = uieditfield(app.exportGridLayout, 'text');
            app.navigEditField.Value = 'OPD, T';
            app.imtimeLabel_2 = uilabel(app.exportGridLayout);
            app.imtimeLabel_2.Text = 'pt';
            app.timeSize = uieditfield(app.exportGridLayout, 'numeric');
            app.timeSize.Limits = [0 Inf];
            app.timeSize.RoundFractionalValues = 'on';
            app.timeUnitEditField = uieditfield(app.exportGridLayout, 'text');
            app.timeUnitEditField.ValueChangedFcn = createCallbackFcn(app, @timeUnitEditFieldValueChanged, true);
            app.timeUnitEditField.Value = 's';
            app.ControlsGridLayout = uigridlayout(app.MainGridLayout);
            app.prevImageButton = uiimage(app.ControlsGridLayout);
            app.prevImageButton.ImageClickedFcn = createCallbackFcn(app, @prevImageButtonImageClicked, true);
            app.prevImageButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'leftArrow.svg');
            app.reload = uiimage(app.ControlsGridLayout);
            app.reload.ImageClickedFcn = createCallbackFcn(app, @reloadImageClicked, true);
            app.reload.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'reload.svg');
            app.nextImageButton = uiimage(app.ControlsGridLayout);
            app.nextImageButton.ImageClickedFcn = createCallbackFcn(app, @nextImageButtonImageClicked, true);
            app.nextImageButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'rightArrow.svg');
            app.jText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.jText.ValueChangedFcn = createCallbackFcn(app, @jTextValueChanged, true);
            app.NimText = uieditfield(app.ControlsGridLayout, 'numeric');
            app.NimText.Editable = 'off';
            app.commentEditFieldLabel = uilabel(app.ControlsGridLayout);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.ControlsGridLayout, 'text');
            app.commentEditField.ValueChangedFcn = createCallbackFcn(app, @commentEditFieldValueChanged, true);
            app.RightToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.colormapDropDown_4Label = uilabel(app.RightToolsGridLayout);
            app.colormapDropDown_4Label.Text = 'colormap';
            app.colormapDropDownRight = uidropdown(app.RightToolsGridLayout);
            app.colormapDropDownRight.Items = {'gray', 'parula', 'hot', 'invertedGreen'};
            app.colormapDropDownRight.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownRightValueChanged, true);
            app.colormapDropDownRight.Value = 'gray';
            app.resetSlidersRight = uibutton(app.RightToolsGridLayout, 'push');
            app.resetSlidersRight.ButtonPushedFcn = createCallbackFcn(app, @resetRightLimitsButtonPushed, true);
            app.resetSlidersRight.Text = 'reset';
            app.invertCheckBoxRight = uicheckbox(app.RightToolsGridLayout);
            app.invertCheckBoxRight.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxRightValueChanged, true);
            app.invertCheckBoxRight.Text = 'invert';
            app.minRightEditField = uieditfield(app.RightToolsGridLayout, 'numeric');
            app.minRightEditField.ValueChangedFcn = createCallbackFcn(app, @limitsRightValueChanged, true);
            app.maxRightEditField = uieditfield(app.RightToolsGridLayout, 'numeric');
            app.maxRightEditField.ValueChangedFcn = createCallbackFcn(app, @limitsRightValueChanged, true);
            app.holdClimRightCheckBox = uicheckbox(app.RightToolsGridLayout);
            app.holdClimRightCheckBox.Text = 'hold';
            app.RenderingGridLayout = uigridlayout(app.MainGridLayout);
            app.displayButton = uibuttongroup(app.RenderingGridLayout);
            app.displayButton.SelectionChangedFcn = createCallbackFcn(app, @displayButtonSelectionChanged, true);
            app.displayButton3D = uiradiobutton(app.displayButton);
            app.displayButton3D.Text = '3D';
            app.displayButtonStandard = uiradiobutton(app.displayButton);
            app.displayButtonStandard.Text = 'standard';
            app.displayButtonStandard.Value = true;
            app.scaleButton = uibuttongroup(app.RenderingGridLayout);
            app.scaleButton.SelectionChangedFcn = createCallbackFcn(app, @scaleButtonSelectionChanged, true);
            app.scaleButtonum = uiradiobutton(app.scaleButton);
            app.scaleButtonum.Text = 'µm';
            app.scaleButtonpx = uiradiobutton(app.scaleButton);
            app.scaleButtonpx.Text = 'px';
            app.scaleButtonpx.Value = true;
            app.ChannelButtonGroup = uibuttongroup(app.RenderingGridLayout);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.Channel4Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel4Button.Text = 'Channel 4';
            app.Channel3Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel3Button.Text = 'Channel 3';
            app.Channel2Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel2Button.Text = 'Channel 2';
            app.Channel1Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel1Button.Text = 'Channel 1';
            app.Channel1Button.Value = true;
            app.Channel5Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel5Button.Text = 'Channel 5';
            app.Channel6Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel6Button.Text = 'Channel 6';
            app.Channel7Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel7Button.Text = 'Channel 7';
            app.Channel8Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel8Button.Text = 'Channel 8';
            app.GridLayout2 = uigridlayout(app.MainGridLayout);
            app.messageArea = uitextarea(app.GridLayout2);
            app.messageArea.Editable = 'off';
            app.copyButton = uiimage(app.GridLayout2);
            app.copyButton.ImageClickedFcn = createCallbackFcn(app, @copyButtonImageClicked, true);
            app.copyButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'clipboard.svg');
            app.clearButton = uiimage(app.GridLayout2);
            app.clearButton.ImageClickedFcn = createCallbackFcn(app, @clearButtonImageClicked, true);
            app.clearButton.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'erase.svg');
            app.Image5 = uiimage(app.MainGridLayout);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'logoPhaseLAB2.png');
            app.LeftToolsGridLayout = uigridlayout(app.MainGridLayout);
            app.resetSlidersLeft = uibutton(app.LeftToolsGridLayout, 'push');
            app.resetSlidersLeft.ButtonPushedFcn = createCallbackFcn(app, @resetLeftLimitsButtonPushed, true);
            app.resetSlidersLeft.Text = 'reset';
            app.colormapDropDown_3Label = uilabel(app.LeftToolsGridLayout);
            app.colormapDropDown_3Label.Text = 'colormap';
            app.colormapDropDownLeft = uidropdown(app.LeftToolsGridLayout);
            app.colormapDropDownLeft.Items = {'Sepia', 'phase1024', 'Pradeep', 'gray', 'parula', 'jet', 'rainbow', 'darkBkg'};
            app.colormapDropDownLeft.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownLeftValueChanged, true);
            app.colormapDropDownLeft.Value = 'Sepia';
            app.invertCheckBoxLeft = uicheckbox(app.LeftToolsGridLayout);
            app.invertCheckBoxLeft.ValueChangedFcn = createCallbackFcn(app, @invertCheckBoxLeftValueChanged, true);
            app.invertCheckBoxLeft.Text = 'invert';
            app.minLeftEditField = uieditfield(app.LeftToolsGridLayout, 'numeric');
            app.minLeftEditField.ValueChangedFcn = createCallbackFcn(app, @limitsLeftValueChanging, true);
            app.maxLeftEditField = uieditfield(app.LeftToolsGridLayout, 'numeric');
            app.maxLeftEditField.ValueChangedFcn = createCallbackFcn(app, @limitsLeftValueChanging, true);
            app.holdClimLeftCheckBox = uicheckbox(app.LeftToolsGridLayout);
            app.holdClimLeftCheckBox.Text = 'hold';
            app.DrymassdensityCheckBox = uicheckbox(app.LeftToolsGridLayout);
            app.DrymassdensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrymassdensityCheckBoxValueChanged, true);
            app.DrymassdensityCheckBox.Text = 'Dry mass density';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseLABgui_multiCanal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end