classdef figurePolarGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout_main          matlab.ui.container.GridLayout
        GridLayout_Controls      matlab.ui.container.GridLayout
        GridLayout8              matlab.ui.container.GridLayout
        imageTypeDropDown        matlab.ui.control.DropDown
        ImagetypeDropDownLabel   matlab.ui.control.Label
        nmLabel                  matlab.ui.control.Label
        ChannelButtonGroup       matlab.ui.container.ButtonGroup
        Button_Channel1          matlab.ui.control.RadioButton
        Button_Channel2          matlab.ui.control.RadioButton
        Button_Channel3          matlab.ui.control.RadioButton
        Button_Channel4          matlab.ui.control.RadioButton
        all_limCheckBox          matlab.ui.control.CheckBox
        limValueEditField        matlab.ui.control.NumericEditField
        limValueLabel            matlab.ui.control.Label
        GridLayout7              matlab.ui.container.GridLayout
        Image2                   matlab.ui.control.Image
        exportFolderEditField    matlab.ui.control.EditField
        DISPLAYLabel             matlab.ui.control.Label
        EXPORTLabel              matlab.ui.control.Label
        GridLayout_exportFormat  matlab.ui.container.GridLayout
        eCheckBox                matlab.ui.control.CheckBox
        rgbImageEllipseCheckBox  matlab.ui.control.CheckBox
        dWCheckBox               matlab.ui.control.CheckBox
        WbarCheckBox             matlab.ui.control.CheckBox
        txtCheckBox              matlab.ui.control.CheckBox
        pngCheckBox              matlab.ui.control.CheckBox
        figCheckBox              matlab.ui.control.CheckBox
        epsCheckBox              matlab.ui.control.CheckBox
        ExportallButton          matlab.ui.control.Button
        ExportButton             matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        maxEditField             matlab.ui.control.NumericEditField
        maxEditFieldLabel        matlab.ui.control.Label
        minEditField             matlab.ui.control.NumericEditField
        minLabel                 matlab.ui.control.Label
        nmLabel_2                matlab.ui.control.Label
        holdCheckBox             matlab.ui.control.CheckBox
        MeanOPDButton            matlab.ui.control.StateButton
        polarNumberEditField     matlab.ui.control.NumericEditField
        nextPolarButton          matlab.ui.control.Button
        previousPolarButton      matlab.ui.control.Button
        GridLayout_Title         matlab.ui.container.GridLayout
        ImagePolarcontrolLabel   matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        GridLayout               matlab.ui.container.GridLayout
        EditField                matlab.ui.control.EditField
        NimEditField             matlab.ui.control.NumericEditField
        nimEditField             matlab.ui.control.NumericEditField
        nextButton               matlab.ui.control.Button
        previousButton           matlab.ui.control.Button
        UIAxes_OPD               matlab.ui.control.UIAxes
        UIAxes_Theta             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pIm0 % Original, unmodfied ImageEM object
        pIm % copy of the current ImageEM object
        cb_OPD % Theta Axes colorbar
        cb_Theta % Theta Axes colorbar
        Nim
        IM  % (smoothed) ImageQLSI
        smfun = @(x) x % Smoothing function, either imgaussfilt or Id
        Nch % Number of channels
    end

    properties(Dependent)
        nim
        ich % # of the displayed channel
    end
    
    methods (Access = private)
        
        function updateImages(app)

            % LEFT IMAGE
            app.pIm = app.pIm0(app.nim,app.ich); % copy of the ImageEM object
            if app.MeanOPDButton.Value
                numim = app.polarNumberEditField.Value;
                %imagesc(app.UIAxes_OPD, app.pIm.IM(numim).OPDnm);           
                app.UIAxes_OPD.findobj('Type','image').CData = app.pIm.IM(numim).OPDnm;
            else
                imagesc(app.UIAxes_OPD, 1e9*app.pIm.Wbar);
            end

            if ~app.holdCheckBox.Value
                app.minEditField.Value = min(app.UIAxes_OPD.findobj('Type','image').CData(:));
                app.maxEditField.Value = max(app.UIAxes_OPD.findobj('Type','image').CData(:));
            end
            app.update_climOPD()


            % RIGHT IMAGE
            switch app.imageTypeDropDown.Value
                case 'Polarization ellipse'
                    app.limValueEditField.Value = app.pIm.eLim;
                    imagesc(app.UIAxes_Theta, app.pIm.rgbImageEllipse);
                    clim(app.UIAxes_Theta, 'auto')
                    % update of the colorbar
                    app.cb_Theta.Ticks = [0,0.25,0.5,0.75,1];
                    app.cb_Theta.TickLabels = {"0","\pi/4","\pi/2","3\pi/4","\pi"};
                    app.limValueLabel.Text = "e lim";
                    app.nmLabel.Visible = "off";
                    app.rgbImageEllipseCheckBox.Text = 'rgbImageEllipse';
                    colormap(app.UIAxes_Theta,"hsv");

                case 'Retardance'
                    app.limValueEditField.Value = app.pIm.retardanceMax*1e9;
                    imagesc(app.UIAxes_Theta, app.pIm.retardance);
                    clim(app.UIAxes_Theta, [min(min(imgaussfilt(app.pIm.retardance,10))) app.limValueEditField.Value*1e-9])
                    % update of the colorbar
                    app.cb_Theta.TicksMode = 'auto';
                    app.cb_Theta.TickLabelsMode = 'auto';
                    app.limValueLabel.Text = "Ret max";
                    app.nmLabel.Visible = "on";
                    app.rgbImageEllipseCheckBox.Text = 'rgbImage';
                    colormap(app.UIAxes_Theta,sepia);

                case 'Dichroism'
                    app.limValueEditField.Value = app.pIm.dichroMax;
                    imagesc(app.UIAxes_Theta, app.pIm.dichroism);
                    clim(app.UIAxes_Theta, [min(min(imgaussfilt(app.pIm.dichroism,10))) app.limValueEditField.Value])
                    % update of the colorbar
                    app.cb_Theta.TicksMode = 'auto';
                    app.cb_Theta.TickLabelsMode = 'auto';
                    app.limValueLabel.Text = "Dichro max";
                    app.nmLabel.Visible = "off";
                    app.rgbImageEllipseCheckBox.Text = 'rgbImage';
                    colormap(app.UIAxes_Theta,"gray");
 
                otherwise
                    warning('no image type selected')
            end
            
            
            xlim(app.UIAxes_OPD,[1 app.pIm.Nx]);
            ylim(app.UIAxes_OPD,[1 app.pIm.Ny]);
            xlim(app.UIAxes_Theta,[1 app.pIm.Nx]);
            ylim(app.UIAxes_Theta,[1 app.pIm.Ny]);
            linkaxes([app.UIAxes_OPD, app.UIAxes_Theta])

            sb = Scalebar(20e-6, app.pIm.IM(1).pxSize);
            sb.insert(app.UIAxes_OPD)


            drawnow
        end

        
        function enablePolarButtons(app, val)
            if val
                app.nextPolarButton.Enable      = "on";
                app.previousPolarButton.Enable  = "on";
                app.polarNumberEditField.Enable = "on";
            else
                app.nextPolarButton.Enable      = "off";
                app.previousPolarButton.Enable  = "off";
                app.polarNumberEditField.Enable = "off";
            end
        end
        
        function update_climOPD(app)
            if app.minEditField.Value ~= app.maxEditField.Value % in case of uniform image
                clim(app.UIAxes_OPD, [app.minEditField.Value, app.maxEditField.Value])
            end
        end
        
        function hout = displayTrialImage(app)
            h = figure;
            fullwidth
            hwidth = h.Position(3);
            h.Position(4) = hwidth/3;
            h.Color = [1 1 1];
            h.ToolBar = "none";

            axOPD = copy(app.UIAxes_OPD);
            axOPD.Parent=h;
            axOPD.Position = [10,10,hwidth/3,hwidth/3];
            cb = colorbar(axOPD);
            copyColorBar(app.cb_OPD, cb)
            cb.Color = 'k';
            cb.Label.String = "Wavefront [nm]";

            axTheta = copy(app.UIAxes_Theta);
            axTheta.Parent = h;
            axTheta.Position = [10+2*hwidth/3,10,hwidth/3,hwidth/3];
            cb3 = colorbar(axTheta);
            copyColorBar(app.cb_Theta, cb3)
            cb3.Color = 'k';
            cb3.Label.String = "Pol. ellipse angle [rad]";

            if nargin
                hout = h;
            end

        end

    end

    methods

        function val = get.nim(app)
            val = app.nimEditField.Value; 
        end
        function val = get.ich(app)
            val = str2double(app.ChannelButtonGroup.SelectedObject.Text);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, polarImages)
            app.pIm0 = polarImages;
            app.Nch = size(polarImages,2);
            app.pIm = app.pIm0(1);
            app.limValueEditField.Value = app.pIm0(1).eLim;

            if app.Nch == 1
                app.ChannelButtonGroup.Visible = "off";
            elseif app.Nch == 2
                app.Button_Channel3.Visible = "off";
                app.Button_Channel4.Visible = "off";
            elseif app.Nch == 3
                app.Button_Channel4.Visible = "off";
            end

            % Setting the Axes
            app.cb_OPD   = colorbar(app.UIAxes_OPD,  'Location','southoutside','Color',[1 1 1]);
            app.cb_OPD.FontSize = 16;
            colormap(app.UIAxes_OPD,sepia);
            app.cb_Theta = colorbar(app.UIAxes_Theta,'Location','southoutside','Color',[1 1 1]);
            app.cb_Theta.FontSize = 16;

            app.UIAxes_OPD.XColor = [1 1 1];
            app.UIAxes_OPD.YColor = [1 1 1];
            app.UIAxes_OPD.Title.Color = [1 1 1];

            app.UIAxes_Theta.XColor = [1 1 1];
            app.UIAxes_Theta.YColor = [1 1 1];
            app.UIAxes_Theta.Title.Color = [1 1 1];


            set(app.UIAxes_OPD,'DataAspectRatio',[1 1 1])
            set(app.UIAxes_Theta,     'DataAspectRatio',[1 1 1])

            drawnow

            app.Nim = size(app.pIm0,1);
            app.NimEditField.Value = app.Nim;

            app.updateImages()
        end

        % Callback function: nextButton, nimEditField, previousButton
        function arrowButtonPushed(app, event)
            switch event.Source.Tag
                case 'next'
                    step = 1;
                case 'previous'
                    step = -1;
                otherwise  % don't push any arrow, but enter an image number in the nimEditField
                    step = 0;
            end

            app.nimEditField.Value = mod(app.nim + step - 1, app.Nim) + 1;
            app.pIm = app.pIm0(app.nim,app.ich); % copy of the ImageEM object


            switch app.imageTypeDropDown.Value
                case 'Polarization ellipse'
                    app.limValueEditField.Value = app.pIm.eLim;

                case 'Retardance'
                    app.limValueEditField.Value = app.pIm.retardanceMax*1e9;

                case 'Dichroism'
                    app.limValueEditField.Value = app.pIm.dichroMax;

            end            
            
            app.updateImages()
            
        end

        % Button pushed function: ExportButton, ExportallButton
        function ExportButtonPushed(app, event)
            event.Source.Enable = "off";
            drawnow
            tickList0   = app.Panel_exportFormats.Children.Children;
            formatList0 = tickList0(1:4);
            typeList0   = tickList0(5:end);
            typeValid   = [typeList0.Value];
            formatValid = [formatList0.Value];

            folder = "export";
            mkdir(folder)
            switch event.Source.Tag
                case 'all'
                    app.pIm0.export(folder, "formats", string({formatList0(formatValid).Text}), "types", string({typeList0(typeValid).Text}))
                otherwise
                    app.pIm.export( folder, "formats", string({formatList0(formatValid).Text}), "types", string({typeList0(typeValid).Text}))
            end
            event.Source.Enable = "on";
        end

        % Value changed function: MeanOPDButton
        function MeanOPDButtonValueChanged(app, event)
            value = app.MeanOPDButton.Value;
            if value
                app.MeanOPDButton.Text = '4 polar images';
                app.enablePolarButtons(1)
                app.MeanOPDButton.BackgroundColor = [0.4 0.3 0.1];
                app.MeanOPDButton.FontColor = [1 1 1];
            else
                app.MeanOPDButton.Text = 'Mean polar images';
                app.enablePolarButtons(0)
                app.MeanOPDButton.BackgroundColor = [1 1 1];
                app.MeanOPDButton.FontColor = [0 0 0];
            end
            app.updateImages()
        end

        % Button pushed function: nextPolarButton, previousPolarButton
        function navigatePolarButtonPushed(app, event)
            imNum = app.polarNumberEditField.Value;
            switch event.Source.Tag
                case 'next'
                    imNum = mod(imNum, 4) + 1;
                case 'previous'
                    imNum = mod(imNum - 2 , 4) + 1;

            end
            app.polarNumberEditField.Value = imNum;
            app.updateImages()
        end

        % Value changed function: maxEditField, minEditField
        function climEditFieldValueChanged(app, event)
            app.update_climOPD()
        end

        % Value changed function: imageTypeDropDown
        function imageTypeDropDownValueChanged(app, event)

            app.updateImages()

        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
            
            app.updateImages()
        end

        % Callback function
        function exportButtonPushed(app, event)
            % h = app.pIm.IM(1).Illumination.S3; % handedness
            % if h == 1
            %     hand = 'R';
            % else
            %     hand = 'L';
            % end
            for ic = 1:app.Nch
                app.ChannelButtonGroup.SelectedObject = app.("Button_Channel"+ic);
                app.updateImages()
                hout = app.displayTrialImage();
                saveas(hout,app.exportFolderEditField.Value+"/PolarImages_"+app.nim+"_"+app.ich+".png")
                delete(hout)
                exportImage(app.pIm.rgbImageEllipse,app.exportFolderEditField.Value+"/PolarEllipseAngle_"+app.nim+"_"+app.ich+".png")
                exportImage(app.pIm.e,app.exportFolderEditField.Value+"/Ellipticity_"+app.nim+"_"+app.ich+".png","colormap",gray)
            end    
            drawnow
            figure(app.UIFigure)
        end

        % Callback function
        function exportFolderImageClicked(app, event)
            app.UIFigure.Visible='off' ;
            app.exportFolderEditField.Value = uigetdir();
            app.UIFigure.Visible='on';
        end

        % Callback function
        function abs_eCheckBoxValueChanged(app, event)
            app.updateImages()
            
        end

        % Value changed function: limValueEditField
        function limValueEditFieldValueChanged(app, event)
            value = app.limValueEditField.Value;
            if app.all_limCheckBox.Value
                vals = 1:app.Nim;
            else
                vals = app.nim;
            end
            for ii = vals
                switch app.imageTypeDropDown.Value
                    case 'Polarization ellipse'
                        for jj = 1:app.Nch
                        app.pIm0(ii,jj).set_eLim(value);
                        end
                    case 'Retardance'
                        for jj = 1:app.Nch
                        app.pIm0(ii,jj).set_retMax(value*1e-9);
                        end
                    case 'Dichroism'
                        for jj = 1:app.Nch
                        app.pIm0(ii,jj).set_dichroMax(value);
                        end
                end
            end

            app.updateImages()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.UIAxes_Theta = uiaxes(app.GridLayout_main);
            title(app.UIAxes_Theta, 'Ellipse angle')
            app.UIAxes_OPD = uiaxes(app.GridLayout_main);
            title(app.UIAxes_OPD, 'OPD [nm]')
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.previousButton = uibutton(app.GridLayout, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.previousButton.Tag = 'previous';
            app.previousButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousButton.Text = '';
            app.nextButton = uibutton(app.GridLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nextButton.Tag = 'next';
            app.nextButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextButton.Text = '';
            app.nimEditField = uieditfield(app.GridLayout, 'numeric');
            app.nimEditField.Limits = [1 Inf];
            app.nimEditField.ValueChangedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nimEditField.Value = 1;
            app.NimEditField = uieditfield(app.GridLayout, 'numeric');
            app.NimEditField.Limits = [1 Inf];
            app.NimEditField.Editable = 'off';
            app.NimEditField.Value = 1;
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '/';
            app.GridLayout_Title = uigridlayout(app.GridLayout_main);
            app.Image = uiimage(app.GridLayout_Title);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_IA_3.png');
            app.ImagePolarcontrolLabel = uilabel(app.GridLayout_Title);
            app.ImagePolarcontrolLabel.Text = {'ImagePolar'; 'control'};
            app.GridLayout3 = uigridlayout(app.GridLayout_main);
            app.previousPolarButton = uibutton(app.GridLayout3, 'push');
            app.previousPolarButton.ButtonPushedFcn = createCallbackFcn(app, @navigatePolarButtonPushed, true);
            app.previousPolarButton.Tag = 'previous';
            app.previousPolarButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousPolarButton.Enable = 'off';
            app.previousPolarButton.Text = '';
            app.nextPolarButton = uibutton(app.GridLayout3, 'push');
            app.nextPolarButton.ButtonPushedFcn = createCallbackFcn(app, @navigatePolarButtonPushed, true);
            app.nextPolarButton.Tag = 'next';
            app.nextPolarButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextPolarButton.Enable = 'off';
            app.nextPolarButton.Text = '';
            app.polarNumberEditField = uieditfield(app.GridLayout3, 'numeric');
            app.polarNumberEditField.Limits = [1 4];
            app.polarNumberEditField.RoundFractionalValues = 'on';
            app.polarNumberEditField.ValueDisplayFormat = '%1.0d';
            app.polarNumberEditField.Editable = 'off';
            app.polarNumberEditField.Enable = 'off';
            app.polarNumberEditField.Value = 1;
            app.MeanOPDButton = uibutton(app.GridLayout3, 'state');
            app.MeanOPDButton.ValueChangedFcn = createCallbackFcn(app, @MeanOPDButtonValueChanged, true);
            app.MeanOPDButton.Text = 'Mean OPD';
            app.holdCheckBox = uicheckbox(app.GridLayout3);
            app.holdCheckBox.Text = 'hold';
            app.nmLabel_2 = uilabel(app.GridLayout3);
            app.nmLabel_2.Text = 'nm';
            app.minLabel = uilabel(app.GridLayout3);
            app.minLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout3, 'numeric');
            app.minEditField.RoundFractionalValues = 'on';
            app.minEditField.ValueDisplayFormat = '%11.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @climEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.GridLayout3);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.maxEditField.RoundFractionalValues = 'on';
            app.maxEditField.ValueDisplayFormat = '%11.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @climEditFieldValueChanged, true);
            app.GridLayout_Controls = uigridlayout(app.GridLayout_main);
            app.ExportButton = uibutton(app.GridLayout_Controls, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportallButton = uibutton(app.GridLayout_Controls, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportallButton.Tag = 'all';
            app.ExportallButton.Text = 'Export all';
            app.GridLayout_exportFormat = uigridlayout(app.GridLayout_Controls);
            app.epsCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.epsCheckBox.Text = 'eps';
            app.figCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.figCheckBox.Text = 'fig';
            app.pngCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.pngCheckBox.Text = 'png';
            app.txtCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.txtCheckBox.Text = 'txt';
            app.WbarCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.WbarCheckBox.Text = 'Wbar';
            app.dWCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.dWCheckBox.Text = 'dW';
            app.rgbImageEllipseCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.rgbImageEllipseCheckBox.Text = 'rgbImageEllipse';
            app.eCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.eCheckBox.Text = 'e2';
            app.EXPORTLabel = uilabel(app.GridLayout_Controls);
            app.EXPORTLabel.Text = 'EXPORT';
            app.DISPLAYLabel = uilabel(app.GridLayout_Controls);
            app.DISPLAYLabel.Text = 'DISPLAY';
            app.GridLayout7 = uigridlayout(app.GridLayout_Controls);
            app.exportFolderEditField = uieditfield(app.GridLayout7, 'text');
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'newGUI', 'folder2.svg');
            app.GridLayout8 = uigridlayout(app.GridLayout_Controls);
            app.limValueLabel = uilabel(app.GridLayout8);
            app.limValueLabel.Text = 'Limit value';
            app.limValueEditField = uieditfield(app.GridLayout8, 'numeric');
            app.limValueEditField.Limits = [0 200];
            app.limValueEditField.ValueDisplayFormat = '%.2f';
            app.limValueEditField.ValueChangedFcn = createCallbackFcn(app, @limValueEditFieldValueChanged, true);
            app.limValueEditField.Value = 0.5;
            app.all_limCheckBox = uicheckbox(app.GridLayout8);
            app.all_limCheckBox.Text = 'apply to all';
            app.all_limCheckBox.Value = true;
            app.ChannelButtonGroup = uibuttongroup(app.GridLayout8);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.Button_Channel4 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_Channel4.Text = '4';
            app.Button_Channel3 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_Channel3.Text = '3';
            app.Button_Channel2 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_Channel2.Text = '2';
            app.Button_Channel1 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_Channel1.Text = '1';
            app.Button_Channel1.Value = true;
            app.nmLabel = uilabel(app.GridLayout8);
            app.nmLabel.Visible = 'off';
            app.nmLabel.Text = 'nm';
            app.ImagetypeDropDownLabel = uilabel(app.GridLayout8);
            app.ImagetypeDropDownLabel.Text = 'Image type';
            app.imageTypeDropDown = uidropdown(app.GridLayout8);
            app.imageTypeDropDown.Items = {'Polarization ellipse', 'Retardance', 'Dichroism'};
            app.imageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @imageTypeDropDownValueChanged, true);
            app.imageTypeDropDown.Value = 'Polarization ellipse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figurePolarGUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end