classdef polarBalanceDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        maxvalEditField       matlab.ui.control.NumericEditField
        maxvalEditFieldLabel  matlab.ui.control.Label
        EditField_4           matlab.ui.control.NumericEditField
        EditField_3           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
        EditField_1           matlab.ui.control.NumericEditField
        rawLabel              matlab.ui.control.Label
        normalizedLabel       matlab.ui.control.Label
        resetnormButton       matlab.ui.control.Button
        normButton            matlab.ui.control.Button
        dispButton            matlab.ui.control.Button
        EditField_4norm       matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        EditField_3norm       matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        EditField_2norm       matlab.ui.control.NumericEditField
        ZetaLabel             matlab.ui.control.Label
        EditField_1norm       matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
    end


    properties (Access = private)
        normVals (1,4) double = [1 1 1 1] % Description
        dispValues = []
    end

    methods (Access = public)
        
        function setpolar(app,val)

            for ii = 1:4
                app.("EditField_"+ii).Value = val(ii);
                app.("EditField_"+ii+"norm").Value = val(ii)/app.normVals(ii);
            end

        end

        function val = getpolar(app)

            val = [app.EditField_1norm.Value, ...
                   app.EditField_2norm.Value, ...
                   app.EditField_3norm.Value, ...
                   app.EditField_4norm.Value];

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: normButton
        function normButtonPushed(app, event)
            meanVal = ( app.EditField_1.Value + app.EditField_2.Value + app.EditField_3.Value + app.EditField_4.Value ) / 4;
            for ii = 1:4
                app.normVals(ii) = app.("EditField_"+ii).Value/meanVal;
            end
        end

        % Button pushed function: resetnormButton
        function resetnormButtonPushed(app, event)
            app.normVals = [1 1 1 1];
        end

        % Button pushed function: dispButton
        function dispButtonPushed(app, event)
            vals = zeros(1,4);
            nVals = zeros(1,4);
            for ii = 1:4
                vals(ii) = app.("EditField_"+ii).Value;
                nVals(ii) = app.("EditField_"+ii+"norm").Value;
            end
            
            app.dispValues = [app.dispValues; vals, nVals];
            disp(app.dispValues)
        end

        % Value changed function: maxvalEditField
        function maxvalEditFieldValueChanged(app, event)
            value = app.maxvalEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '0°';
            app.EditField_1norm = uieditfield(app.GridLayout, 'numeric');
            app.EditField_1norm.RoundFractionalValues = 'on';
            app.EditField_1norm.ValueDisplayFormat = '%11.0f';
            app.EditField_1norm.Editable = 'off';
            app.ZetaLabel = uilabel(app.GridLayout);
            app.ZetaLabel.Text = '45°';
            app.EditField_2norm = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2norm.RoundFractionalValues = 'on';
            app.EditField_2norm.ValueDisplayFormat = '%11.0f';
            app.EditField_2norm.Editable = 'off';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '90°';
            app.EditField_3norm = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3norm.RoundFractionalValues = 'on';
            app.EditField_3norm.ValueDisplayFormat = '%11.0f';
            app.EditField_3norm.Editable = 'off';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '135°';
            app.EditField_4norm = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4norm.RoundFractionalValues = 'on';
            app.EditField_4norm.ValueDisplayFormat = '%11.0f';
            app.EditField_4norm.Editable = 'off';
            app.dispButton = uibutton(app.GridLayout, 'push');
            app.dispButton.ButtonPushedFcn = createCallbackFcn(app, @dispButtonPushed, true);
            app.dispButton.Text = 'disp';
            app.normButton = uibutton(app.GridLayout, 'push');
            app.normButton.ButtonPushedFcn = createCallbackFcn(app, @normButtonPushed, true);
            app.normButton.Text = 'norm';
            app.resetnormButton = uibutton(app.GridLayout, 'push');
            app.resetnormButton.ButtonPushedFcn = createCallbackFcn(app, @resetnormButtonPushed, true);
            app.resetnormButton.Text = 'reset norm';
            app.normalizedLabel = uilabel(app.GridLayout);
            app.normalizedLabel.Text = 'normalized';
            app.rawLabel = uilabel(app.GridLayout);
            app.rawLabel.Text = 'raw';
            app.EditField_1 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_1.ValueDisplayFormat = '%11.0f';
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%11.0f';
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%11.0f';
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%11.0f';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.maxvalEditFieldLabel = uilabel(app.GridLayout2);
            app.maxvalEditFieldLabel.Text = 'max val';
            app.maxvalEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maxvalEditField.ValueDisplayFormat = '%11.0f';
            app.maxvalEditField.ValueChangedFcn = createCallbackFcn(app, @maxvalEditFieldValueChanged, true);
            app.maxvalEditField.Value = 20000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polarBalanceDisplay

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end