classdef polarCGMlive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        retMaxEditField           matlab.ui.control.NumericEditField
        retMaxLabel               matlab.ui.control.Label
        histogramOPDButton        matlab.ui.control.Button
        polarbalanceButton        matlab.ui.control.Button
        polarFigureButton         matlab.ui.control.Button
        PhaseLABButton            matlab.ui.control.Button
        dxSizeEditField           matlab.ui.control.NumericEditField
        dxSizeEditFieldLabel      matlab.ui.control.Label
        GammaEditField            matlab.ui.control.NumericEditField
        GammaEditFieldLabel       matlab.ui.control.Label
        lambdaEditField           matlab.ui.control.NumericEditField
        lambdaEditFieldLabel      matlab.ui.control.Label
        MobjEditField             matlab.ui.control.NumericEditField
        MobjEditFieldLabel        matlab.ui.control.Label
        CGdistEditField           matlab.ui.control.NumericEditField
        CGdistEditFieldLabel      matlab.ui.control.Label
        zetaEditField             matlab.ui.control.NumericEditField
        zetaEditFieldLabel        matlab.ui.control.Label
        GridLayoutParams          matlab.ui.container.GridLayout
        cropEditField             matlab.ui.control.NumericEditField
        cropEditFieldLabel        matlab.ui.control.Label
        SwitchDefinition          matlab.ui.control.Switch
        ExpTimeEditField          matlab.ui.control.NumericEditField
        ExpTimeEditFieldLabel     matlab.ui.control.Label
        SNAPButton                matlab.ui.control.Button
        STOPButton                matlab.ui.control.Button
        LIVEButton                matlab.ui.control.Button
        TButton                   matlab.ui.control.Button
        GradOPDButton             matlab.ui.control.Button
        FourierButton             matlab.ui.control.Button
        RefNumberSpinner          matlab.ui.control.Spinner
        RefButton                 matlab.ui.control.Button
        histogramButton           matlab.ui.control.Button
        snapNumberSpinner         matlab.ui.control.Spinner
        SAVEButton                matlab.ui.control.Button
        PolarangleButton          matlab.ui.control.Button
        OPDButton                 matlab.ui.control.Button
        InterferoButton           matlab.ui.control.Button
        polarCGMliveimagingLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        MI % Microscope
        IL % Illumination
        cam % camera
        Itf    (1,1) Interfero % full frame current acquired interfero
        Itf4   (1,4) Interfero % polarsplit current acquired interfero
        Ref    (1,1) Interfero % full frame reference interfero
        Ref4   (1,4) Interfero % small reference interfero, for polarsplit images
        IM     (1,1) ImageQLSI % full frame ImageQLSI object
        IM4    (1,4) ImageQLSI % full frame ImageQLSI object
        polarImages
        hfig   matlab.ui.Figure
        refNumber  = 0          % Ref number
        itfSavedNumber  = 0          % Ref number
        liveOn logical = true
        roi
    end

    properties (Dependent)
        Nh % number of handles
        crop % border width of the crop of the images
    end
    
    methods (Access = private)

        function  live(app)
            app.liveOn = true;
            app.LIVEButton.Enable = "off";
            app.STOPButton.Enable = "on";

            if ~isrunning(app.cam.hndl)
                app.cam.setMode('Continuous','Internal')
                app.cam.startAcquisition();
            end
            
            while any(isvalid(app.hfig)) && app.liveOn

                app.Itf.Itf0 = app.cam.acquire_continuous();
                app.Itf.Itf0 = app.Itf.Itf0(1+app.crop:end-app.crop,1+app.crop:end-app.crop);
                app.Itf.expTime = app.ExpTimeEditField.Value*1e-3;

                [~, zeta] = app.get1orderLocation();
                app.MI.CGcam.RL = RelayLens(zeta*1.47/3.8);

                if app.Nh == 0
                    app.hfig(app.Nh+1) = figure();
                    app.hfig(app.Nh).Position = [20*(app.Nh-1) 175  979  600];
                    app.hfig(app.Nh).UserData.type = event.Source.Tag;
                end

                app.updateImages()

            end
            app.cam.stopAcquisition();
            app.LIVEButton.Enable = "on";
            app.STOPButton.Enable = "off";

        end

        function displayITF(app, h)
            if ~isrunning(app.cam.hndl)
                app.cam.startAcquisition();
            end

            if ~isfield(h.UserData,'him')
                hax = axes(h);
                h.UserData.him = imagesc(hax,app.Itf.Itf0);
                set(hax, 'XDir', 'reverse');
                colorbar
                h.Name = "Interferogram";
                clim([0 2^16])
                set(gca, 'DataAspectRatio', [1 1 1]);
            else
                h.UserData.him.CData = app.Itf.Itf0;
            end
            drawnow
        end

        function displayTF(app, h)
            if ~isrunning(app.cam.hndl)
                app.cam.startAcquisition();
            end

            set(h,"CloseRequestFcn", @(src,event) closeThetaZeta(src))

            if ~isfield(h.UserData,'him')
                subplot(1,2,1);
                h.UserData.him = imagesc(app.Itf.Itf0);
                h.UserData.app_thetaZetaDisplay = thetaZetaDisplay;
                h.UserData.app_thetaZetaDisplay.UIFigure.Position = ...
                    [ app.UIFigure.Position(1) + app.UIFigure.Position(3), ...
                    app.UIFigure.Position(2), ...
                    h.UserData.app_thetaZetaDisplay.UIFigure.Position(3), ...
                    h.UserData.app_thetaZetaDisplay.UIFigure.Position(4)];
                h.Name = "Fourier transform";
                title("Zoom on interferogram")
                set(gca,'DataAspectRatio' , [1 1 1]);
                set(gca, 'XDir', 'reverse');
                colormap("gray")
                zoom(10)
                subplot(1,2,2);
                h.UserData.him2 = imagesc(sqrt(sqrt(abs(fftshift(fft2(app.Itf.Itf0))))));
                set(gca,'DataAspectRatio' , [1 1 1]);
                set(gca, 'XDir', 'reverse');
                colormap("parula")
                title("Fourier transform")
            else
                h.UserData.him.CData = app.Itf.Itf0;
                h.UserData.him2.CData = imgaussfilt(sqrt(sqrt(abs(fftshift(fft2(app.Itf.Itf0))))),4);
                [theta, zeta] = app.get1orderLocation;
                title("Fourier transform ")
                h.UserData.app_thetaZetaDisplay.setTheta(theta)
                h.UserData.app_thetaZetaDisplay.setZeta(zeta)
            end
            app.zetaEditField.Value = h.UserData.app_thetaZetaDisplay.zetaEditField.Value;
            drawnow

            function closeThetaZeta(src)
                close(src.UserData.app_thetaZetaDisplay.UIFigure)
                delete(src)
            end
        end

        function displayOPD(app, h)
            nn = 4;
            app.Itf.Reference(app.Ref)
%            Itf = Itf0.splitPolars();
%            Itf.Reference(app.Ref0)
try
            app.IM = app.Itf.QLSIprocess(app.IL, 'definition', app.SwitchDefinition.Value);
catch
    pause(1)
end
            if ~isfield(h.UserData,'him')
                hax = axes(h);
                h.UserData.him = imagesc(hax, app.IM(1).OPDnm(1+nn:end-nn,1+nn:end-nn));
                set(hax,'DataAspectRatio' , [1 1 1]);
                set(hax, 'XDir', 'reverse');
                cb = colorbar;
                cb.Label.String = 'OPD  [nm]';
                colormap(Sepia)
                h.Name = "OPD";
            else
                h.UserData.him.CData = app.IM.OPDnm(1+nn:end-nn,1+nn:end-nn);
            end
            drawnow
        
        end
    
        function displayGradOPD(app, h)
            app.Itf.Reference(app.Ref)
%            Itf = Itf0.splitPolars();
%            Itf.Reference(app.Ref0)
            app.IM = app.Itf.QLSIprocess(app.IL, 'definition', app.SwitchDefinition.Value);
            
            if ~isfield(h.UserData,'him')
                hax = axes(h);
                h.UserData.him = imagesc(hax, app.IM(1).DWnorm);
                set(hax,'DataAspectRatio' , [1 1 1]);
                cset(hax, 'XDir', 'reverse');
                b = colorbar;
                cb.Label.String = 'Norm Grad OPD';
                colormap(Sepia)
                h.Name = "OPD";
            else
                h.UserData.him.CData = app.IM.DWnorm;
            end
            drawnow
        
        end
        function displayT(app, h)
            app.Itf.Reference(app.Ref)
%            Itf = Itf0.splitPolars();
%            Itf.Reference(app.Ref0)
            app.IM = app.Itf.QLSIprocess(app.IL, 'definition', app.SwitchDefinition.Value);
            
            if ~isfield(h.UserData,'him')
                hax = axes(h);
                h.UserData.him = imagesc(hax, app.IM(1).T);
                set(hax,'DataAspectRatio' , [1 1 1]);
                set(hax, 'XDir', 'reverse');
                cb = colorbar;
                cb.Label.String = 'Norm Grad OPD';
                colormap('gray')
                clim([0 1.2])
                h.Name = "OPD";
            else
                h.UserData.him.CData = app.IM.T;
            end
            drawnow
        
        end
    
        function displayHIST(app, h)
                if isempty(h.Children)
                    hax = axes(h);
                    histogram(hax,app.Itf.Itf,0:500:2^16);
                    h.Name = "Histogram Itf";
                else
                    histogram(h.Children,app.Itf.Itf,0:500:2^16)
                end
            drawnow
        end
    
        function displayHISTOPD(app, h)
                if isempty(h.Children)
                    
                    figure
                    imageph(app.IM.OPDnm)
                    app.roi = drawrectangle('color','r');
                    
                    roi = round(app.roi.Position);
                    x = roi(1);
                    y = roi(2);
                    w = roi(3);
                    h_ = roi(4);
                    x = max(1,x);
                    y = max(1,y);
                    xend = min(size(app.IM.OPDnm,2), x+w-1);
                    yend = min(size(app.IM.OPDnm,2), y+h_-1);

                    hax = axes(h);
                    histogram(hax,app.IM.OPDnm(y:yend,x:xend),150);
                    h.Name = "Histogram OPD";
                else

                    roi = round(app.roi.Position);
                    x = roi(1);
                    y = roi(2);
                    w = roi(3);
                    h_ = roi(4);
                    x = max(1,x);
                    y = max(1,y);
                    xend = min(size(app.IM.OPDnm,2), x+w-1);
                    yend = min(size(app.IM.OPDnm,2), y+h_-1);
                    
                    
                    histogram(h.Children,app.IM.OPDnm(y:yend,x:xend)-mean(mean(app.IM.OPDnm(y:yend,x:xend))),150);
                end
            drawnow
        end
    
        function displayPolarBalance(app, h)
            set(h,"CloseRequestFcn", @(src,event) closePolarBalance(src))

            if isempty(h.Children)
                h.UserData.app_polarBalanceDisplay = polarBalanceDisplay();
                hax = axes(h);
                val1 = mean(mean(app.Itf.Itf(1:2:end,1:2:end)));
                val2 = mean(mean(app.Itf.Itf(2:2:end,1:2:end)));
                val3 = mean(mean(app.Itf.Itf(2:2:end,2:2:end)));
                val4 = mean(mean(app.Itf.Itf(1:2:end,2:2:end)));
                h.UserData.app_polarBalanceDisplay.setpolar([val1, val2, val3, val4])
                vals = h.UserData.app_polarBalanceDisplay.getpolar();
                plot( hax, vals ,'o',LineWidth=2)
                ylim( [0, 2^16/4] )
                xlim([0.5 4.5])
                h.Name = "Polar balance";
                h.UserData.app_polarBalanceDisplay.UIFigure.Position = ...
                    [ app.UIFigure.Position(1) + app.UIFigure.Position(3), ...
                    app.UIFigure.Position(2), ...
                    h.UserData.app_polarBalanceDisplay.UIFigure.Position(3), ...
                    h.UserData.app_polarBalanceDisplay.UIFigure.Position(4)];
            else
                val1 = mean(mean(app.Itf.Itf(1:2:end,1:2:end)));
                val2 = mean(mean(app.Itf.Itf(2:2:end,1:2:end)));
                val3 = mean(mean(app.Itf.Itf(2:2:end,2:2:end)));
                val4 = mean(mean(app.Itf.Itf(1:2:end,2:2:end)));
                h.UserData.app_polarBalanceDisplay.setpolar([val1, val2, val3, val4])
                vals = h.UserData.app_polarBalanceDisplay.getpolar();
                h.Children.Children.YData = vals;
            end
            maxVal = h.UserData.app_polarBalanceDisplay.maxvalEditField.Value;
            ylim(h.Children,[0 maxVal])
                
            drawnow

            function closePolarBalance(src)
                close(src.UserData.app_polarBalanceDisplay.UIFigure)
                delete(src)
            end
            
        end
    
        function displayPOLAR(app, h)

            nn = 4;
            %app.Itf.Itf0 = app.cam.doAcquisition();
            app.Itf4 = app.Itf.splitPolars();
            app.Itf4.Reference(app.Ref4)
            app.IM4 = app.Itf4.QLSIprocess(app.IL, 'definition', app.SwitchDefinition.Value,'displayBar',false);
            app.IM4.adjustPolarOffsets('auto'); % manual; manual
            %app.polarImages = app.IM4.extractPolarImages(app.retMaxEditField.Value);
            %app.polarImages = PolarImages(app.IM4.flatten("Gaussian","nGauss",150), app.retMaxEditField.Value);
            app.polarImages = ImagePolar(app.IM4, 'retMax',app.retMaxEditField.Value*1e-9);
            app.polarImages.rgb_bg0 = 'white';  
            if ~isfield(h.UserData,'him')
                hax = axes(h);
                h.UserData.him = imagesc(hax, app.polarImages.rgbImage(1+nn:end-nn,1+nn:end-nn,:));
                set(hax,'DataAspectRatio' , [1 1 1]);
                set(hax, 'XDir', 'reverse');
                colormap("hsv")
            else
                h.UserData.him.CData = app.polarImages.rgbImage(1+nn:end-nn,1+nn:end-nn,:);
            end
    
            drawnow

        end
    
        
        function ableButtons(app,val)
            arguments
                app
                val string {mustBeMember(val,["on","off"])}
            end
            app.OPDButton.Enable = val;
            app.GradOPDButton.Enable = val;
            app.TButton.Enable = val;
            app.PolarangleButton.Enable = val;
        end


        function [theta, zeta] = get1orderLocation(app)
                Npx = 400;
                fitf = log(imgaussfilt(abs(fft2(app.Itf.Itf0(1:Npx,1:Npx))),1));
                fitf = fitf(1:Npx/2,1:Npx/2);
                mask = ones(size(fitf));
                mask(1:5,1:5)=0;
                [~, linpos] = max(mask(:).*fitf(:));
                [ny, nx] = ind2sub(size(fitf),linpos);
                theta = round(180/pi*angle((nx-1)+1i*(ny-1)),1);
                zeta = round(0.5*Npx/abs((nx-1)+1i*(ny-1)),1);
        end
        
        function updateImages(app)
            for ih = 1:app.Nh
                h = app.hfig(ih);
                if isvalid(h) % if the figure hasn't been closed
                    h.NumberTitle = 'off';
                    app.("display"+app.hfig(ih).UserData.type)(h)
                end
            end            
        end
    end


    methods  % Dependent methods

        function val = get.Nh(app)
            val = numel(app.hfig);
        end

        function val = get.crop(app)
            val = app.cropEditField.Value;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            addpath(genpath('C:\Users\GB Manip\Documents\MATLAB\PhaseLAB'))
            addpath(genpath('C:\Users\GB Manip\Documents\MATLAB\PhaseLIVE'))


            ME = Medium('air', 'glass');
            OB = Objective(app.MobjEditField.Value, 1.3, 'Olympus');

            Cam  = Camera('PHX050S-PC');
            Grat = CrossGrating('Gamma',app.GammaEditField.Value*1e-6);

            zeta   = app.zetaEditField.Value;
            dxSize = app.dxSizeEditField.Value*1e-6;
            Gamma  = app.GammaEditField.Value*1e-6;
            Z = 2*dxSize*zeta/Gamma;

            CGcam = CGcamera(Cam,Grat,Z);
            app.MI = Microscope(OB,200,CGcam,'PhaseLIVE');
            CGcam.setDistance(app.CGdistEditField.Value*1e-3);

            app.IL = Illumination(app.lambdaEditField.Value,ME,1,[1 1i]);


            % CONNECT THE CAM
            app.cam = CGcam.Camera.makeAlive();
            app.cam.turn('on');
            app.cam.setExpTime(app.ExpTimeEditField.Value*1e-3);


            app.cam.setMode('Fixed','Internal');
            
            app.cam.startAcquisition();
            app.Itf = Interfero(app.cam.acquire_fixed(1), app.MI);
            app.cam.stopAcquisition();
            app.IM = ImageQLSI(1);
            app.IM4 = ImageQLSI(4)';

            app.ableButtons("off")
            app.STOPButton.Enable = "off";
            app.LIVEButton.Enable = "on";
            

        end

        % Value changed function: ExpTimeEditField
        function ExpTimeEditFieldValueChanged(app, event)
            value = app.ExpTimeEditField.Value;
            if isrunning(app.cam.hndl)
                app.cam.stopAcquisition();
                app.cam.setExpTime(value*1e-3);
                app.cam.startAcquisition();
            else
                app.cam.setExpTime(value*1e-3);
            end
        end

        % Value changed function: CGdistEditField
        function CGdistEditFieldValueChanged(app, event)
            value = app.CGdistEditField.Value;
            app.MI.CGcam.setDistance(value*1e-3);

        end

        % Button pushed function: FourierButton, GradOPDButton, 
        % ...and 7 other components
        function imagingButtonPushed(app, event)

            app.hfig(app.Nh+1) = figure();
            app.hfig(app.Nh).Position = [20*(app.Nh-1) 175  979  600];
            app.hfig(app.Nh).UserData.type = event.Source.Tag;
            app.live()
        end

        % Button pushed function: RefButton
        function RefButtonPushed(app, event)
            event.Source.Enable = "off";
            drawnow
            restart = 0;
            if isrunning(app.cam.hndl)
                restart = 1;
                app.cam.stopAcquisition();
            end
            Nim =  app.RefNumberSpinner.Value;
            app.cam.setMode('Fixed','Internal', Nim = Nim);
            app.cam.startAcquisition();

            imraw = app.cam.acquire_fixed(Nim);
            if Nim>1
                imraw = mean(imraw,3);
            end
            app.Ref = Interfero(imraw(1+app.crop:end-app.crop,1+app.crop:end-app.crop),app.MI);
            app.Ref.expTime = app.ExpTimeEditField.Value*1e-3;
            app.Ref4 = app.Ref.splitPolars();

            app.ableButtons("on");
            app.cam.stopAcquisition();
            app.cam.setMode('Continuous','Internal');
            if restart % restart if acquisition was originally started
                app.cam.startAcquisition();
            end
            hd = datetime('now');
            app.refNumber = [num2str(hd.Hour) num2str(hd.Minute) num2str(textk(round(hd.Second)))];
            event.Source.Enable = "on";
            
        end

        % Callback function
        function zoomresetButtonPushed(app, event)

            for ih = 1:app.Nh
                if isvalid(app.hfig(ih))
                    try % fails if type is HIST
                        zoom(app.hfig(ih).UserData.him.Parent, 'out')
                    end
                end
            end

        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if isempty(app.Ref.Itf)
                warning("empty reference, nothing saved")
            else
                event.Source.Enable = "off";
                event.Source.Text = "wait...";
                drawnow
                refFileName = "REF_"+app.refNumber+".tif";  % REF_0001.txt
                if ~exist(refFileName,'file') % if the ref file hasn't been saved yet
                    %writematrix(app.Ref.Itf,refFileName)
                    saveTiffMeta(refFileName, app.Itf.Ref, app.MI)
                end
                app.itfSavedNumber = app.itfSavedNumber+1;
                itfFileName = ['ITF_' textkkk(app.itfSavedNumber) '_' app.refNumber '.tif'];  % ITF_0001_0001.txt
                fileList = dir("ITF*");
                if ~isempty(fileList)
                    while contains([fileList(:).name],itfFileName(1:8)) % If the itf file numer has already been saved
                        app.itfSavedNumber = app.itfSavedNumber+1;
                        itfFileName = ['ITF_' textkkk(app.itfSavedNumber) '_' app.refNumber '.tif'];  % ITF_0001_0001.txt
                    end
                end
                    
                %writematrix(app.Itf.Itf,itfFileName)

                saveTiffMeta(itfFileName, app.Itf, app.MI)

                event.Source.Enable = "on";
                event.Source.Text = "SAVE";
                app.live()
            end
        end

        % Button pushed function: LIVEButton
        function LIVEButtonPushed(app, event)
            app.live()
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            event.Source.Enable = "off";
            app.LIVEButton.Enable = "on";
            drawnow
            app.liveOn = false;
        end

        % Button pushed function: SNAPButton
        function SNAPButtonPushed(app, event)
            event.Source.Enable = "off";
            drawnow
            restart = 0;
            if isrunning(app.cam.hndl)
                restart = 1;
                app.cam.stopAcquisition();
            end
            app.cam.setMode("Fixed","Internal", Nim = app.snapNumberSpinner.Value);
            app.cam.startAcquisition();

            imraw = app.cam.acquire_fixed(app.snapNumberSpinner.Value);
            if app.snapNumberSpinner.Value>1
                imraw = mean(imraw,3);
            end
            app.Itf.Itf0 = imraw(1+app.crop:end-app.crop,1+app.crop:end-app.crop);

            app.cam.stopAcquisition();
            app.cam.setMode("Continuous","Internal");
            if restart % restart if acquisition was originally running
                app.cam.startAcquisition();
            end
            event.Source.Enable = "on";
            app.liveOn = false;
            app.LIVEButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.updateImages


        end

        % Value changed function: MobjEditField
        function MobjEditFieldValueChanged(app, event)
            app.MI.Objective = app.MobjEditField.Value;
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.IL = Illumination(app.lambdaEditField.Value);
        end

        % Value changed function: zetaEditField
        function zetaEditFieldValueChanged(app, event)
            app.MI.CGcam.RL = RelayLens(app.zetaEditField.Value);
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            app.MI.CGcam.CG = CrossGrating('Gamma',app.GammaEditField.Value);
        end

        % Value changed function: dxSizeEditField
        function dxSizeEditFieldValueChanged(app, event)
            app.MI.CGcam.Camera = Camera(app.dxSizeEditField.Value,1,1);
        end

        % Button pushed function: PhaseLABButton
        function PhaseLABButtonPushed(app, event)
            app.IM.figure
        end

        % Button pushed function: polarFigureButton
        function polarFigureButtonPushed(app, event)
            app.polarImages.figure
        end

        % Value changed function: retMaxEditField
        function retMaxEditFieldValueChanged(app, event)
            app.updateImages()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.polarCGMliveimagingLabel = uilabel(app.GridLayout);
            app.polarCGMliveimagingLabel.Text = 'polar-CGM live imaging';
            app.InterferoButton = uibutton(app.GridLayout, 'push');
            app.InterferoButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.InterferoButton.Tag = 'ITF';
            app.InterferoButton.Text = 'Interfero';
            app.OPDButton = uibutton(app.GridLayout, 'push');
            app.OPDButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.OPDButton.Tag = 'OPD';
            app.OPDButton.Text = 'OPD';
            app.PolarangleButton = uibutton(app.GridLayout, 'push');
            app.PolarangleButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.PolarangleButton.Tag = 'POLAR';
            app.PolarangleButton.Text = 'Polar angle';
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.snapNumberSpinner = uispinner(app.GridLayout);
            app.snapNumberSpinner.Limits = [1 Inf];
            app.snapNumberSpinner.RoundFractionalValues = 'on';
            app.snapNumberSpinner.ValueDisplayFormat = '%d';
            app.snapNumberSpinner.Value = 30;
            app.histogramButton = uibutton(app.GridLayout, 'push');
            app.histogramButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.histogramButton.Tag = 'HIST';
            app.histogramButton.Text = 'histogram';
            app.RefButton = uibutton(app.GridLayout, 'push');
            app.RefButton.ButtonPushedFcn = createCallbackFcn(app, @RefButtonPushed, true);
            app.RefButton.Text = 'Ref';
            app.RefNumberSpinner = uispinner(app.GridLayout);
            app.RefNumberSpinner.Limits = [1 Inf];
            app.RefNumberSpinner.ValueDisplayFormat = '%d';
            app.RefNumberSpinner.Value = 30;
            app.FourierButton = uibutton(app.GridLayout, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.FourierButton.Tag = 'TF';
            app.FourierButton.Text = 'Fourier';
            app.GradOPDButton = uibutton(app.GridLayout, 'push');
            app.GradOPDButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.GradOPDButton.Tag = 'GradOPD';
            app.GradOPDButton.Text = 'Grad OPD';
            app.TButton = uibutton(app.GridLayout, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.TButton.Tag = 'T';
            app.TButton.Text = 'T';
            app.LIVEButton = uibutton(app.GridLayout, 'push');
            app.LIVEButton.ButtonPushedFcn = createCallbackFcn(app, @LIVEButtonPushed, true);
            app.LIVEButton.Text = 'LIVE';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SNAPButton = uibutton(app.GridLayout, 'push');
            app.SNAPButton.ButtonPushedFcn = createCallbackFcn(app, @SNAPButtonPushed, true);
            app.SNAPButton.Text = 'SNAP';
            app.GridLayoutParams = uigridlayout(app.GridLayout);
            app.ExpTimeEditFieldLabel = uilabel(app.GridLayoutParams);
            app.ExpTimeEditFieldLabel.Text = 'Exp. Time';
            app.ExpTimeEditField = uieditfield(app.GridLayoutParams, 'numeric');
            app.ExpTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExpTimeEditFieldValueChanged, true);
            app.ExpTimeEditField.Value = 100;
            app.SwitchDefinition = uiswitch(app.GridLayoutParams, 'slider');
            app.SwitchDefinition.Items = {'low', 'high'};
            app.SwitchDefinition.Value = 'low';
            app.cropEditFieldLabel = uilabel(app.GridLayoutParams);
            app.cropEditFieldLabel.Text = 'crop';
            app.cropEditField = uieditfield(app.GridLayoutParams, 'numeric');
            app.zetaEditFieldLabel = uilabel(app.GridLayout);
            app.zetaEditFieldLabel.Text = 'zeta';
            app.zetaEditField = uieditfield(app.GridLayout, 'numeric');
            app.zetaEditField.ValueChangedFcn = createCallbackFcn(app, @zetaEditFieldValueChanged, true);
            app.zetaEditField.Editable = 'off';
            app.zetaEditField.Value = 4;
            app.CGdistEditFieldLabel = uilabel(app.GridLayout);
            app.CGdistEditFieldLabel.Text = 'CG dist';
            app.CGdistEditField = uieditfield(app.GridLayout, 'numeric');
            app.CGdistEditField.ValueChangedFcn = createCallbackFcn(app, @CGdistEditFieldValueChanged, true);
            app.CGdistEditField.Value = 0.25;
            app.MobjEditFieldLabel = uilabel(app.GridLayout);
            app.MobjEditFieldLabel.Text = 'Mobj';
            app.MobjEditField = uieditfield(app.GridLayout, 'numeric');
            app.MobjEditField.ValueChangedFcn = createCallbackFcn(app, @MobjEditFieldValueChanged, true);
            app.MobjEditField.Value = 40;
            app.lambdaEditFieldLabel = uilabel(app.GridLayout);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.GridLayout, 'numeric');
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Value = 530;
            app.GammaEditFieldLabel = uilabel(app.GridLayout);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.GridLayout, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.GammaEditField.Value = 39;
            app.dxSizeEditFieldLabel = uilabel(app.GridLayout);
            app.dxSizeEditFieldLabel.Text = 'dxSize';
            app.dxSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.dxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @dxSizeEditFieldValueChanged, true);
            app.dxSizeEditField.Value = 3.45;
            app.PhaseLABButton = uibutton(app.GridLayout, 'push');
            app.PhaseLABButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseLABButtonPushed, true);
            app.PhaseLABButton.Text = 'PhaseLAB';
            app.polarFigureButton = uibutton(app.GridLayout, 'push');
            app.polarFigureButton.ButtonPushedFcn = createCallbackFcn(app, @polarFigureButtonPushed, true);
            app.polarFigureButton.Text = 'polarFigure';
            app.polarbalanceButton = uibutton(app.GridLayout, 'push');
            app.polarbalanceButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.polarbalanceButton.Tag = 'PolarBalance';
            app.polarbalanceButton.Text = 'polar balance';
            app.histogramOPDButton = uibutton(app.GridLayout, 'push');
            app.histogramOPDButton.ButtonPushedFcn = createCallbackFcn(app, @imagingButtonPushed, true);
            app.histogramOPDButton.Tag = 'HISTOPD';
            app.histogramOPDButton.Text = 'histogram OPD';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.retMaxLabel = uilabel(app.GridLayout2);
            app.retMaxLabel.Text = 'retMax';
            app.retMaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.retMaxEditField.ValueChangedFcn = createCallbackFcn(app, @retMaxEditFieldValueChanged, true);
            app.retMaxEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polarCGMlive

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end