classdef polarImageInvestigator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        fixedCheckBox_2         matlab.ui.control.CheckBox
        nmLabel_8               matlab.ui.control.Label
        nmLabel_7               matlab.ui.control.Label
        OPDminEditField_2       matlab.ui.control.NumericEditField
        OPDminEditField_2Label  matlab.ui.control.Label
        OPDmaxEditField_2       matlab.ui.control.NumericEditField
        OPDmaxEditField_2Label  matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        fixedCheckBox           matlab.ui.control.CheckBox
        GridLayout_2            matlab.ui.container.GridLayout
        EditField               matlab.ui.control.EditField
        NimEditField            matlab.ui.control.NumericEditField
        nimEditField            matlab.ui.control.NumericEditField
        nextButton              matlab.ui.control.Button
        previousButton          matlab.ui.control.Button
        nmLabel_6               matlab.ui.control.Label
        OPDmaxEditField         matlab.ui.control.NumericEditField
        OPDmaxEditFieldLabel    matlab.ui.control.Label
        nmLabel_5               matlab.ui.control.Label
        OPDminEditField         matlab.ui.control.NumericEditField
        OPDminEditFieldLabel    matlab.ui.control.Label
        theta0EditField         matlab.ui.control.NumericEditField
        theta0EditFieldLabel    matlab.ui.control.Label
        nmLabel_1               matlab.ui.control.Label
        nmLabel_2               matlab.ui.control.Label
        nmLabel_3               matlab.ui.control.Label
        nmLabel_4               matlab.ui.control.Label
        Label_angle1            matlab.ui.control.Label
        Label_angle2            matlab.ui.control.Label
        Label_angle3            matlab.ui.control.Label
        Label_angle4            matlab.ui.control.Label
        meas1EditField          matlab.ui.control.NumericEditField
        meas2EditField          matlab.ui.control.NumericEditField
        meas3EditField          matlab.ui.control.NumericEditField
        meas4EditField          matlab.ui.control.NumericEditField
        UIAxes_color2           matlab.ui.control.UIAxes
        UIAxes_color3           matlab.ui.control.UIAxes
        UIAxes_color4           matlab.ui.control.UIAxes
        UIAxes_color1           matlab.ui.control.UIAxes
        axPlot                  matlab.ui.control.UIAxes
        axIm                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        axPx (1,4) matlab.ui.control.UIAxes % Description
        polarIm ImagePolar
        IM
        IMc
        img % displayed image
        x0 = 1
        y0 = 1
        xPlot = linspace(0,pi,91)
        dphimax
        him
    end

    properties(Dependent)
        nim
        Nim
    end
    
    methods (Access = private)
        
        function updateMeas(app)
            % update the 4 pixel values

            app.axPx(1).Children.CData = app.IMc(1).OPDnm(app.y0,app.x0);
            app.axPx(2).Children.CData = app.IMc(2).OPDnm(app.y0,app.x0);
            app.axPx(3).Children.CData = app.IMc(3).OPDnm(app.y0,app.x0);
            app.axPx(4).Children.CData = app.IMc(4).OPDnm(app.y0,app.x0);
            
            app.meas1EditField.Value = app.axPx(1).Children.CData;
            app.meas2EditField.Value = app.axPx(2).Children.CData;
            app.meas3EditField.Value = app.axPx(3).Children.CData;
            app.meas4EditField.Value = app.axPx(4).Children.CData;
        end
        
        % Fonction pour détecter si la souris survole l'image
        function mouseOverImage(app)
            % Obtenir la position de la souris dans l'unité de l'objet Axes
            mousePos = app.axIm.CurrentPoint;  % CurrentPoint retourne [x, y; x, y]
        
            % Obtenir les limites de l'objet Axes
            xLimits = app.axIm.XLim;
            yLimits = app.axIm.YLim;
        
            % Vérifier si la souris est dans les limites des axes
            if mousePos(1,1) >= xLimits(1) && mousePos(1,1) <= xLimits(2) && ...
               mousePos(1,2) >= yLimits(1) && mousePos(1,2) <= yLimits(2)
                
                % Action à effectuer si la souris est sur l'image
                disp(app.axIm.CurrentPoint);
                app.x0 = round(app.axIm.CurrentPoint(1,1));
                app.y0 = round(app.axIm.CurrentPoint(1,2));
                app.updateMeas()
                app.updatePlot()
                % Ajoutez ici le code pour l'événement que vous voulez déclencher
            end
        end
        
        function updatePlot(app)
            j = app.x0;
            i = app.y0;
            %app.axPlot.YLim = [app.polarIm.Wbar(i,j)*1e9 - 5*app.dphimax, app.polarIm.Wbar(i,j)*1e9 + 5*app.dphimax];
            plot(app.axPlot,  [0, pi/4, pi/2 ,3*pi/4], ...
                [app.IMc(1).OPDnm(i,j), app.IMc(2).OPDnm(i,j), app.IMc(3).OPDnm(i,j), app.IMc(4).OPDnm(i,j)],...
                'o', 'LineWidth', 2, 'Color', [0.8, 0.5, 0.2])

            hold(app.axPlot, "on")
            colorsc = hsv;
            ret = app.polarIm.retardance(i,j)*1e9;
            avg = app.polarIm.Wbar(i,j)*1e9;
            theta0 = app.polarIm.theta0(i,j);
            plot(app.axPlot,  app.xPlot, avg + ret*cos(2*(app.xPlot - theta0)), ...
                "Color", colorsc(1+round(255*mod(pi+theta0,2*pi)/(2*pi)),:))
            hold(app.axPlot, "off")
            app.theta0EditField.Value = theta0*180/pi;

            app.OPDminEditField_2.Value = app.axPlot.YLim(1);
            app.OPDmaxEditField_2.Value = app.axPlot.YLim(2); 
        end
        
        function updateImage(app)
            % plot the image
            imType = 'Wbar';
            fac = 1e9; % to be applied to the image, for instance to convert in nm everywhere
            app.img = app.polarIm.(imType);
            app.him.CData = app.img*fac;
            colorbar(app.axIm)
            colormap(app.axIm,Sepia)
            set(app.axIm,'DataAspectRatio',[1 1 1])
            xlim(app.axIm,[1,app.IMc(1).Nx])
            ylim(app.axIm,[1,app.IMc(1).Ny])

            for ii = 1:4
                app.axPx(ii).Children.CData = app.IMc(ii).OPDnm(1,1);
            end
            %update the 4 pixel values
            app.updateMeas()

            if ~app.fixedCheckBox.Value
                % initialise the min and max displayed values
                app.OPDminEditField.Value = min(app.IMc(1,1).OPDnm(:));
                app.OPDmaxEditField.Value = max(app.IMc(1,1).OPDnm(:));
            end            
            % adjust the colorscales
            app.adjust_clim()            
        end
    end

    methods
        
        function val = get.nim(app)
           val = app.nimEditField.Value; 
        end
        function val = get.Nim(app)
           val = app.NimEditField.Value; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IM)
            arguments
                app
                IM (:,4) ImageMethods
            end
            app.NimEditField.Value = size(IM,1);
            app.IM = IM;
            app.IMc = IM(1,:);
            app.polarIm = ImagePolar(app.IMc);
            imType = 'Wbar'; % retardance
            fac = 1e9; % to be applied to the image, for instance to convert in nm everywhere
            app.img = app.polarIm.(imType);

            app.dphimax = max(max(imgaussfilt(app.polarIm.dphi,2)));

            % create the 4 pixel images
            app.axPx(1) = app.UIAxes_color1;
            app.axPx(2) = app.UIAxes_color2;
            app.axPx(3) = app.UIAxes_color3;
            app.axPx(4) = app.UIAxes_color4;
            
            for ii = 1:4
                imagesc(app.axPx(ii),app.IMc(ii).OPDnm(1,1))
                set(app.axPx(ii),'InnerPosition',[0, 0, 1, 1],'Units','normalized','DataAspectRatio',[1 1 1],"Visible",'on')
                colormap(app.axPx(ii),Sepia)
            end

            % plot the image
            app.him = imagesc(app.axIm,app.img*fac);
            colorbar(app.axIm)
            colormap(app.axIm,Sepia)
            set(app.axIm,'DataAspectRatio',[1 1 1])
            xlim(app.axIm,[1,app.IMc(1).Nx])
            ylim(app.axIm,[1,app.IMc(1).Ny])

            app.updateImage()

            app.UIFigure.WindowButtonMotionFcn = @(src, event) mouseOverImage(app);


        end

        % Value changed function: OPDmaxEditField, OPDminEditField
        function adjust_clim(app, event)
            valMin = app.OPDminEditField.Value;
            valMax = app.OPDmaxEditField.Value;
            clim(app.axIm, [valMin, valMax])
            for ii = 1:4
                clim(app.axPx(ii), [valMin, valMax])
            end
            %app.adjust_clim() 
            
        end

        % Value changed function: OPDmaxEditField_2, OPDminEditField_2
        function adjust_ylim(app, event)
            valMin = app.OPDminEditField_2.Value;
            valMax = app.OPDmaxEditField_2.Value;
            ylim(app.axPlot, [valMin, valMax])
            for ii = 1:4
                clim(app.axPx(ii), [valMin, valMax])
            end

        end

        % Button pushed function: nextButton
        function arrowButtonPushed(app, event)
            switch event.Source.Tag
                case 'next'
                    step = 1;
                case 'previous'
                    step = -1;
                otherwise  % don't push any arrow, but enter an image number in the nimEditField
                    step = 0;
            end

            app.nimEditField.Value = mod(app.nim + step - 1, app.Nim) + 1;
            app.IMc = app.IM(app.nim,:);
            app.polarIm = ImagePolar(app.IMc);
            app.updateImage()
            
        end

        % Value changed function: nimEditField
        function nimEditFieldValueChanged(app, event)
            app.IMc = app.IM(app.nim,:);
            app.polarIm = ImagePolar(app.IMc);
        end

        % Value changed function: fixedCheckBox_2
        function fixedCheckBox_2ValueChanged(app, event)
            value = app.fixedCheckBox_2.Value;
            if value
                app.axPlot.YLimMode = "manual";
            else
                app.axPlot.YLimMode = "auto";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.axIm = uiaxes(app.GridLayout);
            title(app.axIm, 'Retardance')
            app.axPlot = uiaxes(app.GridLayout);
            title(app.axPlot, 'Profile')
            app.axPlot.XLim = [0 3.14159265358979];
            app.axPlot.XTick = [0 0.785398163397448 1.5707963267949 2.35619449019234 3.14159265358979];
            app.axPlot.XTickLabel = {'0'; '\pi/4'; '\pi/2'; '3\pi/4'; '\pi'};
            app.UIAxes_color1 = uiaxes(app.GridLayout);
            app.UIAxes_color1.XTick = [];
            app.UIAxes_color1.YTick = [];
            app.UIAxes_color4 = uiaxes(app.GridLayout);
            app.UIAxes_color4.XTick = [];
            app.UIAxes_color4.YTick = [];
            app.UIAxes_color3 = uiaxes(app.GridLayout);
            app.UIAxes_color3.XTick = [];
            app.UIAxes_color3.YTick = [];
            app.UIAxes_color2 = uiaxes(app.GridLayout);
            app.UIAxes_color2.XTick = [];
            app.UIAxes_color2.YTick = [];
            app.meas4EditField = uieditfield(app.GridLayout, 'numeric');
            app.meas3EditField = uieditfield(app.GridLayout, 'numeric');
            app.meas2EditField = uieditfield(app.GridLayout, 'numeric');
            app.meas1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Label_angle4 = uilabel(app.GridLayout);
            app.Label_angle4.Text = '135°';
            app.Label_angle3 = uilabel(app.GridLayout);
            app.Label_angle3.Text = '90°';
            app.Label_angle2 = uilabel(app.GridLayout);
            app.Label_angle2.Text = '45°';
            app.Label_angle1 = uilabel(app.GridLayout);
            app.Label_angle1.Text = '0°';
            app.nmLabel_4 = uilabel(app.GridLayout);
            app.nmLabel_4.Text = 'nm';
            app.nmLabel_3 = uilabel(app.GridLayout);
            app.nmLabel_3.Text = 'nm';
            app.nmLabel_2 = uilabel(app.GridLayout);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_1 = uilabel(app.GridLayout);
            app.nmLabel_1.Text = 'nm';
            app.theta0EditFieldLabel = uilabel(app.GridLayout);
            app.theta0EditFieldLabel.Text = 'theta0';
            app.theta0EditField = uieditfield(app.GridLayout, 'numeric');
            app.theta0EditField.RoundFractionalValues = 'on';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OPDminEditFieldLabel = uilabel(app.GridLayout2);
            app.OPDminEditFieldLabel.Text = 'OPDmin';
            app.OPDminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.OPDminEditField.ValueChangedFcn = createCallbackFcn(app, @adjust_clim, true);
            app.nmLabel_5 = uilabel(app.GridLayout2);
            app.nmLabel_5.Text = 'nm';
            app.OPDmaxEditFieldLabel = uilabel(app.GridLayout2);
            app.OPDmaxEditFieldLabel.Text = 'OPDmax';
            app.OPDmaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.OPDmaxEditField.ValueChangedFcn = createCallbackFcn(app, @adjust_clim, true);
            app.nmLabel_6 = uilabel(app.GridLayout2);
            app.nmLabel_6.Text = 'nm';
            app.GridLayout_2 = uigridlayout(app.GridLayout2);
            app.previousButton = uibutton(app.GridLayout_2, 'push');
            app.previousButton.Tag = 'previous';
            app.previousButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousButton.Text = '';
            app.nextButton = uibutton(app.GridLayout_2, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nextButton.Tag = 'next';
            app.nextButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextButton.Text = '';
            app.nimEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.nimEditField.Limits = [1 Inf];
            app.nimEditField.ValueChangedFcn = createCallbackFcn(app, @nimEditFieldValueChanged, true);
            app.nimEditField.Value = 1;
            app.NimEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.NimEditField.Limits = [1 Inf];
            app.NimEditField.Editable = 'off';
            app.NimEditField.Value = 1;
            app.EditField = uieditfield(app.GridLayout_2, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '/';
            app.fixedCheckBox = uicheckbox(app.GridLayout2);
            app.fixedCheckBox.Text = 'fixed';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.OPDmaxEditField_2Label = uilabel(app.GridLayout3);
            app.OPDmaxEditField_2Label.Text = 'OPDmax';
            app.OPDmaxEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.OPDmaxEditField_2.ValueDisplayFormat = '%0.0f';
            app.OPDmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @adjust_ylim, true);
            app.OPDminEditField_2Label = uilabel(app.GridLayout3);
            app.OPDminEditField_2Label.Text = 'OPDmin';
            app.OPDminEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.OPDminEditField_2.ValueChangedFcn = createCallbackFcn(app, @adjust_ylim, true);
            app.nmLabel_7 = uilabel(app.GridLayout3);
            app.nmLabel_7.Text = 'nm';
            app.nmLabel_8 = uilabel(app.GridLayout3);
            app.nmLabel_8.Text = 'nm';
            app.fixedCheckBox_2 = uicheckbox(app.GridLayout3);
            app.fixedCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @fixedCheckBox_2ValueChanged, true);
            app.fixedCheckBox_2.Text = 'fixed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polarImageInvestigator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end