classdef segmentationMaskGenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        ManualdrawingButton  matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        typeDropDown         matlab.ui.control.DropDown
        typeDropDownLabel    matlab.ui.control.Label
        DoneButton           matlab.ui.control.Button
        TextArea             matlab.ui.control.TextArea
        Grid_Cut             matlab.ui.container.GridLayout
        GridLayout2          matlab.ui.container.GridLayout
        invertCheckBox       matlab.ui.control.CheckBox
        CutLabel             matlab.ui.control.Label
        cutEditField         matlab.ui.control.NumericEditField
        cutSlider            matlab.ui.control.Slider
        Grid_Smoothing       matlab.ui.container.GridLayout
        smoothingEditField   matlab.ui.control.NumericEditField
        smoothingSlider      matlab.ui.control.Slider
        SmoothingLabel       matlab.ui.control.Label
        Grid_Threshold       matlab.ui.container.GridLayout
        thresholdEditField   matlab.ui.control.NumericEditField
        thresholdSlider      matlab.ui.control.Slider
        thresholdLabel       matlab.ui.control.Label
        UIAxes_imgCut        matlab.ui.control.UIAxes
        UIAxes_imgSmoothing  matlab.ui.control.UIAxes
        UIAxes_Threshold     matlab.ui.control.UIAxes
        UIAxes_img0          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img0 % raw image
        IM   % ImageMethod object if any as a first input
        mask1 logical % mask of the GradThreshold
        mask2 double % mask aftersmoothing
        Dimgx % gradient of the image along x
        Dimgy % gradient of the image along y
    end

    properties(Access = public)
        Finished = false
        maskFinal logical % mask after final threshold
        manualMask = []   % Drawn mask, if any, discarding all the parameters
    end

    properties(Dependent)
        threshold % Gradient threshold
        smoothing
        cut
        invert
        type
    end
    
    methods (Access = private)
        
        function computeMasks(app)
            if isempty(app.manualMask)
                switch app.type
                    case 'gradients'
                        [app.maskFinal, app.mask1, app.mask2] = computeSegmentationMasks(app.Dimgx, app.Dimgy, ...
                            "threshold",app.threshold, "smoothing", app.smoothing, "cut", app.cutEditField.Value, "invert", app.invert,"type",'gradients');
                    case 'OPD'
                        [app.maskFinal, app.mask1, app.mask2] = computeSegmentationMasks(app.img0, ...
                            "threshold",app.threshold, "smoothing", app.smoothing, "cut", app.cutEditField.Value, "invert", app.invert,"type",'OPD');
                    case 'T'
                        [app.maskFinal, app.mask1, app.mask2] = computeSegmentationMasks(app.img0, ...
                            "threshold",app.threshold, "smoothing", app.smoothing, "cut", app.cutEditField.Value, "invert", app.invert,"type",'T');
                end
            else
                if app.invert
                    app.maskFinal = ~app.manualMask;
                else
                    app.maskFinal = app.manualMask;
                end
            end
        end
    
        
        function updateImages(app)
            app.UIAxes_Threshold.Children.CData = app.mask1;
            app.UIAxes_imgSmoothing.Children.CData       = app.mask2;
            app.UIAxes_imgCut.Children.CData = app.maskFinal;

        end
    end

    methods

        function val = get.threshold(app)
            val = app.thresholdEditField.Value;
        end

        function val = get.cut(app)
            val = app.cutEditField.Value;
        end

        function val = get.smoothing(app)
            val = app.smoothingEditField.Value;
        end

        function val = get.invert(app)
            val = app.invertCheckBox.Value;
        end

        function val = get.type(app)
            val = app.typeDropDown.Value;
        end
 
        % function set.gradThresh(app, val)
        %     % ensures 
        %     if val<1e-3
        %         app.thresholdEditField.Value = val*1e9;
        %         app.thresholdSlider.Value = val*1e9;
        %     else
        %         app.thresholdEditField.Value = val;
        %         app.thresholdSlider.Value = val;
        %     end
        % end

        function set.threshold(app, val)
                app.thresholdEditField.Value = val;
                app.thresholdSlider.Value = val;
        end

        function set.cut(app, val)
                app.cutEditField.Value = val;
                app.cutSlider.Value = val;
        end

        function set.smoothing(app, val)
                app.smoothingEditField.Value = val;
                app.smoothingSlider.Value = val;
        end

        function set.invert(app, val)
            arguments
                app
                val logical
            end
                app.invertCheckBox.Value = val;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img0, opt)
            arguments
                app
                img0 = rand(100,70)
                opt.threshold= 2
                opt.smoothing  = 5
                opt.cut        = 0.1
                opt.invert     = false
                opt.type {mustBeMember(opt.type, {'OPD','T','gradients'})} = 'gradients'
            end


            app.threshold = opt.threshold;
            app.smoothing = opt.smoothing;
            app.cut       = opt.cut;
            app.invert    = opt.invert;

            if isa(img0,'ImageQLSI')
                app.typeDropDown.Value = opt.type;
                app.IM = img0;
                app.img0  = img0.(opt.type);
                app.Dimgx = img0.DWx;
                app.Dimgy = img0.DWy;
            elseif isnumeric(img0)
                app.img0  = img0;
                [app.Dimgx, app.Dimgy] = imgradientxy(img0);
            else
                error('Not a proper input type. Must be ImageQLSI or numeric')
            end

            %app.UIAxes_img0.Visible = 'off';
            app.computeMasks()

            % display img0 image
            imagesc(app.UIAxes_img0,app.img0)
            app.UIAxes_img0.XLim = [1,size(app.img0,2)];
            app.UIAxes_img0.YLim = [1,size(app.img0,1)];
            app.UIAxes_img0.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_img0,phase1024)
            colorbar(app.UIAxes_img0,'location','southoutside')
            
            % display threshold image
            imagesc(app.UIAxes_Threshold, app.mask1)
            app.UIAxes_Threshold.XLim = [1,size(app.img0,2)];
            app.UIAxes_Threshold.YLim = [1,size(app.img0,1)];
            app.UIAxes_Threshold.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_Threshold,"gray")
            colorbar(app.UIAxes_Threshold,'location','southoutside')

            % display smoothed image
            imagesc(app.UIAxes_imgSmoothing, app.mask2)
            app.UIAxes_imgSmoothing.XLim = [1,size(app.img0,2)];
            app.UIAxes_imgSmoothing.YLim = [1,size(app.img0,1)];
            app.UIAxes_imgSmoothing.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_imgSmoothing,"gray")
            colorbar(app.UIAxes_imgSmoothing,'location','southoutside')

            % display Final image
            imagesc(app.UIAxes_imgCut, app.maskFinal)
            app.UIAxes_imgCut.XLim = [1,size(app.img0,2)];
            app.UIAxes_imgCut.YLim = [1,size(app.img0,1)];
            app.UIAxes_imgCut.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_imgCut,"gray")
            colorbar(app.UIAxes_imgCut,'location','southoutside')


        end

        % Value changed function: cutEditField, cutSlider, invertCheckBox, 
        % ...and 4 other components
        function paramValueChanged(app, event)
            tag = event.Source.Tag;
            if ~strcmp(tag,'invert')
                app.manualMask = [];
                app.([tag 'EditField']).Value = event.Source.Value;
                app.([tag 'Slider']).Value    = event.Source.Value;
            end

            if isa(event.Source,'matlab.ui.control.Slider')
                lims = event.Source.Limits;
                Dlims = lims(2)-lims(1);
                if event.Source.Value<lims(1) + Dlims/10  % Si le slider atteint les extrémités
                    event.Source.Limits = event.Source.Limits/10;
                elseif event.Source.Value>lims(2) - Dlims/10
                    event.Source.Limits = event.Source.Limits*10;
                end
            elseif ~strcmp(tag,'invert')
                app.(tag+"Slider").Limits = [0, 10^ceil(log10(event.Source.Value))];
            end                
            app.computeMasks()
            app.updateImages()

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.Finished = true;
        end

        % Value changed function: typeDropDown
        function typeDropDownValueChanged(app, event)
            value = app.typeDropDown.Value;
            if ~isempty(app.IM)
                app.img0  = app.IM.(value);
            end
            app.UIAxes_img0.Children.CData = app.img0;
            app.computeMasks()
            app.updateImages()
        end

        % Button pushed function: ManualdrawingButton
        function ManualdrawingButtonPushed(app, event)
            roi = drawpolygon(app.UIAxes_img0);
            app.maskFinal = createMask(roi);
            app.manualMask = app.maskFinal;
            app.UIAxes_imgCut.Children.CData = app.maskFinal;
            app.updateImages()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_img0 = uiaxes(app.GridLayout);
            title(app.UIAxes_img0, 'Image')
            app.UIAxes_img0.XTick = [];
            app.UIAxes_img0.YTick = [];
            app.UIAxes_img0.ZTick = [];
            app.UIAxes_Threshold = uiaxes(app.GridLayout);
            title(app.UIAxes_Threshold, 'Mask')
            app.UIAxes_Threshold.XTick = [];
            app.UIAxes_Threshold.YTick = [];
            app.UIAxes_imgSmoothing = uiaxes(app.GridLayout);
            title(app.UIAxes_imgSmoothing, 'Mask')
            app.UIAxes_imgSmoothing.XTick = [];
            app.UIAxes_imgSmoothing.YTick = [];
            app.UIAxes_imgCut = uiaxes(app.GridLayout);
            title(app.UIAxes_imgCut, 'Final mask')
            app.UIAxes_imgCut.XTick = [];
            app.UIAxes_imgCut.YTick = [];
            app.Grid_Threshold = uigridlayout(app.GridLayout);
            app.thresholdLabel = uilabel(app.Grid_Threshold);
            app.thresholdLabel.Text = 'Threshold';
            app.thresholdSlider = uislider(app.Grid_Threshold);
            app.thresholdSlider.Limits = [0 10];
            app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.thresholdSlider.Tag = 'threshold';
            app.thresholdSlider.Value = 2;
            app.thresholdEditField = uieditfield(app.Grid_Threshold, 'numeric');
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.thresholdEditField.Tag = 'threshold';
            app.thresholdEditField.Value = 2e-09;
            app.Grid_Smoothing = uigridlayout(app.GridLayout);
            app.SmoothingLabel = uilabel(app.Grid_Smoothing);
            app.SmoothingLabel.Text = 'Smoothing';
            app.smoothingSlider = uislider(app.Grid_Smoothing);
            app.smoothingSlider.Limits = [0 20];
            app.smoothingSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.smoothingSlider.Tag = 'smoothing';
            app.smoothingSlider.Value = 5;
            app.smoothingEditField = uieditfield(app.Grid_Smoothing, 'numeric');
            app.smoothingEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.smoothingEditField.Tag = 'smoothing';
            app.smoothingEditField.Value = 5;
            app.Grid_Cut = uigridlayout(app.GridLayout);
            app.cutSlider = uislider(app.Grid_Cut);
            app.cutSlider.Limits = [0 1];
            app.cutSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.cutSlider.Tag = 'cut';
            app.cutSlider.Value = 0.1;
            app.cutEditField = uieditfield(app.Grid_Cut, 'numeric');
            app.cutEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.cutEditField.Tag = 'cut';
            app.cutEditField.Value = 0.1;
            app.GridLayout2 = uigridlayout(app.Grid_Cut);
            app.CutLabel = uilabel(app.GridLayout2);
            app.CutLabel.Text = 'Cut';
            app.invertCheckBox = uicheckbox(app.GridLayout2);
            app.invertCheckBox.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.invertCheckBox.Tag = 'invert';
            app.invertCheckBox.Text = 'invert';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Segmentation'; 'Mask'; 'Generator'};
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.typeDropDownLabel = uilabel(app.GridLayout3);
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.GridLayout3);
            app.typeDropDown.Items = {'gradients', 'T', 'OPD'};
            app.typeDropDown.ValueChangedFcn = createCallbackFcn(app, @typeDropDownValueChanged, true);
            app.typeDropDown.Value = 'OPD';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ManualdrawingButton = uibutton(app.GridLayout4, 'push');
            app.ManualdrawingButton.ButtonPushedFcn = createCallbackFcn(app, @ManualdrawingButtonPushed, true);
            app.ManualdrawingButton.Text = 'Manual drawing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationMaskGenApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end