classdef figurePolar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout_main              matlab.ui.container.GridLayout
        GridLayout                   matlab.ui.container.GridLayout
        EditField                    matlab.ui.control.EditField
        NimEditField                 matlab.ui.control.NumericEditField
        nimEditField                 matlab.ui.control.NumericEditField
        nextButton                   matlab.ui.control.Button
        previousButton               matlab.ui.control.Button
        GridLayout_smoothingParam    matlab.ui.container.GridLayout
        GridLayout_sliderSmoothing   matlab.ui.container.GridLayout
        smoothingSlider              matlab.ui.control.Slider
        smoothingEditField           matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel      matlab.ui.control.Label
        pxLabel                      matlab.ui.control.Label
        GridLayout_retMaxParam       matlab.ui.container.GridLayout
        GridLayout_sliderRetMax      matlab.ui.container.GridLayout
        retMaxSlider                 matlab.ui.control.Slider
        retMaxEditField              matlab.ui.control.NumericEditField
        RetardancemaxEditFieldLabel  matlab.ui.control.Label
        nmLabel                      matlab.ui.control.Label
        UIAxes_OPD                   matlab.ui.control.UIAxes
        UIAxes_Retardance            matlab.ui.control.UIAxes
        UIAxes_Theta                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pIm0 % Original, unmodfied ImagePolar object
        pIm % copy of the current ImagePolar object
        cb_OPD % Theta Axes colorbar
        cb_Theta % Theta Axes colorbar
        cb_Ret   % Retardance Axes colorbar
        Nim
        IM  % (smoothed) ImageQLSI
        smfun = @(x) x % Smoothing function, either imgaussfilt or Id
    end

    properties(Dependent)
        nim
    end
    
    methods (Access = private)
        
        function updateImages(app)
            
    
            imagesc(app.UIAxes_OPD, app.pIm.IM(1).OPDnm); % 1st image of the four
            imagesc(app.UIAxes_Retardance, app.pIm.retardance*1e9);
            clim(app.UIAxes_Retardance, [0 app.retMaxEditField.Value])
            imagesc(app.UIAxes_Theta, app.pIm.rgbImage);
            xlim(app.UIAxes_OPD,[1 app.pIm.Nx]);
            ylim(app.UIAxes_OPD,[1 app.pIm.Ny]);
            xlim(app.UIAxes_Retardance,[1 app.pIm.Nx]);
            ylim(app.UIAxes_Retardance,[1 app.pIm.Ny]);
            xlim(app.UIAxes_Theta,[1 app.pIm.Nx]);
            ylim(app.UIAxes_Theta,[1 app.pIm.Ny]);
            linkaxes([app.UIAxes_OPD, app.UIAxes_Retardance, app.UIAxes_Theta])
            drawnow
        end

        
        function updateImagePolarParameters(app)
            app.pIm.set_retMax(app.retMaxEditField.Value)

        end
        
        function recomputeIM(app)
            % function that recompute IM from the smoothing parameter
            if app.smoothingEditField.Value == 0
                app.pIm.IM = app.pIm0(app.nim).IM;
            else
                app.pIm.IM = app.pIm0(app.nim).IM.flatten("Gaussian","nGauss",app.smoothingEditField.Value);
            end
            
        end
    end

    methods

        function val = get.nim(app)
            val = app.nimEditField.Value; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, polarImages)
            app.pIm0 = polarImages;
            app.pIm = app.pIm0(1);
            app.retMaxEditField.Value = app.pIm0(1).retardanceMax*1e9;
            app.retMaxSlider.Value    = app.pIm0(1).retardanceMax*1e9;
            
            % Setting the Axes
            app.cb_OPD   = colorbar(app.UIAxes_OPD,  'Location','southoutside','Color',[1 1 1]);
            app.cb_Ret   = colorbar(app.UIAxes_Retardance,  'Location','southoutside','Color',[1 1 1]);
            app.cb_Theta = colorbar(app.UIAxes_Theta,'Location','southoutside','Color',[1 1 1]);
            colormap(app.UIAxes_OPD,Sepia);
            colormap(app.UIAxes_Retardance,Sepia);
            colormap(app.UIAxes_Theta,"hsv");
            app.UIAxes_OPD.XColor= [1 1 1];
            app.UIAxes_OPD.YColor= [1 1 1];
            app.UIAxes_OPD.Title.Color = [1 1 1];
            app.UIAxes_Retardance.XColor= [1 1 1];
            app.UIAxes_Retardance.YColor= [1 1 1];
            app.UIAxes_Retardance.Title.Color = [1 1 1];
            app.UIAxes_Theta.XColor= [1 1 1];
            app.UIAxes_Theta.YColor= [1 1 1];
            app.UIAxes_Theta.Title.Color = [1 1 1];

            set(app.UIAxes_OPD,'DataAspectRatio',[1 1 1])
            set(app.UIAxes_Retardance,'DataAspectRatio',[1 1 1])
            set(app.UIAxes_Theta,     'DataAspectRatio',[1 1 1])

            drawnow

            app.Nim = numel(app.pIm0);
            app.NimEditField.Value = app.Nim;

            app.updateImages()
        end

        % Value changed function: retMaxEditField
        function retMaxEditFieldValueChanged(app, event)
            value = app.retMaxEditField.Value;
            app.retMaxSlider.Value = value;
            app.pIm.set_retMax(value)
            app.updateImages()
        end

        % Value changed function: smoothingEditField
        function smoothingEditFieldValueChanged(app, event)
            value = app.smoothingEditField.Value;
            app.smoothingSlider.Value = value;
            app.recomputeIM()
            app.updateImages()
        end

        % Value changed function: retMaxSlider
        function retMaxSliderValueChanged(app, event)
            app.retMaxSlider.Value = round(app.retMaxSlider.Value);
            value = app.retMaxSlider.Value;
            app.retMaxEditField.Value = value;
            app.pIm.set_retMax(value)
            app.updateImages()
        end

        % Value changed function: smoothingSlider
        function smoothingSliderValueChanged(app, event)
            value = app.smoothingSlider.Value;
            app.smoothingSlider.Value = round(value);
            app.smoothingEditField.Value = round(value);
            app.recomputeIM()
            app.updateImages()
        end

        % Callback function: nextButton, nimEditField, previousButton
        function arrowButtonPushed(app, event)
            switch event.Source.Tag
                case 'next'
                    step = 1;
                case 'previous'
                    step = -1;
                otherwise  % don't push any arrow, but enter an image number in the nimEditField
                    step = 0;
            end

            app.nimEditField.Value = mod(app.nim + step - 1, app.Nim) + 1;
            app.pIm = duplicate(app.pIm0(app.nim)); % copy of the ImagePolar object
            app.pIm.set_retMax(app.retMaxEditField.Value) % copy the actual retMax value of the slide in the newly displayed image
            app.recomputeIM() % apply the smoothing on pIm in case nGauss is not zero
            app.updateImages()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.UIAxes_Theta = uiaxes(app.GridLayout_main);
            title(app.UIAxes_Theta, 'Theta')
            app.UIAxes_Retardance = uiaxes(app.GridLayout_main);
            title(app.UIAxes_Retardance, 'Retardance [nm]')
            app.UIAxes_OPD = uiaxes(app.GridLayout_main);
            title(app.UIAxes_OPD, 'OPD [nm]')
            app.GridLayout_retMaxParam = uigridlayout(app.GridLayout_main);
            app.nmLabel = uilabel(app.GridLayout_retMaxParam);
            app.nmLabel.Text = 'nm';
            app.RetardancemaxEditFieldLabel = uilabel(app.GridLayout_retMaxParam);
            app.RetardancemaxEditFieldLabel.Text = 'Retardance max';
            app.retMaxEditField = uieditfield(app.GridLayout_retMaxParam, 'numeric');
            app.retMaxEditField.Limits = [0 200];
            app.retMaxEditField.RoundFractionalValues = 'on';
            app.retMaxEditField.ValueDisplayFormat = '%11.0f';
            app.retMaxEditField.ValueChangedFcn = createCallbackFcn(app, @retMaxEditFieldValueChanged, true);
            app.retMaxEditField.Value = 20;
            app.GridLayout_sliderRetMax = uigridlayout(app.GridLayout_retMaxParam);
            app.retMaxSlider = uislider(app.GridLayout_sliderRetMax);
            app.retMaxSlider.Limits = [0 200];
            app.retMaxSlider.ValueChangedFcn = createCallbackFcn(app, @retMaxSliderValueChanged, true);
            app.retMaxSlider.Value = 20;
            app.GridLayout_smoothingParam = uigridlayout(app.GridLayout_main);
            app.pxLabel = uilabel(app.GridLayout_smoothingParam);
            app.pxLabel.Text = 'px';
            app.SmoothingEditFieldLabel = uilabel(app.GridLayout_smoothingParam);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.smoothingEditField = uieditfield(app.GridLayout_smoothingParam, 'numeric');
            app.smoothingEditField.Limits = [0 500];
            app.smoothingEditField.RoundFractionalValues = 'on';
            app.smoothingEditField.ValueDisplayFormat = '%11.0f';
            app.smoothingEditField.ValueChangedFcn = createCallbackFcn(app, @smoothingEditFieldValueChanged, true);
            app.GridLayout_sliderSmoothing = uigridlayout(app.GridLayout_smoothingParam);
            app.smoothingSlider = uislider(app.GridLayout_sliderSmoothing);
            app.smoothingSlider.Limits = [0 500];
            app.smoothingSlider.ValueChangedFcn = createCallbackFcn(app, @smoothingSliderValueChanged, true);
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.previousButton = uibutton(app.GridLayout, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.previousButton.Tag = 'previous';
            app.previousButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousButton.Text = '';
            app.nextButton = uibutton(app.GridLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nextButton.Tag = 'next';
            app.nextButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextButton.Text = '';
            app.nimEditField = uieditfield(app.GridLayout, 'numeric');
            app.nimEditField.Limits = [1 Inf];
            app.nimEditField.ValueChangedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nimEditField.Value = 1;
            app.NimEditField = uieditfield(app.GridLayout, 'numeric');
            app.NimEditField.Limits = [1 Inf];
            app.NimEditField.Editable = 'off';
            app.NimEditField.Value = 1;
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figurePolar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end