classdef LIVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout_main              matlab.ui.container.GridLayout
        messageArea                  matlab.ui.control.Label
        GridLayout_foot              matlab.ui.container.GridLayout
        GridLayout_frameRate         matlab.ui.container.GridLayout
        HzEditField                  matlab.ui.control.NumericEditField
        HzEditFieldLabel             matlab.ui.control.Label
        GridLayout_panelButtons      matlab.ui.container.GridLayout
        configButton                 matlab.ui.control.Button
        xyzButton                    matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        PhaseLABButton               matlab.ui.control.Button
        GridLayout_windowsButtons    matlab.ui.container.GridLayout
        closeButton                  matlab.ui.control.Button
        polarButton                  matlab.ui.control.StateButton
        canals2Button                matlab.ui.control.StateButton
        RytovButton                  matlab.ui.control.StateButton
        PDCMButton                   matlab.ui.control.StateButton
        massButton                   matlab.ui.control.StateButton
        dWButton                     matlab.ui.control.StateButton
        WButton                      matlab.ui.control.StateButton
        TButton                      matlab.ui.control.StateButton
        rawButton                    matlab.ui.control.StateButton
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout_Imaging           matlab.ui.container.GridLayout
        GridLayout_tools             matlab.ui.container.GridLayout
        HR_Button                    matlab.ui.control.StateButton
        cropButton                   matlab.ui.control.Button
        pxLabel                      matlab.ui.control.Label
        imageDimensionsDropDown      matlab.ui.control.DropDown
        msLabel                      matlab.ui.control.Label
        expTimeEditField             matlab.ui.control.NumericEditField
        tauLabel                     matlab.ui.control.Label
        flattenButton                matlab.ui.control.StateButton
        cancelSymmetryButton         matlab.ui.control.Button
        rot90Button                  matlab.ui.control.Button
        HmirrorButton                matlab.ui.control.Button
        VmirrorButton                matlab.ui.control.Button
        GridLayout_Save              matlab.ui.container.GridLayout
        loadFileButton               matlab.ui.control.Button
        GridLayout_savePrefix        matlab.ui.container.GridLayout
        saveLamp                     matlab.ui.control.Lamp
        saveFolderEditField          matlab.ui.control.EditField
        saveFolderButton             matlab.ui.control.Button
        savePrefixEditField          matlab.ui.control.EditField
        autosaveCheckBox             matlab.ui.control.CheckBox
        savedFileNameEditField       matlab.ui.control.EditField
        saveButton                   matlab.ui.control.Button
        GridLayout_Imaging_controls  matlab.ui.container.GridLayout
        LiveButton                   matlab.ui.control.StateButton
        RefButton                    matlab.ui.control.StateButton
        SnapButton                   matlab.ui.control.Button
        liveAvgEditField             matlab.ui.control.NumericEditField
        refAvgEditField              matlab.ui.control.NumericEditField
        snapAvgEditField             matlab.ui.control.NumericEditField
        GridLayout_specialControls   matlab.ui.container.GridLayout
        rightButton                  matlab.ui.control.Button
        leftButton                   matlab.ui.control.Button
        jimEditField                 matlab.ui.control.NumericEditField
        burstButton                  matlab.ui.control.Button
        timeLapseButton              matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        GridLayout_setup             matlab.ui.container.GridLayout
        camStatusLamp                matlab.ui.control.Lamp
        camConnectionLamp            matlab.ui.control.Button
        mmLabel                      matlab.ui.control.Label
        CGdistanceEditField          matlab.ui.control.NumericEditField
        nmLabel                      matlab.ui.control.Label
        RelayLensEditField           matlab.ui.control.NumericEditField
        RelayLensEditFieldLabel      matlab.ui.control.Label
        CrossGratingDropDown         matlab.ui.control.DropDown
        CrossGratingDropDownLabel    matlab.ui.control.Label
        cameraDropDown               matlab.ui.control.DropDown
        cameraDropDownLabel          matlab.ui.control.Label
        microscopeTLDropDown         matlab.ui.control.DropDown
        microscopeTLDropDownLabel    matlab.ui.control.Label
        objBrandDropDown             matlab.ui.control.DropDown
        lambdaEditField              matlab.ui.control.NumericEditField
        lambdaLabel                  matlab.ui.control.Label
        MobjEditField                matlab.ui.control.NumericEditField
        MobjEditFieldLabel           matlab.ui.control.Label
    end


    properties (SetAccess = private)
        Itf0 (:,1) Interfero % current interferogram. Can be an array of Interfero, if a burst has been acquired.
        IM  ImageMethods % current ImageQLSI object
        Ref0 (1,1) Interfero % current reference interferogram
        MI  (1,1) Microscope % current microscope.   Same handle as Itf.Microscope & Ref.Microscope & IM.Microscope
        IL  (1,1) Illumination % current illumination. Same handle as IM.Illumination

        Itf4 % 4-polar Itf
        Ref4
    end

    properties (Dependent)
        % The properties are associated with set and get methods.
        % Here is how other functions interact with this class,
        % not by modifying Itf and IM themselves.
        % The caution protects, e.g., the Microscope and Illumination properties
        % of the Itf and IM properties.

        itf  % current interferogram image / not an object, just an image
        ref  % current interferogram image
        W0    % current wavefront image
        T0    % current intensity image
        DWx0  % current x-gradient image
        DWy0  % current y-gradient image

    end

    properties (Dependent)
        Cam % CameraExp object
        Nx
        Ny

        jim  % # of the displayed image, in the case of a stored burst or time lapse
        Nim  % # total numer of app.Itf objects, in the case of a stored burst or time lapse
    end

    properties (SetAccess = private)
        hfigList % struct containing the list of the handles of the open figures
        haxList  % struct containing the list of the handles of the axes in figures
        himList  % struct containing the list of the handles of the displayed images

        roi images.roi.Rectangle = images.roi.Rectangle.empty(1,0)  % Region of interest to be cropped before processing and display

        piezoWindow       piezoControlWindow  = piezoControlWindow.empty()
        lambdaWindow   lambdaControlWindow    = lambdaControlWindow.empty()
    end

    properties(Access = private)

        nPos = 0 % position of the last opened figure, 0, 1 or 2 (1st, 2nd and 3rd lateral third of the screen)

        D4Transform char % Symmetry transformation of the image, char array composed of H, V, and R letters. e.g.,'HRV' 

        cameraType {mustBeMember(cameraType,{'','Andor','Hamamatsu','Pixelink','PCO'})} = ''

        %  color of the toggle buttons
        colorButtonOn  = [0.5, 0.5, 0.5] % dark
        colorButtonOff = [0.94, 0.94, 0.94] % bright

        lampColorConnected = [0.60    0.77    0.60];   % green
        lampColorNone = [0.9 0.9 0.9];  % light gray
        lampColorOff = [0.5 0.5 0.5];  % light gray
        lampColorWait = [0.7 0.5,0.3]; % orange
        lampColorError = [0.8    0.40    0.40];   % red
        lampColorRunning = [0.7 0.5,0.3]; % orange

    end

    properties(Dependent)
        NavgLive % returns the liveAvgEditField value
        NavgRef  % returns the refAvgEditField value
        NavgSnap % returns the sanoAvgEditField value

        expTime % returns the expTimeEditField value

        status_hfigList % list of logicalv alues indicating which image windows are open

    end
    
    methods (Access = private)

        function changeButtonColor(app,btn)
            % change the raw, T, W, ... button colors
            if btn.Value == 1
                % Activated mode
                btn.BackgroundColor = app.colorButtonOn;
                btn.FontColor = [1 1 1];  % White
            else
                % Desactivated mode
                btn.BackgroundColor = app.colorButtonOff ;
                btn.FontColor = [0 0 0];  % Black
            end
        end
        
        function connectCamera(app)
            app.camConnectionLampState('Wait')
            app.Cam.setupSDK()
            rc = app.Cam.turn('on');
            app.Cam.setExpTime(app.expTime);
  
            if rc~=0
                disp('error in the camera startup')
                app.camConnectionLampState('Error');
            else
                app.camConnectionLampState('Connected');
            end

        end

        function disconnectCamera(app)
            app.camConnectionLampState('Wait')
            rc = app.Cam.turn('off');
            if rc~=0
                disp('error in disconnecting the camera')
                app.camConnectionLampState('Error');
            else
                app.camConnectionLampState('None');
            end

        end

        function enableCameraParameters(app,value)
            % enable or not the parameters in relation with a RL
            arguments
                app
                value %{mustBeMember(value,{"on","off"})}
            end
            app.CrossGratingDropDown.Enable = value;
            app.RelayLensEditField.Enable = value;
            app.CrossGratingDropDownLabel.Enable = value;
            app.RelayLensEditFieldLabel.Enable = value;
            app.CGdistanceEditField.Enable = value;
                        
        end

        function buildWindowsHandles(app)
            % Function that builds the hfig, hax, him properties from the
            % list of button tags ('raw','T','W',...))
            childrens = app.GridLayout_windowsButtons.Children;
            Nt = numel(childrens);
        
            for k = 1:Nt
                element = childrens(k);
                if isa(element, 'matlab.ui.control.StateButton')
                    if ~isempty(element.Tag)
                        app.hfigList.(element.Tag) = matlab.ui.Figure.empty();
                        app.haxList.(element.Tag) =  matlab.graphics.axis.Axes.empty();
                        app.himList.(element.Tag) =  matlab.graphics.primitive.Image.empty();
                    end
                end
            end
        end
            
        function toggleFigure(app,tag,val) % build or delete the popup figures
            
            app.([tag,'Button']).Value = val;
            if val % Open the figure
%                    [hf,ha,hi] = buildCustomFigure(tag,'Nx',app.Nx,'Ny',app.Ny,'Position',app.nPos,'enableClose',false);
                    appW = imageWindowLive(tag,'Nx',app.Nx,'Ny',app.Ny,'Position',app.nPos,'enableClose',false);
                    app.nPos =  app.nPos+1;
                    app.hfigList.(tag) = appW.hf;
                    app.haxList.(tag) = appW.ha;
                    app.himList.(tag) = appW.hi;
                    app.updateImages();
    
            else   % Close the figure
                app.([tag,'Button']).Value = 0;
                if isvalid(app.hfigList.(tag))
                    close(app.hfigList.(tag));
                end
                delete(app.hfigList.(tag))
                app.hfigList.(tag) = matlab.ui.Figure.empty();
                app.himList.(tag) = matlab.graphics.primitive.Image.empty();
                app.haxList.(tag) = matlab.graphics.axis.Axes.empty();

            end

            if ~any(app.status_hfigList) % if all the windows are closed
                app.setAcqMode('stop')
                app.closeButton.Text = 'close';
            else
                app.closeButton.Text = 'close all';
            end
            
            app.changeButtonColor(app.([tag,'Button']));
        end

        function updateCamera(app)
            % updates the GUI info, and the app.MI property
            camName = app.cameraDropDown.Value;

            switch camName
                case {'Zyla5.5','Orca Flash','Panda','OE #17-816','2-color','PHX050S-PC','Simu','Pixelink'}
                    app.cameraDropDownLabel.Text = 'Camera';
                    app.enableCameraParameters("on");
                    cam = Camera(strrep(camName, ' ', ''));
                    app.MI.CGcam.Camera = cam.makeAlive();

                case {'sC8-830','sC8-944'}
                    app.cameraDropDownLabel.Text = 'CGcamera';
                    app.MI.CGcam = CGcamera(camName);
                    app.MI.CGcam.Camera = app.MI.CGcam.Camera.makeAlive();
                    app.CrossGratingDropDown.Value = 'sC8';
                    app.RelayLensEditField.Value = [];
                    app.CGdistanceEditField.Value = app.MI.CGcam.CGpos*1e3;
                    app.MI2panel()
                    app.enableCameraParameters("off");
            end
            app.imageDimensionsDropDown.Items = app.Cam.imageDimensionsList;
        end
        
        function acquire(app,mode)
            app.setAcqMode(mode)

            if strcmp(app.Cam.mode,'ref')
                imgType = 'Ref';
            else
                imgType = 'Itf';
            end

            app.camStatusLampState('Running')

            %% FIXED MODE. Acquisition of a fixed numer of images.
            if ~strcmp(app.Cam.mode,'live') && ~strcmp(app.Cam.mode,'timeLapse')

                if strcmp(app.Cam.mode, 'ref')
                    Nimg = app.refAvgEditField.Value;
                elseif  strcmp(app.Cam.mode, 'snap') || strcmp(app.Cam.mode, 'burst')
                    Nimg = app.snapAvgEditField.Value;
                end
                
                app.Cam.setMode('Fixed', 'Internal',"expTime",app.expTime,"Nim",app.refAvgEditField.Value)
                app.Cam.startAcquisition()
                app.camStatusLampState("Running")
                
                buf = app.Cam.acquire_fixed(Nimg,app);

                dt = datetime('now');

                if strcmpi(app.Cam.mode,'burst')
                    app.(lower(imgType)) = buf;
                else % snap or timeLapse => average the Itfs
                    app.(lower(imgType)) = mean(buf, 3);   % app.Itf0 or app.Ref0
                end

                app.([imgType '0']).setParams("timeStamp",dt,"expTime",app.expTime, ...
                                        "Navg",size(buf,3),"ROI",app.Cam.getROI())
                    
                % if some windows are open, then compute the images (W, T, ...) and display
                if ~strcmp([imgType '0'],'Ref') && any( app.status_hfigList )
                    app.computeImages(app.Cam.mode)
                    app.updateImages()
                end

                if strcmp([imgType '0'],'Ref')
                    app.RefButton.Value = 0;
                end
                    

            %% CONTINUOUS MODE.Live acquisition
            else

                app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime)
                app.Cam.startAcquisition()

                if all(app.status_hfigList == 0) && strcmpi(app.Cam.mode,'live') % if live mode (and not timeLapse) and nothing to display
                    app.setAcqMode('stop')
                    return % Then abort
                end

                k = 0;
                while(~strcmp(app.Cam.mode,'stop')) % as long as it is not stop, run live

                    tic
                    Nimg = app.liveAvgEditField.Value;
                    if Nimg == 1
                        app.itf = app.Cam.acquire_continuous(app);
                    else
                        app.itf = 0;
                        for ii = 1:Nimg % TODO: make this in Fixed mode. It will be faster. With a condition on Nim. Nim == 1 -> Continuous. Nim > 1 -> Fixed and average like in snap
                            app.itf = app.itf + app.Cam.acquire_continuous(app)/Nimg;
                        end
                    end
                    app.Itf0.setParams("timeStamp",datetime('now'),"expTime",app.Cam.expTime, ...
                                      "Navg",1, ...      % TO BE MODIFIED BY THE NUMBER OF AVERAGED IMAGES
                                      "ROI",app.Cam.getROI())                    

                    drawnow
                    
                    % in case the camera mode changed to
                    % timeLapse, ref or snap during live

                    switch app.Cam.mode
                        case 'timeLapse'
                            if ~exist('appTL','var')
                                appTL = timeLapsePanel(app);
                            elseif ~isvalid(appTL)
                                appTL = timeLapsePanel(app);
                            end
                            
                            if ~isempty(appTL.t0) % if the time t0 has been created by pressing the start button of the appTL panel
                                if k == 0 % first time, first image
                                    nextTime = appTL.t0;
                                    dt = appTL.dt;
                                    % Parameters
                                    dt = seconds(dt);         % Interval between successive images
                                end
                                if isvalid(appTL)
                                    appTL.setNextImageTime(nextTime)
                                end
                                if datetime('now') >= nextTime && k<appTL.N
                                    k = k+1;
                                    nextTime = appTL.t0 + k*dt;
                                    app.saveCurrentImage()
                                    if isvalid(appTL)
                                        appTL.setImageNumber(k)
                                    end
                                elseif k>=appTL.N
                                    k=0;
                                    if isvalid(appTL)
                                        appTL.setImageNumber(k)
                                    end
                                    appTL.setNextImageTime('0')
                                    if all(app.status_hfigList == 0) % if all the windows are closed
                                        app.setAcqMode('stop')
                                    else
                                        app.setAcqMode('live')
                                    end
                                    app.toggleSavePanel(true)
                                    appTL.t0 = [];
                                    appTL.toogleStartButton('start')

                                end
                            end
                        case 'ref'
                            app.Cam.stopAcquisition()
                            app.camStatusLampState("Off")

                            app.acquire('ref')
                            app.setAcqMode('live')
                            app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.refAvgEditField.Value)
                            app.Cam.startAcquisition()
                            app.camStatusLampState("Running")


                        case 'snap'
                            app.Cam.stopAcquisition()
                            app.camStatusLampState("Off")
                            app.acquire('snap')
                            app.setAcqMode('stop')
                            app.Cam.startAcquisition() % even if not necessary to avoid a warning message of stopping the acquisition twice
                            app.camStatusLampState("Running")
                    end

                    if any( app.status_hfigList ) 
                        app.computeImages()
                        app.updateImages()
                    else
                        drawnow
                    end
                    app.HzEditField.Value = round(1/toc,1);
                end
                app.HzEditField.Value = double.empty();

            end

            app.Cam.stopAcquisition()
            app.camStatusLampState('Off')


            % AUTOSAVE
            if app.autosaveCheckBox.Value && (strcmpi(mode,'snap') || strcmpi(mode,'burst'))
                app.saveCurrentImage()
            else
                app.setAcqMode('stop')

            end
    
            if numel(app.Itf0) > 1
                app.enableImageNavigation()
            end


        end


        function updateIL(app)
            app.IL.lambda = app.lambdaEditField.Value;
        end
        
        function clearImages(app)
            if ~isempty(app.himList.raw)
                app.himList.raw.CData  = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.T)
                app.himList.T.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.W)
                app.himList.W.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.dW)
                app.himList.dW.CData   = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.mass)
                app.himList.mass.CData = zeros(app.Ny,app.Nx);
            end
            
            if ~isempty(app.himList.PDCM)
                app.himList.PDCM.CData = zeros(app.Ny,app.Nx);
            end

        end
        
        function clearRef(app)
            app.ref = [];
            app.Itf0.clearTF()            
        end
        
        function cropDefinition(app)
            figs = findall(0,'Type','figure','Tag','liveWindow');
            for k = 1:numel(figs)
                if ~isempty(figs(k).CurrentObject)
                    hfig = figs(k);
                end
            end
            if ~exist('hfig','var') % If none is active, just take any, like the last one in the list
                hfig = figs(k);
            end
            hax = findall(hfig,'Tag','LeftAxis');
            if isempty(hax)
                warning('no axis found in the window')
                return
            end
            h = figure;
            hax = copy(hax);
            hax.Parent=h;
            colorbar('off')
            hax.Position = [10,10,400,400];
            h.Position = [632 174 417 459];
            h.Color = [0.9 0.9 0.9];
            hax.Title.String = 'toto';
            hax.Title.Color = [1 1 1];
            hax.XColor = [0 0 0];
            hax.YColor = [0 0 0];
            hax.Children.XData = 1:app.Itf0(1).Nx;
            hax.Children.YData = 1:app.Itf0(1).Ny;

            app.roi = drawrectangle(hax);
%            addlistener(roi,'ROIMoved',@(src, evt) roiMoved(app));
            %roi.DeleteFcn = @roiDeleted;
            
            % function roiMoved(src, evt, app)
            %     newCrop = round(evt.CurrentPosition);
            %     %ha = src.Parent;
            %     app.roi = newCrop;
            % end

        end
        
        function enableImageNavigation(app,val)
            arguments
                app
                val = 1
            end
            if val
                app.rightButton.Enable = 1;
                app.leftButton.Enable = 1;
                app.jimEditField.Enable= 1;
            else
                app.rightButton.Enable = 0;
                app.leftButton.Enable = 0;
                app.jimEditField.Enable = 0;
            end
        end
        
        function moveImage(app,tag)
            switch tag
                case 'right'
                    app.jim = app.jim+1;
                case 'left'
                    app.jim = app.jim-1;
            end
            app.updateImages()
        end

        function MI2panel(app)
            % update the app.MI with what is written in the parameters panel

            app.MobjEditField.Value = app.MI.Objective.Mobj;   % update Mobj field
            app.cameraDropDown.Value = app.MI.CGcam.fileName;
            app.microscopeTLDropDown.Value = string(app.MI.f_TL) ;
            if isempty(app.MI.CGcam.RL)
                 app.RelayLensEditField.Value = [];
            else
                app.RelayLensEditField.Value = app.MI.CGcam.RL.zoom;
            end
            app.CrossGratingDropDown.Value = app.MI.CGcam.CG.name;

        end
        
        
        function writeMessage(app,val,color)
            arguments
                app
                val
                color = [0.8 0.8 0.8]
            end

            app.messageArea.Text = val;
            app.messageArea.FontColor = color;

        end
    end


    methods(Access = ?setupControlWindow)

        function panel2MI(app)
            % update the app.MI with what is written in the parameters panel
            % except the camera (update the camera with app.updateCamera() )

            app.MI.Objective = Objective(app.MobjEditField.Value,0.7,app.objBrandDropDown.Value);
            app.MI.f_TL = app.microscopeTLDropDown.Value; 
            app.MI.CGcam.setDistance(app.CGdistanceEditField.Value*1e-3); 
            if isempty(app.RelayLensEditField.Value)
                app.MI.CGcam.RL = RelayLens.empty();
            else
                app.MI.CGcam.RL = RelayLens(app.RelayLensEditField.Value);
            end
            if strcmp(app.CrossGratingDropDown.Value(end-1:end),'µm')
                app.MI.CGcam.CG = CrossGrating(Gamma = str2double(app.CrossGratingDropDown.Value(1:end-2))*1e-6,lambda0 = 550e-9);
            else
                app.MI.CGcam.CG = CrossGrating(app.CrossGratingDropDown.Value);
            end

        end


    end


    methods(Access = public)

        function computeImages(app, mode)
            arguments
                app
                mode char = ''
            end
            % function that computes and distribute the computed images (T, W, dW, etc)
            % on the differennt open windows.
            
            HR = app.HR_Button.Value; % High resolution
            HRlist = {'low','high'};
            cellValues = struct2cell(app.himList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            statusList = ~isEmptyLogical;
            noref = isempty(app.Itf0(1).Ref.Itf);
            
            % As a function of if raw, dW, T and OPD are required, compute only what is needed, ie dW, W and or T
            % Note: in any case, if raw is not the only parameter on, dW need to be computed
            if strcmpi(mode,'burst')
                app.IM = QLSIprocess(app.Itf0,app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref);
            elseif any(statusList(2:end)) % if any image other than raw need to be computed
                if statusList(4) && all(~statusList([2:3, 5:end])) % If only dW needs to be displayed (and maybe raw)
                    % Then no W or T calculation
                    computeT = 0;
                    computeW = 0;
                elseif isempty(app.himList.T) && isempty(app.himList.Rytov) % If still neither T nor Rytov, but something more (W, PDCM, etc...)
                    % then compute W
                    computeT = 0;
                    computeW = 1;
                elseif statusList(3) && all(~statusList([2, 5:end])) % if T and nothing else than raw and dW (ie nothing that requires the computation of W)
                    computeT = 1;
                    computeW = 0;
                else
                    computeT = 1;
                    computeW = 1;
                end
            
                if isempty(app.roi) % if empty
                    [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                      'computeT', computeT, ...
                                                      'computeW', computeW, ...
                                                      'saveGradients',0, ...
                                                      'computeIM',0);
                elseif ~isvalid(app.roi) % or if deleted
                    [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                      'computeT', computeT, ...
                                                      'computeW', computeW, ...
                                                      'saveGradients',0, ...
                                                      'computeIM',0);
            
                else % apply roi crop
                    [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1).crop('roi',app.roi.Position),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                      'computeT', computeT, ...
                                                      'computeW', computeW, ...
                                                      'saveGradients',0, ...
                                                      'computeIM',0);
                end
            
            end

        end
 
        function updateImages(app)
            if app.flattenButton.Value
                symD4 = @(img) ZernikeRemoval(applySymmetryD4_bis(img,app.D4Transform),2,0);
            else
                symD4 = @(img) applySymmetryD4_bis(img,app.D4Transform);
            end
            if ~isempty(app.himList.raw)
                if ~isempty(app.Itf0(1).Itf)
                    app.himList.raw.CData  = symD4( app.Itf0(app.jim).Itf );
                end
            end
            if ~isempty(app.himList.T)
                app.himList.T.CData    = symD4( app.IM(app.jim).T );
            end
            if ~isempty(app.himList.W)
                app.himList.W.CData    = symD4( app.IM(app.jim).OPDnm );
            end
            if ~isempty(app.himList.dW)
                app.himList.dW.CData   = symD4( app.IM(app.jim).DWx.^2 + app.IM(app.jim).DWy.^2 );
            end
            if ~isempty(app.himList.mass)
                app.himList.mass.CData = symD4( 5.56*1e-3 * app.IM(app.jim).OPDnm );
            end
            
            if ~isempty(app.himList.PDCM)
                dxDWy  = imgradientxy(app.IM(app.jim).DWy);
                [~, dyDWx] = imgradientxy(app.IM(app.jim).DWx);
                app.himList.PDCM.CData = symD4( imgaussfilt(dxDWy-dyDWx,3) );
            end
            
            if ~isempty(app.himList.Rytov)
                app.himList.Rytov.CData    = symD4( app.IM(app.jim).Rytov );
            end

            if ~isempty(app.himList.polar)
        
                nn = 4;
                %app.Itf.Itf0 = app.cam.doAcquisition();
                app.Itf4 = app.Itf0.splitPolars();
                if isempty(app.Ref4)
                    app.Ref4 = app.Ref0.splitPolars();
                    app.Itf4.Reference(app.Ref4)
                end
                IM4 = app.Itf4.QLSIprocess(app.IL, 'definition', 'low','displayBar',false);
                IM4.adjustPolarOffsets('auto'); % manual; manual
                %app.polarImages = app.IM4.extractPolarImages(app.retMaxEditField.Value);
                %app.polarImages = PolarImages(app.IM4.flatten("Gaussian","nGauss",150), app.retMaxEditField.Value);
                polarImages = ImagePolar(IM4, app.retMaxEditField.Value);
                polarImages.rgb_bg0 = 'white';  
                app.himList.polar.CData = polarImages.rgbImage(1+nn:end-nn,1+nn:end-nn,:);
                colormap("hsv")


            end
            % update twin images

            % Display something on the twin image, if there is.
            fieldList = fields(app.hfigList);
            for im = 1:length(app.status_hfigList)
                if app.status_hfigList(im)
                    if app.hfigList.(fieldList{im}).UserData.isTwinAx % if twin ax not empty
                        himLeft = app.himList.(fieldList{im});
                        haxRight = app.hfigList.(fieldList{im}).UserData.twinAxis;
                        haxLeft = app.haxList.(fieldList{im});
                        himRight = app.hfigList.(fieldList{im}).UserData.twinAxis.Children;
                        % HISTROGAM
                        if strcmp(app.hfigList.(fieldList{im}).UserData.twinImageType,'histo')
                            %histogram(app.hfigList.(fieldList{im}).UserData.twinAxis,...
                            %    app.himList.(fieldList{im}).CData)
                            [counts, edges] = histcounts(himLeft.CData);
                            binCenters = edges(1:end-1) + diff(edges)/2;
                            himRight.XData = binCenters;
                            himRight.YData = counts;
                            
                            xlim(haxRight,[0,2^app.Cam.bitDepth])
                        end

                        % FFT
                        if strcmp(app.hfigList.(fieldList{im}).UserData.twinImageType,'FFT')
                            himRight.CData = sqrt(sqrt(abs(fftshift(fft2(himLeft.CData)))));
                        end

                        % crosscut
                        if strcmp(app.hfigList.(fieldList{im}).UserData.twinImageType,'crosscut')
                    
                            if himRight.XData == 1
                                [cOPD,cx,cy,cx0,cy0] = improfile_GB(100, hax=haxLeft);
                                app.hfigList.(fieldList{im}).UserData.cx0 = cx0;
                                app.hfigList.(fieldList{im}).UserData.cy0 = cy0;
                                himRight.XData = 1;
                            else
                                cx0 = app.hfigList.(fieldList{im}).UserData.cx0;
                                cy0 = app.hfigList.(fieldList{im}).UserData.cy0;
                                [cx,cy,cOPD] = improfile_GB(100,Xi=cx0,Yi=cy0,Image = himLeft.CData);
                            end

                            dist=0;
                            for ni=1:length(cx)-1
                                dist = dist + sqrt((cx(ni)-cx(ni+1))^2+(cy(ni)-cy(ni+1))^2);
                            end

                            himRight.YData = cOPD;
                            himRight.XData = himRight.XData/max(himRight.XData)*dist;
                            himRight.LineWidth = 2;
                            himRight.Color = [0.8 0.5 0.2];
                            xlabel(haxRight,'px')
                            ylabel(haxRight,'nm')
                            title(haxRight,'wavefront','Color',[0.8 0.5 0.2],'FontSize',14)
                            grid(haxRight,"on")
                        
                        end

                        
                    end
                end
            end
            
            drawnow            
        end

        function saveCurrentImage(app)
            app.saveLamp.Color = [0.9, 0.5, 0.3];
            pathToMLAPP = fileparts(mfilename('fullpath'));
            currentIconPath = app.saveButton.Icon; % MOVIE_on.svg or SAVE.svg
            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE2.svg');

            if ~all(app.Itf0(1).ROI == app.Ref0.ROI) % if the ROI are not the same
                if isnat(app.Itf0(1).timeStamp)
                    error('no interferogram has been acquired yet')
                end
                app.savedFileNameEditField.Value = 'error: not the same Ref/Itf ROIs';
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');
                app.saveLamp.Color = [0.5, 0.5, 0.5];

                return
            end

            if isnat(app.Itf0(1).timeStamp)
                app.savedFileNameEditField.Value = 'error: no Itf acquired';
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');
                 app.saveLamp.Color = [0.5, 0.5, 0.5];

                return
            end                

            folder = app.saveFolderEditField.Value;

            if isempty(folder)
                folder = pwd;
            else
                folder = app.saveFolderEditField.Value;
            end

            if isnat(app.Ref0.timeStamp)
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');
                app.savedFileNameEditField.Value = 'warning: no Ref saved';
                RefFileName = '';
                pause(1)
            else
                RefFileName = createFileName('REF', folder, app.Ref0.timeStamp,app.Ref0.ROI);
                saveTiffMeta(folder+"/"+RefFileName, app.Ref0, app.MI, "lambda", app.IL.lambda, "ROI",app.Ref0.ROI)
            end
            
            for ii = 1:app.Nim
                ItfFileName = createFileName(app.savePrefixEditField.Value, folder);
                saveTiffMeta(folder+"/"+ItfFileName, app.Itf0(ii), app.MI, "lambda", app.IL.lambda, "ROI", app.Itf0(ii).ROI, "RefFileName",  RefFileName)
            end

            app.savedFileNameEditField.Value = ItfFileName;
            app.saveButton.Icon = currentIconPath;
            drawnow

            app.saveLamp.Color = [0.5, 0.5, 0.5];
            
        end
 
        function setAcqMode(app, mode)
            % Modify the appearance of the GUI (enable of disable buttons,
            % change icons, etc.
            arguments
                app
                mode (1,:) char {mustBeMember(mode,{'live','stop','snap','timeLapse','ref','burst','external'})}
            end

            switch mode
                case {'live','timeLapse'}
                    % turn the GUI into live state:
                    % Change the live icon to stop
                    app.LiveButton.Value = 1;
                    app.LiveButton.Icon = 'STOP.svg';
                    app.imageDimensionsDropDown.Enable = false;


                case 'stop'
                    % turn the GUI into stop state:
                    % turn the lamp green, change the stop icon to live
                    app.LiveButton.Value = 0;
                    app.RefButton.Value = 0;
                    app.LiveButton.Icon = 'LIVE.svg';
                    app.Cam.mode = 'stop';
                    app.HzEditField.Value = double.empty();
                    app.imageDimensionsDropDown.Enable = true;
                    
                    app.toggleSavePanel(true)
                    
                case 'snap'
                    app.LiveButton.Value = 1;
                    app.LiveButton.Icon = 'STOP.svg';
                    app.imageDimensionsDropDown.Enable = false;

            end

            app.Cam.mode = mode;
        end

        function toggleSavePanel(app,val)
            arguments
                app
                val logical
            end

            if val
                app.GridLayout_Save.BackgroundColor       = [0.9412    0.9412    0.9412];
                app.GridLayout_savePrefix.BackgroundColor = [0.9412    0.9412    0.9412]; 
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');
                app.autosaveCheckBox.Enable = true;
                app.savePrefixEditField.Enable = true;
                app.saveFolderEditField.Enable = true;
                app.saveFolderButton.Enable = true;
            else
                app.setAcqMode('live')
                app.GridLayout_Save.BackgroundColor       = [0.7, 0.3, 0.1];
                app.GridLayout_savePrefix.BackgroundColor = [0.7, 0.3, 0.1]; 
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'MOVIE_on.svg');
                app.autosaveCheckBox.Enable = false;
                app.savePrefixEditField.Enable = false;
                app.saveFolderEditField.Enable = false;
                app.saveFolderButton.Enable = false;
            end
        
        end
    end
    
    methods % set and get methods

        function img = get.itf(app)
            if isempty(app.roi)
                img = app.Itf0(1).Itf;
            else
                [~,~,img] = app.Itf0(1).crop('roi',app.roi.Position);
            end
        end

        function set.itf(app,img)
            if length(size(img)) == 2 % img is only one image
                app.Itf0 = app.Itf0(1);
                app.Itf0.Itf0 = img;
            elseif length(size(img)) == 3  % burst mode
                Ni = size(img,3);
                if Ni == app.Nim % already the proper number of images
                    for ii = 1:Ni
                        app.Itf0(ii).Itf0 = img(:,:,ii);
                    end
                else
                    % build an array of Interfero
                    app.Itf0 = Interfero(Ni);
                    for ii = 1:Ni
                        app.Itf0(ii) = Interfero(img(:,:,ii), app.MI);
                        app.Itf0(ii).Ref = app.Ref0;
                    end
                end
            end


        end

        function val = get.ref(app)
            val = app.Ref0.Itf;
        end
        function set.ref(app,val)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if isempty(val)
                app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF0.svg');
            else
                app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF.svg');
            end
            app.Ref0.Itf0 = val;
        end

        function val = get.W0(app)
            val = app.IM.OPDnm;
        end
        function set.W0(app,val)
            app.IM.OPD0 = val;
        end

        function val = get.T0(app)
            val = app.IM.T;
        end
        function set.T0(app,val)
            app.IM.T0 = val;
        end

        function val = get.DWx0(app)
            val = app.IM.DWx0;
        end
        function set.DWx0(app,val)
            app.IM.DWx0 = val;
        end

        function val = get.DWy0(app)
            val = app.IM.DWy0;
        end
        function set.DWy0(app,val)
            app.IM.DWy0 = val;
        end

        function val = get.Cam(app)
            val = app.MI.CGcam.Camera;
        end

        function val = get.Ny(app) % Andor camera display image with the width vertical in Matlab
            val = app.Cam.width;
        end

        function val = get.Nx(app)
            val = app.Cam.height;
        end

        function val = get.jim(app)
            val = app.jimEditField.Value;
        end

        function set.jim(app,val)
            app.jimEditField.Value = mod(val-1,app.Nim)+1;
            if val > 1
                app.enableImageNavigation(1);
            else
                app.enableImageNavigation(0);
            end
        end

        function val = get.Nim(app)
            val = numel(app.Itf0);
        end

        function val = get.NavgLive(app)
            val = app.liveAvgEditField.Value;
        end

        function val = get.NavgRef(app)
            val = app.refAvgEditField.Value;
        end

        function val = get.NavgSnap(app)
            val = app.snapAvgEditField.Value;
        end

        function val = get.expTime(app)
            val = app.expTimeEditField.Value*1e-3;
        end

        function set.expTime(app,val)
            if val ~= app.snapAvgEditField.Value
                app.expTimeEditField.Value = val*1e3;
            end
            app.MI.CGcam.Camera.setExpTime(val);
        end

        function val2 = camConnectionLampState(app,val)
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'Connected','None','Wait','Error'})} = 'none'% state of the camera lamp button
            end
            if ~strcmp(val,'')
                val(1) = upper(val(1)); % uppercase the first letter
                app.camConnectionLamp.BackgroundColor = app.("lampColor"+val);
                app.camConnectionLamp.UserData.state = val;
            elseif nargout
                val2 = app.camConnectionLamp.UserData.state;
            end
            drawnow
        end

        function val2 = camStatusLampState(app,val)
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'Running','Off'})} = 'Off' % state of the camera lamp button
            end
            if ~strcmp(val,'')
                %val(1) = upper(val(1)); % uppercase the first letter
                app.camStatusLamp.Color = app.("lampColor"+val);
                app.camStatusLamp.UserData.state = val;
            elseif nargout
                val2 = app.camStatusLamp.UserData.state;
            end
            drawnow
        end

        function results = get.status_hfigList(app)
            % returns an array of logical values saying which windows are open.
            cellValues = struct2cell(app.hfigList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            results = ~isEmptyLogical;
        end

        function val = imList(app,tag)
            switch tag
                case 'raw'
                    val = app.Itf0(1).Itf;
                case 'W'
                    val = app.IM.OPDnm;
                case 'T'
                    val = app.IM.T;
                case 'dW'
                    val = app.IM.D2Wnorm;
                case 'mass'
                    val = app.IM.dmd;
                case 'PDCM'
                    val = app.IM.PDCM;
                case 'Rytov'
                    val = app.IM.Rytov;
                otherwise
                    error('tag not defined')
            end
        end


    end

    methods(Static)
       function setupAndorSDK3()
            % ge the root directory of Matlab
            matlabRootPath = matlabroot;
        
            % Detection of the OS
            if ispc
                % bBuild the path for Windows
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            elseif ismac
                % Build the path for Mac
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            else
                error('unsupported OS');
            end
        
            % add the path
            addpath(genpath(sdkPath));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.writeMessage('');
            app.IM = ImageQLSI();
                        
            %% Define the app (figure) position (middle top)
            screenSize = get(0, 'ScreenSize');

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calculate the x position to center horizontally (optional)
            xPos = (screenSize(3) - appWidth) / 2;
            
            % Calculate the y position to align at the top of the screen.
            % Since the coordinate system starts at the bottom left,
            % we set y such that yPos + appHeight = screenSize(4)
            yPos = screenSize(4) - appHeight - 30;

            app.UIFigure.Position(1:2) = [xPos,yPos];
            
            %% Define the volors of the upper-left lamps
            app.camConnectionLamp.UserData.colorOn    = app.lampColorConnected;
            app.camConnectionLamp.UserData.colorOff   = app.lampColorNone;
            app.camConnectionLamp.UserData.colorWait  = app.lampColorWait;
            app.camConnectionLamp.UserData.colorError = app.lampColorError;

            app.camStatusLamp.UserData.colorRunning  = app.lampColorRunning;
            app.camStatusLamp.UserData.colorRunning  = app.lampColorOff;

            app.camConnectionLampState('None')
            app.camStatusLampState('Off')


            %% Creating the app Microscope
            % Creating a blank Microscope
            app.MI = Microscope();
            app.MI.CGcam.CG = CrossGrating();
            app.MI.software = 'PhaseLAB';
            app.panel2MI()
            app.RelayLensEditField.AllowEmpty = 'on';
            app.HzEditField.AllowEmpty = 'on';
            app.RelayLensEditField.Value = [];

            app.IL = Illumination(app.lambdaEditField.Value,Medium(1.33,1.5));
            app.IM.Illumination = app.IL;
            app.IM.Microscope = app.MI;
            
            % update the Microscope from the initial panel
            app.updateCamera()
            app.panel2MI()
            
            app.Itf0     = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            app.Itf0.Ref = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            app.Itf0.Ref.Itf0 = [];
            app.Ref0 = app.Itf0.Ref;
            
            % assign tags to figure handles in app.hfig
            app.buildWindowsHandles();


        end

        % Value changed function: PDCMButton, RytovButton, TButton, 
        % ...and 5 other components
        function toggleImageWindow(app, event)
            % open or close the corresponding figure

            app.toggleFigure(event.Source.Tag,event.Source.Value)

        end

        % Value changed function: CGdistanceEditField, 
        % ...and 5 other components
        function microscopeValueChanged(app, event)
            value = app.microscopeTLDropDown.Value;
            if ~isnan(str2double(value))
                app.microscopeTLDropDownLabel.Text = 'f_TL';
                %str2double(value)  % La chaîne ne contient que des nombres
            else
                app.microscopeTLDropDownLabel.Text = 'Microscope';  % 'La chaîne contient d'autres caractères que des nombres
            end

            app.panel2MI()
        end

        % Button pushed function: closeButton
        function closeButtonValueChanged(app, event)
            if all(app.status_hfigList == 0) % if all the windows are closed
                app.disconnectCamera()
                delete(app.UIFigure)
            else % if some windows are open, just close these windows
                tags = fields(app.hfigList);
                Na = numel(tags);
                for ia = 1:Na
                    if ~isempty(app.hfigList.(tags{ia}))
                        app.toggleFigure(tags{ia},0)
                    end
                end
                app.nPos=0;
                app.setAcqMode('stop')
                event.Source.Text = 'close';
            end            
        end

        % Button pushed function: camConnectionLamp
        function camConnectionLampButtonPushed(app, event)
            initState = app.camConnectionLamp.UserData.state; % initial state, 'on' or 'off'
            app.camConnectionLampState('Wait')
            switch initState
                case {'Connected','Error','Wait'} % then turn off
                    app.disconnectCamera()
                case {'None'}
                    app.connectCamera()
            end


        end

        % Value changed function: expTimeEditField
        function expTimeEditFieldValueChanged(app, event)
            app.Cam.setExpTime(app.expTime);
        end

        % Value changed function: cameraDropDown
        function cameraDropDownValueChanged(app, event)
            if strcmpi(app.camConnectionLamp.UserData.state, 'on')
                app.disconnectCamera()
            end
            app.updateCamera()
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.updateIL()
            
        end

        % Value changed function: imageDimensionsDropDown
        function imageDimensionsDropDownValueChanged(app, event)
            value = app.imageDimensionsDropDown.Value;
            app.Cam.setImageDimensions(value)
            app.clearImages()
            app.clearRef()

        end

        % Callback function: LiveButton, RefButton, SnapButton,
        % burstButton, 
        % ...and 1 other component
        function AcquisitionButtonsValueChanged(app, event)
            
            % If no camera connected
            if strcmpi(app.camConnectionLamp.UserData.state,'None')
                if strcmp(event.Source.Tag,'live')
                    event.Source.Value = 0; % restore the live button to unpushed, in case it was the live button that were pushed.
                end
                return % then don't do anything
            end
            
            app.Itf0 = app.Itf0(1); % in case the previous mode was burst
            app.IM = app.IM(1); % in case the previous mode was burst
            app.jim = 1;

        
            % if press stop during live mode
            if strcmp(event.Source.Tag,'live') && strcmp(app.Cam.mode, 'live')
                    app.setAcqMode('stop'); % then stop
                    %app.autosaveCheckBox.Value = false;
                    return
            end

            % if live mode was not running
            if strcmp(app.Cam.mode, 'stop')
                switch event.Source.Tag
                    case {'live','timeLapse'}
                        %app.Cam.stopAcquisition() % just in case
                        app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
    
                    case{'burst', 'snap', 'ref'}
                        %app.Cam.stopAcquisition() % just in case
                        app.Cam.setMode('Fixed', 'Software',"expTime",app.expTime,"Nim",app.snapAvgEditField.Value)
    
                end

                % ACQUIRE
                app.acquire(event.Source.Tag);
            else % if live mode was running
                app.setAcqMode(event.Source.Tag) %  Just set the current mode, and the camera live while loop will deal with it
            end


        end

        % Value changed function: HR_Button
        function HR_ButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.HR_Button.Value
                app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR2.svg');
            else
                app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR.svg');
            end
        end

        % Button pushed function: saveFolderButton
        function saveFolderButtonPushed(app, event)
            app.UIFigure.Visible='off' ;
            val = uigetdir();
            if val~=0
                app.saveFolderEditField.Value = val;
            end
            app.UIFigure.Visible='on';

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.saveCurrentImage()
        end

        % Button pushed function: HmirrorButton, VmirrorButton, rot90Button
        function applySymmetryPushed(app, event)
            app.D4Transform =  [app.D4Transform event.Source.Tag];
        end

        % Button pushed function: cancelSymmetryButton
        function cancelSymmetryButtonPushed(app, event)
            app.D4Transform = '';
        end

        % Button pushed function: cropButton
        function cropButtonPushed(app, event)
            app.cropDefinition()
        end

        % Button pushed function: loadFileButton
        function loadFileButtonPushed(app, event)
            app.UIFigure.Visible='off' ;
            [fileName, folder] = uigetfile();
            app.UIFigure.Visible='on';
            app.Itf0 = importItfRef([folder '/' fileName],app.MI);
            app.updateImages()
        end

        % Callback function: jimEditField, leftButton, rightButton
        function moveButtonPushed(app, event)
            app.moveImage(event.Source.Tag)
        end

        % Button pushed function: xyzButton
        function piezoButtonPushed(app, event)

            if isempty(app.piezoWindow) ||  ~isvalid(app.piezoWindow)
                app.piezoWindow  = piezoControlWindow();
            end

        end

        % Button pushed function: PhaseLABButton
        function PhaseLABButtonPushed(app, event)
            if isempty(app.IM(1).OPD)
                app.Itf0.figure()
            else
                app.IM.figure()
            end        
        end

        % Button pushed function: Button
        function lambdaButtonPushed(app, event)
            if isempty(app.lambdaWindow) ||  ~isvalid(app.lambdaWindow)
                app.IL = Monochromator(app.IL);
                app.lambdaWindow  = lambdaControlWindow(app);
            end
            
        end

        % Button pushed function: configButton
        function configButtonPushed(app, event)
            configControlWindow(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PhaseLAB - Live imaging';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'favicon.png');
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.GridLayout_setup = uigridlayout(app.GridLayout_main);
            app.MobjEditFieldLabel = uilabel(app.GridLayout_setup);
            app.MobjEditFieldLabel.Text = 'Mobj';
            app.MobjEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.MobjEditField.Limits = [1 Inf];
            app.MobjEditField.RoundFractionalValues = 'on';
            app.MobjEditField.ValueDisplayFormat = '%6.0f';
            app.MobjEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.MobjEditField.Value = 60;
            app.lambdaLabel = uilabel(app.GridLayout_setup);
            app.lambdaLabel.Text = 'λ';
            app.lambdaEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.lambdaEditField.Limits = [100 Inf];
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Value = 625;
            app.objBrandDropDown = uidropdown(app.GridLayout_setup);
            app.objBrandDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon'};
            app.objBrandDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.objBrandDropDown.Value = 'Olympus';
            app.microscopeTLDropDownLabel = uilabel(app.GridLayout_setup);
            app.microscopeTLDropDownLabel.Text = 'microscope/TL';
            app.microscopeTLDropDown = uidropdown(app.GridLayout_setup);
            app.microscopeTLDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon', '165', '180', '200'};
            app.microscopeTLDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.microscopeTLDropDown.Value = 'Olympus';
            app.cameraDropDownLabel = uilabel(app.GridLayout_setup);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.GridLayout_setup);
            app.cameraDropDown.Items = {'Simu', 'Zyla5.5', 'Orca Flash', 'Panda', 'Pixelink', '2-color', 'PHX050S-PC', 'sC8-830', 'sC8-944'};
            app.cameraDropDown.ValueChangedFcn = createCallbackFcn(app, @cameraDropDownValueChanged, true);
            app.cameraDropDown.Value = 'Simu';
            app.CrossGratingDropDownLabel = uilabel(app.GridLayout_setup);
            app.CrossGratingDropDownLabel.Text = '2D Grating';
            app.CrossGratingDropDown = uidropdown(app.GridLayout_setup);
            app.CrossGratingDropDown.Items = {'F02', 'F03', 'F03b', 'F04', 'P02', 'P04', 'sC8', 'Sid4Element', '39 µm', '35.1 µm', '22.5 µm', '13 µm'};
            app.CrossGratingDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CrossGratingDropDown.Value = 'F02';
            app.RelayLensEditFieldLabel = uilabel(app.GridLayout_setup);
            app.RelayLensEditFieldLabel.Text = 'Relay Lens';
            app.RelayLensEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.RelayLensEditField.Limits = [0 Inf];
            app.RelayLensEditField.ValueDisplayFormat = '%2.2f';
            app.RelayLensEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.RelayLensEditField.Value = 1.11;
            app.nmLabel = uilabel(app.GridLayout_setup);
            app.nmLabel.Text = 'nm';
            app.CGdistanceEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.CGdistanceEditField.ValueDisplayFormat = '%2.3f';
            app.CGdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CGdistanceEditField.Value = 0.8;
            app.mmLabel = uilabel(app.GridLayout_setup);
            app.mmLabel.Text = 'mm';
            app.camConnectionLamp = uibutton(app.GridLayout_setup, 'push');
            app.camConnectionLamp.ButtonPushedFcn = createCallbackFcn(app, @camConnectionLampButtonPushed, true);
            app.camConnectionLamp.Text = '';
            app.camStatusLamp = uilamp(app.GridLayout_setup);
            app.Image = uiimage(app.GridLayout_main);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'banniere.svg');
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.GridLayout_Imaging_controls = uigridlayout(app.GridLayout);
            app.GridLayout_specialControls = uigridlayout(app.GridLayout_Imaging_controls);
            app.timeLapseButton = uibutton(app.GridLayout_specialControls, 'push');
            app.timeLapseButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.timeLapseButton.Tag = 'timeLapse';
            app.timeLapseButton.Icon = fullfile(pathToMLAPP, 'icons', 'MOVIE.svg');
            app.timeLapseButton.Tooltip = {'acqiure time lapse'};
            app.timeLapseButton.Text = '';
            app.burstButton = uibutton(app.GridLayout_specialControls, 'push');
            app.burstButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.burstButton.Tag = 'burst';
            app.burstButton.Icon = fullfile(pathToMLAPP, 'icons', 'BURST.svg');
            app.burstButton.Tooltip = {'acqiure time lapse'};
            app.burstButton.Text = '';
            app.jimEditField = uieditfield(app.GridLayout_specialControls, 'numeric');
            app.jimEditField.Limits = [1 Inf];
            app.jimEditField.RoundFractionalValues = 'on';
            app.jimEditField.ValueDisplayFormat = '%d';
            app.jimEditField.ValueChangedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.jimEditField.Tag = 'none';
            app.jimEditField.Enable = 'off';
            app.jimEditField.Value = 1;
            app.leftButton = uibutton(app.GridLayout_specialControls, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.leftButton.Tag = 'left';
            app.leftButton.Icon = fullfile(pathToMLAPP, 'icons', 'left.svg');
            app.leftButton.Enable = 'off';
            app.leftButton.Tooltip = {'acqiure time lapse'};
            app.leftButton.Text = '';
            app.rightButton = uibutton(app.GridLayout_specialControls, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.rightButton.Tag = 'right';
            app.rightButton.Icon = fullfile(pathToMLAPP, 'icons', 'right.svg');
            app.rightButton.Enable = 'off';
            app.rightButton.Tooltip = {'acqiure time lapse'};
            app.rightButton.Text = '';
            app.snapAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.snapAvgEditField.Limits = [1 Inf];
            app.snapAvgEditField.RoundFractionalValues = 'on';
            app.snapAvgEditField.ValueDisplayFormat = '%11.0f';
            app.snapAvgEditField.Value = 30;
            app.refAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.refAvgEditField.Limits = [1 Inf];
            app.refAvgEditField.RoundFractionalValues = 'on';
            app.refAvgEditField.ValueDisplayFormat = '%11.0f';
            app.refAvgEditField.Value = 30;
            app.liveAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.liveAvgEditField.Limits = [1 Inf];
            app.liveAvgEditField.RoundFractionalValues = 'on';
            app.liveAvgEditField.ValueDisplayFormat = '%11.0f';
            app.liveAvgEditField.Value = 1;
            app.SnapButton = uibutton(app.GridLayout_Imaging_controls, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.SnapButton.Tag = 'snap';
            app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'SNAP.svg');
            app.SnapButton.Text = '';
            app.RefButton = uibutton(app.GridLayout_Imaging_controls, 'state');
            app.RefButton.ValueChangedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.RefButton.Tag = 'ref';
            app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF0.svg');
            app.RefButton.Text = '';
            app.LiveButton = uibutton(app.GridLayout_Imaging_controls, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.LiveButton.Tag = 'live';
            app.LiveButton.Icon = fullfile(pathToMLAPP, 'icons', 'LIVE.svg');
            app.LiveButton.Text = '';
            app.GridLayout_Save = uigridlayout(app.GridLayout);
            app.saveButton = uibutton(app.GridLayout_Save, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');
            app.saveButton.Text = '';
            app.GridLayout_savePrefix = uigridlayout(app.GridLayout_Save);
            app.savedFileNameEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.savedFileNameEditField.Editable = 'off';
            app.autosaveCheckBox = uicheckbox(app.GridLayout_savePrefix);
            app.autosaveCheckBox.Text = 'autosave';
            app.savePrefixEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.savePrefixEditField.Value = 'ITF';
            app.saveFolderButton = uibutton(app.GridLayout_savePrefix, 'push');
            app.saveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @saveFolderButtonPushed, true);
            app.saveFolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder.svg');
            app.saveFolderButton.Text = '';
            app.saveFolderEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.saveFolderEditField.Editable = 'off';
            app.saveLamp = uilamp(app.GridLayout_savePrefix);
            app.loadFileButton = uibutton(app.GridLayout_Save, 'push');
            app.loadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFileButtonPushed, true);
            app.loadFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.svg');
            app.loadFileButton.Tooltip = {'import image'};
            app.loadFileButton.Text = '';
            app.GridLayout_Imaging = uigridlayout(app.GridLayout);
            app.GridLayout_tools = uigridlayout(app.GridLayout_Imaging);
            app.VmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.VmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.VmirrorButton.Tag = 'V';
            app.VmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorV.svg');
            app.VmirrorButton.Tooltip = {'vertical mirror'};
            app.VmirrorButton.Text = '';
            app.HmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.HmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.HmirrorButton.Tag = 'H';
            app.HmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorH.svg');
            app.HmirrorButton.Tooltip = {'Horizontal mirror'};
            app.HmirrorButton.Text = '';
            app.rot90Button = uibutton(app.GridLayout_tools, 'push');
            app.rot90Button.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.rot90Button.Tag = 'R';
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'icons', 'rot90.svg');
            app.rot90Button.Tooltip = {'Clockwise rotation'};
            app.rot90Button.Text = '';
            app.cancelSymmetryButton = uibutton(app.GridLayout_tools, 'push');
            app.cancelSymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @cancelSymmetryButtonPushed, true);
            app.cancelSymmetryButton.Icon = fullfile(pathToMLAPP, 'icons', 'erase2.svg');
            app.cancelSymmetryButton.Tooltip = {'Back to native orientation'};
            app.cancelSymmetryButton.Text = '';
            app.flattenButton = uibutton(app.GridLayout_tools, 'state');
            app.flattenButton.Tooltip = {'Flatten'};
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'icons', 'flatten.svg');
            app.flattenButton.Text = '';
            app.tauLabel = uilabel(app.GridLayout_tools);
            app.tauLabel.Text = 'τ';
            app.expTimeEditField = uieditfield(app.GridLayout_tools, 'numeric');
            app.expTimeEditField.Limits = [1 Inf];
            app.expTimeEditField.ValueChangedFcn = createCallbackFcn(app, @expTimeEditFieldValueChanged, true);
            app.expTimeEditField.Value = 80;
            app.msLabel = uilabel(app.GridLayout_tools);
            app.msLabel.Text = 'ms';
            app.imageDimensionsDropDown = uidropdown(app.GridLayout_tools);
            app.imageDimensionsDropDown.Items = {'2560 x 2160', '2064 x 2048', '1920 x 1080', '1392 x 1040', '528 x 512', '240 x 256', '144 x 128', '2560 x 304', 'custom'};
            app.imageDimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @imageDimensionsDropDownValueChanged, true);
            app.imageDimensionsDropDown.Value = '2560 x 2160';
            app.pxLabel = uilabel(app.GridLayout_tools);
            app.pxLabel.Text = 'px';
            app.cropButton = uibutton(app.GridLayout_tools, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.cropButton.Tooltip = {'Crop the field of view'};
            app.cropButton.Text = '';
            app.HR_Button = uibutton(app.GridLayout_tools, 'state');
            app.HR_Button.ValueChangedFcn = createCallbackFcn(app, @HR_ButtonValueChanged, true);
            app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR.svg');
            app.HR_Button.Text = '';
            app.GridLayout_foot = uigridlayout(app.GridLayout_main);
            app.GridLayout_windowsButtons = uigridlayout(app.GridLayout_foot);
            app.rawButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.rawButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.rawButton.Tag = 'raw';
            app.rawButton.Text = 'raw';
            app.TButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.TButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.TButton.Tag = 'T';
            app.TButton.Text = 'T';
            app.WButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.WButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.WButton.Tag = 'W';
            app.WButton.Text = 'W';
            app.dWButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWButton.Tag = 'dW';
            app.dWButton.Text = '∇ W';
            app.massButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.massButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.massButton.Tag = 'mass';
            app.massButton.Text = 'mass';
            app.PDCMButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.PDCMButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.PDCMButton.Tag = 'PDCM';
            app.PDCMButton.Text = 'PDCM';
            app.RytovButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.RytovButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.RytovButton.Tag = 'Rytov';
            app.RytovButton.Text = 'Rytov';
            app.canals2Button = uibutton(app.GridLayout_windowsButtons, 'state');
            app.canals2Button.Tag = 'canals2';
            app.canals2Button.Text = '2-canals';
            app.polarButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.polarButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.polarButton.Tag = 'polar';
            app.polarButton.Text = 'polar';
            app.closeButton = uibutton(app.GridLayout_windowsButtons, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonValueChanged, true);
            app.closeButton.Text = 'close';
            app.GridLayout_panelButtons = uigridlayout(app.GridLayout_foot);
            app.PhaseLABButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.PhaseLABButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseLABButtonPushed, true);
            app.PhaseLABButton.Text = 'PhaseLAB';
            app.Button = uibutton(app.GridLayout_panelButtons, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @lambdaButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'rainbow.png');
            app.Button.Text = 'λ';
            app.xyzButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.xyzButton.ButtonPushedFcn = createCallbackFcn(app, @piezoButtonPushed, true);
            app.xyzButton.Icon = fullfile(pathToMLAPP, 'icons', 'xyz.svg');
            app.xyzButton.Text = 'xyz';
            app.configButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.configButton.ButtonPushedFcn = createCallbackFcn(app, @configButtonPushed, true);
            app.configButton.Icon = fullfile(pathToMLAPP, 'icons', 'params2.svg');
            app.configButton.Text = 'config';
            app.GridLayout_frameRate = uigridlayout(app.GridLayout_foot);
            app.HzEditFieldLabel = uilabel(app.GridLayout_frameRate);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.GridLayout_frameRate, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.ValueDisplayFormat = '%4.2g';
            app.messageArea = uilabel(app.GridLayout_main);
            app.messageArea.Text = 'message';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIVE

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end