classdef imageWindowLive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout_main      matlab.ui.container.GridLayout
        GridLayout_controls  matlab.ui.container.GridLayout
        maxEditField         matlab.ui.control.EditField
        minEditField         matlab.ui.control.EditField
        invertCheckBox       matlab.ui.control.CheckBox
        holdCheckBox         matlab.ui.control.CheckBox
        GridLayout_tools     matlab.ui.container.GridLayout
        FFT_Button           matlab.ui.control.StateButton
        crosscut_Button      matlab.ui.control.StateButton
        cancel_Button        matlab.ui.control.StateButton
        histoButton          matlab.ui.control.Button
        colormapDropDown     matlab.ui.control.DropDown
        UIAxesLeft           matlab.ui.control.UIAxes
        UIAxesRight          matlab.ui.control.UIAxes
    end


    properties (SetAccess = private, GetAccess = {?LIVE})
        hf
        ha
        hi
    end

    methods (Access = private)


        function displayRightAxis(app,val)
            arguments
                app
                val {mustBeMember(val,{'on','off'})}
            end

            if strcmp(app.UIAxesRight.Visible, val)
                return
            end
            app.UIAxesRight.Visible =  val;
            app.GridLayout_main.Visible = 'off';
            switch val
                case 'off'
                    app.GridLayout_main.ColumnWidth = {'1x',0};
                    app.UIFigure.Position(3) = app.UIFigure.Position(3)/2;
                    app.UIFigure.UserData.isTwinAx = false;
                otherwise % 'on'
                    app.GridLayout_main.ColumnWidth = {'1x','1x'};
                    app.UIFigure.Position(3) = app.UIFigure.Position(3)*2;
                    app.UIFigure.UserData.isTwinAx = true;
            end
            app.GridLayout_main.Visible = 'on';
            
        end
        
        function updateColorMap(app)
            cmap = str2func(app.colormapDropDown.Value);
            if app.invertCheckBox.Value
                colormap(app.UIAxesLeft, flipud(cmap()))
            else
                colormap(app.UIAxesLeft, cmap())
            end
            
        end

        function updateClim(app)
            if app.holdCheckBox.Value
                clim(app.UIAxesLeft,app.UIAxesLeft.CLim) % Fix the clim to the current value.
                if isempty(app.minEditField.Value)
    %                uic_min.String = sprintf('%.3g',(hax.CLim(1)));
                    app.minEditField.Value = format3sig(app.UIAxesLeft.CLim(1));
                else
                    app.UIAxesLeft.CLim(1) = str2double(app.minEditField.Value);
                end
                if isempty(app.maxEditField.Value)
    %                uic_max.String = sprintf('%.3g',(hax.CLim(2)));
                    app.maxEditField.Value = format3sig(app.UIAxesLeft.CLim(2));
                else
                    app.UIAxesLeft.CLim(2) = str2double(app.maxEditField.Value);
                end
    %            uic_max.String = num2str(hax.CLim(2));
            else
                app.UIAxesLeft.CLimMode = 'auto';
                app.minEditField.Value = '';
                app.maxEditField.Value = '';
            end
    
        end


    end

    methods (Static, Access = private)
        
        function s = format3sig(x)
    
            if x == 0
                s = '0';
                return
            end
    
            n = floor(log10(abs(x)));
            ndec = max(0, 3-n-1);
    
            s = sprintf(['%.' num2str(ndec) 'f'],x);
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tag, opt)
            arguments
                app
                tag char = 'W'
                opt.Nx (1,1) double = 1024
                opt.Ny (1,1) double = 1024
                opt.Position = 0 % 0, 1, or 2
                opt.enableClose logical = true
            end

            app.displayRightAxis('off')

            %% Set the position of the figure
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            % Define the window width as one-third of the screen width
            figWidth = screenWidth / 3;
            
            % Define the window height (you can adjust this value as needed)
            figHeight = figWidth;  % Example: 500 pixels tall
            
            % Calculate y position to center the window vertically
            yPos = (screenHeight - figHeight) / 2;
            
            yPos = yPos/2; % otherwise may overlap with the app window
            
            % Set x position to 0 (aligned to the left edge of the screen)
            xPos = mod(opt.Position,3)*figWidth;

            app.UIFigure.Position = [xPos yPos figWidth figWidth];

            app.updateColorMap()

            %
            app.hf = app.UIFigure;
            app.ha = app.UIAxesLeft;
            app.hi = imagesc(zeros(opt.Ny, opt.Nx), 'Parent', app.UIAxesLeft);


            %%
            app.UIAxesLeft.XColor = 'w';
            app.UIAxesLeft.YColor = 'w';
            set(app.UIAxesLeft,'YDir','normal')
            axis(app.UIAxesLeft, 'image');

            app.UIAxesRight.XColor = 'w';
            app.UIAxesRight.YColor = 'w';
            set(app.UIAxesRight,'YDir','normal')

            cbLeft = colorbar(app.UIAxesLeft,'Color','w');
            cbRight = colorbar(app.UIAxesRight,'Color','w');

            app.UIFigure.UserData.isTwinAx = false;


            %% Personalize the figure as a function of the tag
            switch tag
                case 'W'
                    cbLeft.Label.String = 'nm';
                    colormap(app.UIAxesLeft, sepia);
                    app.colormapDropDown.Value = 'sepia';
                otherwise
                    cbLeft.Label.String = '';
                    colormap(app.UIAxesLeft, gray);
                    app.colormapDropDown.Value = 'gray';
            
            end

            if ~opt.enableClose
                app.UIFigure.CloseRequestFcn = @(src, event) [];
            end
    

        end

        % Button pushed function: histoButton
        function cancelButtonPushed(app, event)
            app.displayRightAxis('off')
        end

        % Value changed function: FFT_Button, cancel_Button, 
        % ...and 1 other component
        function ButtonValueChanged(app, event)

            app.displayRightAxis('on')
            app.UIFigure.UserData.twinImageType = event.Source.Tag;
            app.UIFigure.UserData.twinAxis = app.UIAxesRight;
            
            switch event.Source.Tag
                case 'histo'
                    plot(app.UIFigure.UserData.twinAxis,1,1)
                    app.UIFigure.UserData.twinAxis.YDir = 'normal';
                case 'FFT'
                    imagesc(app.UIFigure.UserData.twinAxis,zeros(size(app.hi.CData)))
                    axis(app.UIFigure.UserData.twinAxis,'image') % fit the axes to the image
                    app.UIFigure.UserData.twinAxis.YDir = 'normal';
                case 'crosscut'
                    plot(app.UIFigure.UserData.twinAxis,1,1)
                    app.UIFigure.UserData.twinAxis.YDir = 'normal';
            end


        end

        % Value changed function: colormapDropDown, invertCheckBox
        function colormapDropDownValueChanged(app, event)
            app.updateColorMap()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Live window';
            app.UIFigure.Tag = 'liveWindow';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.UIAxesRight = uiaxes(app.GridLayout_main);
            xlabel(app.UIAxesRight, 'X')
            ylabel(app.UIAxesRight, 'Y')
            zlabel(app.UIAxesRight, 'Z')
            app.UIAxesRight.Tag = 'RightAxis';
            app.UIAxesLeft = uiaxes(app.GridLayout_main);
            xlabel(app.UIAxesLeft, 'X')
            ylabel(app.UIAxesLeft, 'Y')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesLeft.Tag = 'LeftAxis';
            app.GridLayout_controls = uigridlayout(app.GridLayout_main);
            app.colormapDropDown = uidropdown(app.GridLayout_controls);
            app.colormapDropDown.Items = {'gray', 'sepia', 'parula', 'Pradeep'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'gray';
            app.GridLayout_tools = uigridlayout(app.GridLayout_controls);
            app.histoButton = uibutton(app.GridLayout_tools, 'push');
            app.histoButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.histoButton.Tag = 'cancel';
            app.histoButton.Icon = 'erase.svg';
            app.histoButton.Tooltip = {'Horizontal mirror'};
            app.histoButton.Text = '';
            app.cancel_Button = uibutton(app.GridLayout_tools, 'state');
            app.cancel_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.cancel_Button.Tag = 'histo';
            app.cancel_Button.Icon = 'histo.svg';
            app.cancel_Button.Text = '';
            app.crosscut_Button = uibutton(app.GridLayout_tools, 'state');
            app.crosscut_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.crosscut_Button.Tag = 'crosscut';
            app.crosscut_Button.Icon = 'Pathcrosscut.svg';
            app.crosscut_Button.Text = '';
            app.FFT_Button = uibutton(app.GridLayout_tools, 'state');
            app.FFT_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.FFT_Button.Tag = 'FFT';
            app.FFT_Button.Text = 'FFT';
            app.holdCheckBox = uicheckbox(app.GridLayout_controls);
            app.holdCheckBox.Text = 'hold';
            app.invertCheckBox = uicheckbox(app.GridLayout_controls);
            app.invertCheckBox.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.invertCheckBox.Text = 'invert';
            app.minEditField = uieditfield(app.GridLayout_controls, 'text');
            app.maxEditField = uieditfield(app.GridLayout_controls, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageWindowLive(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end