classdef setupControlWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Image               matlab.ui.control.Image
        GridLayout_buttons  matlab.ui.container.GridLayout
        loadButton          matlab.ui.control.Button
        prevNameButton      matlab.ui.control.Button
        configListBox       matlab.ui.control.ListBox
        saveNameButton      matlab.ui.control.Button
        eraseButton         matlab.ui.control.Button
        nextNameButton      matlab.ui.control.Button
        saveNameEditField   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        appLive % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appLive)
            app.appLive = appLive;

            % load the files

            pathToMLAPP = fileparts(mfilename('fullpath'));

            fileList = dir([pathToMLAPP '/../spec/Microscopes/*.json']);
            Nf = numel(fileList);
            for ii = 1:Nf
                config = fileList(ii).name(1:end-5);
                if ~strcmp(config,'default')
                    app.configListBox.Items = [app.configListBox.Items, {config}];
                    app.configListBox.Value = config;
                end
            end
        end

        % Button pushed function: saveNameButton
        function saveNameButtonPushed(app, event)
            s.CGcamera      = app.appLive.cameraDropDown.Value;
            s.Mobj          = app.appLive.MobjEditField.Value;
            s.objBrand      = app.appLive.objBrandDropDown.Value;
            s.microscopeTL  = app.appLive.microscopeTLDropDown.Value;
            s.RelayLens     = app.appLive.RelayLensEditField.Value;
            s.CrossGrating  = app.appLive.CrossGratingDropDown.Value;
            s.CGdistance    = app.appLive.CGdistanceEditField.Value;
            s.lambda        = app.appLive.lambdaEditField.Value;
            s.definition    = app.appLive.imageDimensionsDropDown.Value;
            s.tau           = app.appLive.expTimeEditField.Value;
            s.Nref          = app.appLive.refAvgEditField.Value;
            s.Nsnap         = app.appLive.snapAvgEditField.Value;

            jsonText = jsonencode(s);
            jsonText=replace(jsonText,',',char(13));
            jsonText=replace(jsonText,':',': ');

            pathToMLAPP = fileparts(mfilename('fullpath'));
            newConfig = strrep(app.saveNameEditField.Value, ' ', '');

            if isempty(newConfig)
                return;  % Do nothing if the input is empty
            end
            
            if strcmpi(newConfig,'default')
                return;  % Do nothing if the input is default
            end
            

            % Append new item to existing list
            currentItems = app.configListBox.Items;

            isDuplicate = any(strcmpi(currentItems, newConfig));
            
            if isDuplicate
                % Ask user if they want to replace it
                answer = questdlg( ...
                    sprintf('The configuration \"%s\" already exists.\nDo you want to replace it?', newConfig), ...
                    'Duplicate Item', ...
                    'Yes', 'No', 'No');
        
                if strcmp(answer, 'No')
                    return;
                end
        
                % Replace the existing item (keep position)
                index = find(strcmpi(currentItems, newConfig), 1);
                currentItems{index} = newConfig;
                app.configListBox.Items = currentItems;
                app.configListBox.Value = newConfig;

                fid = fopen([pathToMLAPP '/../spec/Microscopes/' newConfig '.json'],'w');
                fprintf(fid,jsonText);
                fclose(fid);
                
            else  % append new item         
            
                app.configListBox.Items = [currentItems, {newConfig}];
                app.configListBox.Value = newConfig;

                fid = fopen([pathToMLAPP '/../spec/Microscopes/' newConfig '.json'],'w');
                fprintf(fid,jsonText);
                fclose(fid);
            end

        end

        % Button pushed function: eraseButton
        function eraseButtonPushed(app, event)
            selectedItem = app.configListBox.Value;       % Get selected item
            currentItems = app.configListBox.Items;       % Get current list of items
            if strcmpi(selectedItem,'default')
                % don't erase the default  item
                return
            end
            % If nothing is selected, do nothing
            if isempty(selectedItem)
                return;
            end
        
            % Show confirmation dialog
            answer = questdlg( ...
                sprintf('Are you sure you want to delete Configuration:\n\"%s\"?', selectedItem), ...
                'Confirm Deletion', ...
                'Yes', 'No', 'No');
        
            if strcmp(answer, 'Yes')            % Remove the selected item
                newItems = currentItems(~strcmp(currentItems, selectedItem));
            
                % Update the listbox
                app.configListBox.Items = newItems;
                % and delete the corresonding file
                pathToMLAPP = fileparts(mfilename('fullpath'));
                delete([pathToMLAPP '/../spec/Microscopes/' selectedItem '.json'])
            
                % Optional: set a new selected value
                if ~isempty(newItems)
                    app.configListBox.Value = newItems{1};  % Select first remaining item
                else
                    app.configListBox.Value = '';
                end


            end            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            fullpath = fullfile(pathToMLAPP, '..', 'spec', 'Microscopes', [app.configListBox.Value '.json']);
        
            if ~isfile(fullpath)
                error('File can''t be found : %s', fullpath);
            end
        
            % Lecture du texte JSON
            fid = fopen(fullpath, 'r');
            raw = fread(fid, inf, '*char')';
            fclose(fid);
        
            % Parser le JSON brut
            jsonText = strrep(raw, char(13), ','); % retransformer ton JSON modifié en JSON valide
            jsonText = strrep(jsonText, ': ', ':');
            jsonText = regexprep(jsonText, ',\s*$', ''); % retirer la dernière virgule si besoin
        
            s = jsondecode(jsonText);  % structure MATLAB avec les valeurs
        
        
            app.appLive.cameraDropDown.Value          = s.CGcamera;
            app.appLive.MobjEditField.Value           = s.Mobj;
            app.appLive.objBrandDropDown.Value        = s.objBrand;
            app.appLive.microscopeTLDropDown.Value    = s.microscopeTL;
            app.appLive.RelayLensEditField.Value      = s.RelayLens;
            app.appLive.CrossGratingDropDown.Value    = s.CrossGrating;
            app.appLive.CGdistanceEditField.Value     = s.CGdistance;
            app.appLive.lambdaEditField.Value         = s.lambda;
            app.appLive.expTimeEditField.Value        = s.tau;
            app.appLive.refAvgEditField.Value         = s.Nref;
            app.appLive.snapAvgEditField.Value        = s.Nsnap;
            
            app.appLive.panel2MI()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_buttons = uigridlayout(app.GridLayout);
            app.saveNameEditField = uieditfield(app.GridLayout_buttons, 'text');
            app.saveNameEditField.Value = 'default';
            app.nextNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.nextNameButton.Tag = 'next';
            app.nextNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'down.svg');
            app.nextNameButton.Text = '';
            app.eraseButton = uibutton(app.GridLayout_buttons, 'push');
            app.eraseButton.ButtonPushedFcn = createCallbackFcn(app, @eraseButtonPushed, true);
            app.eraseButton.Icon = fullfile(pathToMLAPP, 'icons', 'clear.svg');
            app.eraseButton.Text = '';
            app.saveNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.saveNameButton.ButtonPushedFcn = createCallbackFcn(app, @saveNameButtonPushed, true);
            app.saveNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');
            app.saveNameButton.Text = '';
            app.configListBox = uilistbox(app.GridLayout_buttons);
            app.configListBox.Items = {'default'};
            app.configListBox.Value = 'default';
            app.prevNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.prevNameButton.Tag = 'prev';
            app.prevNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'up.svg');
            app.prevNameButton.Text = '';
            app.loadButton = uibutton(app.GridLayout_buttons, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Icon = fullfile(pathToMLAPP, 'icons', 'load.svg');
            app.loadButton.Text = '';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'setupControlTitle.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setupControlWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end