classdef LIVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout_main                matlab.ui.container.GridLayout
        GridLayout_foot                matlab.ui.container.GridLayout
        GridLayout_frameRate           matlab.ui.container.GridLayout
        HzEditField                    matlab.ui.control.NumericEditField
        HzEditFieldLabel               matlab.ui.control.Label
        GridLayout_panelButtons        matlab.ui.container.GridLayout
        shutButton                     matlab.ui.control.Button
        configButton                   matlab.ui.control.Button
        xyzButton                      matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        PhaseLABButton                 matlab.ui.control.Button
        GridLayout_windowsButtons      matlab.ui.container.GridLayout
        messageEditField               matlab.ui.control.EditField
        retMaxEditField                matlab.ui.control.NumericEditField
        closeButton                    matlab.ui.control.Button
        polarButton                    matlab.ui.control.StateButton
        RytovButton                    matlab.ui.control.StateButton
        PDCMButton                     matlab.ui.control.StateButton
        massButton                     matlab.ui.control.StateButton
        dWButton                       matlab.ui.control.StateButton
        dWxButton                      matlab.ui.control.StateButton
        dWyButton                      matlab.ui.control.StateButton
        WButton                        matlab.ui.control.StateButton
        TButton                        matlab.ui.control.StateButton
        rawButton                      matlab.ui.control.StateButton
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout_Imaging             matlab.ui.container.GridLayout
        GridLayout_tools               matlab.ui.container.GridLayout
        GridLayout_extraFigureButtons  matlab.ui.container.GridLayout
        previewButton                  matlab.ui.control.StateButton
        balance2channelsButton         matlab.ui.control.StateButton
        balance4channelsButton         matlab.ui.control.StateButton
        FTButton                       matlab.ui.control.StateButton
        HRButton                       matlab.ui.control.StateButton
        cropButton                     matlab.ui.control.Button
        pxLabel                        matlab.ui.control.Label
        imageDimensionsDropDown        matlab.ui.control.DropDown
        msLabel                        matlab.ui.control.Label
        expTimeEditField               matlab.ui.control.NumericEditField
        tauLabel                       matlab.ui.control.Label
        flattenButton                  matlab.ui.control.StateButton
        cancelSymmetryButton           matlab.ui.control.Button
        rot90Button                    matlab.ui.control.Button
        HmirrorButton                  matlab.ui.control.Button
        VmirrorButton                  matlab.ui.control.Button
        GridLayout_Save                matlab.ui.container.GridLayout
        loadFileButton                 matlab.ui.control.Button
        GridLayout_savePrefix          matlab.ui.container.GridLayout
        saveLamp                       matlab.ui.control.Lamp
        saveFolderEditField            matlab.ui.control.EditField
        saveFolderButton               matlab.ui.control.Button
        savePrefixEditField            matlab.ui.control.EditField
        autosaveCheckBox               matlab.ui.control.CheckBox
        savedFileNameEditField         matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        GridLayout_Imaging_controls    matlab.ui.container.GridLayout
        LiveButton                     matlab.ui.control.StateButton
        RefButton                      matlab.ui.control.StateButton
        SnapButton                     matlab.ui.control.Button
        liveAvgEditField               matlab.ui.control.NumericEditField
        refAvgEditField                matlab.ui.control.NumericEditField
        snapAvgEditField               matlab.ui.control.NumericEditField
        GridLayout_specialControls     matlab.ui.container.GridLayout
        rightButton                    matlab.ui.control.Button
        leftButton                     matlab.ui.control.Button
        jimEditField                   matlab.ui.control.NumericEditField
        burstButton                    matlab.ui.control.Button
        timeLapseButton                matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        GridLayout_setup               matlab.ui.container.GridLayout
        RelayLensCheckBox              matlab.ui.control.CheckBox
        camRunningLamp                 matlab.ui.control.Lamp
        camConnectionLamp              matlab.ui.control.Button
        mmLabel                        matlab.ui.control.Label
        CGdistanceEditField            matlab.ui.control.NumericEditField
        nmLabel                        matlab.ui.control.Label
        RelayLensEditField             matlab.ui.control.NumericEditField
        RelayLensEditFieldLabel        matlab.ui.control.Label
        CrossGratingDropDown           matlab.ui.control.DropDown
        CrossGratingDropDownLabel      matlab.ui.control.Label
        cameraDropDown                 matlab.ui.control.DropDown
        cameraDropDownLabel            matlab.ui.control.Label
        microscopeTLDropDown           matlab.ui.control.DropDown
        microscopeTLDropDownLabel      matlab.ui.control.Label
        objBrandDropDown               matlab.ui.control.DropDown
        lambdaEditField                matlab.ui.control.NumericEditField
        lambdaLabel                    matlab.ui.control.Label
        MobjEditField                  matlab.ui.control.NumericEditField
        MobjEditFieldLabel             matlab.ui.control.Label
    end


    properties (SetAccess = private)
        Itf0 (:,1) Interfero % current interferogram. Can be an array of Interfero, if a burst has been acquired.
        IM  ImageMethods % current ImageQLSI object
        Ref0 (1,1) Interfero % current reference interferogram
        MI  (1,1) Microscope % current microscope.   Same handle as Itf.Microscope & Ref.Microscope & IM.Microscope
        IL  (1,1) Illumination % current illumination. Same handle as IM.Illumination

        Itf4 % 4-polar Itf
        Ref4

    end

    properties (Dependent)
        % The properties are associated with set and get methods.
        % Here is how other functions interact with this class,
        % not by modifying Itf and IM themselves.
        % The caution protects, e.g., the Microscope and Illumination properties
        % of the Itf and IM properties.

        itf  % current interferogram image / not an object, just an image
        ref  % current ref interferogram image / not an object, just an image
        W0    % current wavefront image
        T0    % current intensity image
        DWx0  % current x-gradient image
        DWy0  % current y-gradient image

        Cam % CameraLive object
        Nx
        Ny

        jim  % # of the displayed image, in the case of a stored burst or time lapse
        Nim  % # total numer of app.Itf objects, in the case of a stored burst or time lapse

    end

    properties (SetAccess = private)
        hWinList % struct containing the list of the handles of the imageWindowLive apps
        hfList % struct containing the list of the handles of the open imageWindowLive figures
        haList  % struct containing the list of the handles of the axes in imageWindowLive right axes
        ha2List  % struct containing the list of the handles of the twin axes in imageWindowLive left axes
        hiList  % struct containing the list of the handles of the displayed imageWindowLive left images

        roi images.roi.Rectangle = images.roi.Rectangle.empty(1,0)  % Region of interest to be cropped before processing and display

        piezoWindow       piezoControlWindow  = piezoControlWindow.empty()
        lambdaWindow   lambdaControlWindow    = lambdaControlWindow.empty()
        shutterWindow   shutterControlWindow  = shutterControlWindow.empty()

        NavgLive % returns the liveAvgEditField value
        NavgRef  % returns the refAvgEditField value
        NavgSnap % returns the sanoAvgEditField value

        expTime % returns the expTimeEditField value

        status_hfigList % list of logical values indicating which image windows are open
    
        acqMode (1,:) char {mustBeMember(acqMode,{'Live','Stop','Burst','Snap','TimeLapse','Ref'})} = 'Stop'
    end

    properties(Access = private)

        %imTypes = {'raw','T','W','DW','mass','PDCM','Rytov','2-canals','polar'}

        nPos = 0 % position of the last opened figure, 0, 1 or 2 (1st, 2nd and 3rd lateral third of the screen)

        D4Transform char % Symmetry transformation of the image, char array composed of H, V, and R letters. e.g.,'HRV' 

        cameraType {mustBeMember(cameraType,{'','Andor','Hamamatsu','Pixelink','PCO'})} = ''

        % COLORS
        %  color of the toggle buttons
        colorButtonOn  = [0.5, 0.5, 0.5] % dark
        colorButtonOff = [0.94, 0.94, 0.94] % bright
        % lamp colors
        lampColorConnected = [0.60    0.77    0.60];   % green
        lampColorNone = [0.9 0.9 0.9];  % light gray
        lampColorOff = [0.5 0.5 0.5];  % light gray
        lampColorWait = [0.7 0.5,0.3]; % orange
        lampColorError = [0.8    0.40    0.40];   % red
        lampColorRunning = [0.7 0.5,0.3]; % orange

    end

    methods (Access = private)

        function connectCamera(app)
            app.setCamConnectionLampState('Wait')
            app.Cam.setupSDK()
            rc = app.Cam.turn('on');
            app.Cam.setExpTime(app.expTime);
  
            if rc~=0
                disp('error in the camera startup')
                app.setCamConnectionLampState('Error');
            else
                app.setCamConnectionLampState('Connected');
                app.setAcqPanelAppearance('camera')
                app.setAcqMode('Stop');
                app.cameraDropDown.Enable = "off";
            end

        end

        function disconnectCamera(app)
            app.setCamConnectionLampState('Wait')
            rc = app.Cam.turn('off');
            if rc~=0
                disp('error in disconnecting the camera')
                app.setCamConnectionLampState('Error');
            else
                app.setCamConnectionLampState('None');
                app.setAcqPanelAppearance('nocamera')
                app.setAcqMode('Stop');
                app.cameraDropDown.Enable = "on";
            end

        end

        function enableCameraParameters(app,value)
            % enable or not the parameters in relation with a relay lens
            arguments
                app
                value %{mustBeMember(value,{"on","off"})}
            end
            app.CrossGratingDropDown.Enable      = value;
            if app.RelayLensCheckBox.Value
                app.RelayLensEditField.Enable        = value;
            end
            app.CrossGratingDropDownLabel.Enable = value;
            app.RelayLensEditFieldLabel.Enable   = value;
            app.CGdistanceEditField.Enable       = value;
                        
        end

        function initializeWindowsHandles(app)
            % Function that builds the hfig, hax, him properties from the
            % list of button tags ('raw','T','W',...))
            childrens = app.GridLayout_windowsButtons.Children;
            Nt = numel(childrens);
        
            for k = 1:Nt
                element = childrens(k);
                if isa(element, 'matlab.ui.control.StateButton')
                    if ~isempty(element.Tag)
                        app.hWinList.(element.Tag) = imageWindowLive.empty();
                        app.hfList.(element.Tag) = matlab.ui.Figure.empty();
                        app.haList.(element.Tag) =  matlab.graphics.axis.Axes.empty();
                        app.hiList.(element.Tag) =  matlab.graphics.primitive.Image.empty();
                    end
                end
            end

            % extra figures
            childrens = app.GridLayout_extraFigureButtons.Children;
            Nt = numel(childrens);
        
            for k = 1:Nt
                element = childrens(k);
                app.hWinList.(element.Tag) = struct.empty;
                app.hfList.(element.Tag) = matlab.ui.Figure.empty();
                app.haList.(element.Tag) =  matlab.graphics.axis.Axes.empty();
                app.hiList.(element.Tag) =  matlab.graphics.primitive.Image.empty();
            end


        end
            
        function toggleWindow(app,tag,val) % build or delete the popup windows
            % app.toggleWindow('raw',1)
            arguments
                app
                tag % 'raw', 'W', 'T', ...
                val (1,1) double {mustBeMember(val,[0 1])}
            end

            btn = app.([tag,'Button']); % handle to the pushed button
            btn.Value = val;            % Whether the button should be set on or off
            if val % if button was turned on
                if contains(tag,'balance')
                    app.toggleBalanceWindow(tag);
                elseif strcmp(tag,'FT')
                    app.toggleFTwindow();
                    
                else
                    % open an imageWindowLive app
                    appW = imageWindowLive(tag,'Nx',app.Nx,'Ny',app.Ny,'Position',app.nPos,'enableClose',false);
                    app.nPos =  app.nPos+1;
                    app.hWinList.(tag) = appW;
                    app.hfList.(tag) = appW.hf;
                    app.haList.(tag) = appW.ha;
                    app.ha2List.(tag) = appW.ha2;
                    app.hiList.(tag) = appW.hi;
                    app.updateImages();
                end
    
            else   % Close the Window
                %btn.Value = 0;
                if isvalid(app.hfList.(tag))
                    close(app.hfList.(tag));
                end
                delete(app.hfList.(tag))
                if strcmp(tag,'FT')
                    delete(app.hWinList.FT.app_thetaZetaDisplay)
                end
                if contains(tag,'balance')
                    delete(app.hWinList.(tag).app_channelsBalanceDisplay)
                end
                app.hfList.(tag) = matlab.ui.Figure.empty();
                app.hiList.(tag) = matlab.graphics.primitive.Image.empty();
                app.haList.(tag) = matlab.graphics.axis.Axes.empty();
                if isa(app.hWinList,'imageWindowLive')
                    app.hWinList.(tag) = imageWindowLive.empty();
                else
                    app.hWinList.(tag) = struct.empty();
                end                    

            end

            if ~any(app.status_hfigList) % if all the windows are closed
                app.setAcqMode('Stop')
                app.closeButton.Text = 'close';
                app.Itf0.Itf0 = []; % clear the Itf image, to avoid pushing save and don't know what is saved
                app.nPos=0;
            else
                app.closeButton.Text = 'close all';
            end
            
            app.updateWindowButtonColor(btn);

            if sum(app.status_hfigList) == 1 && val == 1 && ~strcmp(app.acqMode,'Live') % if display the very first figure, and not already live
                % launch the live mode
                try
                    app.Cam.stopAcquisition() % just in case in was not stopped
                end
                app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                app.acquire('Live');
            end

        end

        function updateWindowButtonColor(app,btn)
            % update the raw, T, W, ... button colors
            if btn.Value == 1
                % Activated mode
                btn.BackgroundColor = app.colorButtonOn;
                btn.FontColor = [1 1 1];  % White
            else
                % Desactivated mode
                btn.BackgroundColor = app.colorButtonOff ;
                btn.FontColor = [0 0 0];  % Black
            end
        end
        
        function updateCamera(app)
            % updates the GUI info, and the app.MI property
            camName = app.cameraDropDown.Value;

            switch camName
                case {'Zyla5.5','Orca Flash','Panda','OE #17-816','2-color','PHX050S-PC','Simu','Pixelink'}
                    app.cameraDropDownLabel.Text = 'Camera';
                    app.enableCameraParameters("on");
                    cam = Camera(strrep(camName, ' ', ''));
                    app.MI.CGcam.Camera = cam.makeAlive();

                case {'sC8-830','sC8-944'}
                    app.cameraDropDownLabel.Text = 'CGcamera';
                    app.MI.CGcam = CGcamera(camName);
                    app.MI.CGcam.Camera = app.MI.CGcam.Camera.makeAlive();
                    app.CrossGratingDropDown.Value = 'sC8';
                    app.RelayLensEditField.Value = [];
                    app.CGdistanceEditField.Value = app.MI.CGcam.CGpos*1e3;
                    app.MI2panel()
                    app.enableCameraParameters("off");
            end
            app.imageDimensionsDropDown.Items = app.Cam.imageDimensionsList;
        end 
        
        function acquire(app,mode)
            arguments
                app
                mode (1,:) char {mustBeMember(mode,{'Live','Stop','Snap','TimeLapse','Ref','Burst','External'})}
            end

            app.setAcqMode(mode)

            if strcmp(mode,'Ref')
                app.Itf0.clearFcrops()
                app.setButtonIcon('Ref', 'busy')
            end

            app.switchShutter('On')

            %% FIXED MODE. Acquisition of a fixed numer of images.
            switch mode
                case {'Ref','Snap','Burst'}

                    if strcmp(app.acqMode, 'Ref')
                        Nimg = app.refAvgEditField.Value;
                    elseif  strcmp(app.acqMode, 'Snap') || strcmp(app.acqMode, 'Burst')
                        Nimg = app.snapAvgEditField.Value;
                        app.setButtonIcon('Snap','busy')
                    else
                        pause(1)
                    end

                     
                    app.Cam.setMode('Fixed', 'Internal',"expTime",app.expTime,"Nim",Nimg)
                    app.Cam.startAcquisition()

                    %app.setCamRunningLampState("Running")
                    %buf = app.Cam.acquire_fixed(Nimg,app);
                    %app.setCamRunningLampState('Off')

                    buf = app.CamAcquire('fixed',Nimg);
    
                    dt = datetime('now');
        
                    if strcmp(app.acqMode,'Ref')
                        app.ref = mean(buf, 3);
                        app.Ref0.setParams("timeStamp",dt,"expTime",app.expTime, ...
                                            "Navg",size(buf,3),"ROI",app.Cam.getROI())
                    elseif strcmp(app.acqMode,'Snap')
                        app.itf = mean(buf, 3);   % app.Itf0 or app.Ref0
                        app.Itf0.setParams("timeStamp",dt,"expTime",app.expTime, ...
                                            "Navg",size(buf,3),"ROI",app.Cam.getROI())
                    elseif strcmp(app.acqMode,'Burst')
                        app.itf = buf;   % app.Itf0 or app.Ref0
                        app.Itf0.setParams("timeStamp",dt,"expTime",app.expTime, ...
                                            "Navg",1,"ROI",app.Cam.getROI())
                    end

                    % Update the display:
                    app.computeImages(app.acqMode)
                    app.updateImages()
    
                    % update the buttons:
                    if strcmp(app.acqMode,'Ref')
                        app.RefButton.Value = 0;
                    elseif  strcmp(app.acqMode, 'Snap')
                        app.setButtonIcon('Snap','on')
                    end
    
                    app.stopAcquisition()
                    

            %% CONTINUOUS MODE.Live acquisition
                case {'Live','TimeLapse'}

    
                    app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                    app.Cam.startAcquisition()
    
                    if all(app.status_hfigList == 0) && strcmpi(app.acqMode,'Live') % if live mode (and not timeLapse) and nothing to display
                        app.setAcqMode('Stop')
                        return % Then abort
                    end
    
                    k = 0;
                    while(~strcmp(app.acqMode,'Stop')) % as long as it is not stop, run live
    
                        tic
                        Nimg = app.liveAvgEditField.Value;
                        if Nimg == 1
                            %app.setCamRunningLampState("Running")
                            %app.itf = app.Cam.acquire_continuous(app);
                            app.itf = app.CamAcquire('continuous');
                            %app.setCamRunningLampState("Off")
                        else
                            itf0 = 0;
                            for ii = 1:Nimg % TODO: make this in Fixed mode. It will be faster. With a condition on Nim. Nim == 1 -> Continuous. Nim > 1 -> Fixed and average like in snap
                                %app.setCamRunningLampState("Running")
                                %itf0 = itf0 + app.Cam.acquire_continuous(app)/Nimg;
                                itf0 = itf0 + app.CamAcquire('continuous')/Nimg;
                                %app.setCamRunningLampState("Off")
                            end
                            app.itf = itf0;
                        end
                        app.Itf0.setParams("timeStamp",datetime('now'),"expTime",app.Cam.expTime, ...
                                          "Navg",1, ...      % TO BE MODIFIED BY THE NUMBER OF AVERAGED IMAGES
                                          "ROI",app.Cam.getROI())                    
    
                        drawnow
                        
                        % in case the camera mode changed to
                        % timeLapse, ref or snap during live
    
                        switch app.acqMode
                            case 'TimeLapse'
                                if ~exist('appTL','var')
                                    appTL = timeLapsePanel(app);
                                elseif ~isvalid(appTL)
                                    appTL = timeLapsePanel(app);
                                end
                                
                                if ~isempty(appTL.t0) % if the time t0 has been created by pressing the start button of the appTL panel
                                    if k == 0 % first time, first image
                                        nextTime = appTL.t0;
                                        dt = appTL.dt;
                                        % Parameters
                                        dt = seconds(dt);         % Interval between successive images
                                    end
                                    if isvalid(appTL)
                                        appTL.setNextImageTime(nextTime)
                                    end
                                    if datetime('now') >= nextTime && k<appTL.N
                                        k = k+1;
                                        nextTime = appTL.t0 + k*dt;


                                        app.switchShutter('On')

                                        % take a snapshot
                                        NimgSnap = app.snapAvgEditField.Value;  
                                        app.Cam.stopAcquisition()
                                        app.Cam.setMode('Fixed', 'Internal',"expTime",app.expTime,"Nim",NimgSnap)
                                        app.Cam.startAcquisition()
                                            
                                        buf = app.CamAcquire('fixed',NimgSnap);
                                        app.switchShutter('Off')
                        
                                        tnow = datetime('now');
                        
                                        app.itf = mean(buf, 3);   % app.Itf0 or app.Ref0
                                        app.Itf0.setParams("timeStamp",tnow,"expTime",app.expTime, ...
                                                            "Navg",size(buf,3),"ROI",app.Cam.getROI())


                                        app.saveCurrentImage()


                                        app.Cam.stopAcquisition()
                                        app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                                        app.Cam.startAcquisition()


                                        if isvalid(appTL)
                                            appTL.setImageNumber(k)
                                        end
                                    elseif k>=appTL.N
                                        k=0;
                                        if isvalid(appTL)
                                            appTL.setImageNumber(k)
                                        end
                                        appTL.setNextImageTime('0')
                                        if all(app.status_hfigList == 0) % if all the windows are closed
                                            app.setAcqMode('Stop')
                                        else
                                            app.setAcqMode('Live')
                                        end
                                        app.toggleSavePanel(true)
                                        appTL.t0 = [];
                                        appTL.toogleStartButton('start')
    
                                    end
                                end
                            case 'Ref'
                                app.stopAcquisition()
    
                                app.acquire('Ref')
                                app.setAcqMode('Live')
                                app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.refAvgEditField.Value)
                                app.Cam.startAcquisition()
                                app.setCamRunningLampState("Running")
    
    
                            case 'Snap'

                                app.stopAcquisition()
                                app.acquire('Snap')
                                %app.setAcqMode('Stop')
                                
                            case 'Burst'
                                app.stopAcquisition()
    
                                app.acquire('Burst')
                                %app.setAcqMode('Stop')

                        end
    
                        if any( app.status_hfigList ) 
                            app.computeImages()
                            app.updateImages()
                        else
                            drawnow
                        end
                        app.HzEditField.Value = round(1/toc,1);
    
    
                        %if strcmp(app.acqMode,'Snap')
                        %    break % exit the while loop
                        %end
                    end % end while loop
                    
                    app.HzEditField.Value = double.empty();
                    
                    % if ~strcmp(app.acqMode,'Snap')
                    %     app.stopAcquisition()
                    % end
            
            end
 

            % AUTOSAVE
            if app.autosaveCheckBox.Value && (strcmpi(mode,'Snap') || strcmpi(mode,'Burst'))
                app.saveCurrentImage()
                pause(1)
                
            else
                app.setAcqMode('Stop')

            end
    
            if numel(app.Itf0) > 1
                app.enableImageNavigation()
            end


            app.switchShutter('Off')
        
        
        end


        function updateIL(app)
            app.IL.lambda = app.lambdaEditField.Value;
        end
        
        function clearImages(app)
            if ~isempty(app.hiList.raw)
                app.hiList.raw.CData  = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.T)
                app.hiList.T.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.W)
                app.hiList.W.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.dW)
                app.hiList.dW.CData   = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.dWx)
                app.hiList.dWx.CData   = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.dWy)
                app.hiList.dWy.CData   = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.hiList.mass)
                app.hiList.mass.CData = zeros(app.Ny,app.Nx);
            end
            
            if ~isempty(app.hiList.PDCM)
                app.hiList.PDCM.CData = zeros(app.Ny,app.Nx);
            end

        end
        
        function clearRef(app)
            app.Ref0 = Interfero();
            app.Ref0.Microscope = app.MI;
            app.ref = [];
            app.Itf0.clearTF()            
        end
        
        function cropDefinition(app)
            figs = findall(0,'Type','figure','Tag','liveWindow');
            for k = 1:numel(figs)
                if ~isempty(figs(k).CurrentObject)
                    hfig = figs(k);
                end
            end
            if ~exist('hfig','var') % If none is active, just take any, like the last one in the list
                hfig = figs(k);
            end
            hax = findall(hfig,'Tag','LeftAxis');
            if isempty(hax)
                warning('no axis found in the window')
                return
            end
            h = figure;
            hax = copy(hax);
            hax.Parent=h;
            colorbar('off')
            hax.Position = [10,10,400,400];
            h.Position = [632 174 417 459];
            h.Color = [0.9 0.9 0.9];
            hax.Title.String = 'toto';
            hax.Title.Color = [1 1 1];
            hax.XColor = [0 0 0];
            hax.YColor = [0 0 0];
            hax.Children.XData = 1:app.Itf0(1).Nx;
            hax.Children.YData = 1:app.Itf0(1).Ny;

            app.roi = drawrectangle(hax);
%            addlistener(roi,'ROIMoved',@(src, evt) roiMoved(app));
            %roi.DeleteFcn = @roiDeleted;
            
            % function roiMoved(src, evt, app)
            %     newCrop = round(evt.CurrentPosition);
            %     %ha = src.Parent;
            %     app.roi = newCrop;
            % end

        end
        
        function enableImageNavigation(app,val)
            arguments
                app
                val = 1
            end
            if val
                app.rightButton.Enable = 1;
                app.leftButton.Enable = 1;
                app.jimEditField.Enable= 1;
            else
                app.rightButton.Enable = 0;
                app.leftButton.Enable = 0;
                app.jimEditField.Enable = 0;
            end
        end
        
        function moveImage(app,tag)
            switch tag
                case 'right'
                    app.jim = app.jim+1;
                case 'left'
                    app.jim = app.jim-1;
            end
            app.updateImages()
        end

        function MI2panel(app)
            % update the app.MI with what is written in the parameters panel

            app.MobjEditField.Value = app.MI.Objective.Mobj;   % update Mobj field
            app.cameraDropDown.Value = app.MI.CGcam.fileName;
            app.microscopeTLDropDown.Value = string(app.MI.f_TL) ;
            if isempty(app.MI.CGcam.RL)
                 app.RelayLensEditField.Value = [];
            else
                app.RelayLensEditField.Value = app.MI.CGcam.RL.zoom;
            end
            app.CrossGratingDropDown.Value = app.MI.CGcam.CG.name;

        end
        
        
        function writeMessage(app,val,color)
            arguments
                app
                val
                color = [0.92,0.76,0.63]
            end

            app.messageEditField.Value = val;
            app.messageEditField.FontColor = color;
            pause(1)
            app.messageEditField.Value = "";

        end
        
        function stopAcquisition(app)
            app.Cam.stopAcquisition()            
            app.setCamRunningLampState('Off')
            app.setAcqMode('Stop')
        end
        
        function toggleFTwindow(app)
            % opens a popup window, but not from the imageWindowLive class,
            % just a uifigure
            value = app.FTButton.Value;
            if value
                hf = figure();
                
                hf.CloseRequestFcn = @(src,evt) [];

                hwin = struct();
                hwin.hf = hf;

                subplot(1,2,1);
                hwin.him = imagesc(app.Itf0.Itf);

                hwin.app_thetaZetaDisplay = thetaZetaDisplay;
                hwin.app_thetaZetaDisplay.UIFigure.Position = ...
                    [ app.UIFigure.Position(1) + app.UIFigure.Position(3), ...
                    app.UIFigure.Position(2), ...
                    hwin.app_thetaZetaDisplay.UIFigure.Position(3), ...
                    hwin.app_thetaZetaDisplay.UIFigure.Position(4)];
                hf.Name = "Fourier transform";
                title("Zoom on interferogram")
                set(gca,'DataAspectRatio' , [1 1 1]);
                set(gca, 'XDir', 'reverse');
                colormap("gray")
                zoom(10)
                subplot(1,2,2);
                hwin.him2 = imagesc(sqrt(sqrt(abs(fftshift(fft2(app.Itf0.Itf))))));
                set(gca,'DataAspectRatio' , [1 1 1]);
                set(gca, 'XDir', 'reverse');
                colormap("parula")
                title("Fourier transform")


                hwin.ha = hwin.him.Parent;
                hwin.ha2 = hwin.him2.Parent;

               set(hwin.ha,'YDir','normal')
                set(hwin.ha2,'YDir','normal')


                app.hfList.FT = hf;
                app.haList.FT = hwin.him.Parent;
                app.ha2List.FT = hwin.him2.Parent;
                app.hiList.FT = hwin.him;
                hwin.getRightImageType = 'FT';

                app.hWinList.FT = hwin;

                hf.UserData.handles = hwin;

            end

        end

        function toggleBalanceWindow(app,tag)

            value = app.(tag+"Button").Value;
            if value

                if isempty(app.Itf0.Itf) % if no interfero, cancel.
                    app.(tag+"Button").Value = 0;
                    return
                end
                
                hf = figure();
                
                hf.CloseRequestFcn = @(src,evt) [];

                hwin = struct();
                hwin.hf = hf;

                hwin.him = plot([1 2 3 4],[0 0 0 0]);
                hwin.ha = hwin.him.Parent;
        
                %event.Source.BackgroundColor = [0.4 0.4 0.4];
                %pathToMLAPP = fileparts(mfilename('fullpath'));
                %event.Source.Icon = fullfile(pathToMLAPP, 'icons', [tag '_on.svg']);
    
                hf.CloseRequestFcn = @(src,evt) [];
                tokens = regexp(tag, '\d+', 'match'); % extract a number from a char array
                Nval = str2double(tokens{1}); % 2 or 4
                hwin.app_channelsBalanceDisplay = channelsBalanceDisplay(Nval);
                hwin.app_channelsBalanceDisplay.UIFigure.CloseRequestFcn = @(src,evt) [];
                hwin.ha = axes(hf);
                switch tag
                    case 'balance2channels'
                        val1 = mean(mean(app.Itf0.Itf(1:2:end,1:2:end)));
                        val2 = mean(mean(app.Itf0.Itf(2:2:end,1:2:end)));
                        hwin.app_channelsBalanceDisplay.setValues([val1, val2])
                    case 'balance4channels' 
                        val1 = mean(mean(app.Itf0.Itf(1:2:end,1:2:end)));
                        val2 = mean(mean(app.Itf0.Itf(2:2:end,1:2:end)));
                        val3 = mean(mean(app.Itf0.Itf(2:2:end,2:2:end)));
                        val4 = mean(mean(app.Itf0.Itf(1:2:end,2:2:end)));
                        hwin.app_channelsBalanceDisplay.setValues([val1, val2, val3, val4])
                end
                vals = hwin.app_channelsBalanceDisplay.getValues();
                hwin.him = plot( hwin.ha, vals ,'o',LineWidth=2);
                ylim( [0, 2^16/4] )
                xlim([0.5 4.5])
                hf.Name = "Channels balance";
                hwin.app_channelsBalanceDisplay.UIFigure.Position = ...
                    [ app.UIFigure.Position(1) + app.UIFigure.Position(3), ...
                    app.UIFigure.Position(2), ...
                    hwin.app_channelsBalanceDisplay.UIFigure.Position(3), ...
                    hwin.app_channelsBalanceDisplay.UIFigure.Position(4)];
    
       
                hwin.ha = hwin.him.Parent;

                hwin.ha2 = [];
                app.hfList.(tag) = hf;
                app.haList.(tag) = hwin.him.Parent;
                app.ha2List.(tag) = [];
                app.hiList.(tag) = hwin.him;
                hwin.getRightImageType = tag;

                app.hWinList.(tag) = hwin;
                

                hf.UserData.handles = hwin;
            
            end
    
        end
        
        function img = CamAcquire(app,mode,Nimg)
            arguments
                app
                mode {mustBeMember(mode,{'fixed','continuous'})}
                Nimg = 1
            end
            app.setCamRunningLampState("Running")
            switch mode
                case 'continuous'
                    img = app.Cam.acquire_continuous(app);
                case 'fixed'
                    img = app.Cam.acquire_fixed(Nimg,app);
                    app.setCamRunningLampState("Off")
            end
            
        end
        
        function switchShutter(app,state)
            if ~isempty(app.shutterWindow) && isvalid(app.shutterWindow)
                if app.shutterWindow.AutoshutterButton.Value
                    switch state
                        case 'On'
                            app.shutterWindow.open()
                        case 'Off'
                            app.shutterWindow.close()
                    end
                end
            end
        end
    end


    methods(Access = ?setupControlWindow)

        function panel2MI(app)
            % update the app.MI with what is written in the parameters panel
            % except the camera (update the camera with app.updateCamera() )
 

            value = app.microscopeTLDropDown.Value;
            if ~isnan(str2double(value))
                app.microscopeTLDropDownLabel.Text = 'f_TL';
                %str2double(value)  % La chaîne ne contient que des nombres
            else
                app.microscopeTLDropDownLabel.Text = 'Microscope';  % 'La chaîne contient d'autres caractères que des nombres
            end


            app.MI.Objective = Objective(app.MobjEditField.Value,0.7,app.objBrandDropDown.Value);
            app.MI.f_TL = app.microscopeTLDropDown.Value; 
            app.MI.CGcam.setDistance(app.CGdistanceEditField.Value*1e-3); 
            if isempty(app.RelayLensEditField.Value)
                app.MI.CGcam.RL = RelayLens.empty();
            else
                app.MI.CGcam.RL = RelayLens(app.RelayLensEditField.Value);
            end
            if strcmp(app.CrossGratingDropDown.Value(end-1:end),'µm')
                app.MI.CGcam.CG = CrossGrating(Gamma = str2double(app.CrossGratingDropDown.Value(1:end-2))*1e-6,lambda0 = 550e-9);
            else
                app.MI.CGcam.CG = CrossGrating(app.CrossGratingDropDown.Value);
            end

        end


    end


    methods(Access = public)

        function computeImages(app, mode)
            arguments
                app
                mode char = ''
            end
            % function that computes and distribute the computed images (T, W, dW, etc)
            % on the differennt open windows.
            
            HR = app.HRButton.Value; % High resolution
            HRlist = {'low','high'};
            cellValues = struct2cell(app.hiList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            statusList = ~isEmptyLogical;
            noref = isempty(app.Itf0(1).Ref.Itf);
            
            % As a function of if raw, dW, T and OPD are required, compute only what is needed, ie dW, W and or T
            % Note: in any case, if raw is not the only parameter on, dW need to be computed
            if strcmpi(mode,'Burst')
                app.IM = QLSIprocess(app.Itf0,app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref);
            elseif any(statusList(2:8)) % if any image other than raw need to be computed
                if ~all(app.Itf0(1).ROI == app.Ref0.ROI)
                    %warning('Ref and Itf do not have the same ROIs. Ref erased. Acquire a new one.')
                    %app.clearRef();
                    app.writeMessage("Take a new reference");
                    app.setAcqMode('Stop')
                    return
                end
                if statusList(4) && all(~statusList([2:3, 5:end])) % If only dW needs to be displayed (and maybe raw)
                    % Then no W or T calculation
                    computeT = 0;
                    computeW = 0;
                elseif isempty(app.hiList.T) && isempty(app.hiList.Rytov) % If still neither T nor Rytov, but something more (W, PDCM, etc...)
                    % then compute W
                    computeT = 0;
                    computeW = 1;
                elseif statusList(3) && all(~statusList([2, 5:end])) % if T and nothing else than raw and dW (ie nothing that requires the computation of W)
                    computeT = 1;
                    computeW = 0;
                else
                    computeT = 1;
                    computeW = 1;
                end 
            
                try 
                    if isempty(app.roi) % if empty
                        [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                          'computeT', computeT, ...
                                                          'computeW', computeW, ...
                                                          'saveGradients',0, ...
                                                          'computeIM',0);
                    elseif ~isvalid(app.roi) % or if deleted
                        [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                          'computeT', computeT, ...
                                                          'computeW', computeW, ...
                                                          'saveGradients',0, ...
                                                          'computeIM',0);
                
                    else % apply roi crop
                        [~,app.DWx0,app.DWy0,app.W0,app.T0]  = QLSIprocess(app.Itf0(1).crop('roi',app.roi.Position),app.IL,'definition',HRlist{HR+1},'displayCrop',false,'noRef',noref, ...
                                                          'computeT', computeT, ...
                                                          'computeW', computeW, ...
                                                          'saveGradients',0, ...
                                                          'computeIM',0);
                    end

                catch
                    app.writeMessage('QLSIprocess does not work')
                    app.setCamConnectionLampState('Error')
                    app.setAcqMode('Stop')
                end
            
            end

        end
 
        function updateImages(app)
            if app.flattenButton.Value
                symD4 = @(img) ZernikeRemoval(applySymmetryD4_bis(img,app.D4Transform),2,0);
            else
                symD4 = @(img) applySymmetryD4_bis(img,app.D4Transform);
            end
            if ~isempty(app.hiList.raw)
                if ~isempty(app.Itf0(1).Itf)
                    app.hiList.raw.CData  = symD4( app.Itf0(app.jim).Itf );
                end
            end
            if ~isempty(app.hiList.T)
                app.hiList.T.CData    = symD4( app.IM(app.jim).T );
            end
            if ~isempty(app.hiList.W)
                app.hiList.W.CData    = symD4( app.IM(app.jim).OPDnm );
            end
            if ~isempty(app.hiList.dW)
                app.hiList.dW.CData   = symD4( app.IM(app.jim).DWx.^2 + app.IM(app.jim).DWy.^2 );
            end
            if ~isempty(app.hiList.dWx)
                app.hiList.dWx.CData   = symD4( app.IM(app.jim).DWx);
            end
            if ~isempty(app.hiList.dWy)
                app.hiList.dWy.CData   = symD4( app.IM(app.jim).DWy);
            end
            if ~isempty(app.hiList.mass)
                app.hiList.mass.CData = symD4( 5.56*1e-3 * app.IM(app.jim).OPDnm );
            end
            
            if ~isempty(app.hiList.PDCM)
                dxDWy  = imgradientxy(app.IM(app.jim).DWy);
                [~, dyDWx] = imgradientxy(app.IM(app.jim).DWx);
                app.hiList.PDCM.CData = symD4( imgaussfilt(dxDWy-dyDWx,3) );
            end
            
            if ~isempty(app.hiList.Rytov)
                app.hiList.Rytov.CData    = symD4( app.IM(app.jim).Rytov );
            end

            if ~isempty(app.hiList.polar)
        
                nn = 4;
                %app.Itf.Itf0 = app.cam.doAcquisition();
                app.Itf4 = app.Itf0.splitPolars();
                if isempty(app.Ref4)
                    app.Ref4 = app.Ref0.splitPolars();
                    app.Itf4.Reference(app.Ref4)
                end
                IM4 = app.Itf4.QLSIprocess(app.IL, 'definition', 'low','displayBar',false);
                IM4.adjustPolarOffsets('auto'); % manual; manual
                %app.polarImages = app.IM4.extractPolarImages(app.retMaxEditField.Value);
                %app.polarImages = PolarImages(app.IM4.flatten("Gaussian","nGauss",150), app.retMaxEditField.Value);
                polarImages = ImagePolar(IM4, eLim = app.retMaxEditField.Value);
                 app.hiList.polar.CData = polarImages.rgbImageEllipse(1+nn:end-nn,1+nn:end-nn,:);
                colormap("hsv")
            end

            if ~isempty(app.hiList.FT)
                hwin = app.hWinList.FT;
                him = hwin.him;
                him2 = hwin.him2;
                him.CData = app.Itf0.Itf;
                him2.CData = imgaussfilt(sqrt(sqrt(abs(fftshift(fft2(app.Itf0.Itf))))),4);
                [theta, zeta] = app.get1orderLocation;
                Z = 2*zeta*2*app.MI.CGcam.Camera.dxSize/app.MI.CGcam.CG.Gamma;
                app.MI.CGcam.RL = RelayLens(Z);
                app.RelayLensEditField.Value = Z;

                if ~isempty(app.Itf0.Ref.Fcrops(1).x)
                    app.Itf0.Ref.Fcrops.draw(hwin.ha2)
                end
                title("Fourier transform ")
                hwin.app_thetaZetaDisplay.setTheta(theta)
                hwin.app_thetaZetaDisplay.setZeta(zeta)

                hwin.app_thetaZetaDisplay.setZoom(app.MI.CGcam.RL.zoom)
%                app.zetaEditField.Value = hf.UserData.app_thetaZetaDisplay.zetaEditField.Value;
    
            end

            if ~isempty(app.hiList.balance2channels)
                hwin = app.hWinList.balance2channels;
                val1 = mean(mean(app.Itf0.Itf(1:2:end,1:2:end)));
                val2 = mean(mean(app.Itf0.Itf(2:2:end,1:2:end)));
                hwin.app_channelsBalanceDisplay.setValues([val1, val2])
                vals = hwin.app_channelsBalanceDisplay.getValues();
                hwin.him.YData = vals;
                maxVal = hwin.app_channelsBalanceDisplay.maxvalEditField.Value;
                ylim(hwin.ha,[0 maxVal])

            end

            if ~isempty(app.hiList.balance4channels)
                hwin = app.hWinList.balance4channels;
                val1 = mean(mean(app.Itf0.Itf(1:2:end,1:2:end)));
                val2 = mean(mean(app.Itf0.Itf(2:2:end,1:2:end)));
                val3 = mean(mean(app.Itf0.Itf(2:2:end,2:2:end)));
                val4 = mean(mean(app.Itf0.Itf(1:2:end,2:2:end)));
                hwin.app_channelsBalanceDisplay.setValues([val1, val2, val3, val4])
                vals = hwin.app_channelsBalanceDisplay.getValues();
                hwin.him.YData = vals;
                maxVal = hwin.app_channelsBalanceDisplay.maxvalEditField.Value;
                ylim(hwin.ha,[0 maxVal])

            end


            % update twin images

            % Display something on the twin image, if there is.
            fieldList = fields(app.hWinList);
            for im = 1:length(app.status_hfigList)
                if app.status_hfigList(im)
                    hw = app.hWinList.(fieldList{im});
                    if ~isempty(hw.ha2) && isvalid(hw.ha2) % if Right axis exits
                        % HISTROGAM
                        if strcmp(hw.getRightImageType,'histo')
                            %histogram(app.hWinList.(fieldList{im}).UserData.twinAxis,...
                            %    app.himList.(fieldList{im}).CData)
                            [counts, edges] = histcounts(hw.hi.CData);
                            binCenters = edges(1:end-1) + diff(edges)/2;
                            hw.hi2.XData = binCenters;
                            hw.hi2.YData = counts;
                            xlim(hw.ha2,[0,2^app.Cam.bitDepth])
        
                            % FFT
                        elseif strcmp(hw.getRightImageType,'FFT')
                            hw.hi2.CData = sqrt(sqrt(abs(fftshift(fft2(hw.hi.CData)))));


                        % crosscut
                        elseif strcmp(hw.getRightImageType,'crosscut')
                            
                            if hw.hi2.XData == 1 % First call of crosscut
                                % Then draw the cut line
                                [Z, d_coords, hw.ha.UserData.hpoly] = plotPolylineCrosscut(hw.ha,"color",[0.9 0.5 0.3]);
                            else
                                [Z, d_coords] = plotPolylineCrosscut(hw.ha,"Img",hw.hi.CData,"polyLine",hw.ha.UserData.hpoly);
                            end

                            % dist=0;
                            % for ni=1:length(cx)-1
                            %     dist = dist + sqrt((cx(ni)-cx(ni+1))^2+(cy(ni)-cy(ni+1))^2);
                            % end

                            hw.hi2.YData = Z;
                            hw.hi2.XData = d_coords;
                            hw.hi2.LineWidth = 2;
                            hw.hi2.Color = [0.8 0.5 0.2];
                            xlabel(hw.ha2,'px')
                            ylabel(hw.ha2,'nm')
                            title(hw.ha2,'wavefront','Color',[0.8 0.5 0.2],'FontSize',14)
                            grid(hw.ha2,"on")
                        
                        % edgeProfile
                        elseif strcmp(hw.getRightImageType,'edgeProfile')
                            parameters = hw.extraParameters;
                            crossCutDirection = parameters{1};
                            Width = parameters{2};
                            if hw.hi2.XData == 1 % First call of crosscut
                                % Then draw the cut line
                                [Z, hw.ha.UserData.hline] = plotLineEdgeProfile(hw.ha,crossCutDirection,"color",[0.9 0.5 0.3],"width",Width);
                            else
                                Z = plotLineEdgeProfile(hw.ha,crossCutDirection,"Img",hw.hi.CData,"hline",hw.ha.UserData.hline,"width",Width);
                            end

                            % dist=0;
                            % for ni=1:length(cx)-1
                            %     dist = dist + sqrt((cx(ni)-cx(ni+1))^2+(cy(ni)-cy(ni+1))^2);
                            % end

                            hw.hi2.YData = Z;
                            hw.hi2.XData = 1:numel(Z);
                            hw.hi2.LineWidth = 2;
                            hw.hi2.Color = [0.8 0.5 0.2];
                            xlabel(hw.ha2,'px')
                            title(hw.ha2,'wavefront','Color',[0.8 0.5 0.2],'FontSize',14)
                            grid(hw.ha2,"on")
                        
                        end

                        
                    end
                end
            end

            drawnow            
        end

        function saveCurrentImage(app)
            app.saveLamp.Color = [0.9, 0.5, 0.3];
            
            currentIconState = app.getButtonIcon('Save'); % on, off or busy
            app.setButtonIcon('Save','busy')

            
            % set the proper folder
            if isempty(app.saveFolderEditField.Value)
                folder = pwd;
            else
                folder = app.saveFolderEditField.Value;
            end

            % REF SAVE
            % Check ROIs match, and whether both Itf and Ref are acquired.
            % If everything is ok, save Ref
            if isnat(app.Itf0(1).timeStamp) % if no Itf acquired, error
                warning('no interferogram has been acquired yet')
                app.savedFileNameEditField.Value = 'error: no Itf acquired';
                app.setButtonIcon('Save','error');
                app.saveLamp.Color = [0.5, 0.5, 0.5];
                return

            elseif isnat(app.Ref0.timeStamp) % warning, no Ref
                warning('no reference has been acquired yet')
                app.setButtonIcon('Save','error');
                app.savedFileNameEditField.Value = 'error: no Ref acquired';
                app.saveLamp.Color = [0.5, 0.5, 0.5];
                return

            elseif ~all(app.Itf0(1).ROI == app.Ref0.ROI) % if the ROI are not the same, error
                warning('not the same Ref/Itf ROIs')
                app.setButtonIcon('Save','error');
                app.savedFileNameEditField.Value = 'error: not the same Ref/Itf ROIs';
                app.saveLamp.Color = [0.5, 0.5, 0.5];
                return
            end

            hd = app.Ref0.timeStamp;
            refNumber = [textk(hd.Hour) num2str(hd.Minute) num2str(textk(round(hd.Second)))];
            RefFileName = "Ref_"+refNumber+".tif";
            if ~exist(folder+"/"+RefFileName,'file') % if the ref file hasn't been saved yet    
                %RefFileName = createFileName('Ref', folder, app.Ref0.timeStamp,app.Ref0.ROI);
                
                saveTiffMeta(folder+"/"+RefFileName, app.Ref0, app.MI, "lambda", app.IL.lambda, "ROI",app.Ref0.ROI)
            end
            
            for ii = 1:app.Nim
                ItfFileName = createFileName(app.savePrefixEditField.Value, folder);
                saveTiffMeta(folder+"/"+ItfFileName, app.Itf0(ii), app.MI, "lambda", app.IL.lambda, "ROI", app.Itf0(ii).ROI, "RefFileName",  RefFileName)
            end

            app.savedFileNameEditField.Value = ItfFileName;
            app.setButtonIcon('Save', currentIconState)

            drawnow

            app.saveLamp.Color = [0.5, 0.5, 0.5];
            
        end
 
        function setAcqMode(app, mode)
            arguments
                app
                mode (1,:) char {mustBeMember(mode,{'Live','Stop','Snap','TimeLapse','Ref','Burst'})}
            end

            setAcqPanelAppearance(app, mode)
            app.acqMode = mode;
        end

        function setAcqPanelAppearance(app, status)
            % Modify the appearance of the GUI (enable of disable buttons,
            % change icons, etc.
            arguments
                app
                status (1,:) char {mustBeMember(status,{'Live','Stop','Snap','TimeLapse','Ref','Burst','External','nocamera','camera'})}
            end

            
            switch status

                case {'Live','TimeLapse'}
                    % turn the GUI into live state:
                    % Change the live icon to stop
                    
                    app.LiveButton.Value = 1;
                    app.setButtonIcon('Live','busy')
                    app.imageDimensionsDropDown.Enable = false;
                    
                    % if ~any(app.status_hfigList()) % if no window figure is open
                    %     % open raw
                    %     app.toggleFigure('raw',true)
                    % end

                case 'Stop'
                    % turn the GUI into stop state:
                    % turn the lamp green, change the stop icon to live
                    app.LiveButton.Value = 0;
                    app.RefButton.Value = 0;
                    app.setButtonIcon('Live','off')
                    app.HzEditField.Value = double.empty();
                    app.imageDimensionsDropDown.Enable = true;
                    app.setCamRunningLampState('Off')
                    app.toggleSavePanel(true)
                    
                case 'Snap'
                    app.LiveButton.Value = 1;
                    app.setButtonIcon('Snap','on')
                    app.imageDimensionsDropDown.Enable = false;
                    
                case 'nocamera'
                    app.LiveButton.Enable = 'off';
                    app.RefButton.Enable = 'off';
                    app.SnapButton.Enable = 'off';
                    app.burstButton.Enable = 'off';
                    app.timeLapseButton.Enable = 'off';
                    
                case 'camera'
                    app.LiveButton.Enable = 'on';
                    app.RefButton.Enable = 'on';
                    app.SnapButton.Enable = 'on';
                    app.burstButton.Enable = 'on';
                    app.timeLapseButton.Enable = 'on';
                    

            end


        end

        function toggleSavePanel(app,val)
            arguments
                app
                val logical
            end

            if val
                app.GridLayout_Save.BackgroundColor       = [0.9412    0.9412    0.9412];
                app.GridLayout_savePrefix.BackgroundColor = [0.9412    0.9412    0.9412]; 
                app.setButtonIcon('Save','off')
                app.autosaveCheckBox.Enable = true;
                app.savePrefixEditField.Enable = true;
                app.saveFolderEditField.Enable = true;
                app.saveFolderButton.Enable = true;
            else
                app.setAcqMode('Live')
                app.GridLayout_Save.BackgroundColor       = [0.7, 0.3, 0.1];
                app.GridLayout_savePrefix.BackgroundColor = [0.7, 0.3, 0.1]; 
                app.setButtonIcon('Save','movie');
                app.autosaveCheckBox.Enable = false;
                app.savePrefixEditField.Enable = false;
                app.saveFolderEditField.Enable = false;
                app.saveFolderButton.Enable = false;
            end
        
        end
    end
    
    methods % set and get methods

        function img = get.itf(app)
            if isempty(app.roi)
                img = app.Itf0(1).Itf;
            else
                [~,~,img] = app.Itf0(1).crop('roi',app.roi.Position);
            end
        end

        function set.itf(app,img)
            if length(size(img)) == 2 % img is only one image
                app.Itf0 = app.Itf0(1);
                app.Itf0.Itf0 = img;
            elseif length(size(img)) == 3  % burst mode
                Ni = size(img,3);
                if Ni == app.Nim % already the proper number of images
                    for ii = 1:Ni
                        app.Itf0(ii).Itf0 = img(:,:,ii);
                    end
                else
                    % build an array of Interfero
                    app.Itf0 = Interfero(Ni);
                    for ii = 1:Ni
                        app.Itf0(ii) = Interfero(img(:,:,ii), app.MI);
                        app.Itf0(ii).Ref = app.Ref0;
                    end
                end
            end


        end

        function val = get.ref(app)
            val = app.Ref0.Itf;
        end

        function set.ref(app,val)
            if isempty(val)
                app.setButtonIcon('Ref','off')
            else
                app.setButtonIcon('Ref','on')
            end
            app.Ref0.Itf0 = val;
        end

        function val = get.W0(app)
            val = app.IM.OPDnm;
        end
        function set.W0(app,val)
            app.IM.OPD0 = val;
        end

        function val = get.T0(app)
            val = app.IM.T;
        end
        function set.T0(app,val)
            app.IM.T0 = val;
        end

        function val = get.DWx0(app)
            val = app.IM.DWx0;
        end
        function set.DWx0(app,val)
            app.IM.DWx0 = val;
        end

        function val = get.DWy0(app)
            val = app.IM.DWy0;
        end
        function set.DWy0(app,val)
            app.IM.DWy0 = val;
        end

        function val = get.Cam(app)
            val = app.MI.CGcam.Camera;
        end

        function val = get.Ny(app) % Andor camera display image with the width vertical in Matlab
            val = app.Cam.width;
        end

        function val = get.Nx(app)
            val = app.Cam.height;
        end

        function val = get.jim(app)
            val = app.jimEditField.Value;
        end

        function set.jim(app,val)
            app.jimEditField.Value = mod(val-1,app.Nim)+1;
            if val > 1
                app.enableImageNavigation(1);
            else
                app.enableImageNavigation(0);
            end
        end

        function val = get.Nim(app)
            val = numel(app.Itf0);
        end

        function val = get.NavgLive(app)
            val = app.liveAvgEditField.Value;
        end

        function val = get.NavgRef(app)
            val = app.refAvgEditField.Value;
        end

        function val = get.NavgSnap(app)
            val = app.snapAvgEditField.Value;
        end

        function val = get.expTime(app)
            val = app.expTimeEditField.Value*1e-3;
        end

        %%

        function setCamConnectionLampState(app,val)
            % app.setCamConnectionLampState("None")
            arguments
                app
                val {mustBeMember(val,{'Connected','None','Wait','Error'})} = 'None'% state of the camera lamp button
            end
            app.camConnectionLamp.BackgroundColor = app.("lampColor"+val);
            app.camConnectionLamp.UserData.state = val;
            
            drawnow
        end

        function setCamRunningLampState(app,val)
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'Running','Off'})} = 'Off' % state of the camera lamp button
            end
            %val(1) = upper(val(1)); % uppercase the first letter
            app.camRunningLamp.Color = app.("lampColor"+val);
            %app.camRunningLamp.UserData.state = val;
            drawnow
        end

        function results = get.status_hfigList(app)
            % returns an array of logical values saying which windows are open.
            cellValues = struct2cell(app.hfList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            results = ~isEmptyLogical;
        end

        % function val = imList(app,tag)
        %     switch tag
        %         case 'raw'
        %             val = app.Itf0(1).Itf;
        %         case 'W'
        %             val = app.IM.OPDnm;
        %         case 'T'
        %             val = app.IM.T;
        %         case 'dW'
        %             val = app.IM.D2Wnorm;
        %         case 'mass'
        %             val = app.IM.dmd;
        %         case 'PDCM'
        %             val = app.IM.PDCM;
        %         case 'Rytov'
        %             val = app.IM.Rytov;
        %         otherwise
        %             error('tag not defined')
        %     end
        % end

        function setButtonIcon(app,name,state)
            arguments
                app
                name
                state {mustBeMember(state,{'on','off','busy','movie','error'})}
            end
            %% app.setButtonState('Snap','on')
            %% app.setButtonState('Ref',false)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.(name+"Button").Icon = fullfile(pathToMLAPP, 'icons', name+"_"+state+".svg");
        end

        function state = getButtonIcon(app,name)
            arguments
                app
                name
            end
            %% app.setButtonState('Snap')
            %% app.setButtonState('Ref')
            %% return 'on', 'off' or 'busy'
            svgName = app.(name+"Button").Icon;
            state = regexp(svgName, '(?<=_)[^_\.]+(?=\.[^\.]+$)', 'match', 'once');
        end

        function [theta, zeta] = get1orderLocation(app)
                Npx = min([400, app.Itf0.Nx, app.Itf0.Ny]);
                fitf = log(imgaussfilt(abs(fft2(app.Itf0.Itf(1:Npx,1:Npx))),1));
                fitf = fitf(1:Npx/2,1:Npx/2);
                mask = ones(size(fitf));
                mask(1:5,1:5)=0;
                [~, linpos] = max(mask(:).*fitf(:));
                [ny, nx] = ind2sub(size(fitf),linpos);
                theta = round(180/pi*angle((nx-1)+1i*(ny-1)),1);
                zeta = round(0.5*Npx/abs((nx-1)+1i*(ny-1)),1);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setAcqPanelAppearance('nocamera')
            app.writeMessage('');
            app.IM = ImageQLSI();
                        
            %% Define the app (figure) position (middle top)
            screenSize = get(0, 'ScreenSize');

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calculate the x position to center horizontally (optional)
            xPos = (screenSize(3) - appWidth) / 2;
            
            % Calculate the y position to align at the top of the screen.
            % Since the coordinate system starts at the bottom left,
            % we set y such that yPos + appHeight = screenSize(4)
            yPos = screenSize(4) - appHeight - 30;

            app.UIFigure.Position(1:2) = [xPos,yPos];
            
            %% Define the colors of the upper-left lamps
            %app.camConnectionLamp.UserData.colorOn    = app.lampColorConnected;
            %app.camConnectionLamp.UserData.colorOff   = app.lampColorNone;
            %app.camConnectionLamp.UserData.colorWait  = app.lampColorWait;
            %app.camConnectionLamp.UserData.colorError = app.lampColorError;

            %app.camRunningLamp.UserData.colorRunning  = app.lampColorRunning;
            %app.camRunningLamp.UserData.colorRunning  = app.lampColorOff;

            app.setCamConnectionLampState('None')
            app.setCamRunningLampState('Off')


            %% Creating the app Microscope
            % Creating a blank Microscope
            app.MI = Microscope();
            app.MI.CGcam.CG = CrossGrating();
            app.MI.software = 'PhaseLAB';
            %app.panel2MI()
            app.RelayLensEditField.AllowEmpty = 'on';
            app.HzEditField.AllowEmpty = 'on';
            app.RelayLensEditField.Value = [];

            app.IL = Illumination(app.lambdaEditField.Value,Medium(1.33,1.5),1,[1 1i 0]);
            app.IM.Illumination = app.IL;
            app.IM.Microscope = app.MI;
            
            % update the Microscope from the initial panel
            app.updateCamera()
            app.panel2MI()
            
            %app.Itf0     = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            %app.Itf0.Ref = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            app.Itf0     = Interfero([],app.MI);
            app.Itf0.Ref = Interfero([],app.MI);
            app.Ref0 = app.Itf0.Ref;
            
            % assign tags to figure handles in app.hfig
            app.initializeWindowsHandles();


        end

        % Value changed function: FTButton, PDCMButton, RytovButton, 
        % ...and 10 other components
        function toggleImageWindow(app, event)
            % open or close the corresponding figure
            app.toggleWindow(event.Source.Tag,event.Source.Value)

        end

        % Value changed function: CGdistanceEditField, 
        % ...and 5 other components
        function microscopeValueChanged(app, event)
            % update the microscope as soon as something has changed in the
            % panel
            app.panel2MI()

        end

        % Button pushed function: closeButton
        function closeButtonValueChanged(app, event)
            if all(app.status_hfigList == 0) % if all the windows are closed
                % close the app
                app.disconnectCamera()
                delete(app.UIFigure)
            else % if some windows are open
                % just close these windows
                tags = fields(app.hfList);
                Na = numel(tags);
                for ia = 1:Na
                    if ~isempty(app.hfList.(tags{ia}))
                        app.toggleWindow(tags{ia},0)
                    end
                end
                app.nPos=0;
                app.setAcqMode('Stop')
                event.Source.Text = 'close';
            end            
        end

        % Button pushed function: camConnectionLamp
        function camConnectionLampButtonPushed(app, event)
            initState = app.camConnectionLamp.UserData.state; % initial state
            app.setCamConnectionLampState('Wait')
            switch initState
                case {'Connected','Error','Wait'} % then disconnect camera
                    app.disconnectCamera()
                case {'None'} % then connect camera
                    app.connectCamera()
                otherwise
                    error('Wrong input')
            end


        end

        % Value changed function: expTimeEditField
        function expTimeEditFieldValueChanged(app, event)
            app.Cam.setExpTime(app.expTime);
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.updateIL()
            
        end

        % Value changed function: imageDimensionsDropDown
        function imageDimensionsDropDownValueChanged(app, event)
            value = app.imageDimensionsDropDown.Value;
            app.Cam.setImageDimensions(value)
            app.clearImages()
            
        end

        % Button pushed function: timeLapseButton
        function AcquisitionButtonsValueChanged(app, event)
            
            % If no camera connected
            % if strcmpi(app.camConnectionLamp.UserData.state,'None')
            %     if strcmp(event.Source.Tag,'live')
            %         event.Source.Value = 0; % restore the live button to unpushed, in case it was the live button that were pushed.
            %     end
            %     return % then don't do anything
            % end
            
            app.Itf0 = app.Itf0(1); % in case the previous mode was burst
            app.IM = app.IM(1); % in case the previous mode was burst
            app.jim = 1;
            btn = event.Source.Tag;
        
            if strcmp(app.acqMode, 'Live')
                % if the acquisition was running
                switch btn
                    case 'Live' % press the Live/stop button
                        app.setAcqMode('Stop'); % then stop

                    case {'Burst', 'Snap', 'Ref','TimeLapse'}
                        app.setAcqMode(btn)

                end

            elseif strcmp(app.acqMode, 'Stop') 
                % if the acquisition was not running

                app.setCamConnectionLampState('Connected')

                switch btn
                    
                    case {'Live','TimeLapse'}
                        try
                            app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                        catch % happens when camera was not stopped properly for any reason
                            app.writeMessage("Camera not stopped properly")
                            app.Cam.stopAcquisition()
                            app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                        end                            

                    case{'Burst', 'Snap', 'Ref'}
                        try
                            app.Cam.setMode('Fixed', 'Software',"expTime",app.expTime,"Nim",app.snapAvgEditField.Value)
                        catch
                            warning("Problem")
                            app.Cam.stopAcquisition
                            app.Cam.setMode('Continuous', 'Software',"expTime",app.expTime,"Nim",app.liveAvgEditField.Value)
                        end                            

                end

                if strcmp(btn,'Live') && ~any(app.status_hfigList()) % if no window figure is open
                    % open raw
                    app.toggleWindow('raw',true)
                end


                % run
                app.acquire(btn);

            end


        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)

            if strcmp(app.acqMode, 'Live') % if the acquisition was running
                
                app.setAcqMode('Stop'); % then stop
                app.stopAcquisition()

            elseif strcmp(app.acqMode, 'Stop') % if the acquisition was NOT running

                % in case the previous mode was burst:
                %app.Itf0 = app.Itf0(1);
                %app.IM = app.IM(1);
                %app.jim = 1;

                if ~any(app.status_hfigList()) % if no window figure is open
                    % open raw
                    app.toggleWindow('raw',true)
                end
            
                % run
                app.setCamConnectionLampState('Connected')
                app.acquire('Live');

            end
            
        end

        % Value changed function: RefButton
        function RefButtonValueChanged(app, event)
                    
            if strcmp(app.acqMode, 'Live') % if the acquisition was running
                % Just change the mode, and the while loop will see it, operate accordingly, and then continue running.
                app.setAcqMode('Ref')

            elseif strcmp(app.acqMode, 'Stop') % if the acquisition was not running
                % simply acquire a Ref image
                app.acquire('Ref');

            end


        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
        
            if strcmp(app.acqMode, 'Live') % if the acquisition was running
                % Just change the mode, and the while loop will see it, operate accordingly, and then continue running.
                app.setAcqMode('Snap')

            elseif strcmp(app.acqMode, 'Stop') % if the acquisition was not running
                % in case the previous mode was burst:
                %app.Itf0 = app.Itf0(1);
                %app.IM = app.IM(1);
                %app.jim = 1;

                if ~any(app.status_hfigList()) % if no window figure is open
                    % open raw
                    app.toggleWindow('raw',true)
                end

                % run
                app.acquire('Snap');

            end
            
        end

        % Button pushed function: burstButton
        function burstButtonPushed(app, event)
        
            if strcmp(app.acqMode, 'Live') % if the acquisition was running
               app.setAcqMode('Burst')

            elseif strcmp(app.acqMode, 'Stop')  % if the acquisition was not running

                if ~any(app.status_hfigList()) % if no window figure is open
                    % open raw
                    app.toggleWindow('raw',true)
                end

                % run
                app.acquire('Burst');

            end
            
        end

        % Value changed function: HRButton
        function HRButtonValueChanged(app, event)
            if app.HRButton.Value
                app.setButtonIcon('HR','on')
            else
                app.setButtonIcon('HR','off')
            end
        end

        % Button pushed function: saveFolderButton
        function saveFolderButtonPushed(app, event)
            app.UIFigure.Visible='off' ;
            val = uigetdir();
            if val~=0
                app.saveFolderEditField.Value = val;
            end
            app.UIFigure.Visible='on';

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveCurrentImage()
        end

        % Button pushed function: HmirrorButton, VmirrorButton, rot90Button
        function applySymmetryPushed(app, event)
            app.D4Transform =  [app.D4Transform event.Source.Tag];
        end

        % Button pushed function: cancelSymmetryButton
        function cancelSymmetryButtonPushed(app, event)
            app.D4Transform = '';
        end

        % Button pushed function: cropButton
        function cropButtonPushed(app, event)
            app.cropDefinition()
        end

        % Button pushed function: loadFileButton
        function loadFileButtonPushed(app, event)
            %app.UIFigure.Visible='off' ;
            folder = app.saveFolderEditField.Value;
            if isempty(folder)
                folder = pwd;
            end
            app.Itf0 = importItfRef(folder,app.MI,'selection','manual');
            if ~isempty(app.Itf0.Ref)
                app.ref = app.Itf0.Ref.Itf;
            else
                app.clearRef()
            end
            %app.UIFigure.Visible='on';
            app.updateImages()
        end

        % Callback function: jimEditField, leftButton, rightButton
        function moveButtonPushed(app, event)
            app.moveImage(event.Source.Tag)
        end

        % Button pushed function: xyzButton
        function piezoButtonPushed(app, event)

            if isempty(app.piezoWindow) ||  ~isvalid(app.piezoWindow)
                app.piezoWindow  = piezoControlWindow();
            end

        end

        % Button pushed function: PhaseLABButton
        function PhaseLABButtonPushed(app, event)
            if isempty(app.IM(1).OPD)
                app.Itf0.figure()
            else
                app.IM.figure()
            end        
        end

        % Button pushed function: Button
        function lambdaButtonPushed(app, event)
            if isempty(app.lambdaWindow) ||  ~isvalid(app.lambdaWindow)
                app.IL = Monochromator(app.IL);
                app.lambdaWindow  = lambdaControlWindow(app);
            end
            
        end

        % Button pushed function: configButton
        function configButtonPushed(app, event)
            configControlWindow(app)
        end

        % Value changed function: RelayLensCheckBox
        function RelayLensCheckBoxValueChanged(app, event)
            value = app.RelayLensCheckBox.Value;
            if value
                app.RelayLensEditField.Enable = 'on';
                if isempty(app.RelayLensEditField.Value)
                    app.RelayLensEditField.Value = 1;
                end
                app.MI.CGcam.RL = RelayLens(app.RelayLensEditField.Value);
            else
                app.RelayLensEditField.Enable = 'off';
                app.MI.CGcam.removeRL();
            end
        end

        % Value changed function: cameraDropDown
        function cameraDropDownValueChanged(app, event)
            app.updateCamera()
        end

        % Button pushed function: shutButton
        function shutButtonPushed(app, event)
            app.shutterWindow = shutterControlWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PhaseLAB - Live imaging';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'favicon.png');
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.GridLayout_setup = uigridlayout(app.GridLayout_main);
            app.MobjEditFieldLabel = uilabel(app.GridLayout_setup);
            app.MobjEditFieldLabel.Text = 'Mobj';
            app.MobjEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.MobjEditField.Limits = [1 Inf];
            app.MobjEditField.RoundFractionalValues = 'on';
            app.MobjEditField.ValueDisplayFormat = '%6.0f';
            app.MobjEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.MobjEditField.Value = 60;
            app.lambdaLabel = uilabel(app.GridLayout_setup);
            app.lambdaLabel.Text = 'λ';
            app.lambdaEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.lambdaEditField.Limits = [100 Inf];
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Value = 625;
            app.objBrandDropDown = uidropdown(app.GridLayout_setup);
            app.objBrandDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon'};
            app.objBrandDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.objBrandDropDown.Value = 'Olympus';
            app.microscopeTLDropDownLabel = uilabel(app.GridLayout_setup);
            app.microscopeTLDropDownLabel.Text = 'microscope/TL';
            app.microscopeTLDropDown = uidropdown(app.GridLayout_setup);
            app.microscopeTLDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon', '165', '180', '200'};
            app.microscopeTLDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.microscopeTLDropDown.Value = 'Olympus';
            app.cameraDropDownLabel = uilabel(app.GridLayout_setup);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.GridLayout_setup);
            app.cameraDropDown.Items = {'Simu', 'Zyla5.5', 'Orca Flash', 'Panda', 'Pixelink', '2-color', 'PHX050S-PC', 'sC8-830', 'sC8-944'};
            app.cameraDropDown.ValueChangedFcn = createCallbackFcn(app, @cameraDropDownValueChanged, true);
            app.cameraDropDown.Value = 'Simu';
            app.CrossGratingDropDownLabel = uilabel(app.GridLayout_setup);
            app.CrossGratingDropDownLabel.Text = 'Grating';
            app.CrossGratingDropDown = uidropdown(app.GridLayout_setup);
            app.CrossGratingDropDown.Items = {'F02', 'F03', 'F03b', 'F04', 'P02', 'P04', 'sC8', 'Sid4Element', '39 µm', '35.1 µm', '22.5 µm', '13 µm'};
            app.CrossGratingDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CrossGratingDropDown.Value = 'F02';
            app.RelayLensEditFieldLabel = uilabel(app.GridLayout_setup);
            app.RelayLensEditFieldLabel.Enable = 'off';
            app.RelayLensEditFieldLabel.Text = 'Relay Lens';
            app.RelayLensEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.RelayLensEditField.Limits = [0 Inf];
            app.RelayLensEditField.ValueDisplayFormat = '%2.2f';
            app.RelayLensEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.RelayLensEditField.Enable = 'off';
            app.RelayLensEditField.Value = 1;
            app.nmLabel = uilabel(app.GridLayout_setup);
            app.nmLabel.Text = 'nm';
            app.CGdistanceEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.CGdistanceEditField.ValueDisplayFormat = '%2.3f';
            app.CGdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CGdistanceEditField.Value = 0.8;
            app.mmLabel = uilabel(app.GridLayout_setup);
            app.mmLabel.Text = 'mm';
            app.camConnectionLamp = uibutton(app.GridLayout_setup, 'push');
            app.camConnectionLamp.ButtonPushedFcn = createCallbackFcn(app, @camConnectionLampButtonPushed, true);
            app.camConnectionLamp.Text = '';
            app.camRunningLamp = uilamp(app.GridLayout_setup);
            app.RelayLensCheckBox = uicheckbox(app.GridLayout_setup);
            app.RelayLensCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelayLensCheckBoxValueChanged, true);
            app.RelayLensCheckBox.Text = '';
            app.Image = uiimage(app.GridLayout_main);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'banniere.svg');
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.GridLayout_Imaging_controls = uigridlayout(app.GridLayout);
            app.GridLayout_specialControls = uigridlayout(app.GridLayout_Imaging_controls);
            app.timeLapseButton = uibutton(app.GridLayout_specialControls, 'push');
            app.timeLapseButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionButtonsValueChanged, true);
            app.timeLapseButton.Tag = 'TimeLapse';
            app.timeLapseButton.Icon = fullfile(pathToMLAPP, 'icons', 'MOVIE.svg');
            app.timeLapseButton.Tooltip = {'acquire time lapse'};
            app.timeLapseButton.Text = '';
            app.burstButton = uibutton(app.GridLayout_specialControls, 'push');
            app.burstButton.ButtonPushedFcn = createCallbackFcn(app, @burstButtonPushed, true);
            app.burstButton.Tag = 'Burst';
            app.burstButton.Icon = fullfile(pathToMLAPP, 'icons', 'BURST.svg');
            app.burstButton.Tooltip = {'acquire a burst'};
            app.burstButton.Text = '';
            app.jimEditField = uieditfield(app.GridLayout_specialControls, 'numeric');
            app.jimEditField.Limits = [1 Inf];
            app.jimEditField.RoundFractionalValues = 'on';
            app.jimEditField.ValueDisplayFormat = '%d';
            app.jimEditField.ValueChangedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.jimEditField.Tag = 'none';
            app.jimEditField.Enable = 'off';
            app.jimEditField.Value = 1;
            app.leftButton = uibutton(app.GridLayout_specialControls, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.leftButton.Tag = 'left';
            app.leftButton.Icon = fullfile(pathToMLAPP, 'icons', 'left.svg');
            app.leftButton.Enable = 'off';
            app.leftButton.Tooltip = {'acqiure time lapse'};
            app.leftButton.Text = '';
            app.rightButton = uibutton(app.GridLayout_specialControls, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @moveButtonPushed, true);
            app.rightButton.Tag = 'right';
            app.rightButton.Icon = fullfile(pathToMLAPP, 'icons', 'right.svg');
            app.rightButton.Enable = 'off';
            app.rightButton.Tooltip = {'acqiure time lapse'};
            app.rightButton.Text = '';
            app.snapAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.snapAvgEditField.Limits = [1 Inf];
            app.snapAvgEditField.RoundFractionalValues = 'on';
            app.snapAvgEditField.ValueDisplayFormat = '%11.0f';
            app.snapAvgEditField.Value = 30;
            app.refAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.refAvgEditField.Limits = [1 Inf];
            app.refAvgEditField.RoundFractionalValues = 'on';
            app.refAvgEditField.ValueDisplayFormat = '%11.0f';
            app.refAvgEditField.Value = 30;
            app.liveAvgEditField = uieditfield(app.GridLayout_Imaging_controls, 'numeric');
            app.liveAvgEditField.Limits = [1 Inf];
            app.liveAvgEditField.RoundFractionalValues = 'on';
            app.liveAvgEditField.ValueDisplayFormat = '%11.0f';
            app.liveAvgEditField.Value = 1;
            app.SnapButton = uibutton(app.GridLayout_Imaging_controls, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Tag = 'Snap';
            app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'Snap_on.svg');
            app.SnapButton.Text = '';
            app.RefButton = uibutton(app.GridLayout_Imaging_controls, 'state');
            app.RefButton.ValueChangedFcn = createCallbackFcn(app, @RefButtonValueChanged, true);
            app.RefButton.Tag = 'Ref';
            app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'Ref_off.svg');
            app.RefButton.Text = '';
            app.LiveButton = uibutton(app.GridLayout_Imaging_controls, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Tag = 'Live';
            app.LiveButton.Icon = fullfile(pathToMLAPP, 'icons', 'Live_off.svg');
            app.LiveButton.Text = '';
            app.GridLayout_Save = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout_Save, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'Save_off.svg');
            app.SaveButton.Text = '';
            app.GridLayout_savePrefix = uigridlayout(app.GridLayout_Save);
            app.savedFileNameEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.savedFileNameEditField.Editable = 'off';
            app.autosaveCheckBox = uicheckbox(app.GridLayout_savePrefix);
            app.autosaveCheckBox.Text = 'autosave';
            app.savePrefixEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.savePrefixEditField.Value = 'ITF';
            app.saveFolderButton = uibutton(app.GridLayout_savePrefix, 'push');
            app.saveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @saveFolderButtonPushed, true);
            app.saveFolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder.svg');
            app.saveFolderButton.Text = '';
            app.saveFolderEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.saveFolderEditField.Editable = 'off';
            app.saveLamp = uilamp(app.GridLayout_savePrefix);
            app.loadFileButton = uibutton(app.GridLayout_Save, 'push');
            app.loadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFileButtonPushed, true);
            app.loadFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.svg');
            app.loadFileButton.Tooltip = {'import image'};
            app.loadFileButton.Text = '';
            app.GridLayout_Imaging = uigridlayout(app.GridLayout);
            app.GridLayout_tools = uigridlayout(app.GridLayout_Imaging);
            app.VmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.VmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.VmirrorButton.Tag = 'V';
            app.VmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorV.svg');
            app.VmirrorButton.Tooltip = {'vertical mirror'};
            app.VmirrorButton.Text = '';
            app.HmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.HmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.HmirrorButton.Tag = 'H';
            app.HmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorH.svg');
            app.HmirrorButton.Tooltip = {'Horizontal mirror'};
            app.HmirrorButton.Text = '';
            app.rot90Button = uibutton(app.GridLayout_tools, 'push');
            app.rot90Button.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.rot90Button.Tag = 'R';
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'icons', 'rot90.svg');
            app.rot90Button.Tooltip = {'Clockwise rotation'};
            app.rot90Button.Text = '';
            app.cancelSymmetryButton = uibutton(app.GridLayout_tools, 'push');
            app.cancelSymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @cancelSymmetryButtonPushed, true);
            app.cancelSymmetryButton.Icon = fullfile(pathToMLAPP, 'icons', 'erase0.svg');
            app.cancelSymmetryButton.Tooltip = {'Back to native orientation'};
            app.cancelSymmetryButton.Text = '';
            app.flattenButton = uibutton(app.GridLayout_tools, 'state');
            app.flattenButton.Tooltip = {'Flatten'};
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'icons', 'flatten.svg');
            app.flattenButton.Text = '';
            app.tauLabel = uilabel(app.GridLayout_tools);
            app.tauLabel.Text = 'τ';
            app.expTimeEditField = uieditfield(app.GridLayout_tools, 'numeric');
            app.expTimeEditField.Limits = [1 Inf];
            app.expTimeEditField.ValueChangedFcn = createCallbackFcn(app, @expTimeEditFieldValueChanged, true);
            app.expTimeEditField.Value = 80;
            app.msLabel = uilabel(app.GridLayout_tools);
            app.msLabel.Text = 'ms';
            app.imageDimensionsDropDown = uidropdown(app.GridLayout_tools);
            app.imageDimensionsDropDown.Items = {'2560 x 2160', '2064 x 2048', '1920 x 1080', '1392 x 1040', '528 x 512', '240 x 256', '144 x 128', '2560 x 304', 'custom'};
            app.imageDimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @imageDimensionsDropDownValueChanged, true);
            app.imageDimensionsDropDown.Value = '2560 x 2160';
            app.pxLabel = uilabel(app.GridLayout_tools);
            app.pxLabel.Text = 'px';
            app.cropButton = uibutton(app.GridLayout_tools, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.cropButton.Tooltip = {'Crop the field of view'};
            app.cropButton.Text = '';
            app.HRButton = uibutton(app.GridLayout_tools, 'state');
            app.HRButton.ValueChangedFcn = createCallbackFcn(app, @HRButtonValueChanged, true);
            app.HRButton.Icon = fullfile(pathToMLAPP, 'icons', 'HR_off.svg');
            app.HRButton.Text = '';
            app.GridLayout_extraFigureButtons = uigridlayout(app.GridLayout_tools);
            app.FTButton = uibutton(app.GridLayout_extraFigureButtons, 'state');
            app.FTButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.FTButton.Tag = 'FT';
            app.FTButton.Text = 'FT';
            app.balance4channelsButton = uibutton(app.GridLayout_extraFigureButtons, 'state');
            app.balance4channelsButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.balance4channelsButton.Tag = 'balance4channels';
            app.balance4channelsButton.Icon = fullfile(pathToMLAPP, 'icons', 'balance4channels_off.svg');
            app.balance4channelsButton.Text = '';
            app.balance2channelsButton = uibutton(app.GridLayout_extraFigureButtons, 'state');
            app.balance2channelsButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.balance2channelsButton.Tag = 'balance2channels';
            app.balance2channelsButton.Icon = fullfile(pathToMLAPP, 'icons', 'balance2channels_off.svg');
            app.balance2channelsButton.Text = '';
            app.previewButton = uibutton(app.GridLayout_extraFigureButtons, 'state');
            app.previewButton.Tag = 'preview';
            app.previewButton.Icon = fullfile(pathToMLAPP, 'icons', 'preview_off.png');
            app.previewButton.Text = '';
            app.GridLayout_foot = uigridlayout(app.GridLayout_main);
            app.GridLayout_windowsButtons = uigridlayout(app.GridLayout_foot);
            app.rawButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.rawButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.rawButton.Tag = 'raw';
            app.rawButton.Text = 'raw';
            app.TButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.TButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.TButton.Tag = 'T';
            app.TButton.Text = 'T';
            app.WButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.WButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.WButton.Tag = 'W';
            app.WButton.Text = 'W';
            app.dWyButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWyButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWyButton.Tag = 'dWy';
            app.dWyButton.Text = '∇yW';
            app.dWxButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWxButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWxButton.Tag = 'dWx';
            app.dWxButton.Text = '∇xW';
            app.dWButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWButton.Tag = 'dW';
            app.dWButton.Text = '|∇W|';
            app.massButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.massButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.massButton.Tag = 'mass';
            app.massButton.Text = 'mass';
            app.PDCMButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.PDCMButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.PDCMButton.Tag = 'PDCM';
            app.PDCMButton.Text = 'PDCM';
            app.RytovButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.RytovButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.RytovButton.Tag = 'Rytov';
            app.RytovButton.Text = 'Rytov';
            app.polarButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.polarButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.polarButton.Tag = 'polar';
            app.polarButton.Text = 'polar';
            app.closeButton = uibutton(app.GridLayout_windowsButtons, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonValueChanged, true);
            app.closeButton.Text = 'close';
            app.retMaxEditField = uieditfield(app.GridLayout_windowsButtons, 'numeric');
            app.messageEditField = uieditfield(app.GridLayout_windowsButtons, 'text');
            app.messageEditField.Editable = 'off';
            app.GridLayout_panelButtons = uigridlayout(app.GridLayout_foot);
            app.PhaseLABButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.PhaseLABButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseLABButtonPushed, true);
            app.PhaseLABButton.Text = 'PhaseLAB';
            app.Button = uibutton(app.GridLayout_panelButtons, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @lambdaButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'rainbow.png');
            app.Button.Text = 'λ';
            app.xyzButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.xyzButton.ButtonPushedFcn = createCallbackFcn(app, @piezoButtonPushed, true);
            app.xyzButton.Icon = fullfile(pathToMLAPP, 'icons', 'xyz.svg');
            app.xyzButton.Text = 'xyz';
            app.configButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.configButton.ButtonPushedFcn = createCallbackFcn(app, @configButtonPushed, true);
            app.configButton.Icon = fullfile(pathToMLAPP, 'icons', 'params2.svg');
            app.configButton.Text = 'config';
            app.shutButton = uibutton(app.GridLayout_panelButtons, 'push');
            app.shutButton.ButtonPushedFcn = createCallbackFcn(app, @shutButtonPushed, true);
            app.shutButton.Icon = fullfile(pathToMLAPP, 'icons', 'diaphragm.svg');
            app.shutButton.Text = 'shut.';
            app.GridLayout_frameRate = uigridlayout(app.GridLayout_foot);
            app.HzEditFieldLabel = uilabel(app.GridLayout_frameRate);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.GridLayout_frameRate, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.ValueDisplayFormat = '%4.2g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIVE

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end