classdef imageWindowLive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout_main           matlab.ui.container.GridLayout
        GridLayout_controls       matlab.ui.container.GridLayout
        GridLayout_extraTools     matlab.ui.container.GridLayout
        edgeDirectionButtonGroup  matlab.ui.container.ButtonGroup
        YButton                   matlab.ui.control.RadioButton
        XButton                   matlab.ui.control.RadioButton
        edgeWidthEditField        matlab.ui.control.NumericEditField
        widthEditFieldLabel       matlab.ui.control.Label
        maxEditField              matlab.ui.control.NumericEditField
        minEditField              matlab.ui.control.NumericEditField
        invertCheckBox            matlab.ui.control.CheckBox
        holdCheckBox              matlab.ui.control.CheckBox
        GridLayout_tools          matlab.ui.container.GridLayout
        edgeProfile_Button        matlab.ui.control.StateButton
        cancel_Button             matlab.ui.control.StateButton
        FFT_Button                matlab.ui.control.StateButton
        crosscut_Button           matlab.ui.control.StateButton
        histo_Button              matlab.ui.control.StateButton
        colormapDropDown          matlab.ui.control.DropDown
        UIAxesLeft                matlab.ui.control.UIAxes
        UIAxesRight               matlab.ui.control.UIAxes
    end

    
    properties(SetAccess = private)
        type
    end

    properties (Dependent)
        hf
        ha
        ha2
        hi
        hi2
        extraParameters
    end

    properties (Access = private)
        color1 = [0.5 0.5 0.5]    % color activated button
        color0 = [0.96,0.96,0.96] % color released button
    end 

    properties (Constant,Access = private)
        imTypes = {'raw','T','W','dW','dWx','dWy','mass','PDCM','Rytov','2-canals','polar','FT','balance'}
        im2Types = {'cancel','histo','FFT','crosscut','edgeProfile'};
    end

    methods (Access = private)


        function displayRightAxis(app,val)
            arguments
                app
                val {mustBeMember(val,{'on','off'})}
            end
            
            % if axis already on or already off, don't do anything
            if isvalid(app.UIAxesRight) && strcmp(val, 'on') ...
                || ~isvalid(app.UIAxesRight) && strcmp(val, 'off')
                return
            end

            switch val
                case 'off'
                    app.GridLayout_main.ColumnWidth = {'1x',0};
                    app.UIFigure.Position(3) = app.UIFigure.Position(3)/2;
                    app.UIAxesRight.delete() 
                    % release all the buttons:
                    app.histo_Button.Value = 0;
                    app.FFT_Button.Value = 0;
                    app.crosscut_Button.Value = 0;
                    app.edgeProfile_Button.Value = 0;

                    app.removeAllPolylines(app.ha)

                    app.GridLayout_extraTools.Visible = "off";

                    app.histo_Button.BackgroundColor = app.color0;
                    app.FFT_Button.BackgroundColor = app.color0;
                    app.crosscut_Button.BackgroundColor = app.color0;
                    app.edgeProfile_Button.BackgroundColor = app.color0;
                otherwise % 'on'
                    app.UIAxesRight = uiaxes(app.GridLayout_main);
                    app.UIAxesRight.Layout.Row = 2;
                    app.UIAxesRight.Layout.Column = 2;

                    app.GridLayout_main.ColumnWidth = {'1x','1x'};
                    app.UIFigure.Position(3) = app.UIFigure.Position(3)*2;

                    app.UIAxesRight.XColor = 'w';
                    app.UIAxesRight.YColor = 'w';
                    set(app.UIAxesRight,'YDir','normal')

            end
            app.GridLayout_main.Visible = 'on';
            
        end
        
        function updateColorMap(app)
            cmap = str2func(app.colormapDropDown.Value);
            if app.invertCheckBox.Value
                colormap(app.UIAxesLeft, flipud(cmap()))
            else
                colormap(app.UIAxesLeft, cmap())
            end
            
        end

        function updateClim(app)
            if app.holdCheckBox.Value
                clim(app.UIAxesLeft,app.UIAxesLeft.CLim) % Fix the clim to the current value.
                if isempty(app.minEditField.Value)
    %                uic_min.String = sprintf('%.3g',(hax.CLim(1)));
                    app.minEditField.Value = format3sig(app.UIAxesLeft.CLim(1));
                else
                    app.UIAxesLeft.CLim(1) = str2double(app.minEditField.Value);
                end
                if isempty(app.maxEditField.Value)
    %                uic_max.String = sprintf('%.3g',(hax.CLim(2)));
                    app.maxEditField.Value = format3sig(app.UIAxesLeft.CLim(2));
                else
                    app.UIAxesLeft.CLim(2) = str2double(app.maxEditField.Value);
                end
    %            uic_max.String = num2str(hax.CLim(2));
            else
                app.UIAxesLeft.CLimMode = 'auto';
                app.minEditField.Value = '';
                app.maxEditField.Value = '';
            end
    
        end


        function activateButton(app,val0)
            arguments
                app
                val0 char {mustBeMember(val0,{'cancel','histo','FFT','crosscut','edgeProfile'})}
            end

            valList = {'cancel','histo','FFT','crosscut','edgeProfile'};

            for val = valList
                if ~strcmp(val{1},val0) % other button than the activated one
                    app.turnButton(val{1},0)
                else
                    app.turnButton(val{1},1)
                end
                
            end
        end

        
        function turnButton(app,val,state)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.([val '_Button']).Value = state;
            app.([val '_Button']).BackgroundColor = app.("color"+state);

            if strcmp(val,'cancel')
                app.cancel_Button.Icon = fullfile(pathToMLAPP, 'icons', "erase"+state+".svg");
            elseif strcmp(val,'FFT')
                app.FFT_Button.FontColor = state + [0 0 0];
            elseif strcmp(val,'histo')
                app.histo_Button.Icon = fullfile(pathToMLAPP, 'icons', "histo"+state+".svg");
            elseif strcmp(val,'crosscut')
                app.crosscut_Button.Icon = fullfile(pathToMLAPP, 'icons', "Pathcrosscut"+state+".svg");
            elseif strcmp(val,'edgeProfile')
                app.edgeProfile_Button.Icon = fullfile(pathToMLAPP, 'icons', "edgeprofile"+state+".svg");
            end

        end
    end

    methods

        function val = getRightImageType(app)
            for typ = app.im2Types
                if app.(typ{1}+"_Button").Value
                    val = typ{1};
                    return
                end
            end
        end

        function set.type(app, val)
            arguments
                app
                val
            end
            if ismember(val,app.imTypes)
                app.type = val;
            else
                 error('not a proper image type')
            end
        end

        function val = get.hf(app)
            val = app.UIFigure;
        end
        function val = get.ha(app)
            val = app.UIAxesLeft;
        end
        function val = get.ha2(app)
            val = app.UIAxesRight;
        end
        function val = get.hi(app)
            val = findall(app.ha, 'Type', 'image');
        end
        function val = get.hi2(app)
            val = findall(app.ha2, 'Type', 'image');
            if isempty(val)
                val = findall(app.ha2, 'Type', 'line');
            end
        end

        function val = get.extraParameters(app)
            % {'X' or 'Y' , edge width}
            crosscutDirection = lower(app.edgeDirectionButtonGroup.SelectedObject.Text);
            edgeWidth = app.edgeWidthEditField.Value;
            val = {crosscutDirection, edgeWidth};
        end


    end

    methods (Static, Access = private)
        
        function s = format3sig(x)
    
            if x == 0
                s = '0';
                return
            end
    
            n = floor(log10(abs(x)));
            ndec = max(0, 3-n-1);
    
            s = sprintf(['%.' num2str(ndec) 'f'],x);
        end
        function removeAllPolylines(ax)
        %REMOVEALLPOLYLINES Supprime toutes les polylines ROI d'un axe.
        %
        %   removeAllPolylines(ax)
        
            arguments
                ax {mustBeA(ax, ["matlab.graphics.axis.Axes","matlab.ui.control.UIAxes"])}
            end
        
            h = findall(ax);
        
            isPoly = arrayfun(@(x) isa(x, 'images.roi.Polyline'), h);
            delete(h(isPoly));
            isLine = arrayfun(@(x) isa(x, 'images.roi.Line'), h);
            delete(h(isLine));
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tag, opt)
            arguments
                app
                tag char = 'W'
                opt.Nx (1,1) double = 1024
                opt.Ny (1,1) double = 1024
                opt.Position = 0 % 0, 1, or 2
                opt.enableClose logical = true
            end

            app.type = tag;

            app.displayRightAxis('off')
            app.cancel_Button.BackgroundColor = app.color1;

            %% Set the position of the figure
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            % Define the window width as one-third of the screen width
            figWidth = screenWidth / 3;
            
            % Define the window height (you can adjust this value as needed)
            figHeight = figWidth;  % Example: 500 pixels tall
            
            % Calculate y position to center the window vertically
            yPos = (screenHeight - figHeight) / 2;
            
            yPos = yPos/2; % otherwise may overlap with the app window
            
            % Set x position to 0 (aligned to the left edge of the screen)
            xPos = mod(opt.Position,3)*figWidth;

            app.UIFigure.Position = [xPos yPos figWidth figWidth];

            app.updateColorMap()

            %
            app.ha.Children = imagesc(zeros(opt.Ny, opt.Nx), 'Parent', app.UIAxesLeft);


            %%
            app.UIAxesLeft.XColor = 'w';
            app.UIAxesLeft.YColor = 'w';
            set(app.UIAxesLeft,'YDir','normal')
            axis(app.UIAxesLeft, 'image');


            cbLeft = colorbar(app.UIAxesLeft,'Color','w');

               %% Personalize the figure as a function of the tag
            switch tag
                case 'W'
                    cbLeft.Label.String = 'nm';
                    colormap(app.UIAxesLeft, sepia);
                    app.colormapDropDown.Value = 'sepia';
                otherwise
                    cbLeft.Label.String = '';
                    colormap(app.UIAxesLeft, gray);
                    app.colormapDropDown.Value = 'gray';
            
            end

            if ~opt.enableClose
                app.UIFigure.CloseRequestFcn = @(src, event) [];
            end
    

        end

        % Value changed function: FFT_Button, cancel_Button, 
        % ...and 3 other components
        function ButtonValueChanged(app, event)
            val = event.Value;
            if val == 0 || strcmp(event.Source.Tag,'cancel') % button was on, and switched off, or press cancel
                app.activateButton('cancel')
                app.displayRightAxis('off')
                return
            end

            % else button was off, and switched on:
            app.displayRightAxis('on')
            switch event.Source.Tag
                case 'histo'
                    app.activateButton('histo')
                    plot(app.UIAxesRight,1,1)
                    app.UIAxesRight.YDir = 'normal';
                case 'FFT'
                    app.activateButton('FFT')
                    imagesc(app.UIAxesRight,zeros(size(app.hi.CData)))
                    axis(app.UIAxesRight,'image') % fit the axes to the image
                    app.UIAxesRight.YDir = 'normal';
                case 'crosscut'
                    app.activateButton('crosscut')
                    plot(app.UIAxesRight,1,1)
                    app.UIAxesRight.YDir = 'normal';
                case 'edgeProfile'
                    app.activateButton('edgeProfile')
                    plot(app.UIAxesRight,1,1)
                    app.UIAxesRight.YDir = 'normal';
                    app.GridLayout_extraTools.Visible = "on";

            end


        end

        % Value changed function: colormapDropDown, invertCheckBox
        function colormapDropDownValueChanged(app, event)
            app.updateColorMap()
            
        end

        % Value changed function: holdCheckBox
        function holdCheckBoxValueChanged(app, event)
            value = app.holdCheckBox.Value;
            if value
                valMin = min(app.hi.CData(:));
                valMax = max(app.hi.CData(:));
                app.minEditField.Value = valMin;
                app.maxEditField.Value = valMax;
                app.minEditField.Enable = true;
                app.maxEditField.Enable = true;
                app.ha.CLim = [valMin valMax];
            else
                app.minEditField.Value = [];
                app.maxEditField.Value = [];
                app.minEditField.Enable = false;
                app.maxEditField.Enable = false;
                app.ha.CLimMode = 'auto';
            end
        end

        % Value changed function: maxEditField, minEditField
        function minmaxEditFieldValueChanged(app, event)
 
            valMin = app.minEditField.Value;
            valMax = app.maxEditField.Value;
            app.ha.CLim = [valMin valMax];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Live window';
            app.UIFigure.Tag = 'liveWindow';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.UIAxesRight = uiaxes(app.GridLayout_main);
            title(app.UIAxesRight, 's')
            xlabel(app.UIAxesRight, 'X')
            ylabel(app.UIAxesRight, 'Y')
            zlabel(app.UIAxesRight, 'Z')
            app.UIAxesRight.Tag = 'RightAxis';
            app.UIAxesLeft = uiaxes(app.GridLayout_main);
            xlabel(app.UIAxesLeft, 'X')
            ylabel(app.UIAxesLeft, 'Y')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesLeft.Tag = 'LeftAxis';
            app.GridLayout_controls = uigridlayout(app.GridLayout_main);
            app.colormapDropDown = uidropdown(app.GridLayout_controls);
            app.colormapDropDown.Items = {'gray', 'sepia', 'parula', 'Pradeep'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'gray';
            app.GridLayout_tools = uigridlayout(app.GridLayout_controls);
            app.histo_Button = uibutton(app.GridLayout_tools, 'state');
            app.histo_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.histo_Button.Tag = 'histo';
            app.histo_Button.Icon = fullfile(pathToMLAPP, 'icons', 'histo.svg');
            app.histo_Button.Text = '';
            app.crosscut_Button = uibutton(app.GridLayout_tools, 'state');
            app.crosscut_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.crosscut_Button.Tag = 'crosscut';
            app.crosscut_Button.Icon = fullfile(pathToMLAPP, 'icons', 'Pathcrosscut0.svg');
            app.crosscut_Button.Text = '';
            app.FFT_Button = uibutton(app.GridLayout_tools, 'state');
            app.FFT_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.FFT_Button.Tag = 'FFT';
            app.FFT_Button.Text = 'FFT';
            app.cancel_Button = uibutton(app.GridLayout_tools, 'state');
            app.cancel_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.cancel_Button.Tag = 'cancel';
            app.cancel_Button.Icon = fullfile(pathToMLAPP, 'icons', 'erase1.svg');
            app.cancel_Button.Text = '';
            app.cancel_Button.Value = true;
            app.edgeProfile_Button = uibutton(app.GridLayout_tools, 'state');
            app.edgeProfile_Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.edgeProfile_Button.Tag = 'edgeProfile';
            app.edgeProfile_Button.Icon = fullfile(pathToMLAPP, 'icons', 'edgeProfile.svg');
            app.edgeProfile_Button.Text = '';
            app.holdCheckBox = uicheckbox(app.GridLayout_controls);
            app.holdCheckBox.ValueChangedFcn = createCallbackFcn(app, @holdCheckBoxValueChanged, true);
            app.holdCheckBox.Text = 'hold';
            app.invertCheckBox = uicheckbox(app.GridLayout_controls);
            app.invertCheckBox.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.invertCheckBox.Text = 'invert';
            app.minEditField = uieditfield(app.GridLayout_controls, 'numeric');
            app.minEditField.ValueDisplayFormat = '%.3g';
            app.minEditField.AllowEmpty = 'on';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minmaxEditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.minEditField.Value = [];
            app.maxEditField = uieditfield(app.GridLayout_controls, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%.3g';
            app.maxEditField.AllowEmpty = 'on';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @minmaxEditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.maxEditField.Value = [];
            app.GridLayout_extraTools = uigridlayout(app.GridLayout_controls);
            app.widthEditFieldLabel = uilabel(app.GridLayout_extraTools);
            app.widthEditFieldLabel.Text = 'width:';
            app.edgeWidthEditField = uieditfield(app.GridLayout_extraTools, 'numeric');
            app.edgeWidthEditField.Value = 50;
            app.edgeDirectionButtonGroup = uibuttongroup(app.GridLayout_extraTools);
            app.XButton = uiradiobutton(app.edgeDirectionButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.edgeDirectionButtonGroup);
            app.YButton.Text = 'Y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageWindowLive(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end