classdef lambdaControlWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
        GridLayout_buttons        matlab.ui.container.GridLayout
        prevNameButton            matlab.ui.control.Button
        lambdaListBox             matlab.ui.control.ListBox
        saveNameButton            matlab.ui.control.Button
        eraseButton               matlab.ui.control.Button
        nextNameButton            matlab.ui.control.Button
        saveNameEditField         matlab.ui.control.EditField
        GridLayout_lambdaDisplay  matlab.ui.container.GridLayout
        widthLabel                matlab.ui.control.Label
        nmLabel_2                 matlab.ui.control.Label
        widthEditField            matlab.ui.control.NumericEditField
        nmLabel                   matlab.ui.control.Label
        Lamp                      matlab.ui.control.Button
        lambdaEditField           matlab.ui.control.NumericEditField
        GridLayout_Navigator      matlab.ui.container.GridLayout
        GridLayout_step           matlab.ui.container.GridLayout
        nmLabel2                  matlab.ui.control.Label
        stepEditField             matlab.ui.control.NumericEditField
        rightButton               matlab.ui.control.Button
        leftButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        MO (1,1) Monochromator = Monochromator(550) % Monochromator to be controlled
        appLive
        nameList struct = struct()  % ASOLUTE values of the positions, as stored in the stage object
    end

    properties(Dependent)
        lambda (1,1) double  % RELATVE values,as displayed in the app EditFields
    end

    properties (Constant)
        stateList = {'on','off','wait','running','error',''}
    end

    properties(Access = private)
        state = ''
        
        lampColor_on = [0.60    0.77    0.60];   % green
        lampColor_off = [0.9 0.9 0.9];  % light gray
        lampColor_wait = [0.7 0.5,0.3]; % orange
        lampColor_error = [0.8    0.40    0.40];   % red
        lampColor_running = [0.7 0.5,0.3]; % orange
    end


    methods (Access = private)
        
        function setState(app,state)
            arguments
                app
                state % mustBeMember of app.stateList
            end
            if ~ismember(state,app.stateList)
                error('wrong input for the state property')
            else
                app.state = state;
            end
            app.Lamp.BackgroundColor = app.("lampColor_"+state);
            app.state = state;
        end

    end

    methods
        
        function val = get.lambda(app)
            val = app.lambdaEditField.Value*1e-9;            
        end

        function set.lambda(app,val)
            arguments
                app
                val (1,1) double
            end
            if strcmp(app.state,'on')
                app.setState('running')

                app.MO.moveTo(val);
                try
                app.lambdaEditField.Value = lambda2nm(app.MO.lambda);
                app.widthEditField.Value = lambda2nm(app.MO.lambdaWidth);
                catch
                    pause(1)
                end
                
                app.appLive.lambdaEditField.Value = lambda2nm(app.MO.lambda);
                app.setState('on')
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appLive)
            arguments
                app
                appLive = struct() % defaut value
            end
            app.appLive = appLive;

            if isstruct(appLive)
                appLive.IL = Illumination(625e-9);
                app.appLive.lambdaEditField.Value = 625;
            elseif ~isa(appLive,'LIVE')
                error('not the proper input type')
            end
            app.MO = appLive.IL;

            app.setState('off')    
        end

        % Button pushed function: leftButton, rightButton
        function arrowButtonsPushed(app, event)
            val =  app.stepEditField.Value*1e-9;
            if strcmpi(event.Source.Tag,'right')
                app.lambda = app.lambda + val;
            else
                app.lambda = app.lambda - val;
            end                

        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.lambda = event.Source.Value;
            
        end

        % Button pushed function: saveNameButton
        function saveNameButtonPushed(app, event)
            newItem = strrep(app.saveNameEditField.Value, ' ', '');  % remove spaces in the name
            

            if isempty(newItem)
                return;  % Do nothing if the input is empty
            end
            

            % Append new item to existing list
            currentItems = app.lambdaListBox.Items;

            isDuplicate = any(strcmpi(currentItems, newItem));
            
            if isDuplicate
                % Ask user if they want to replace it
                answer = questdlg( ...
                    sprintf('The item \"%s\" already exists.\nDo you want to replace it?', newItem), ...
                    'Duplicate Item', ...
                    'Yes', 'No', 'No');
        
                if strcmp(answer, 'No')
                    return;
                end
        
                % Replace the existing item (keep position)
                index = find(strcmpi(currentItems, newItem), 1);
                currentItems{index} = newItem;
                app.lambdaListBox.Items = currentItems;
                app.lambdaListBox.Value = newItem;
        
            else  % append new item         
            
                app.lambdaListBox.Items = [currentItems, {newItem}];
                app.lambdaListBox.Value = newItem;
            end

            % update position list
            app.nameList.(['w' newItem]) = [app.MO.lambda];

            % Optional: clear the input field
            %app.InputField.Value = '';


        end

        % Button pushed function: eraseButton
        function eraseButtonPushed(app, event)
            selectedItem = app.lambdaListBox.Value;       % Get selected item
            currentItems = app.lambdaListBox.Items;       % Get current list of items

            % If nothing is selected, do nothing
            if isempty(selectedItem)
                return;
            end
        
            % Show confirmation dialog
            answer = questdlg( ...
                sprintf('Are you sure you want to delete:\n\"%s\"?', selectedItem), ...
                'Confirm Deletion', ...
                'Yes', 'No', 'No');
        
            if strcmp(answer, 'Yes')            % Remove the selected item
                newItems = currentItems(~strcmp(currentItems, selectedItem));
            
                % Update the listbox
                app.lambdaListBox.Items = newItems;
                % and the postList
                app.nameList = rmfield(app.nameList,['w' selectedItem]);
            
                % Optional: set a new selected value
                if ~isempty(newItems)
                    app.lambdaListBox.Value = newItems{1};  % Select first remaining item
                else
                    app.lambdaListBox.Value = '';
                end


            end
        end

        % Callback function: lambdaListBox, nextNameButton, prevNameButton
        function goButtonPushed(app, event)
            switch event.Source.Tag 
                case 'next'
                    idx = find(strcmp(app.lambdaListBox.Items, app.lambdaListBox.Value));
                    items = app.lambdaListBox.Items;
                    Ni = numel(items);
                    app.lambdaListBox.Value = items{mod(idx + 1-1, Ni)+1};
                case 'prev'        
                    idx = find(strcmp(app.lambdaListBox.Items, app.lambdaListBox.Value));
                    items = app.lambdaListBox.Items;
                    Ni = numel(items);
                    app.lambdaListBox.Value = items{mod(idx - 1-1, Ni)+1};
            end                    
                    
            selectedItem = app.lambdaListBox.Value;
            wl = app.nameList.(['w' selectedItem]);
            app.lambda = wl;


        end

        % Button pushed function: Lamp
        function LampButtonPushed(app, event)
            switch app.state
                case 'off'
                    app.setState('running')
                    rc = app.MO.turn('on');
                    if rc ~= 0
                        app.setState('error')
                        error('no monochromator found')
                    else
                        app.setState('on')
                    end
                otherwise
                    app.setState('running')
                    rc = app.MO.turn('off');
                    if rc ~= 0
                        app.setState('error')
                        error('no monochromator found')
                    else
                        app.setState('off')
                    end
            end

        end

        % Value changed function: widthEditField
        function widthEditFieldValueChanged(app, event)
            value = app.widthEditField.Value;
            app.MO.lambdaWidth = value;
            try
            app.widthEditField.Value = lambda2nm(app.MO.lambdaWidth);
            catch
                pause(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'x, y stage control';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'go.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_Navigator = uigridlayout(app.GridLayout);
            app.leftButton = uibutton(app.GridLayout_Navigator, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.leftButton.Tag = 'left';
            app.leftButton.Icon = fullfile(pathToMLAPP, 'icons', 'left.svg');
            app.leftButton.Text = '';
            app.rightButton = uibutton(app.GridLayout_Navigator, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.rightButton.Tag = 'right';
            app.rightButton.Icon = fullfile(pathToMLAPP, 'icons', 'right.svg');
            app.rightButton.Text = '';
            app.GridLayout_step = uigridlayout(app.GridLayout_Navigator);
            app.stepEditField = uieditfield(app.GridLayout_step, 'numeric');
            app.stepEditField.Limits = [0 Inf];
            app.stepEditField.RoundFractionalValues = 'on';
            app.stepEditField.ValueDisplayFormat = '%d';
            app.stepEditField.Value = 20;
            app.nmLabel2 = uilabel(app.GridLayout_step);
            app.nmLabel2.Text = 'nm';
            app.GridLayout_lambdaDisplay = uigridlayout(app.GridLayout);
            app.lambdaEditField = uieditfield(app.GridLayout_lambdaDisplay, 'numeric');
            app.lambdaEditField.Limits = [200 999];
            app.lambdaEditField.ValueDisplayFormat = '%5.0f';
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Value = 550;
            app.Lamp = uibutton(app.GridLayout_lambdaDisplay, 'push');
            app.Lamp.ButtonPushedFcn = createCallbackFcn(app, @LampButtonPushed, true);
            app.Lamp.Text = '';
            app.nmLabel = uilabel(app.GridLayout_lambdaDisplay);
            app.nmLabel.Text = 'nm';
            app.widthEditField = uieditfield(app.GridLayout_lambdaDisplay, 'numeric');
            app.widthEditField.Limits = [20 100];
            app.widthEditField.ValueDisplayFormat = '%5.0f';
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.widthEditField.Value = 20;
            app.nmLabel_2 = uilabel(app.GridLayout_lambdaDisplay);
            app.nmLabel_2.Text = 'nm';
            app.widthLabel = uilabel(app.GridLayout_lambdaDisplay);
            app.widthLabel.Text = 'width';
            app.GridLayout_buttons = uigridlayout(app.GridLayout);
            app.saveNameEditField = uieditfield(app.GridLayout_buttons, 'text');
            app.saveNameEditField.Value = 'Green';
            app.nextNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.nextNameButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.nextNameButton.Tag = 'next';
            app.nextNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'down.svg');
            app.nextNameButton.Text = '';
            app.eraseButton = uibutton(app.GridLayout_buttons, 'push');
            app.eraseButton.ButtonPushedFcn = createCallbackFcn(app, @eraseButtonPushed, true);
            app.eraseButton.Icon = fullfile(pathToMLAPP, 'icons', 'clear.svg');
            app.eraseButton.Text = '';
            app.saveNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.saveNameButton.ButtonPushedFcn = createCallbackFcn(app, @saveNameButtonPushed, true);
            app.saveNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'Save_off.svg');
            app.saveNameButton.Text = '';
            app.lambdaListBox = uilistbox(app.GridLayout_buttons);
            app.lambdaListBox.Items = {};
            app.lambdaListBox.DoubleClickedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.lambdaListBox.Value = {};
            app.prevNameButton = uibutton(app.GridLayout_buttons, 'push');
            app.prevNameButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.prevNameButton.Tag = 'prev';
            app.prevNameButton.Icon = fullfile(pathToMLAPP, 'icons', 'up.svg');
            app.prevNameButton.Text = '';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'lambdaControlTitle.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lambdaControlWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end