classdef piezoControlWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        velocityEditField     matlab.ui.control.NumericEditField
        VEditFieldLabel       matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        JoystickButton        matlab.ui.control.StateButton
        Image                 matlab.ui.control.Image
        GridLayout_buttons    matlab.ui.container.GridLayout
        posListBox            matlab.ui.control.ListBox
        savePosButton         matlab.ui.control.Button
        eraseButton           matlab.ui.control.Button
        goButton              matlab.ui.control.Button
        savePosNameEditField  matlab.ui.control.EditField
        GridLayout_Position   matlab.ui.container.GridLayout
        Lamp                  matlab.ui.control.Lamp
        serialNumberDropDown  matlab.ui.control.DropDown
        setzeroButton         matlab.ui.control.Button
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        GridLayout_navigator  matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        motionLamp            matlab.ui.control.Lamp
        GridLayout_step       matlab.ui.container.GridLayout
        umLabel               matlab.ui.control.Label
        stepEditField         matlab.ui.control.NumericEditField
        rightButton           matlab.ui.control.Button
        downButton            matlab.ui.control.Button
        leftButton            matlab.ui.control.Button
        upButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Stage % Moving stage to be controlled
        X0 (1,1) double = 0 % x of the origin values, according to the app
        Y0 (1,1) double = 0 % y of the origin values, according to the app
        posList struct = struct()  % ASOLUTE values of the positions, as stored in the stage object
    end

    properties(Dependent)
        X (1,1) double  % RELATVE values, as displayed in the app EditFields
        Y (1,1) double  % 
    end

    properties(Access = private)
        lampColorOn = [0.60    0.77    0.60];   % green
        lampColorOff = [0.9 0.9 0.9];  % light gray
        lampColorWait = [0.7 0.5,0.3]; % orange
        lampColorError = [0.8    0.40    0.40];   % red
        lampColorRunning = [0.7 0.5,0.3]; % orange
    end

    methods (Access = private)
        
        function updatePosition(app)
            app.X =  app.Stage.X - app.X0;
            app.Y =  app.Stage.Y - app.Y0;
        end

        function val2 = lampState(app,val)
            % set or get the lamp state
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'On','Off','Wait','Error','Running','on','off','wait','running','error',''})} = ''% state of the camera lamp button
            end
            if ~strcmp(val,'')
                val(1) = upper(val(1)); % uppercase the first letter
                app.Lamp.Color = app.("lampColor"+val);
                app.Lamp.UserData.state = val;
            elseif nargout  % returns de lamp state
                val2 = app.Lamp.UserData.state;
            end
            drawnow
        end

    end

    methods
        
        function val = get.X(app)
            val = app.XEditField.Value;            
        end
        function val = get.Y(app)
            val = app.YEditField.Value;            
        end

        function set.X(app,val)
            app.XEditField.Value = val;
        end
        function set.Y(app,val)
            app.YEditField.Value = val;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.motionLamp.Color = app.lampColorOff;

            % IDENTIFY WHICH TYPE PIEZO STAGE IS IMPLEMENTED

            % enumerate the USB devices and the associated PI serial numbers if any:
            SNlist = PIstage.serialNumbersAvailable();
            SNlist = SNlist(~cellfun(@isempty, SNlist)); % remove the empty cells, ie USB devices that don't have PI serial numbers.
            
            if ~isempty(SNlist)

                app.Stage = PIstage();

                app.serialNumberDropDown.Items = [{'none'}; SNlist];
                %app.posList.Origin = app.PI.getPosition();
                return
            end

             % try and find a DAQ card and the associated model
            daqreset
            d = daqlist;
            if ~isempty(d)
                app.Stage = DAQstage();
                
                models = cellstr(d.Model);
                %app.PI = DAQ_Piezo();
                app.serialNumberDropDown.Items = [{'none'};models ];
                return
            end

            app.lampState('error')  

%            app.posList.Origin = app.PI.getPosition();

  
        end

        % Button pushed function: downButton, leftButton, rightButton, 
        % ...and 1 other component
        function arrowButtonsPushed(app, event)
            %val =  1e-3*app.stepEditField.Value;
            val =  app.stepEditField.Value;
            app.motionLamp.Color = app.lampColorWait;
            switch event.Source.Tag
                case 'left'
                    app.Stage.moveBy('X', -val)
                case 'right'
                    app.Stage.moveBy('X', val)
                case 'down'
                    app.Stage.moveBy('Y', val)
                case 'up'
                    app.Stage.moveBy('Y', -val)
            end
            app.updatePosition()
            app.motionLamp.Color = app.lampColorOff;

        end

        % Value changed function: XEditField
        function posEditFieldsValueChanged(app, event)
            app.Stage.moveTo('x', app.X+app.X0, 'y', app.Y+app.Y0);
            app.updatePosition()
            
        end

        % Button pushed function: savePosButton
        function savePosButtonPushed(app, event)
            newItem = strrep(app.savePosNameEditField.Value, ' ', '');  % remove spaces in the name
            

            if isempty(newItem)
                return;  % Do nothing if the input is empty
            end
            

            % Append new item to existing list
            currentItems = app.posListBox.Items;

            isDuplicate = any(strcmpi(currentItems, newItem));
            
            if isDuplicate
                % Ask user if they want to replace it
                answer = questdlg( ...
                    sprintf('The item \"%s\" already exists.\nDo you want to replace it?', newItem), ...
                    'Duplicate Item', ...
                    'Yes', 'No', 'No');
        
                if strcmp(answer, 'No')
                    return;
                end
        
                % Replace the existing item (keep position)
                index = find(strcmpi(currentItems, newItem), 1);
                currentItems{index} = newItem;
                app.posListBox.Items = currentItems;
                app.posListBox.Value = newItem;
        
            else  % append new item         
            
                app.posListBox.Items = [currentItems, {newItem}];
                app.posListBox.Value = newItem;
            end

            % update position list
            app.posList.(newItem) = [app.Stage.X, app.Stage.Y];

            % Optional: clear the input field
            %app.InputField.Value = '';


        end

        % Button pushed function: eraseButton
        function eraseButtonPushed(app, event)
            selectedItem = app.posListBox.Value;       % Get selected item
            currentItems = app.posListBox.Items;       % Get current list of items
            if strcmpi(selectedItem,'origin')
                % don't erase the origin item
                return
            end
            % If nothing is selected, do nothing
            if isempty(selectedItem)
                return;
            end
        
            % Show confirmation dialog
            answer = questdlg( ...
                sprintf('Are you sure you want to delete position:\n\"%s\"?', selectedItem), ...
                'Confirm Deletion', ...
                'Yes', 'No', 'No');
        
            if strcmp(answer, 'Yes')            % Remove the selected item
                newItems = currentItems(~strcmp(currentItems, selectedItem));
            
                % Update the listbox
                app.posListBox.Items = newItems;
                % and the postList
                app.posList = rmfield(app.posList,selectedItem);
            
                % Optional: set a new selected value
                if ~isempty(newItems)
                    app.posListBox.Value = newItems{1};  % Select first remaining item
                else
                    app.posListBox.Value = '';
                end


            end
        end

        % Callback function: goButton, posListBox
        function goButtonPushed(app, event)
            selectedItem = app.posListBox.Value;
            pos = app.posList.(selectedItem);
            app.Stage.moveTo(pos);
            %app.X = pos(1) - app.X0;
            %app.Y = pos(2) - app.Y0;
            app.updatePosition()
        end

        % Button pushed function: setzeroButton
        function setzeroButtonPushed(app, event)
            app.X0 = app.X;
            app.Y0 = app.Y;
            app.posList.Origin = [app.X0, app.Y0];
            app.updatePosition()
        end

        % Value changed function: serialNumberDropDown
        function serialNumberDropDownValueChanged(app, event)
            value = app.serialNumberDropDown.Value;
            rc = app.Stage.connect(value,'XY');
            
            if rc == 0
                app.lampState('On')  
                app.velocityEditField.Value = app.Stage.getVelocity();
                app.XEditField.Value = app.Stage.X;
                app.YEditField.Value = app.Stage.Y;
            else
                app.lampState('Error')  
                
            end

        end

        % Value changed function: JoystickButton
        function JoystickButtonValueChanged(app, event)
            value = app.JoystickButton.Value;
            if value
                app.Stage.turnJoystick('On')
                app.JoystickButton.BackgroundColor = [0.3 0.9 0.6];
                app.GridLayout_navigator.Visible = "off";
            else
                app.Stage.turnJoystick('Off')
                app.JoystickButton.BackgroundColor = [0.10,0.27,0.22];
                app.GridLayout_navigator.Visible = "on";
            end                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Stage.disconnect()
            delete(app)
            
        end

        % Value changed function: velocityEditField
        function velocityEditFieldValueChanged(app, event)
            value = app.velocityEditField.Value;
            app.Stage.setVelocity(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'x, y stage control';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'go.svg');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_navigator = uigridlayout(app.GridLayout);
            app.upButton = uibutton(app.GridLayout_navigator, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.upButton.Tag = 'up';
            app.upButton.Icon = fullfile(pathToMLAPP, 'icons', 'up.svg');
            app.upButton.Text = '';
            app.leftButton = uibutton(app.GridLayout_navigator, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.leftButton.Tag = 'left';
            app.leftButton.Icon = fullfile(pathToMLAPP, 'icons', 'left.svg');
            app.leftButton.Text = '';
            app.downButton = uibutton(app.GridLayout_navigator, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.downButton.Tag = 'down';
            app.downButton.Icon = fullfile(pathToMLAPP, 'icons', 'down.svg');
            app.downButton.Text = '';
            app.rightButton = uibutton(app.GridLayout_navigator, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonsPushed, true);
            app.rightButton.Tag = 'right';
            app.rightButton.Icon = fullfile(pathToMLAPP, 'icons', 'right.svg');
            app.rightButton.Text = '';
            app.GridLayout_step = uigridlayout(app.GridLayout_navigator);
            app.stepEditField = uieditfield(app.GridLayout_step, 'numeric');
            app.stepEditField.Limits = [0 Inf];
            app.stepEditField.RoundFractionalValues = 'on';
            app.stepEditField.ValueDisplayFormat = '%d';
            app.stepEditField.Value = 10;
            app.umLabel = uilabel(app.GridLayout_step);
            app.umLabel.Text = 'µm';
            app.GridLayout4 = uigridlayout(app.GridLayout_navigator);
            app.motionLamp = uilamp(app.GridLayout4);
            app.GridLayout_Position = uigridlayout(app.GridLayout);
            app.YEditFieldLabel = uilabel(app.GridLayout_Position);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout_Position, 'numeric');
            app.YEditField.ValueDisplayFormat = '%5.1f';
            app.XEditFieldLabel = uilabel(app.GridLayout_Position);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout_Position, 'numeric');
            app.XEditField.ValueDisplayFormat = '%5.1f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @posEditFieldsValueChanged, true);
            app.setzeroButton = uibutton(app.GridLayout_Position, 'push');
            app.setzeroButton.ButtonPushedFcn = createCallbackFcn(app, @setzeroButtonPushed, true);
            app.setzeroButton.Text = 'set zero';
            app.serialNumberDropDown = uidropdown(app.GridLayout_Position);
            app.serialNumberDropDown.Items = {'none'};
            app.serialNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @serialNumberDropDownValueChanged, true);
            app.serialNumberDropDown.Value = 'none';
            app.Lamp = uilamp(app.GridLayout_Position);
            app.GridLayout_buttons = uigridlayout(app.GridLayout);
            app.savePosNameEditField = uieditfield(app.GridLayout_buttons, 'text');
            app.savePosNameEditField.Value = 'Pos1';
            app.goButton = uibutton(app.GridLayout_buttons, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Icon = fullfile(pathToMLAPP, 'icons', 'go.svg');
            app.goButton.Text = 'Go';
            app.eraseButton = uibutton(app.GridLayout_buttons, 'push');
            app.eraseButton.ButtonPushedFcn = createCallbackFcn(app, @eraseButtonPushed, true);
            app.eraseButton.Icon = fullfile(pathToMLAPP, 'icons', 'clear.svg');
            app.eraseButton.Text = '';
            app.savePosButton = uibutton(app.GridLayout_buttons, 'push');
            app.savePosButton.ButtonPushedFcn = createCallbackFcn(app, @savePosButtonPushed, true);
            app.savePosButton.Icon = fullfile(pathToMLAPP, 'icons', 'Save_off.svg');
            app.savePosButton.Text = '';
            app.posListBox = uilistbox(app.GridLayout_buttons);
            app.posListBox.Items = {'Origin'};
            app.posListBox.DoubleClickedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.posListBox.Value = 'Origin';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'microMotionControlTitle.svg');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.JoystickButton = uibutton(app.GridLayout2, 'state');
            app.JoystickButton.ValueChangedFcn = createCallbackFcn(app, @JoystickButtonValueChanged, true);
            app.JoystickButton.Text = 'Joystick  Off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.VEditFieldLabel = uilabel(app.GridLayout3);
            app.VEditFieldLabel.Text = 'V';
            app.velocityEditField = uieditfield(app.GridLayout3, 'numeric');
            app.velocityEditField.ValueChangedFcn = createCallbackFcn(app, @velocityEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piezoControlWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end