classdef timeLapsePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout_startButton    matlab.ui.container.GridLayout
        startButton               matlab.ui.control.StateButton
        GridLayout_bottom         matlab.ui.container.GridLayout
        GridLayout_liveTimes      matlab.ui.container.GridLayout
        sLabel                    matlab.ui.control.Label
        EndingTimeEditField       matlab.ui.control.EditField
        EndingtimeEditFieldLabel  matlab.ui.control.Label
        CountDownEditField        matlab.ui.control.EditField
        TimebeforenextimageLabel  matlab.ui.control.Label
        GridLayout_imageCounting  matlab.ui.container.GridLayout
        NimEditField              matlab.ui.control.NumericEditField
        slashLabel                matlab.ui.control.Label
        jimEditField              matlab.ui.control.NumericEditField
        SettingtimelapseparametersLabel  matlab.ui.control.Label
        NEditField                matlab.ui.control.NumericEditField
        dtEditField               matlab.ui.control.NumericEditField
        sEditField_2Label         matlab.ui.control.Label
        sEditField                matlab.ui.control.NumericEditField
        sEditFieldLabel           matlab.ui.control.Label
        minEditField              matlab.ui.control.NumericEditField
        minEditFieldLabel         matlab.ui.control.Label
        hEditField                matlab.ui.control.NumericEditField
        hEditFieldLabel           matlab.ui.control.Label
        NLabel                    matlab.ui.control.Label
        TimeintervalLabel         matlab.ui.control.Label
        TotaldurationLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        lastEdited
        updating
        appLive
    end

    properties(GetAccess = public)
        N   % returned total numer of images
        dt  % returned time interval
        t0   = [] % depart time
    end
    methods (Access = private)
            
        function updateTimestampAndCalculate(app,changedField)
            app.lastEdited.(changedField) = datetime;
            app.calculateThird();
        end
    
        function calculateThird(app)
            if app.updating
                return;
            end
            app.updating = true;
            
            fields = {'T', 'dt', 'N'};
            [~, idx] = min([app.lastEdited.T, app.lastEdited.dt, app.lastEdited.N]);
            fieldToCalculate = fields{idx};
            
            T = app.hEditField.Value*3600 + app.minEditField.Value*60 + app.sEditField.Value; % Total duration in seconds
            dt = app.dtEditField.Value;
            N = app.NEditField.Value;
            
            switch fieldToCalculate
                case 'T'
                    if ~isempty(dt) && ~isempty(N) && N>1
                        totalSec = dt*(N-1);
                        app.hEditField.Value = floor(totalSec/3600);
                        app.minEditField.Value = mod(totalSec/60,60);
                    end
                case 'dt'
                    if ~isempty(T) && ~isempty(N) && N>1
                        app.dtEditField.Value = T/(N-1);
                    end
                case 'N'
                    if ~isempty(T) && ~isempty(dt) && dt>0
                        app.NEditField.Value = floor(T/dt) + 1;
                    end
            end
            app.updating = false;
        end

    end    

    methods

        function setNextImageTime(app,nextTime)
            if istext(nextTime)
                app.CountDownEditField.Value = nextTime;
            else % a datetime            
                dtNext = seconds(nextTime-datetime("now"));
                app.CountDownEditField.Value = sprintf('%d',ceil(dtNext));
            end
        end

        function setImageNumber(app,num)
            app.jimEditField.Value = num;

            if num == app.N
                app.GridLayout_bottom.BackgroundColor = [0.8 1 0.8];
                app.GridLayout_imageCounting.BackgroundColor = [0.8 1 0.8];
                app.GridLayout_liveTimes.BackgroundColor = [0.8 1 0.8];
            end
            
        end
        
        function toogleStartButton(app,val)
            arguments
                app
                val (1,:) char {mustBeMember(val,{'start','stop'})}
            end
            switch val
                case 'start'
                    app.startButton.Icon = 'LIVE.svg';
                    app.startButton.Text = 'start';
                    app.startButton.Value = 0;

                case 'stop'
                    app.startButton.Icon = 'STOP.svg';
                    app.startButton.Text = 'stop';
                    app.startButton.Value = 1;
            end
            
        end
    
    end        
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appLive)
            app.appLive = appLive;
            app.lastEdited.T = datetime;
            app.lastEdited.dt = datetime;
            app.lastEdited.N = datetime;
            app.GridLayout_bottom.Visible = "off";
            %uiwait(app.UIFigure);

        end

        % Value changed function: NEditField, dtEditField, hEditField, 
        % ...and 2 other components
        function hEditFieldValueChanged(app, event)
            app.updateTimestampAndCalculate(event.Source.Tag)           
        end

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            if event.Source.Value % press start
                app.N = app.NEditField.Value;
                app.dt = app.dtEditField.Value;
                app.NimEditField.Value = app.N;
                dt_char = char(datetime('now')+seconds(app.dt*app.N), 'dd/MM hh:mm:ss');
                app.EndingTimeEditField.Value = dt_char;
                app.GridLayout_bottom.Visible = "on";
                
                app.toogleStartButton('stop')
                
                app.appLive.toggleSavePanel(false)
                app.appLive.setAcqMode('TimeLapse')
                app.t0 = datetime('now');
                %uiresume(app.UIFigure); % Free this app to make the parent app LIVE.app continue
            else  % press stop
                app.N = 0;
                app.toogleStartButton('start')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Time Lapse Window';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TotaldurationLabel = uilabel(app.GridLayout);
            app.TotaldurationLabel.Text = 'Total duration';
            app.TimeintervalLabel = uilabel(app.GridLayout);
            app.TimeintervalLabel.Text = 'Time interval';
            app.NLabel = uilabel(app.GridLayout);
            app.NLabel.Text = {'Total number'; 'of images'};
            app.hEditFieldLabel = uilabel(app.GridLayout);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.GridLayout, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Tag = 'T';
            app.minEditFieldLabel = uilabel(app.GridLayout);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.minEditField.Tag = 'T';
            app.sEditFieldLabel = uilabel(app.GridLayout);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.GridLayout, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.sEditField.Tag = 'T';
            app.sEditField_2Label = uilabel(app.GridLayout);
            app.sEditField_2Label.Text = 's';
            app.dtEditField = uieditfield(app.GridLayout, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.dtEditField.Tag = 'dt';
            app.NEditField = uieditfield(app.GridLayout, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.NEditField.Tag = 'N';
            app.SettingtimelapseparametersLabel = uilabel(app.GridLayout);
            app.SettingtimelapseparametersLabel.Text = 'Setting time lapse parameters';
            app.GridLayout_bottom = uigridlayout(app.GridLayout);
            app.GridLayout_imageCounting = uigridlayout(app.GridLayout_bottom);
            app.jimEditField = uieditfield(app.GridLayout_imageCounting, 'numeric');
            app.slashLabel = uilabel(app.GridLayout_imageCounting);
            app.slashLabel.Text = '/';
            app.NimEditField = uieditfield(app.GridLayout_imageCounting, 'numeric');
            app.GridLayout_liveTimes = uigridlayout(app.GridLayout_bottom);
            app.TimebeforenextimageLabel = uilabel(app.GridLayout_liveTimes);
            app.TimebeforenextimageLabel.Text = 'Time before next image';
            app.CountDownEditField = uieditfield(app.GridLayout_liveTimes, 'text');
            app.EndingtimeEditFieldLabel = uilabel(app.GridLayout_liveTimes);
            app.EndingtimeEditFieldLabel.Text = 'Ending time';
            app.EndingTimeEditField = uieditfield(app.GridLayout_liveTimes, 'text');
            app.sLabel = uilabel(app.GridLayout_liveTimes);
            app.sLabel.Text = 's';
            app.GridLayout_startButton = uigridlayout(app.GridLayout);
            app.startButton = uibutton(app.GridLayout_startButton, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Icon = fullfile(pathToMLAPP, 'icons', 'Live_off.svg');
            app.startButton.Text = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeLapsePanel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end